classdef cbraAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LoginView                       matlab.ui.container.Panel
        UMGLogo1                        matlab.ui.control.Image
        UMGLogo2                        matlab.ui.control.Image
        LoginForm                       matlab.ui.container.Panel
        RefreshConn                     matlab.ui.control.Button
        LoginButton                     matlab.ui.control.Button
        PasswordHTML                    matlab.ui.control.HTML
        PasswordHTMLLabel               matlab.ui.control.Label
        UsernameField                   matlab.ui.control.EditField
        UsernameEditField_2Label        matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        WELCOMEinCBRALabel              matlab.ui.control.Label
        AdminView                       matlab.ui.container.TabGroup
        ManageusersTab                  matlab.ui.container.Tab
        ListaUtenti                     matlab.ui.control.Table
        DatiutentePanel                 matlab.ui.container.Panel
        AnnullaButton                   matlab.ui.control.Button
        SalvaUtenteButton               matlab.ui.control.Button
        NuovoUtenteButton               matlab.ui.control.Button
        UtenteSavingStatus              matlab.ui.control.Lamp
        UtenteSaveUserMsg               matlab.ui.control.TextArea
        LivelloDropDown                 matlab.ui.control.DropDown
        SecuritylevelLabel              matlab.ui.control.Label
        CenterUserDropDown              matlab.ui.control.DropDown
        CenterLabel                     matlab.ui.control.Label
        EmailEditField                  matlab.ui.control.EditField
        NomeEditField_2Label_4          matlab.ui.control.Label
        PasswordEditField               matlab.ui.control.EditField
        NomeEditField_2Label_5          matlab.ui.control.Label
        UsernameEditField               matlab.ui.control.EditField
        NomeEditField_2Label_6          matlab.ui.control.Label
        CodiceFiscaleEditField          matlab.ui.control.EditField
        NomeEditField_2Label_3          matlab.ui.control.Label
        CognomeEditField                matlab.ui.control.EditField
        NomeEditField_2Label_7          matlab.ui.control.Label
        NomeEditField                   matlab.ui.control.EditField
        NomeEditField_2Label_8          matlab.ui.control.Label
        NomeUtenteDropDown              matlab.ui.control.DropDown
        UserLabel                       matlab.ui.control.Label
        ManagecentersTab                matlab.ui.container.Tab
        ListaCentri                     matlab.ui.control.Table
        CentersdataPanel                matlab.ui.container.Panel
        AnnullaCentroButton             matlab.ui.control.Button
        SalvaCentroButton               matlab.ui.control.Button
        NuovoCentroButton               matlab.ui.control.Button
        CentroSavingStatus              matlab.ui.control.Lamp
        CentroSaveUserMsg               matlab.ui.control.TextArea
        NumeroPazientiEditField         matlab.ui.control.NumericEditField
        NumeromediciLabel_2             matlab.ui.control.Label
        NumeroMediciEditField           matlab.ui.control.NumericEditField
        NumeromediciLabel               matlab.ui.control.Label
        CenteradministratorDropDown     matlab.ui.control.DropDown
        CenteradministratorDropDownLabel  matlab.ui.control.Label
        LocationCityEditField           matlab.ui.control.EditField
        LocationCityEditFieldLabel      matlab.ui.control.Label
        IndirizzoEditField              matlab.ui.control.EditField
        AddressEditField_2Label         matlab.ui.control.Label
        NomeCentroEditField             matlab.ui.control.EditField
        NomeEditField_2Label_2          matlab.ui.control.Label
        CenterDropDown                  matlab.ui.control.DropDown
        CenterDropDownLabel             matlab.ui.control.Label
        UsagemonitorTab                 matlab.ui.container.Tab
        UsageMonitorPanel               matlab.ui.container.Panel
        SearchUsageButton               matlab.ui.control.Button
        UsagedistributionPanel          matlab.ui.container.Panel
        ErrorTable                      matlab.ui.control.Table
        UsageAxes                       matlab.ui.control.UIAxes
        RegisterederrorsEditField       matlab.ui.control.NumericEditField
        RegisterederrorsEditFieldLabel  matlab.ui.control.Label
        ExportUsageButton               matlab.ui.control.Button
        LoginsdistributionPanel         matlab.ui.container.Panel
        LoginTable                      matlab.ui.control.Table
        LoginAxes                       matlab.ui.control.UIAxes
        ToDatePicker                    matlab.ui.control.DatePicker
        ToDatePickerLabel               matlab.ui.control.Label
        FromDatePicker                  matlab.ui.control.DatePicker
        FromDatePickerLabel             matlab.ui.control.Label
        CenterView                      matlab.ui.container.TabGroup
        GestioneMediciTab               matlab.ui.container.Tab
        ListaMediciCentro               matlab.ui.control.Table
        CenterinfoPanel                 matlab.ui.container.Panel
        NumeroPazienti                  matlab.ui.control.Label
        NumeroMedici                    matlab.ui.control.Label
        CenterNameLabel                 matlab.ui.control.Label
        NewClinic                       matlab.ui.container.Panel
        AnnullaMedicoButton             matlab.ui.control.Button
        SalvaMedicoButton               matlab.ui.control.Button
        NuovoMedicoButton               matlab.ui.control.Button
        MedicoSavingStatus              matlab.ui.control.Lamp
        MedicoSaveUserMsg               matlab.ui.control.TextArea
        EmailMedicoEditField            matlab.ui.control.EditField
        EmailEditField_2Label           matlab.ui.control.Label
        PasswordMedicoEditField         matlab.ui.control.EditField
        PasswordEditField_2Label        matlab.ui.control.Label
        UsernameMedicoEditField         matlab.ui.control.EditField
        UsernameEditField_2Label_2      matlab.ui.control.Label
        CFMedicoEditField               matlab.ui.control.EditField
        NomeEditField_2Label_9          matlab.ui.control.Label
        CognomeMedicoEditField          matlab.ui.control.EditField
        NomeEditField_2Label_10         matlab.ui.control.Label
        NomeMedicoEditField             matlab.ui.control.EditField
        NomeEditField_2Label_11         matlab.ui.control.Label
        DeleteSelectedButton            matlab.ui.control.Button
        MediciDropDown                  matlab.ui.control.DropDown
        UserLabel_2                     matlab.ui.control.Label
        ClinicView                      matlab.ui.container.TabGroup
        InsertPatientTab                matlab.ui.container.Tab
        InsertPatientPanel              matlab.ui.container.Panel
        IndexHospitalizationPanel       matlab.ui.container.Panel
        HospitalizationText_1           matlab.ui.control.TextArea
        HospitalizationSavingStatus_1   matlab.ui.control.Lamp
        ResetButtonFH_1                 matlab.ui.control.Button
        SalvaPrimoRicoveroButton_1      matlab.ui.control.Button
        ClinicalinformationPanel_1      matlab.ui.container.Panel
        TimiEditField_1                 matlab.ui.control.NumericEditField
        CalculateTIMIButtonFH           matlab.ui.control.Button
        StenosisEditField_1             matlab.ui.control.NumericEditField
        Anginaepisodesin24hEditField_1  matlab.ui.control.NumericEditField
        EKGSTdevmmEditField_1           matlab.ui.control.NumericEditField
        ASAusein7daysDropDown_1         matlab.ui.control.DropDown
        LBBBDropDown_1                  matlab.ui.control.DropDown
        HypercolDropDown_1              matlab.ui.control.DropDown
        KillipDropDown_1                matlab.ui.control.DropDown
        HypertensDropDown_1             matlab.ui.control.DropDown
        DyslipidemiaDropDown            matlab.ui.control.DropDown
        SmokeDropDown_1                 matlab.ui.control.DropDown
        PrevCADDropDown_1               matlab.ui.control.DropDown
        ObesityDropDown_1               matlab.ui.control.DropDown
        FamiliarityDropDown_1           matlab.ui.control.DropDown
        DiabeteDropDown_1               matlab.ui.control.DropDown
        stMedCoDate_1                   matlab.ui.control.DatePicker
        PositivecardiacmarkerDropDown_1  matlab.ui.control.DropDown
        TimetotreatmhoursEditField_1    matlab.ui.control.NumericEditField
        HRbpmEditField_1                matlab.ui.control.NumericEditField
        DyastolicBPmmHgEditField_1      matlab.ui.control.NumericEditField
        SystolicBPmmHgEditField_1       matlab.ui.control.NumericEditField
        STEMIDropDown_1                 matlab.ui.control.DropDown
        WeightEditField_1               matlab.ui.control.NumericEditField
        EtaEditField_1                  matlab.ui.control.NumericEditField
        EKGSTdevmmEditField_3Label      matlab.ui.control.Label
        StemiNstemiLabel_1              matlab.ui.control.Label
        stMedCoDatePickerLabel          matlab.ui.control.Label
        StenosisEditFieldLabel          matlab.ui.control.Label
        Anginaepisodesin24hEditFieldLabel  matlab.ui.control.Label
        ASAusein7daysDropDownLabel      matlab.ui.control.Label
        PositivecardiacmarkerDropDownLabel  matlab.ui.control.Label
        HypercolLabel                   matlab.ui.control.Label
        HypertensDropDownLabel          matlab.ui.control.Label
        DyslipidemiaDropDownLabel       matlab.ui.control.Label
        FamiliarityDropDownLabel        matlab.ui.control.Label
        PrevAMILabel_2                  matlab.ui.control.Label
        LBBBDropDownLabel               matlab.ui.control.Label
        KillipDropDownLabel             matlab.ui.control.Label
        ObesityDropDownLabel            matlab.ui.control.Label
        SmokeDropDownLabel              matlab.ui.control.Label
        DiabeteLabel                    matlab.ui.control.Label
        DyastolicBPmmHgEditFieldLabel   matlab.ui.control.Label
        TimetotreatmhoursEditFieldLabel  matlab.ui.control.Label
        HRbpmEditFieldLabel             matlab.ui.control.Label
        SystolicBPLabel                 matlab.ui.control.Label
        WieightKgLabel                  matlab.ui.control.Label
        AgeLabel                        matlab.ui.control.Label
        STEMIDropDown_2Label            matlab.ui.control.Label
        CalculateAgeButton_1            matlab.ui.control.Button
        DateandtimeRevascularizationPanel_1  matlab.ui.container.Panel
        LocoEditField_1                 matlab.ui.control.EditField
        LocoEditFieldLabel              matlab.ui.control.Label
        RevsLabel_1                     matlab.ui.control.Label
        RevmLabel_1                     matlab.ui.control.Label
        RevhLabel_1                     matlab.ui.control.Label
        Revsecond_1                     matlab.ui.control.Spinner
        Revminute_1                     matlab.ui.control.Spinner
        Revhour_1                       matlab.ui.control.Spinner
        RevHourLabel_1                  matlab.ui.control.Label
        DateRev                         matlab.ui.control.DatePicker
        DateLabel_3                     matlab.ui.control.Label
        DateandtimeThrombolysisPanel_1  matlab.ui.container.Panel
        DrugEditField_1                 matlab.ui.control.EditField
        DrugEditFieldLabel              matlab.ui.control.Label
        TrombsLabel_1                   matlab.ui.control.Label
        TrombmLabel_1                   matlab.ui.control.Label
        TrombhLabel_1                   matlab.ui.control.Label
        Trombsecond_1                   matlab.ui.control.Spinner
        Trombminute_1                   matlab.ui.control.Spinner
        TrombHour_1                     matlab.ui.control.Spinner
        TrombHourLabel_1                matlab.ui.control.Label
        DateTromb_1                     matlab.ui.control.DatePicker
        DateLabel_2                     matlab.ui.control.Label
        DateandtimeAMIPanel_1           matlab.ui.container.Panel
        AMIsLabel_1                     matlab.ui.control.Label
        AMImLabel_1                     matlab.ui.control.Label
        AMIhLabel_1                     matlab.ui.control.Label
        AMIsecondEv_1                   matlab.ui.control.Spinner
        AMIminuteEv_1                   matlab.ui.control.Spinner
        AMIhourEv_1                     matlab.ui.control.Spinner
        HourLabel_1                     matlab.ui.control.Label
        DateEvent_1                     matlab.ui.control.DatePicker
        DateLabel                       matlab.ui.control.Label
        DateandtimehospitalizationPanel_1  matlab.ui.container.Panel
        HsLabel_1                       matlab.ui.control.Label
        HmLabel_1                       matlab.ui.control.Label
        HhLabel_1                       matlab.ui.control.Label
        HsecondH_1                      matlab.ui.control.Spinner
        HminuteH_1                      matlab.ui.control.Spinner
        HhourH_1                        matlab.ui.control.Spinner
        HourSpinnerLabel                matlab.ui.control.Label
        HDateH_1                        matlab.ui.control.DatePicker
        DateDatePickerLabel             matlab.ui.control.Label
        PatientPersonalDataPanel        matlab.ui.container.Panel
        PersonalDataSavingStatus        matlab.ui.control.Lamp
        DataMessageText                 matlab.ui.control.TextArea
        ResetButton                     matlab.ui.control.Button
        SalvaNuovoPazienteButton        matlab.ui.control.Button
        SelectCenterAdminDropDown       matlab.ui.control.DropDown
        SelectCenterAdminDropDownLabel  matlab.ui.control.Label
        SSNEditField                    matlab.ui.control.EditField
        PatientIDFCLabel_1              matlab.ui.control.Label
        CalculateFiscalCodeButton       matlab.ui.control.Button
        GenderButtonGroup               matlab.ui.container.ButtonGroup
        FButton                         matlab.ui.control.RadioButton
        MButton                         matlab.ui.control.RadioButton
        AddressEditField                matlab.ui.control.EditField
        AddressEditFieldLabel           matlab.ui.control.Label
        BirthDateEditField              matlab.ui.control.DatePicker
        BirthyDateLabel                 matlab.ui.control.Label
        BirthPlaceEditField             matlab.ui.control.DropDown
        SurnameEditFieldLabel_2         matlab.ui.control.Label
        SurnameEditField                matlab.ui.control.EditField
        SurnameEditFieldLabel           matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        ManagePatientsTab               matlab.ui.container.Tab
        ManagePatientPanel              matlab.ui.container.Panel
        HomePage                        matlab.ui.container.Panel
        AnonymizeDataSwitchLabel        matlab.ui.control.Label
        PazientiList                    matlab.ui.control.Table
        TemplateExcelButton             matlab.ui.control.Button
        AnonymizeDataSwitch             matlab.ui.control.Switch
        ImportExportText                matlab.ui.control.TextArea
        ExportDataButton                matlab.ui.control.Button
        ImportExportLamp                matlab.ui.control.Lamp
        ImportDataButton                matlab.ui.control.Button
        PatientPersonalDataPanel_2      matlab.ui.container.Panel
        ResetButton_3                   matlab.ui.control.Button
        SalvaPazienteButton             matlab.ui.control.Button
        AddressEditField_2              matlab.ui.control.EditField
        AddressEditField_2Label_2       matlab.ui.control.Label
        PersonalDataSavingStatus_2      matlab.ui.control.Lamp
        DataMessageText_2               matlab.ui.control.TextArea
        BirthDateEditField_2            matlab.ui.control.DatePicker
        BirthDateLabel_2                matlab.ui.control.Label
        SSNEditField_2                  matlab.ui.control.EditField
        PatientIDFCLabel_2              matlab.ui.control.Label
        BirthPlaceEditField_2           matlab.ui.control.DropDown
        SurnameEditFieldLabel_3         matlab.ui.control.Label
        SurnameEditField_2              matlab.ui.control.EditField
        SurnameEditField_2Label         matlab.ui.control.Label
        GenderButtonGroup_2             matlab.ui.container.ButtonGroup
        FButton_2                       matlab.ui.control.RadioButton
        MButton_2                       matlab.ui.control.RadioButton
        NameEditField_2                 matlab.ui.control.EditField
        NameEditField_2Label            matlab.ui.control.Label
        ControlPanel                    matlab.ui.container.Panel
        ControlHintAnalysis             matlab.ui.control.Label
        CardiacmarkerstrendButton       matlab.ui.control.Button
        LaboratoryDataButton            matlab.ui.control.Button
        PatientProfileIndexEventButton  matlab.ui.control.Button
        ReloadPatientData               matlab.ui.control.Button
        PazienteDropDown                matlab.ui.control.DropDown
        SelectpatientLabel              matlab.ui.control.Label
        SelectCenterDrop                matlab.ui.control.DropDown
        SelectCenterLabel               matlab.ui.control.Label
        HospitalizationPage             matlab.ui.container.Panel
        HospContainer                   matlab.ui.container.Panel
        HospitalizationsTable           matlab.ui.control.Table
        PlotMedPanel                    matlab.ui.container.Panel
        PlotHosp                        matlab.ui.control.UIAxes
        AnalysisButtonFH                matlab.ui.control.Button
        AcquisitionsButtonFH            matlab.ui.control.Button
        HomeButtonFH                    matlab.ui.control.Button
        HospitalizationPanel            matlab.ui.container.Panel
        HospitalizationSavingStatus_2   matlab.ui.control.Lamp
        HospitalizationText_2           matlab.ui.control.TextArea
        CancelHosp_2                    matlab.ui.control.Button
        SaveHospitalization_2           matlab.ui.control.Button
        SaveNewHospitalization_2        matlab.ui.control.Button
        ClinicalinformationPanel_2      matlab.ui.container.Panel
        TimiEditField_2                 matlab.ui.control.NumericEditField
        CalculateTIMIButton_2           matlab.ui.control.Button
        EKGSTdevmmEditField_2           matlab.ui.control.NumericEditField
        ASAusein7daysDropDown_2         matlab.ui.control.DropDown
        LBBBDropDown_2                  matlab.ui.control.DropDown
        HypercolDropDown_2              matlab.ui.control.DropDown
        KillipDropDown_2                matlab.ui.control.DropDown
        HypertensDropDown_2             matlab.ui.control.DropDown
        DyslipidemiaDropDown_2          matlab.ui.control.DropDown
        SmokeDropDown_2                 matlab.ui.control.DropDown
        PrevCADDropDown_2               matlab.ui.control.DropDown
        ObesityDropDown_2               matlab.ui.control.DropDown
        FamiliarityDropDown_2           matlab.ui.control.DropDown
        DiabeteDropDown_2               matlab.ui.control.DropDown
        PositivecardiacmarkerDropDown_2  matlab.ui.control.DropDown
        StenosisEditField_2             matlab.ui.control.NumericEditField
        Anginaepisodesin24hEditField_2  matlab.ui.control.NumericEditField
        TimetotreatmhoursEditField_2    matlab.ui.control.NumericEditField
        stMedCoDate_2                   matlab.ui.control.DatePicker
        HRbpmEditField_2                matlab.ui.control.NumericEditField
        DyastolicBPmmHgEditField_2      matlab.ui.control.NumericEditField
        SystolicBPmmHgEditField_2       matlab.ui.control.NumericEditField
        STEMIDropDown_2                 matlab.ui.control.DropDown
        WeightEditField_2               matlab.ui.control.NumericEditField
        EtaEditField_2                  matlab.ui.control.NumericEditField
        CalculateAgeButton_2            matlab.ui.control.Button
        EKGSTdevmmEditFieldLabel        matlab.ui.control.Label
        StemiNstemiLabel_2              matlab.ui.control.Label
        PositivecardiacmarkerDropDown_2Label  matlab.ui.control.Label
        ASAusein7daysDropDown_2Label    matlab.ui.control.Label
        HypercolDropDown_2Label         matlab.ui.control.Label
        LBBBDropDown_2Label             matlab.ui.control.Label
        DyslipidemiaDropDown_2Label     matlab.ui.control.Label
        FamiliarityDropDown_2Label      matlab.ui.control.Label
        PrevAMILabel                    matlab.ui.control.Label
        ObesityDropDown_2Label          matlab.ui.control.Label
        SmokeDropDown_2Label            matlab.ui.control.Label
        DiabetesDropDown_2Label         matlab.ui.control.Label
        KillipDropDown_2Label           matlab.ui.control.Label
        HypertensDropDown_2Label        matlab.ui.control.Label
        StenosisEditField_2Label        matlab.ui.control.Label
        Anginaepisodesin24hEditField_2Label  matlab.ui.control.Label
        STEMIDropDown_2Label_2          matlab.ui.control.Label
        TimetotreatmLabel               matlab.ui.control.Label
        stMedCoDatePickerLabel_2        matlab.ui.control.Label
        HRbpmEditField_2Label           matlab.ui.control.Label
        DyastolicBPmmHgEditField_2Label  matlab.ui.control.Label
        SystolicBPmmHgEditField_2Label  matlab.ui.control.Label
        WieightKgLabel_2                matlab.ui.control.Label
        EtEditField_3Label_2            matlab.ui.control.Label
        DateandtimeRevascularizationPanel_2  matlab.ui.container.Panel
        LocoEditField_2                 matlab.ui.control.EditField
        LocoEditField_2Label            matlab.ui.control.Label
        RevsLabel_2                     matlab.ui.control.Label
        RevmLabel_2                     matlab.ui.control.Label
        RevhLabel_2                     matlab.ui.control.Label
        Revsecond_2                     matlab.ui.control.Spinner
        Revminute_2                     matlab.ui.control.Spinner
        Revhour_2                       matlab.ui.control.Spinner
        RevHourLabel_2                  matlab.ui.control.Label
        DateRev_2                       matlab.ui.control.DatePicker
        DateLabel_6                     matlab.ui.control.Label
        DateandtimeThrombolysisPanel_2  matlab.ui.container.Panel
        DrugEditField_2                 matlab.ui.control.EditField
        DrugEditField_2Label            matlab.ui.control.Label
        TrombsLabel_2                   matlab.ui.control.Label
        TrombmLabel_2                   matlab.ui.control.Label
        TrombhLabel_2                   matlab.ui.control.Label
        Trombsecond_2                   matlab.ui.control.Spinner
        Trombminute_2                   matlab.ui.control.Spinner
        TrombHour_2                     matlab.ui.control.Spinner
        TrombHourLabel_2                matlab.ui.control.Label
        DateTromb_2                     matlab.ui.control.DatePicker
        DateLabel_5                     matlab.ui.control.Label
        DateandtimeAMIPanel_2           matlab.ui.container.Panel
        AMIsLabel_2                     matlab.ui.control.Label
        AMImLabel_2                     matlab.ui.control.Label
        AMIhLabel_2                     matlab.ui.control.Label
        AMIsecondEv_2                   matlab.ui.control.Spinner
        AMIminuteEv_2                   matlab.ui.control.Spinner
        AMIhourEv_2                     matlab.ui.control.Spinner
        HourLabel_2                     matlab.ui.control.Label
        DateEvent_2                     matlab.ui.control.DatePicker
        DateLabel_4                     matlab.ui.control.Label
        DateandtimehospitalizationPanel_2  matlab.ui.container.Panel
        HsLabel_2                       matlab.ui.control.Label
        HmLabel_2                       matlab.ui.control.Label
        HhLabel_2                       matlab.ui.control.Label
        HsecondH_2                      matlab.ui.control.Spinner
        HminuteH_2                      matlab.ui.control.Spinner
        HhourH_2                        matlab.ui.control.Spinner
        HourSpinnerLabel_2              matlab.ui.control.Label
        HDateH_2                        matlab.ui.control.DatePicker
        DateDatePickerLabel_2           matlab.ui.control.Label
        ReloadHospData                  matlab.ui.control.Button
        HospitalizationsDrop            matlab.ui.control.DropDown
        SelezionaricoveroLabel_2        matlab.ui.control.Label
        AcquisitionsPage                matlab.ui.container.Panel
        PatientHospitalizationinfoPanel  matlab.ui.container.Panel
        DoctorNameValueLabel            matlab.ui.control.Label
        DoctorLabel                     matlab.ui.control.Label
        EventDateValueLabel             matlab.ui.control.Label
        EventdateLabel                  matlab.ui.control.Label
        HDValueLabel                    matlab.ui.control.Label
        HospitalizationdateLabel        matlab.ui.control.Label
        CenterValueLabel                matlab.ui.control.Label
        CenterLabel_2                   matlab.ui.control.Label
        BirthdayValueLabel              matlab.ui.control.Label
        BirthdayLabel                   matlab.ui.control.Label
        AddressValueLabel               matlab.ui.control.Label
        AddressLabel                    matlab.ui.control.Label
        GenderValueLabel                matlab.ui.control.Label
        GenderLabel                     matlab.ui.control.Label
        SSNValueLabel                   matlab.ui.control.Label
        PatientIDFCLabel_3              matlab.ui.control.Label
        SurnameValueLabel               matlab.ui.control.Label
        SurnameLabel                    matlab.ui.control.Label
        NameValueLabel                  matlab.ui.control.Label
        NameLabel                       matlab.ui.control.Label
        MeasurementsDataPanel           matlab.ui.container.Panel
        AcquisitionsTab                 matlab.ui.control.Table
        CancelButton                    matlab.ui.control.Button
        MeasurementsSaveLamp            matlab.ui.control.Lamp
        MeasurementsSaveText            matlab.ui.control.TextArea
        SaveeditsAcqButton              matlab.ui.control.Button
        SavenewAcqButton                matlab.ui.control.Button
        CKMBEditFieldLabel              matlab.ui.control.Label
        DateandTimeacquisitionPanel     matlab.ui.container.Panel
        HsLabelA                        matlab.ui.control.Label
        HmLabelA                        matlab.ui.control.Label
        HhLabelA                        matlab.ui.control.Label
        HsecondA                        matlab.ui.control.Spinner
        HminuteA                        matlab.ui.control.Spinner
        HhourA                          matlab.ui.control.Spinner
        HourSpinnerLabel_3              matlab.ui.control.Label
        HDateA                          matlab.ui.control.DatePicker
        DateDatePickerLabel_3           matlab.ui.control.Label
        KEditField                      matlab.ui.control.NumericEditField
        KEditFieldLabel                 matlab.ui.control.Label
        cTnIEditFieldLabel              matlab.ui.control.Label
        NaEditField                     matlab.ui.control.NumericEditField
        NaEditFieldLabel                matlab.ui.control.Label
        CKMBEditField                   matlab.ui.control.NumericEditField
        cTnIEditField                   matlab.ui.control.NumericEditField
        cTnTEditField                   matlab.ui.control.NumericEditField
        cTnTEditFieldLabel              matlab.ui.control.Label
        ReloadAcqData                   matlab.ui.control.Button
        AcquisitionslistDropDown        matlab.ui.control.DropDown
        AcquisitionslistDropDownLabel   matlab.ui.control.Label
        AnalysisButtonFAcq              matlab.ui.control.Button
        HospitalizationButtonFAcq       matlab.ui.control.Button
        HomeButtonFAcq                  matlab.ui.control.Button
        AnalysisPage                    matlab.ui.container.Panel
        AnalysisContainer               matlab.ui.container.Panel
        AnalysisPlotPanel               matlab.ui.container.Panel
        AnalysisAx                      matlab.ui.control.UIAxes
        PatientDetailsPanel             matlab.ui.container.Panel
        DoctorNameValueLabel_2          matlab.ui.control.Label
        DoctorLabel_2                   matlab.ui.control.Label
        AddressValueLabel_2             matlab.ui.control.Label
        AddressLabel_2                  matlab.ui.control.Label
        EventDateValueLabel_2           matlab.ui.control.Label
        EventdateLabel_2                matlab.ui.control.Label
        GenderValueLabel_2              matlab.ui.control.Label
        GenderLabel_2                   matlab.ui.control.Label
        HDValueLabel_2                  matlab.ui.control.Label
        HospitalizationdateLabel_2      matlab.ui.control.Label
        SSNValueLabel_2                 matlab.ui.control.Label
        PatientIDFCLabel_4              matlab.ui.control.Label
        BirthdayValueLabel_2            matlab.ui.control.Label
        BirthdayLabel_2                 matlab.ui.control.Label
        SurnameValueLabel_2             matlab.ui.control.Label
        SurnameLabel_2                  matlab.ui.control.Label
        CenterValueLabel_2              matlab.ui.control.Label
        CenterLabel_3                   matlab.ui.control.Label
        NameValueLabel_2                matlab.ui.control.Label
        NameLabel_2                     matlab.ui.control.Label
        AdvancedPanel                   matlab.ui.container.Panel
        Cc0pLabel_2                     matlab.ui.control.Label
        Cc0CheckBox                     matlab.ui.control.CheckBox
        Cs0pLabel_2                     matlab.ui.control.Label
        Cs0CheckBox                     matlab.ui.control.CheckBox
        TdpLabel_2                      matlab.ui.control.Label
        TdCheckBox                      matlab.ui.control.CheckBox
        bpLabel_2                       matlab.ui.control.Label
        bCheckBox                       matlab.ui.control.CheckBox
        Acquisitionnumberinfo           matlab.ui.control.TextArea
        AdvancedSettings                matlab.ui.container.Panel
        Cc0EditField                    matlab.ui.control.NumericEditField
        Cs0EditField                    matlab.ui.control.NumericEditField
        TdEditField                     matlab.ui.control.NumericEditField
        bEditField                      matlab.ui.control.NumericEditField
        aEditField                      matlab.ui.control.NumericEditField
        apLabel_2                       matlab.ui.control.Label
        aCheckBox                       matlab.ui.control.CheckBox
        RestoreButton                   matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        RunMessage                      matlab.ui.control.TextArea
        RunButton                       matlab.ui.control.Button
        ResultsPanel                    matlab.ui.container.Panel
        SavingResultsStatus             matlab.ui.control.Lamp
        SaveResultsButton               matlab.ui.control.Button
        FittingResult                   matlab.ui.container.Panel
        SPT_value                       matlab.ui.control.Label
        SecondPeakTimeLabel             matlab.ui.control.Label
        FPT_value                       matlab.ui.control.Label
        FirstPeakTimeLabel              matlab.ui.control.Label
        SPV_value                       matlab.ui.control.Label
        SecondPeakValueLabel            matlab.ui.control.Label
        FPV_value                       matlab.ui.control.Label
        FirstPeakValueLabel             matlab.ui.control.Label
        SelectAnalysis                  matlab.ui.control.DropDown
        PredictedValuesPanel            matlab.ui.container.Panel
        Time_value                      matlab.ui.control.Label
        TimeLabel                       matlab.ui.control.Label
        Cc0p_value                      matlab.ui.control.Label
        Cc0pLabel                       matlab.ui.control.Label
        Cs0p_value                      matlab.ui.control.Label
        Cs0pLabel                       matlab.ui.control.Label
        Tdp_value                       matlab.ui.control.Label
        TdpLabel                        matlab.ui.control.Label
        bp_value                        matlab.ui.control.Label
        bpLabel                         matlab.ui.control.Label
        ap_value                        matlab.ui.control.Label
        apLabel                         matlab.ui.control.Label
        ParametersSettingsPanel         matlab.ui.container.Panel
        MultistarPanel                  matlab.ui.container.Panel
        LocaloptimizationalgorithmDropDown  matlab.ui.control.DropDown
        LocaloptimizationalgorithmLabel  matlab.ui.control.Label
        OptimizationAlgorithmDropDown   matlab.ui.control.DropDown
        IterationsnumberSpinner         matlab.ui.control.Spinner
        Cc0InitialEditField             matlab.ui.control.NumericEditField
        Cc0UpperSpinner                 matlab.ui.control.Spinner
        Cc0LowerSpinner                 matlab.ui.control.Spinner
        Cs0InitialEditField             matlab.ui.control.NumericEditField
        Cs0UpperSpinner                 matlab.ui.control.Spinner
        Cs0LowerSpinner                 matlab.ui.control.Spinner
        TdInitialEditField              matlab.ui.control.NumericEditField
        TdUpperSpinner                  matlab.ui.control.Spinner
        TdLowerSpinner                  matlab.ui.control.Spinner
        bInitialEditField               matlab.ui.control.NumericEditField
        bUpperSpinner                   matlab.ui.control.Spinner
        bLowerSpinner                   matlab.ui.control.Spinner
        aInitialEditField               matlab.ui.control.NumericEditField
        aUpperSpinner                   matlab.ui.control.Spinner
        aLowerSpinner                   matlab.ui.control.Spinner
        TdSpinnerLabel                  matlab.ui.control.Label
        OptimizationAlgorithmLabel      matlab.ui.control.Label
        IterationsnumberSpinnerLabel    matlab.ui.control.Label
        Cc0SpinnerLabel                 matlab.ui.control.Label
        Cs0SpinnerLabel                 matlab.ui.control.Label
        bSpinnerLabel                   matlab.ui.control.Label
        aSpinnerLabel                   matlab.ui.control.Label
        InitialLabel                    matlab.ui.control.Label
        UpperBoundsLabel                matlab.ui.control.Label
        LowerBoundsLabel                matlab.ui.control.Label
        AnalysisTypeDropDown            matlab.ui.control.DropDown
        MarkerLabel                     matlab.ui.control.Label
        AcquisitionsButtonFAn           matlab.ui.control.Button
        HospitalizationButtonFAn        matlab.ui.control.Button
        HomeButtonFAn                   matlab.ui.control.Button
        ModeLabel                       matlab.ui.control.Label
        ModeDropDown                    matlab.ui.control.DropDown
        LogoutButton                    matlab.ui.control.Button
        CBRACardiacBiomarkersReleaseAnalyzerUMGLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)

        dbUsr='root'
        dbPsw='root'
        conn % DB connection 

        msg % dislpay message --

        livello % security level access
        idCentro % loggedUser center id
        nome_centro % loggedUser center name
        id % loggedUser id
        loggedUser %loggedUser data

        selectedPaziente % paziente selezionato
        selectedHosp % hospitalization selezionata
        nomiComuni % nomi dei comuni per il calcolo del ssn

        newPatientID % variabile temporanea per salvataggio prima hospitalization e import dati

        nAcquisizioni % variabile per bloccare/sbloccare analisi abbinata a runValue
        runValue % variabile per bloccare/sbloccare analisi abbinata a nAcquisizioni
        constant_vector % vettore paramteri costanti per l'analisi
        TemAcq % vettore dei tempi per l'analisi
        ctnt % biomarcatore troponina per l'analisi
        ckmb % biomarcatore ckmb per l'analisi
        sodio % vettore valori sodio per l'analisi
        potassio % vettore valori potassio per l'analisi

        Executions % Execution selezionata in analisi
        ExecutionNumber = 0 % Inizializzazione numero esecuzioni
        ax1 % Subplot 1 in Hospitalization
        ax2 % Subplot 2 in Hospitalization

%         parametersRun % struct to store parameters used for the last simulation
%         fixed_labels = ["a" "b" "0" "0" "Td" "Cs0" "Cc0" "0" "0"] % labels to save fixed values in analysis_parameters
        entity = [struct("name", "utente", "action", "User", "page", "Administration") ...
                    struct("name", "centro", "action", "Center", "page", "Administration") ...
                    struct("name", "paziente", "action", "Patients", "page", "Clinician") ...
                    struct("name", "hospitalization", "action", "Hospitalization", "page", "Clinician") ...
                    struct("name", "acquisizione", "action", "Acquisitions", "page", "Clinician") ...
                    struct("name", "analysis_result", "action", "Analysis", "page", "Clinician")]
    end
    
    methods (Access = private)
        % Function to plot reconstructed curve
        function plotAnalysis(app, ax, tempi, troponina, t_max, v_max, T, X)
            cla(ax)
            %tempi = [0, 6, 18, 23, 28, 45, 72];
            %troponina = [0 1.5 10 7.5 6.7 8.33 4.3];
            plot(ax, tempi, troponina, '*','MarkerSize', 10, 'MarkerEdgeColor',[0.64,0.08,0.18])
            hold(ax, "on")
            plot(ax, T, X, 'Color', [1 0 0.4],'LineWidth',2)
            plot(ax, t_max, v_max, 'b*','MarkerSize', 10) 
            xlabel(ax, 'Time [h]', 'Interpreter', 'latex', 'FontSize', 15)
            ylabel(ax,'cTnT [ng/ml]', 'Interpreter', 'latex', 'FontSize', 15)
            legend(ax, {'cTnT', 'cTnT - model', 'Est. peaks'}, 'Interpreter', 'latex', 'FontSize', 15)
            title(ax,'cTnT', 'Interpreter', 'latex', 'FontSize', 15)
            set(ax,'FontSize',15)
            set(ax,'TickLabelInterpreter','latex')
            grid(ax, 'on')
            hold(ax, "off")
        end
        
        % Function to check null values
        function check = nullCheck(app, a, zero)
            check = true;
            if ~isempty(a)
                for x = a
                    if zero
                        if string(x) == "" || isempty(string(x)) || x == 0
                            check = false;
                        end
                    else
                        x = string(x);
                        if x == "" || isempty(x) 
                            check = false;
                        end
                    end
                end
            else 
                check = false;
            end
        end
        
        % Function to claculate TIMI score
        function [timi, msg, check, i_void] = calculateTIMI(app, eta, diabete, angina, hyperTens, hr, systolicBP, killip, STEMI, lbbb, weight, timetotreatm, hypercholesterolemia, familiarity, smoke, stenosis, asa_usage_7d, st_deviation, cardiac_marker)
            check = true;
            timi_values_STEMI = [eta, diabete, angina, hyperTens, hr, systolicBP, killip, STEMI, lbbb, weight, timetotreatm];
            STEMI_variables = [" Age", " | Diabetes", " | Severe angina episodes in 24h", " | Hypertens.", " | HR [bpm]", " | Systolic BP [mmHg]", " | Killip", " | STEMI", " | LBBB", " | Weight [Kg]", " | Time to treatm. [hours]"];
            timi_values_NSTEMI = [eta, hyperTens, hypercholesterolemia, diabete, familiarity, smoke, stenosis, angina, asa_usage_7d, st_deviation, cardiac_marker];
            NSTEMI_variables = [" Age", " | Hypertens.", " | Hyperchol.", " | Diabetes",  " | CAD familiarity", " | Smoke", " | Stenosis [%]", " | Severe angina episodes in 24h", " | ASA use in 7 days", " | EKG ST changed [mm]", " | Cardiac marker positive"];
            i = 0;
            i_void = [];
            var_str = "";
            timi = 0;
            if strcmp(STEMI, 'Yes')
                for v = timi_values_STEMI
                    i=i+1;
                    if strcmp(string(v),'0') || strcmp(string(v), 'Unknown')
                        i_void = [i_void i];
                        var_str = strcat(var_str, STEMI_variables(i));
                        check = false;
                    end
                end
                if check
                    if ((eta>65)&&(eta<74))
                        timi = timi + 2;
                    elseif  eta>=74
                        timi = timi + 3;
                    end
                    % contributo di diabete ipertensione ed angina
                    if ((strcmp(diabete, 'Yes')==1)|| angina >= 0 ||(strcmp(hyperTens, 'Yes')==1))
                        timi = timi +1;
                    end      
                    % contributo di HR
                    if hr> 100
                        timi = timi + 2;
                    end
                    % contributo Systolic BP
                    if systolicBP< 100
                        timi = timi +3;
                    end
                    % contributo della classe killip
                    if ismember(killip, {'II' 'III' 'IV'})
                        timi = timi + 2;
                    end
                    % contributo antero ST-elevation, LBBB
                    if ((strcmp(STEMI, 'Yes')==1)||(strcmp(lbbb, 'Yes')==1))
                        timi = timi + 1;
                    end
                    % contributo peso
                    if weight< 67
                        timi = timi + 1;
                    end
                    % contributo tempo somministrazione del trattamento
                    if timetotreatm> 4
                        timi = timi +1;
                    end
                    if timi == 0    
                       msg = 'Timi Risk Score [STEMI]= 0. 0.8% risk of all-cause mortality at 30 days.';
                    elseif timi == 1
                        msg = 'Timi Risk Score [STEMI] = 1. 1.6% risk of all-cause mortality at 30 days.';
                    elseif timi == 2
                        msg = 'Timi Risk Score [STEMI] = 2. 2.2% risk of all-cause mortality at 30 days.';
                    elseif timi == 3
                        msg = 'Timi Risk Score [STEMI] = 3. 4.4% risk of all-cause mortality at 30 days.';
                    elseif timi == 4
                       msg = 'Timi Risk Score [STEMI] = 4. 7.3% risk of all-cause mortality at 30 days.';
                    elseif timi == 5
                        msg = 'Timi Risk Score [STEMI] = 5. 12.4% risk of all-cause mortality at 30 days.';
                    elseif timi == 6
                        msg = 'Timi Risk Score [STEMI] = 6. 16.1% risk of all-cause mortality at 30 days.';
                    elseif timi == 7
                        msg = 'Timi Risk Score [STEMI] = 7. 23.4% risk of all-cause mortality at 30 days.';
                    elseif timi == 8
                        msg = 'Timi Risk Score [STEMI] = 8. 26.8% risk of all-cause mortality at 30 days.';
                    elseif timi >= 9
                        msg = 'Timi Risk Score [STEMI] >= 9. 35.9% risk of all-cause mortality at 30 days.';    
                    end
                else
                    %msg = ['To calcolate TIMI, insert valid input (Yes, No or values ​​greater than zero) in the following field: ' ...
                    %    'Age, Diabetes, Angina, Hypertension, HR, Systolic BP, Antero ST-el., LBBB, Weight, Time to treatm'];
                    msg = 'To calcolate TIMI Risk Score for a STEMI patient insert valid input/s (Yes, No or values ​​greater than zero) to the following field: ';
                    msg = strcat(msg, var_str);
                end
            elseif strcmp(STEMI, 'No')
                cad=0;
                for v = timi_values_NSTEMI
                    i=i+1;
                    if strcmp(string(v),'0') || strcmp(string(v), 'Unknown')
                        i_void = [i_void i];
                        var_str = strcat(var_str, NSTEMI_variables(i));
                        check = false;
                    end
                end
                if check
                    if eta >= 65
                        timi = timi+1;
                    end
                    if strcmp(hyperTens, 'Yes')
                        cad=cad+1;
                    end
                    if strcmp(hypercholesterolemia, 'Yes')
                        cad=cad+1;
                    end
                    if strcmp(diabete, 'Yes')
                        cad=cad+1;
                    end
                    if strcmp(familiarity, 'Yes')
                        cad=cad+1;
                    end
                    if strcmp(smoke, 'Yes')
                        cad=cad+1;
                    end
                    if cad >= 3
                        timi=timi+1;
                    end
                    if stenosis >= 50
                        timi=timi+1;
                    end
                    if angina >= 2
                        timi=timi+1;
                    end
                    if strcmp(asa_usage_7d, 'Yes')
                        timi=timi+1;
                    end
                    if st_deviation >= 0.5
                        timi=timi+1;
                    end
                    if strcmp(cardiac_marker, 'Yes')
                        timi=timi+1;
                    end
                    if timi <= 1    
                       msg = 'Timi Risk Score [NSTEMI] <= 1. 4.7% risk of all-cause mortality at 30 days.';
                    elseif timi == 2
                        msg = 'Timi Risk Score [NSTEMI] = 2. 8.3% risk of all-cause mortality at 30 days.';
                    elseif timi == 3
                        msg = 'Timi Risk Score [NSTEMI] = 3. 13.2% risk of all-cause mortality at 30 days.';
                    elseif timi == 4
                       msg = 'Timi Risk Score [NSTEMI] = 4. 19.9% risk of all-cause mortality at 30 days.';
                    elseif timi == 5
                        msg = 'Timi Risk Score [NSTEMI] = 5. 26.2% risk of all-cause mortality at 30 days.';
                    elseif timi == 6
                        msg = 'Timi Risk Score [NSTEMI] = 6. 40.9% risk of all-cause mortality at 30 days.';
                    elseif timi == 7
                        msg = 'Timi Risk Score [NSTEMI] = 7. 40.9% risk of all-cause mortality at 30 days.';
                    end
                else
                    %msg = ['To calcolate TIMI, insert valid input (Yes, No or values ​​greater than zero) in the following field: ' ...
                    %    'Age, Diabetes, Angina, Hypertension, HR, Systolic BP, Antero ST-el., LBBB, Weight, Time to treatm'];
                    msg = 'To calcolate TIMI Risk Score for a UA/NSTEMI patient insert valid input/s (Yes, No or values ​​greater than zero) to the following field: ';
                    msg = strcat(msg, var_str);
                end
            else
                msg = 'Select correct value on STEMI field to calculate TIMI Risk Score for STEMI or UA/NSTEMI patient';
                i_void = [8];
                check = false;
            end
        end
        
        %Clinic page iniztialization: InsertPatientTab
        function startupInsert(app)
            app.HomePage.Visible = "on";
            app.HospitalizationPage.Visible = "off";
            app.AcquisitionsPage.Visible = "off";
            app.AnalysisPage.Visible = "off";
            app.NameEditField.Value = '';
            app.SurnameEditField.Value = '';
            app.AddressEditField.Value = '';
            app.SSNEditField.Value = '';
            app.SSNEditField.Visible = 'off';
            app.PatientIDFCLabel_1.Visible = 'off';
            app.BirthPlaceEditField.Value = 1;
            app.BirthDateEditField.Value = NaT;
%             app.BirthDateEditField.Value = datetime("01-Jan-1850");
            app.MButton.Value = 1;
            app.FButton.Value = 0;
            elements = app.SelectCenterAdminDropDown.ItemsData;
            if ~isempty(elements)
                app.SelectCenterAdminDropDown.Value = elements(1);
            end
            if app.livello == 1
                app.SelectCenterAdminDropDown.Visible = 'on';
                app.SelectCenterAdminDropDownLabel.Visible = 'on';
            else
                app.SelectCenterAdminDropDown.Visible = 'off';
                app.SelectCenterAdminDropDownLabel.Visible = 'off';
            end
            app.DataMessageText.Value = '';
            app.DataMessageText.Visible = "off";
            app.PersonalDataSavingStatus.Color = "g";
            app.PersonalDataSavingStatus.Visible = "off";
            app.IndexHospitalizationPanel.Visible = "off";
            app.HDateH_1.Value = NaT;
            app.HhourH_1.Value = 0;
            app.HminuteH_1.Value = 0;
            app.HsecondH_1.Value = 0;
            app.DateEvent_1.Value = NaT;
            app.AMIhourEv_1.Value = 0;
            app.AMIminuteEv_1.Value = 0;
            app.AMIsecondEv_1.Value = 0;
            app.DateTromb_1.Value = NaT;
            app.TrombHour_1.Value = 0;
            app.Trombminute_1.Value = 0;
            app.Trombsecond_1.Value = 0;
            app.DrugEditField_1.Value = '';
            app.DateRev.Value = NaT;
            app.Revhour_1.Value = 0;
            app.Revminute_1.Value = 0;
            app.Revsecond_1.Value = 0;
            app.LocoEditField_1.Value = '';
            app.stMedCoDate_1.Value = NaT;
            app.EtaEditField_1.Value = 0;
            app.WeightEditField_1.Value = 0;
            app.SystolicBPmmHgEditField_1.Value = 0;
            app.DyastolicBPmmHgEditField_1.Value = 0;
            app.HRbpmEditField_1.Value = 0;
            app.TimetotreatmhoursEditField_1.Value = 0;
            app.DiabeteDropDown_1.Value = 3;
            app.HypertensDropDown_1.Value = 3;
            app.Anginaepisodesin24hEditField_1.Value = 0;
            app.KillipDropDown_1.Value = 1;
            app.STEMIDropDown_1.Value = 3;
            app.STEMIDropDown_1ValueChanged;
            app.LBBBDropDown_1.Value = 3;
            app.SmokeDropDown_1.Value = 3;
            app.ObesityDropDown_1.Value = 3;
            app.PrevCADDropDown_1.Value = 3;
            app.FamiliarityDropDown_1.Value = 3;
            app.DyslipidemiaDropDown.Value = 3;

            app.StenosisEditField_1.Value = 0;
            app.PositivecardiacmarkerDropDown_1.Value = 3;
            app.HypercolDropDown_1.Value = 3;
            app.EKGSTdevmmEditField_1.Value = 0;

            app.TimiEditField_1.Value = 0;

            app.HospitalizationText_1.Value = '';
            app.HospitalizationText_1.Visible = 'off';
            app.HospitalizationSavingStatus_1.Color = 'g';
            app.HospitalizationSavingStatus_1.Visible = 'off';
        end
        
        %Clinic page iniztialization: HomePage
        function startupHomePage(app)
            elements = app.SelectCenterDrop.ItemsData;
            if ~isempty(elements)
                app.SelectCenterDrop.Value = elements(1);
            end
            if app.livello == 1
                app.SelectCenterDrop.Visible = 'on';
                app.SelectCenterLabel.Visible = 'on';
            else
                app.SelectCenterDrop.Visible = 'off';
                app.SelectCenterLabel.Visible = 'off';
            end
%             elements = app.PazienteDropDown.Items;
%             if ~isempty(elements)
%                 app.PazienteDropDown.Value = elements(1);
%                 app.ControlPanel.Visible = 'off';
%             else
%                 app.ControlPanel.Visible = 'off';
%             end
            app.ImportExportLamp.Color = 'g';
            app.ImportExportLamp.Visible = 'off';
            app.ImportExportText.Value = '';
            app.ImportExportText.Visible = "off";
            app.PatientPersonalDataPanel_2.Visible = "off";
            app.NameEditField_2.Value = '';
            app.SurnameEditField_2.Value = '';
            app.BirthPlaceEditField_2.Value = "Place";
            app.BirthDateEditField_2.Value = NaT;
            app.AddressEditField_2.Value = '';
            app.MButton_2.Value = 1;
            app.FButton_2.Value = 0;
            app.SSNEditField_2.Value = '';
            app.PersonalDataSavingStatus_2.Color = 'g';
            app.PersonalDataSavingStatus_2.Visible = 'off';
            app.DataMessageText_2.Value = '';
            app.DataMessageText_2.Visible = "off";
            app.AnonymizeDataSwitch.Value = 0;
        end

        %Clinic page iniztialization: Hospitalizations
        function startupHospitalization(app)
%             elements = app.HospitalizationsDrop.ItemsData;
%             if ~isempty(elements)
%                 app.HospitalizationsDrop.Items = elements(1);
%             else
%                 app.AcquisitionsButtonFH.Enable = "off";
%                 app.AnalysisButtonFH.Enable = "off";
%             end

            app.HospitalizationSavingStatus_2.Color = 'g';
            app.HospitalizationSavingStatus_2.Visible = 'off';
            app.HospitalizationText_2.Value = '';
            app.HospitalizationText_2.Visible = 'off';

            app.EtaEditField_2.BackgroundColor = [1 1 1];
            app.WeightEditField_2.BackgroundColor = [1 1 1];
            app.SystolicBPmmHgEditField_2.BackgroundColor = [1 1 1];
            app.HRbpmEditField_2.BackgroundColor = [1 1 1];
            app.TimetotreatmhoursEditField_2.BackgroundColor = [1 1 1];
            app.DiabeteDropDown_2.BackgroundColor = [0.96,0.96,0.96];
            app.HypertensDropDown_2.BackgroundColor = [0.96,0.96,0.96];
            app.Anginaepisodesin24hEditField_2.BackgroundColor = [1 1 1];
            app.KillipDropDown_2.BackgroundColor = [0.96,0.96,0.96];
            app.STEMIDropDown_2.BackgroundColor = [0.96,0.96,0.96];
            app.LBBBDropDown_2.BackgroundColor = [0.96,0.96,0.96];

            app.StenosisEditField_2.BackgroundColor = [1 1 1];
            app.PositivecardiacmarkerDropDown_2.BackgroundColor = [0.96,0.96,0.96];
            app.HypercolDropDown_2.BackgroundColor = [0.96,0.96,0.96];
            app.EKGSTdevmmEditField_2.BackgroundColor = [1 1 1];

            app.HDateH_2.Value = NaT;
            app.HhourH_2.Value = 0;
            app.HminuteH_2.Value = 0;
            app.HsecondH_2.Value = 0;
            app.DateEvent_2.Value = NaT;
            app.AMIhourEv_2.Value = 0;
            app.AMIminuteEv_2.Value = 0;
            app.AMIsecondEv_2.Value = 0;
            app.DateTromb_2.Value = NaT;
            app.TrombHour_2.Value = 0;
            app.Trombminute_2.Value = 0;
            app.Trombsecond_2.Value = 0;
            app.DrugEditField_2.Value = '';
            app.DateRev_2.Value = NaT;
            app.Revhour_2.Value = 0;
            app.Revminute_2.Value = 0;
            app.Revsecond_2.Value = 0;
            app.LocoEditField_2.Value = '';
            app.stMedCoDate_2.Value = NaT;
            app.EtaEditField_2.Value = 0;
            app.WeightEditField_2.Value = 0;
            app.SystolicBPmmHgEditField_2.Value = 0;
            app.DyastolicBPmmHgEditField_2.Value = 0;
            app.HRbpmEditField_2.Value = 0;
            app.TimetotreatmhoursEditField_2.Value = 0;
            app.DiabeteDropDown_2.Value = 3;
            app.HypertensDropDown_2.Value = 3;
            app.Anginaepisodesin24hEditField_2.Value = 0;
            app.KillipDropDown_2.Value = 1;
            app.STEMIDropDown_2.Value = 3;
            app.STEMIDropDown_2ValueChanged;
            app.LBBBDropDown_2.Value = 3;
            app.SmokeDropDown_2.Value = 3;
            app.ObesityDropDown_2.Value = 3;
            app.PrevCADDropDown_2.Value = 3;
            app.FamiliarityDropDown_2.Value = 3;
            app.DyslipidemiaDropDown_2.Value = 3;
            app.TimiEditField_2.Value = 0;

            app.StenosisEditField_2.Value = 0;
            app.PositivecardiacmarkerDropDown_2.Value = 3;
            app.HypercolDropDown_2.Value = 3;
            app.EKGSTdevmmEditField_2.Value = 0;

            app.HospitalizationText_2.Value = '';
            app.HospitalizationText_2.Visible = 'off';
            app.HospitalizationSavingStatus_2.Color = 'g';
            app.HospitalizationSavingStatus_2.Visible = 'off';
        end
        
        %Clinic page iniztialization: Acquisitions
        function startupAcquisition(app)
            app.HospitalizationButtonFAcq.Enable = 'off';
            app.AnalysisButtonFAcq.Enable = 'off';
            app.NameValueLabel.Text = 'NameValue';
            app.SurnameValueLabel.Text = 'SurnameValue';
            app.SSNValueLabel.Text = 'SSNValue';
            app.GenderValueLabel.Text = 'GenderValue';
            app.AddressValueLabel.Text = 'AddressValue';
            app.BirthdayValueLabel.Text = 'BirthdayValue';
            app.CenterValueLabel.Text = 'CenterValue';
            app.HDValueLabel.Text = 'HDValue';
            app.EventDateValueLabel.Text = 'EventDateValue';
            app.DoctorNameValueLabel.Text = 'DoctorNameValue';
            elements = app.AcquisitionslistDropDown.ItemsData;
            if ~isempty(elements) 
                app.AcquisitionslistDropDown.Value = elements(1);
%                 app.AcquisitionslistDropDownValueChanged;
            end
            app.cTnTEditField.Value = 0;
            app.cTnIEditField.Value = 0;
            app.CKMBEditField.Value = 0;
            app.NaEditField.Value = 0;
            app.KEditField.Value = 0;
            app.HDateA.Value = NaT;
            app.HhourA.Value = 0;
            app.HminuteA.Value = 0;
            app.HsecondA.Value = 0;
            app.MeasurementsSaveText.Value = '';
            app.MeasurementsSaveText.Visible = 'off';
            app.MeasurementsSaveLamp.Color = 'g';
            app.MeasurementsSaveLamp.Visible = 'off';
        end
        
        %Clinic page iniztialization: Analysis
        function startupAnalysis(app)
            app.HospitalizationButtonFAn.Enable = 'off';
            app.AcquisitionsButtonFAn.Enable = 'off';
            app.AnalysisTypeDropDown.Value = 'cTnT';
            cla(app.AnalysisAx);
            app.NameValueLabel_2.Text = 'NameValue';
            app.SurnameValueLabel_2.Text = 'SurnameValue';
            app.SSNValueLabel_2.Text = 'SSNValue';
            app.GenderValueLabel_2.Text = 'GenderValue';
            app.AddressValueLabel_2.Text = 'AddressValue';
            app.BirthdayValueLabel_2.Text = 'BirthdayValue';
            app.CenterValueLabel_2.Text = 'CenterValue';
            app.HDValueLabel_2.Text = 'HDValue';
            app.EventDateValueLabel_2.Text = 'EventDateValue';
            app.DoctorNameValueLabel_2.Text = 'DoctorNameValue';
            if app.livello == 1
                app.ParametersSettingsPanel.Visible = 'on';
                app.RestoreButton.Visible = 'on';
                app.AdvancedPanel.Visible = 'on';
            else
                app.ParametersSettingsPanel.Visible = 'off';
                app.RestoreButton.Visible = 'off';
                app.AdvancedPanel.Visible = 'off';
            end

            app.aLowerSpinner.Value = 0.001;
            app.aLowerSpinner.Limits = [0.001,4.5];
            app.bLowerSpinner.Value = 0.001;
            app.bLowerSpinner.Limits = [0.001,4.5];
            app.Cs0LowerSpinner.Value = 0.001;
            app.Cs0LowerSpinner.Limits = [0.001,180];
            app.Cc0LowerSpinner.Value = 0.1;
            app.Cc0LowerSpinner.Limits = [0.1,380];
            app.TdLowerSpinner.Value = 20;
            app.TdLowerSpinner.Limits = [20,280];

            app.aUpperSpinner.Value = 5;
            app.aUpperSpinner.Limits = [0.006,5];
            app.bUpperSpinner.Value = 5;
            app.bUpperSpinner.Limits = [0.006,5];
            app.Cs0UpperSpinner.Value = 200;
            app.Cs0UpperSpinner.Limits = [0.002,200];
            app.Cc0UpperSpinner.Value = 400;
            app.Cc0UpperSpinner.Limits = [0.199,400];
            app.TdUpperSpinner.Value = 300;
            app.TdUpperSpinner.Limits = [20.2,300];

            app.aInitialEditField.Value = 0.005;
            app.bInitialEditField.Value = 0.005;
            app.Cs0InitialEditField.Value = 0.1;
            app.Cc0InitialEditField.Value = 1;
            app.TdInitialEditField.Value = 30;

            app.IterationsnumberSpinner.Value = 25;
            app.OptimizationAlgorithmDropDown.Value = 'MultiStart';
            app.LocaloptimizationalgorithmDropDown.Value = 'fmincon';

            app.aEditField.Value = 0.005;
            app.bEditField.Value = 0.005;
            app.Cs0EditField.Value = 0.1;
            app.Cc0EditField.Value = 1;
            app.TdEditField.Value = 30;

            app.RunButton.Enable = 'off';
            app.RunMessage.Value = '';
            app.RunMessage.Visible = 'off';

            app.aCheckBox.Value = 0;
            app.bCheckBox.Value = 0;
            app.TdCheckBox.Value = 0;
            app.Cs0CheckBox.Value = 0;
            app.Cc0CheckBox.Value = 0;

            app.FPV_value.Text = '';
            app.FPT_value.Text = '';
            app.SPV_value.Text = '';
            app.SPT_value.Text = '';
            app.ap_value.Text = '';
            app.bp_value.Text = '';
            app.Tdp_value.Text = '';
            app.Cs0p_value.Text = '';
            app.Cc0p_value.Text = '';

            app.Acquisitionnumberinfo.Value = '';
            
            app.constant_vector = zeros(1,9);
            app.nAcquisizioni = 0;
            app.runValue = 0;
            app.ExecutionNumber = 0;
            app.Executions = [];
            app.ResultsPanel.Visible = 'off';
            app.SelectAnalysis.Items = {};
            app.SelectAnalysis.ItemsData = [];
            app.SelectAnalysis.Value = {};
            if ~isempty(app.SelectAnalysis.ItemsData)
                app.SelectAnalysis.Value = 1;
                app.SelectAnalysisValueChanged;
            end
            
        end

        %%Admin page iniztialization
        function startupAdmin(app)
            elements = app.NomeUtenteDropDown.ItemsData;
            if ~isempty(elements)
                app.NomeUtenteDropDown.Value = elements(1);
            end
            app.NomeEditField.Value = '';
            app.CognomeEditField.Value = '';
            app.CodiceFiscaleEditField.Value = '';
            app.UsernameEditField.Value = '';
            app.PasswordEditField.Value = '';
            app.EmailEditField.Value = '';
            elements = app.CenterUserDropDown.ItemsData;
            if ~isempty(elements)
                app.CenterUserDropDown.Value = elements(1);
            end
            elements = app.LivelloDropDown.ItemsData;
            if ~isempty(elements)
                app.LivelloDropDown.Value = elements(1);
            end
            app.UtenteSaveUserMsg.Value = '';
            app.UtenteSaveUserMsg.Visible = 'off';
            app.UtenteSavingStatus.Color = 'g';
            app.UtenteSavingStatus.Visible = 'off';

            elements = app.CenterDropDown.ItemsData;
            if ~isempty(elements)
                app.CenterDropDown.Value = elements(1);
            end
            app.NomeCentroEditField.Value = '';
            app.IndirizzoEditField.Value = '';
            app.LocationCityEditField.Value = '';
            elements = app.CenteradministratorDropDown.ItemsData;
            if ~isempty(elements)
                app.CenteradministratorDropDown.Value = elements(1);
            end
            app.NumeroMediciEditField.Value = 0;
            app.NumeroPazientiEditField.Value = 0;

            app.CentroSaveUserMsg.Value = '';
            app.CentroSaveUserMsg.Visible = 'off';
            app.CentroSavingStatus.Color = 'g';
            app.CentroSavingStatus.Visible = 'off';
        end

        %%Center page iniztialization: InsertPatientTab
        function startupCenter(app)
            app.NomeMedicoEditField.Value = '';
            app.CognomeMedicoEditField.Value = '';
            app.CFMedicoEditField.Value = '';
            app.UsernameMedicoEditField.Value = '';
            app.PasswordMedicoEditField.Value = '';
            app.EmailMedicoEditField.Value = '';

            app.MedicoSaveUserMsg.Value = '';
            app.MedicoSaveUserMsg.Visible = 'off';
            app.MedicoSavingStatus.Color = 'g';
            app.MedicoSavingStatus.Visible = 'off';
        end
        
        function results = calculateAge(app, date1, date2)
            results = round((datenum(date1)-datenum(date2))/31/12);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Chek dbConnection
            app.RefreshConnPushed
        end

        % Button pushed function: RefreshConn
        function RefreshConnPushed(app, event)
%             progbar =  uiprogressdlg(app.UIFigure,'Title','Loading data',...
%                 'Indeterminate','on');
%             drawnow
            try
                app.RefreshConn.Enable = "off";
                app.LoginButton.Enable = "off";
                app.conn=dbConnection(app.dbUsr,app.dbPsw);
                app.TextArea.Visible = "on";
                app.TextArea.Value = "Testing database connection...";
                pause(2)
                url =  app.conn.URL;
                if isempty(url)
                    app.TextArea.Value = "No database connection. Check if database is on or contact support.";
                    app.RefreshConn.Enable = "on";
                else
                    app.TextArea.Value = "Database ready and connected";
                    app.LoginButton.Enable = "on";
                    app.RefreshConn.Enable = "off";
                    pause(2)
                    %strcat("Connected: ", url);
                    %app.TextArea.Visible = "off";
                    %app.TextArea.Value = '';
                end
                close(app.conn)
            catch ME
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "LoginButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
            end
%             close(progbar)
        end

        % Data changed function: PasswordHTML
        function PasswordHTMLDataChanged(app, event)
            app.PasswordHTML
            password = app.PasswordHTML.Data;
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            progbar =  uiprogressdlg(app.UIFigure,'Title','Login',...
                'Message','Checking credentials...');
            pause(.2)
            tic
            %component initialization
            app.ListaUtenti.Data = {};
            app.NomeUtenteDropDown.ItemsData = {};
            app.NomeUtenteDropDown.Items = {};
            app.LivelloDropDown.ItemsData = {};
            app.LivelloDropDown.Items = {};
            app.CenterDropDown.ItemsData = {};
            app.CenterDropDown.Items = {};
            app.CenterUserDropDown.ItemsData = {};
            app.SelectCenterDrop.ItemsData = {};
            app.CenterUserDropDown.Items = {};
            app.SelectCenterDrop.Items = {};
            app.CenteradministratorDropDown.ItemsData = {};
            app.CenteradministratorDropDown.Items = {};
            app.PazientiList.Data = {};
            app.MediciDropDown.Items = {};
            app.MediciDropDown.ItemsData = {};
            app.ListaMediciCentro.Data = {};
            
            app.TextArea.Visible = 'off';
            app.msg = 'Warnign: Insert valid username and password';
            check = 1;
            try
                username = strtrim(app.UsernameField.Value);
                password = strtrim(app.PasswordHTML.Data);
                if isempty(username) || isempty(password)
                    app.msg = 'Warnign: Insert username and password';
                    progbar.Value = .33; 
                    progbar.Message = app.msg;
                    pause(.2)
                    check = 0;
                end
                app.conn=dbConnection(app.dbUsr,app.dbPsw);
                [m, r] =  sqlprotection({username password});
                if r == true && check
                    disp('MESSAGE: Nothing detected;')
                    loginQuery = strcat("SELECT * FROM utente WHERE username = '", username, "' and password ='", passEncrypt(password, 'SHA-256'),"'");
                    app.loggedUser = select(app.conn, loginQuery);
                    loginEvalSql = strcat("SELECT EVENT_ID, TRUNCATE(TIMER_WAIT/1000000000000,6) as Duration ", ...
                        "FROM performance_schema.events_statements_history AS queryLog ", ...
                        "WHERE queryLog.CURRENT_SCHEMA LIKE 'cbra_new' AND queryLog.SQL_TEXT LIKE '", strrep(loginQuery,"'","\'"), "'");
                    loginEvaluation = select(app.conn, loginEvalSql);
                    evalTime = loginEvaluation.Duration(1);
                    app.id = app.loggedUser.id;
                    if isempty(app.loggedUser) 
                        app.msg = 'Warnign: Incorrect username or password';
                        progbar.Value = .33; 
                        progbar.Message = app.msg;
                        pause(.2)
                        check = 0;
                    end
                    if check
                        app.livello = app.loggedUser.livello;
                        if app.loggedUser.id_centro ~= 0
                            app.idCentro = app.loggedUser.id_centro;
                            app.nome_centro = select(app.conn, strcat('SELECT nome FROM centro WHERE id =', string(app.idCentro)));
                            app.nome_centro = app.nome_centro.nome{1};
                        end

                        switch app.livello
                            case 1 %ADMIN
                                app.TextArea.Visible = 'on';
                                app.msg = "ADMINISTRATOR";
                                app.LoginView.Visible = 'off';
                                app.AdminView.Visible = 'on';
                                app.ModeDropDown.Visible = 'on';
                                app.ModeLabel.Visible = 'on';
                                %Gestione utenti
                                % filling table users
                                userTable = strcat('SELECT u.id, u.code_fis, u.cognome, u.nome, u.username, c.nome FROM utente u LEFT join centro c on u.id_centro = c.id;');
                                rows = select(app.conn, userTable);
%                                 curs = exec(app.conn, userTable);app.conn.message
%                                 curs = fetch(curs);
%                                 rows = curs.Data;
                                %disp(rows);
                                app.ListaUtenti.Data = rows;
                                % fill select single users
                                userList = strcat('SELECT id, code_fis FROM utente;');
                                data = select(app.conn, userList);
                                idUsers = data(:,1);
                                codes = data(:,2);
                                app.NomeUtenteDropDown.ItemsData = table2array(idUsers);
                                app.NomeUtenteDropDown.Items = table2array(codes);
                                % fill select single security levels
                                selectLevel = strcat('SELECT * FROM tp_utente;');
                                data = select(app.conn, selectLevel);
                                idLevels = data(:,1);
                                nameLevels = data(:,2);
                                app.LivelloDropDown.ItemsData = table2array(idLevels);
                                app.LivelloDropDown.Items = table2array(nameLevels);
        
                                %Gestione centri
    %                             centerTable = strcat(['SELECT c.id, c.nome, c.indirizzo, c.citta, c.amministratore, '...
    %                                                 'COUNT(DISTINCT(u.id)) AS numero_medici, COUNT(DISTINCT(p.id)) AS numero_pazienti '...
    %                                                 'FROM utente u, paziente p, centro c '...
    %                                                 'WHERE u.id_centro = c.id AND u.livello = 3 AND p.id_centro = c.id '...
    %                                                 'GROUP BY c.id']);
                                centerTable = strcat('SELECT * FROM centro');
                                centri = select(app.conn, centerTable);
                                if ~isempty(centri)
                                    idCenters = centri(:,1);
                                    nameCenters = centri(:,2);
                                    app.CenterUserDropDown.ItemsData = table2array(idCenters); %Manage users
                                    app.CenterUserDropDown.Items = table2array(nameCenters); %Manage users
                                    app.CenterDropDown.ItemsData = table2array(idCenters); %Manage centers
                                    app.CenterDropDown.Items = table2array(nameCenters); %Manage centers
                                    app.SelectCenterDrop.ItemsData = table2array(idCenters); %Home page
                                    app.SelectCenterDrop.Items = table2array(nameCenters); %Home page
                                    app.SelectCenterAdminDropDown.Items = table2array(nameCenters); %Insert patient
                                    app.SelectCenterAdminDropDown.ItemsData = table2array(idCenters); %Insert patient
                                    app.SelectCenterDrop.Visible = 'on';
                                    app.SelectCenterLabel.Visible = 'on';
                                    app.SelectCenterAdminDropDown.Visible = 'on';
                                    app.SelectCenterAdminDropDownLabel.Visible = 'on';
    
                                    adminsId = centri.amministratore;
                                    centri = removevars(centri, {'amministratore'});
                                    admin = [];
                                    for i = 1:size(adminsId)
                                        %disp(string(adminsId(i)));
                                        adminCode = select(app.conn, strcat('SELECT code_fis FROM utente WHERE id=',string(adminsId(i)),';'));
                                        %disp(adminCode)
                                        admin = [admin string(adminCode{1,1})];
                                        %disp(string(adminCode{1,1}));
                                    end
                                    centri.amministratori = admin.';
                                    app.ListaCentri.Data = centri;
                                end
                                % fill select single administrators for Centers
                                adminsList = strcat('SELECT id, code_fis FROM utente');
                                adminsData = select(app.conn, adminsList);
                                idAdmins = adminsData(:,1);
                                nameAdmins = adminsData(:,2);
                                app.CenteradministratorDropDown.ItemsData = table2array(idAdmins);
                                app.CenteradministratorDropDown.Items = table2array(nameAdmins);
                                app.ParametersSettingsPanel.Visible = 'on';
                                app.RestoreButton.Visible = 'on';
        
                                %caricamento nomi in select luogo nascita
                                nomecomuni = load('nomecomuni.mat');
                                %disp(class(name));
                                app.nomiComuni = nomecomuni.name;
                                %disp(app.nomiComuni)
                                app.BirthPlaceEditField.Items = app.nomiComuni;
                                app.BirthPlaceEditField.ItemsData = linspace(1, length(app.nomiComuni), length(app.nomiComuni));
                                %disp(app.BirthPlaceEditField.ItemsData);
        
                                pazientiTab = strcat('SELECT CONCAT(p.cognome, " ", p.nome) AS nome, p.cf, h.dataRicovero, h.oraRicovero, h.dataEvento, h.oraEvento, a.dataAcquisizione, a.oraAcquisizione FROM paziente p LEFT JOIN hospitalization h ON p.id = h.id_paziente LEFT JOIN acquisizione a ON h.id = a.id_hospedalization WHERE p.id_centro=', string(app.idCentro));
                                %disp(pazientiTab)
                                rows = select(app.conn, pazientiTab);
                                %disp(rows)
                                app.PazientiList.Data = rows;
                            
                                app.PlotMedPanel.AutoResizeChildren = 'off';
                                app.ax1 = subplot(2,1,1,'Parent',app.PlotMedPanel);
                                app.ax2 = subplot(2,1,2,'Parent',app.PlotMedPanel);
                                cla(app.ax1, 'reset');
                                cla(app.ax2, 'reset');
    
                                progbar.Value = .33;
                                progbar.Message = 'Login as ADMIN successful';
                                pause(0.2)
                            case 2 %CENTER
                                app.TextArea.Visible = 'on';
                                app.msg = "CENTER";
                                
                                %fill clinic drop down
                                clinisQuery = strcat('SELECT u.id, u.username, CONCAT(u.cognome, " ", u.nome) AS nome, u.code_fis FROM utente u WHERE u.id_centro = ', string(app.idCentro), ' AND u.livello = 3');
                                clinicsData = select(app.conn, clinisQuery);
                                if ~isempty(clinicsData)
                                    app.MediciDropDown.Items = clinicsData.code_fis;
                                    app.MediciDropDown.ItemsData = clinicsData.id;
                                    %fill clinic table
                                    rows = removevars(clinicsData, 'id');
                                    app.ListaMediciCentro.Data = rows;
                                end
                                %fill center info
                                centerInfoQuery = strcat('SELECT c.id, CONCAT(c.nome, " ", c.indirizzo, " ", c.citta) AS nome, c.numero_medici, c.numero_pazienti FROM centro c WHERE c.id = ',string(app.idCentro));
                                centerInfoData = select(app.conn, centerInfoQuery);
                                app.CenterNameLabel.Text = strcat('Center:'," ",centerInfoData.nome(1));
                                app.NumeroMedici.Text = strcat('Clinicians: '," ",string(centerInfoData.numero_medici(1)));
                                app.NumeroPazienti.Text = strcat('Patients: '," ",string(centerInfoData.numero_pazienti(1)));
                                
                                app.LoginView.Visible = 'off';
                                app.CenterView.Visible = 'on';
                                progbar.Value = .33; 
                                progbar.Message = 'Login as CENTER successful';
                                pause(0.2)
                            case 3 %CLINIC
                                app.TextArea.Visible = 'on';
                                app.msg = "CLINIC";
                                app.LoginView.Visible = 'off';
                                app.ClinicView.Visible = 'on';
        
                                %caricamento nomi in select luogo nascita
                                nomecomuni = load('nomecomuni.mat');
                                %disp(name);
                                app.nomiComuni = nomecomuni.name;
                                %disp(app.nomiComuni)
                                app.BirthPlaceEditField.Items = app.nomiComuni;
                                app.BirthPlaceEditField.ItemsData = linspace(1, length(app.nomiComuni), length(app.nomiComuni));
                                
                                %fill pazienti dropdown
    %                             pazientiDrop = strcat('SELECT p.id, CONCAT(p.cognome, " ", p.nome) AS nome, p.cf FROM paziente p, utente m, medico_paziente mp WHERE mp.id_medico = m.id AND mp.id_paziente = p.id AND m.id = ', string(app.id),';');
                                pazientiDrop = strcat('SELECT p.id, CONCAT(p.cognome, " ", p.nome) AS nome, p.cf FROM paziente p WHERE p.id_centro = ', string(app.idCentro),';');
                                pazientiData = select(app.conn, pazientiDrop);
                                if ~isempty(pazientiData)
                                    app.PazienteDropDown.ItemsData = table2array(pazientiData(:,1));
                                    app.PazienteDropDown.Items = table2array(pazientiData(:,3));
                                end
                                
                                %fill pazienti table
    %                             pazientiTab = strcat('SELECT CONCAT(p.cognome, " ", p.nome) AS nome, p.cf, h.dataRicovero, h.oraRicovero, h.dataEvento, h.oraEvento, a.dataAcquisizione, a.oraAcquisizione FROM paziente p LEFT JOIN hospitalization h ON p.id = h.id_paziente LEFT JOIN acquisizione a ON h.id = a.id_hospedalization RIGHT JOIN medico_paziente mp ON p.id = mp.id_paziente WHERE mp.id_medico =', string(app.id));
                                pazientiTab = strcat(['SELECT CONCAT(p.cognome, " ", p.nome) AS nome, p.cf, ' ...
                                    'h.dataRicovero, h.oraRicovero, h.dataEvento, h.oraEvento, ' ...
                                    'a.dataAcquisizione, a.oraAcquisizione ' ...
                                    'FROM paziente p ' ...
                                    'LEFT JOIN hospitalization h ON p.id = h.id_paziente ' ...
                                    'LEFT JOIN acquisizione a ON h.id = a.id_hospedalization ' ...
                                    'WHERE p.id_centro ='], string(app.idCentro));
                                %disp(pazientiTab)
                                rows = select(app.conn, pazientiTab);
                                %disp(rows)
                                if ~isempty(rows)
                                    app.PazientiList.Data = rows;
                                end
                                app.SelectCenterAdminDropDown.Visible = 'off';
                                app.SelectCenterAdminDropDownLabel.Visible = 'off';
    
                                app.PlotMedPanel.AutoResizeChildren = 'off';
                                app.ax1 = subplot(2,1,1,'Parent',app.PlotMedPanel);
                                app.ax2 = subplot(2,1,2,'Parent',app.PlotMedPanel);
                                cla(app.ax1, 'reset');
                                cla(app.ax2, 'reset');
    
                                progbar.Value = .33; 
                                progbar.Message = 'Login as CLINIC successful';
                                pause(0.2)
                            otherwise
                                error('Login:IncorrectCredentials', ...
                                    "Warnign: Incorrect username or password")
                        end
                    else
                        error('Login:IncorrectCredentials', ...
                                    "Warnign: Incorrect username or password")
                    end
                executionTime = toc;
                sqlwrite(app.conn, 'login', table(string(username), app.id, app.livello, evalTime, executionTime, 'VariableNames', ["logged_user" "user_id" "access_level" "evaluation_time" "execution_time"]));
                else
                    app.msg = m;
                    error('Login:IncorrectCredentials', ...
                                    "Warnign: Incorrect username or password")
                end
                progbar.Value = 1;
                progbar.Message = app.msg;
                pause(0.2)
            catch ME
                app.msg = "Internal error";
                if strcmp(ME.message,"Invalid connection.") 
                    progbar.Value = 1;
                    app.msg = "No database connection";
                    progbar.Message = app.msg;
                    pause(0.2)
                    app.RefreshConnPushed
                elseif strcmp(ME.identifier, "Login:IncorrectCredentials")
                    progbar.Value = 1;
                    app.msg = ME.message;
                    progbar.Message = app.msg;
                    pause(0.2)
                end

%                 disp(max([ME.stack(1:end).line]))
                line = max([ME.stack(1:end).line]);
%                 disp(line)
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "LoginButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));

                disp("LoginButtonPushedError")
                disp(ME.identifier)
                disp(ME.message)
%                 rethrow(ME)
            end
            app.TextArea.Value = app.msg;
            app.TextArea.Visible = 'on';
            close(progbar)
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            progbar =  uiprogressdlg(app.UIFigure,'Title','Switching mode',...
                'Indeterminate','on');
            pause(0.2)
            try
                role = app.ModeDropDown.Value;
%                 disp(app.ModeDropDown.ItemsData)

                switch role
                    case 1
                        startupAdmin(app);
                        app.AdminView.Visible = 'on';
                        app.CenterView.Visible = 'off';
                        app.ClinicView.Visible = 'off';
                        app.ManageusersTabButtonDown;
                    case 2
                        startupInsert(app);
                        app.InsertPatientTabButtonDown;
%                         app.HomePage.Visible = 'on';
%                         app.HospitalizationPage.Visible = 'off';
%                         app.AcquisitionsPage.Visible = 'off';
%                         app.AnalysisPage.Visible = 'off';
%                         startupHomePage(app);
%                         startupHospitalization(app);
%                         startupAcquisition(app);
%                         startupAnalysis(app);
                        elements = app.SelectCenterDrop.ItemsData;
                        if ~isempty(elements)
                            app.SelectCenterDrop.Value = elements(1);
                            pause(.2)
                            app.SelectCenterDropValueChanged;
                        end
                        app.AdminView.Visible = 'off';
                        app.CenterView.Visible = 'off';
                        app.ClinicView.Visible = 'on';
                end

%                 switch role
%                     case 1
%                         startupAdmin(app);
%                         app.AdminView.Visible = 'on';
%                         app.CenterView.Visible = 'off';
%                         app.ClinicView.Visible = 'off';
%                     case 2
%                         startupCenter(app);
%                         app.AdminView.Visible = 'off';
%                         app.CenterView.Visible = 'on';
%                         app.ClinicView.Visible = 'off';
%                     case 3
%                         startupInsert(app);
%                         app.HomePage.Visible = 'on';
%                         app.HospitalizationPage.Visible = 'off';
%                         app.AcquisitionsPage.Visible = 'off';
%                         app.AnalysisPage.Visible = 'off';
%                         startupHomePage(app);
%                         startupHospedalization(app);
%                         startupAcquisition(app);
%                         startupAnalysis(app);
%                         elements = app.SelectCenterDrop.ItemsData;
%                         if ~isempty(elements)
%                             app.SelectCenterDrop.Value = elements(1);
%                             app.SelectCenterDropValueChanged;
%                         end
%                         app.AdminView.Visible = 'off';
%                         app.CenterView.Visible = 'off';
%                         app.ClinicView.Visible = 'on';
%                 end
            catch ME
                disp("ModeDropDownValueChanged Error")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "ModeDropDownValueChanged", 'VariableNames', ["identifier" "line" "function_name"]));
%                 rethrow(ME)
            end
            close(progbar)
        end

        % Button down function: ManageusersTab
        function ManageusersTabButtonDown(app, event)
            try
                drawnow
                startupCenter(app);
            catch ME
                disp("ManageusersTabButtonDownError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "ManageusersTabButtonDown", 'VariableNames', ["identifier" "line" "function_name"]));
            end
        end

        % Value changed function: NomeUtenteDropDown
        function NomeUtenteDropDownValueChanged(app, event)
            progbar =  uiprogressdlg(app.UIFigure,'Title','Loading users data',...
                'Indeterminate','on');
            drawnow
            try
                idUtente = app.NomeUtenteDropDown.Value;
                s = strcat('SELECT * FROM utente WHERE id = ',string(idUtente),';');
                data = select(app.conn, s);
                
                app.NomeEditField.Value = string(table2cell(data(1,2)));
                app.CognomeEditField.Value = string(table2cell(data(1,3)));
                app.CodiceFiscaleEditField.Value = string(table2cell(data(1,4)));
                app.UsernameEditField.Value = string(table2cell(data(1,5)));
                app.EmailEditField.Value = string(table2cell(data(1,7)));
                centroVal = table2cell(data(1,8));
                app.CenterUserDropDown.Value = centroVal{1};
                livelloVal = table2cell(data(1,9));
                app.LivelloDropDown.Value = livelloVal{1};
            catch ME
                disp("NomeUtenteDropDownValueChangedError")
                disp(ME)                
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "NomeUtenteDropDownValueChanged", 'VariableNames', ["identifier" "line" "function_name"]));
            end
            close(progbar)
        end

        % Button pushed function: NuovoUtenteButton
        function NuovoUtenteButtonPushed(app, event)
            %ProgressBar init
            progbar =  uiprogressdlg(app.UIFigure,'Title','Save new user',...
                'Message','Saving...','Cancelable','off');
            pause(.2)
            tic
            try
                app.UtenteSavingStatus.Visible = 'off';
                check = true;
                app.UtenteSavingStatus.Color = 'g';
                app.UtenteSaveUserMsg.Visible = "off";
                app.NomeEditField.BackgroundColor = [1 1 1];
                app.CognomeEditField.BackgroundColor = [1 1 1];
                app.UsernameEditField.BackgroundColor = [1 1 1];
                app.PasswordEditField.BackgroundColor = [1 1 1];
                app.CodiceFiscaleEditField.BackgroundColor = [1 1 1];
                app.UtenteSaveUserMsg.Value = '';
                app.msg = "ERROR! insert a valid:";

                nome = string(app.NomeEditField.Value);
                if isempty(nome) || strcmp(nome,"")
                    check = false;
                    app.UtenteSavingStatus.Color = 'r';
                    app.NomeEditField.BackgroundColor = [1 0 0];
                    app.msg = strcat(app.msg, " Name");
                end
                cognome = string(app.CognomeEditField.Value);
                if isempty(cognome) || strcmp(cognome,"")
                    check = false;
                    app.UtenteSavingStatus.Color = 'r';
                    app.CognomeEditField.BackgroundColor = [1 0 0];
                    app.msg = strcat(app.msg, " Surnname");
                end
                codeFis = upper(string(app.CodiceFiscaleEditField.Value));
                if isempty(codeFis) || strcmp(codeFis,"")
                    check = false;
                    app.UtenteSavingStatus.Color = 'r';
                    app.CodiceFiscaleEditField.BackgroundColor = [1 0 0];
                    app.msg = strcat(app.msg, " SSN");
                end
                username = string(app.UsernameEditField.Value);
                if isempty(username) || strcmp(username,"")
                    check = false;
                    app.UtenteSavingStatus.Color = 'r';
                    app.UsernameEditField.BackgroundColor = [1 0 0];
                    app.msg = strcat(app.msg, " Username");
                end
                password = string(app.PasswordEditField.Value);
                if isempty(password) || strcmp(password,"")
                    check = false;
                    app.UtenteSavingStatus.Color = 'r';
                    app.PasswordEditField.BackgroundColor = [1 0 0];
                    app.msg = strcat(app.msg, " Password");
                end
                email = string(app.EmailEditField.Value);

                %ProgressBar update
                progbar.Value = .33; 
                progbar.Message = strcat("Saving user: ",codeFis);
                pause(0.2)

                if check
                    [m, r] = sqlprotection({nome cognome username password email codeFis});
                    if r == true
                        idCentroSel = app.CenterUserDropDown.Value;
                        livelloSel = app.LivelloDropDown.Value;

                        password = string(passEncrypt(password, 'SHA-256'));
                        data = table(nome,cognome,codeFis,username,password,email,idCentroSel,livelloSel,'VariableNames',{'nome','cognome','code_fis','username','password','email','id_centro','livello'});
                        sqlwrite(app.conn,'utente',data);
                        loginEvalSql = strcat("SELECT EVENT_ID, TRUNCATE(TIMER_WAIT/1000000000000,6) as Duration ", ...
                            "FROM performance_schema.events_statements_history AS queryLog ", ...
                            "WHERE queryLog.CURRENT_SCHEMA LIKE 'cbra_new' AND queryLog.SQL_TEXT LIKE 'INSERT INTO utente % VALUES %", username, "%'");
                        loginEvaluation = select(app.conn, loginEvalSql);
                        evalTime = loginEvaluation.Duration(1);
                        
                        app.msg = strcat("Saving ",codeFis, " complete!");
                        app.UtenteSavingStatus.Color = 'g';
    
                        userTable = strcat('SELECT u.id, u.code_fis, u.cognome, u.nome, u.username, c.nome FROM utente u LEFT join centro c on u.id_centro = c.id;');
                        curs = exec(app.conn, userTable);app.conn.message
                        curs = fetch(curs);
                        rows = curs.Data;
                        app.ListaUtenti.Data = rows;
                        userList = strcat('SELECT id, code_fis FROM utente;');
                        data = select(app.conn, userList);
                        idUsers = data(:,1);
                        codes = data(:,2);
                        app.NomeUtenteDropDown.ItemsData = table2array(idUsers);
                        app.NomeUtenteDropDown.Items = table2array(codes);
                        idUtente = select(app.conn, strcat('SELECT id FROM utente WHERE code_fis="',codeFis,'";'));
                        idUtente = idUtente.id;
                        app.NomeUtenteDropDown.Value = idUtente;
                        adminsList = strcat('SELECT id, code_fis FROM utente');
                        adminsData = select(app.conn, adminsList);
                        idAdmins = adminsData(:,1);
                        nameAdmins = adminsData(:,2);
                        app.CenteradministratorDropDown.ItemsData = table2array(idAdmins);
                        app.CenteradministratorDropDown.Items = table2array(nameAdmins);
    
                        if livelloSel == 3
                            centerTable = strcat(['SELECT c.id, c.nome, c.indirizzo, c.citta, c.amministratore, '...
                                                    'COUNT(DISTINCT(u.id)) AS numero_medici '...
                                                    'FROM utente u, centro c '...
                                                    'WHERE u.id_centro = c.id AND u.livello = 3 '...
                                                    'GROUP BY c.id']);
                            centri = select(app.conn, centerTable);
                            medici = centri(:,6);
                            for i = 1:size(centri.id)
                                whereClause=strcat("WHERE id =",string(centri.id(i)));
    %                             disp(whereClause);
    %                             disp(medici(i,:));
                                update(app.conn, 'centro', {'numero_medici'}, medici(i,:), whereClause);
                            end
                            adminsId = centri.amministratore;
                            centri = removevars(centri, {'amministratore'});
                            admin = [];
                            for i = 1:size(adminsId)
                                %disp(string(adminsId(i)));
                                adminCode = select(app.conn, strcat('SELECT code_fis FROM utente WHERE id=',string(adminsId(i)),';'));
                                %disp(adminCode)
                                admin = [admin string(adminCode{1,1})];
                                %disp(string(adminCode{1,1}));
                            end
                            centri.amministratori = admin.';
                            app.ListaCentri.Data = centri;
                        end
                    else
                        app.UtenteSavingStatus.Color = 'r';
                        app.msg = m;
                    end
                    executionTime = toc;
                    update(app.conn, 'utente', {'evaluation_time' 'execution_time'}, table(evalTime, executionTime, 'VariableNames', {'evaluation_time' 'execution_time'}), strcat("WHERE username = '",username,"'"));
                end
                %updating
                progbar.Value = .67;
                progbar.Message = app.msg;
                pause(.2)
                %updating
                progbar.Value = 1;
                progbar.Message = 'Finishing';
                pause(.2)
            catch ME
                disp("NuovoUtenteButtonPushedError")
                errorMsg = strcat("Error on save! User: ",codeFis," already exists or Incorrect inputs ");
                app.msg = errorMsg;
                app.UtenteSavingStatus.Color = 'r';
                disp(ME.identifier)          
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "NuovoUtenteButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
            end
            app.UtenteSaveUserMsg.Visible = 'on';
            app.UtenteSavingStatus.Visible = 'on';
            app.UtenteSaveUserMsg.Value = app.msg;
            close(progbar)
        end

        % Button pushed function: SalvaUtenteButton
        function SalvaUtenteButtonPushed(app, event)
            %ProgressBar init
            progbar =  uiprogressdlg(app.UIFigure,'Title','Save edits',...
                'Message','Saving...','Cancelable','off');
            pause(.2)
            try
                app.UtenteSavingStatus.Visible = 'off';
                check = true;
                app.UtenteSavingStatus.Color = 'g';
                app.UtenteSaveUserMsg.Visible = "off";
                app.NomeEditField.BackgroundColor = [1 1 1];
                app.CognomeEditField.BackgroundColor = [1 1 1];
                app.UsernameEditField.BackgroundColor = [1 1 1];
                app.PasswordEditField.BackgroundColor = [1 1 1];
                app.CodiceFiscaleEditField.BackgroundColor = [1 1 1];
                app.UtenteSaveUserMsg.Value = '';
                app.msg = "ERROR! insert a valid:";

                idUtente = app.NomeUtenteDropDown.Value;

                nome = string(app.NomeEditField.Value);
                if isempty(nome) || strcmp(nome,"")
                    check = false;
                    app.UtenteSavingStatus.Color = 'r';
                    app.NomeEditField.BackgroundColor = [1 0 0];
                    app.msg = strcat(app.msg, ' Name');
                end
                cognome = string(app.CognomeEditField.Value);
                if isempty(cognome) || strcmp(cognome,"")
                    check = false;
                    app.UtenteSavingStatus.Color = 'r';
                    app.CognomeEditField.BackgroundColor = [1 0 0];
                    app.msg = strcat(app.msg,' Surname');
                end
                codeFis = upper(string(app.CodiceFiscaleEditField.Value));
                if isempty(codeFis) || strcmp(codeFis,"")
                    check = false;
                    app.UtenteSavingStatus.Color = 'r';
                    app.CodiceFiscaleEditField.BackgroundColor = [1 0 0];
                    app.msg = strcat(app.msg, ' SSN');
                end
                username = string(app.UsernameEditField.Value);
                if isempty(username) || strcmp(username,"")
                    check = false;
                    app.UtenteSavingStatus.Color = 'r';
                    app.UsernameEditField.BackgroundColor = [1 0 0];
                    app.msg = strcat(app.msg, ' Username');
                end
                password = string(app.PasswordEditField.Value);
                email = string(app.EmailEditField.Value);

                %ProgressBar update
                progbar.Value = .33; 
                progbar.Message = strcat("Saving edits for user: ",codeFis);
                pause(.2)

                if check
                    [m, r] = sqlprotection({nome cognome username password email codeFis email});
                    if r == true
                        app.msg = strcat("Saving ",codeFis, " complete!");
                        app.UtenteSavingStatus.Color = 'g';
                        idCentroSel = app.CenterUserDropDown.Value;
                        livelloSel = app.LivelloDropDown.Value;
        
                        checkNull = nullCheck(app, [idUtente nome cognome username codeFis idCentroSel livelloSel], false);
                        if checkNull
                            s = strcat('SELECT * FROM utente WHERE id = ',int2str(idUtente),';');
                            data = select(app.conn, s); 
                            passwordDB = string(table2cell(data(1,6)));
                            if isempty(convertStringsToChars(password))
                                password = passwordDB;
                            else
                                password = string(passEncrypt(password, 'SHA-256'));
                            end
                            update_usr = strcat('UPDATE utente SET nome="',nome, '", cognome="',cognome,'", code_fis="',codeFis,'", username="',username,'", password="',password,'", email="',email,'", id_centro=',string(idCentroSel),', livello=',string(livelloSel),', date_edit="',datestr(now, "yyyy-mm-dd HH:MM:SS"),'" WHERE id=',string(idUtente),';');
                            curs = exec(app.conn, update_usr);app.conn.message
                            curs = fetch(curs);
        
                            userTable = strcat('SELECT u.id, u.code_fis, u.cognome, u.nome, u.username, c.nome FROM utente u LEFT join centro c on u.id_centro = c.id;');
                            curs = exec(app.conn, userTable);app.conn.message
                            curs = fetch(curs);
                            rows = curs.Data;
                            
                            app.ListaUtenti.Data = rows;
                
                            userList = strcat('SELECT id, code_fis FROM utente;');
                            data = select(app.conn, userList);
                            idUsers = data(:,1);
                            codes = data(:,2);
                            app.NomeUtenteDropDown.ItemsData = table2array(idUsers);
                            app.NomeUtenteDropDown.Items = table2array(codes);
                            app.NomeUtenteDropDown.Value = idUtente;
                            adminsList = strcat('SELECT id, code_fis FROM utente');
                            adminsData = select(app.conn, adminsList);
                            idAdmins = adminsData(:,1);
                            nameAdmins = adminsData(:,2);
                            app.CenteradministratorDropDown.ItemsData = table2array(idAdmins);
                            app.CenteradministratorDropDown.Items = table2array(nameAdmins);
    
                            if livelloSel == 3
                                centerTable = strcat(['SELECT c.id, c.nome, c.indirizzo, c.citta, c.amministratore, '...
                                                        'COUNT(DISTINCT(u.id)) AS numero_medici '...
                                                        'FROM utente u, centro c '...
                                                        'WHERE u.id_centro = c.id AND u.livello = 3 '...
                                                        'GROUP BY c.id']);
                                centri = select(app.conn, centerTable);
                                medici = centri(:,6);
                                for i = 1:size(centri.id)
                                    whereClause=strcat("WHERE id =",string(centri.id(i)));
        %                             disp(whereClause);
        %                             disp(medici(i,:));
                                    update(app.conn, 'centro', {'numero_medici'}, medici(i,:), whereClause);
                                end
                                adminsId = centri.amministratore;
                                centri = removevars(centri, {'amministratore'});
                                admin = [];
                                for i = 1:size(adminsId)
                                    %disp(string(adminsId(i)));
                                    adminCode = select(app.conn, strcat('SELECT code_fis FROM utente WHERE id=',string(adminsId(i)),';'));
                                    %disp(adminCode)
                                    admin = [admin string(adminCode{1,1})];
                                    %disp(string(adminCode{1,1}));
                                end
                                centri.amministratori = admin.';
                                app.ListaCentri.Data = centri;
                            end
    
                        else
                            ME = MException('Error, check empty fields');
                            throw(ME)
                        end
                    else
                        app.UtenteSavingStatus.Color = 'r';
                        app.msg = m;
                    end
                end
                %updating
                progbar.Value = .67;
                progbar.Message = app.msg;
                pause(.2)
                %updating
                progbar.Value = 1;
                progbar.Message = 'Finishing';
                pause(.2)
            catch ME
                disp("SalvaUtenteButtonPushedError")
                errorMsg = strcat("Error on save! User: ",codeFis," doesn't exist or Incorrect inputs");
                app.msg = errorMsg;
                app.UtenteSavingStatus.Color = 'r';
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "SalvaUtenteButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
%                 rethrow(ME)
                close(progbar)
            end
            app.UtenteSaveUserMsg.Visible = 'on';
            app.UtenteSavingStatus.Visible = 'on';
            app.UtenteSaveUserMsg.Value = app.msg;
            close(progbar)
        end

        % Button down function: ManagecentersTab
        function ManagecentersTabButtonDown(app, event)
            try
                drawnow
                startupAdmin(app);
            catch ME
                disp("ManagecentersTabButtonDownError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "ManagecentersTabButtonDown", 'VariableNames', ["identifier" "line" "function_name"]));
            end
        end

        % Value changed function: CenterDropDown
        function CenterDropDownValueChanged(app, event)
            progbar =  uiprogressdlg(app.UIFigure,'Title','Loading centers data',...
                'Indeterminate','on');
            drawnow
            try
                centroId = app.CenterDropDown.Value;
                s = strcat('SELECT * FROM centro WHERE id = ',string(centroId),';');
                data = select(app.conn, s);
                
                app.NomeCentroEditField.Value = string(table2cell(data(1,2)));
                app.IndirizzoEditField.Value = string(table2cell(data(1,3)));
                app.LocationCityEditField.Value = string(table2cell(data(1,4)));
                adminVal = table2cell(data(1,5));
                app.CenteradministratorDropDown.Value = adminVal{1};
                nmedici = table2cell(data(1,6));
                npazienti = table2cell(data(1,7));
                app.NumeroMediciEditField.Value = double(nmedici{1});
                app.NumeroPazientiEditField.Value = double(npazienti{1});
            catch ME
                disp("CenterDropDownValueChangedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "CenterDropDownValueChanged", 'VariableNames', ["identifier" "line" "function_name"]));

%                 rethrow(ME)
            end
            close(progbar)
        end

        % Button pushed function: NuovoCentroButton
        function NuovoCentroButtonPushed(app, event)
            progbar =  uiprogressdlg(app.UIFigure,'Title','Save new center',...
                'Message','Saving...','Cancelable','off');
            pause(.2)
            tic
            try
                check = true;
                app.CentroSavingStatus.Visible = 'off';
                app.CentroSaveUserMsg.Value = '';
                app.CentroSaveUserMsg.Visible = "off";
                app.NomeCentroEditField.BackgroundColor = [1 1 1];
                app.IndirizzoEditField.BackgroundColor = [1 1 1];
                app.LocationCityEditField.BackgroundColor = [1 1 1];
                
                app.msg = "ERROR! insert a valid:";

                nome = string(app.NomeCentroEditField.Value);
                if isempty(nome) || strcmp(nome, "")
                    check = false;
                    app.CentroSavingStatus.Color = 'r';
                    app.NomeCentroEditField.BackgroundColor = [1 0 0];
                    app.msg = strcat(app.msg,' Name');
                end
                indirizzo = string(app.IndirizzoEditField.Value);
%                 if isempty(indirizzo) || strcmp(indirizzo, "")
%                     check = false;
%                     app.CentroSavingStatus.Color = 'r';
%                     app.IndirizzoEditField.BackgroundColor = [1 0 0];
%                     app.msg = strcat(app.msg, ' Address');
%                 end
                citta = string(app.LocationCityEditField.Value);
                if isempty(citta) || strcmp(citta, "")
                    check = false;
                    app.CentroSavingStatus.Color = 'r';
                    app.LocationCityEditField.BackgroundColor = [1 0 0];
                    app.msg = strcat(app.msg,' Location');
                end

                progbar.Value = .33; 
                progbar.Message = strcat("Saving center: ",nome);
                pause(0.2)

                if check
                    [m, r] = sqlprotection({nome, indirizzo, citta});
                    if r == true
                        m = strcat("Save error! Center: ",nome," already exist or Incorrect inputs");
                        app.msg = strcat("Saving ",nome, " complete!");
                        app.CentroSavingStatus.Color = 'g';
                        idAmministratore = app.CenteradministratorDropDown.Value;
                        n_medici = app.NumeroMediciEditField.Value;
                        n_pazienti = app.NumeroPazientiEditField.Value;
    
%                         check = true;
%                         values = [nome, indirizzo, citta];
%                         values = nome;
%                         check = nullCheck(app, values, false);
    
                        if(check)
    
                            data = table(nome,indirizzo,citta,idAmministratore,n_medici,n_pazienti,'VariableNames',{'nome','indirizzo','citta','amministratore','numero_medici','numero_pazienti'});
                            sqlwrite(app.conn,'centro',data);
                            loginEvalSql = strcat("SELECT EVENT_ID, TRUNCATE(TIMER_WAIT/1000000000000,6) as Duration ", ...
                                "FROM performance_schema.events_statements_history AS queryLog ", ...
                                "WHERE queryLog.CURRENT_SCHEMA LIKE 'cbra_new' AND queryLog.SQL_TEXT LIKE 'INSERT INTO centro % VALUES %", nome, "%'");
                            loginEvaluation = select(app.conn, loginEvalSql);
                            evalTime = loginEvaluation.Duration(1);
        
                            %update table on sceen
                            centerTable = strcat('SELECT * FROM centro');
                            centri = select(app.conn, centerTable);
            
                            adminsId = centri.amministratore;
                            centri = removevars(centri, {'amministratore'});
                            admin = [];
                            for i = 1:size(adminsId)
                                %disp(string(adminsId(i)));
                                adminCode = select(app.conn, strcat('SELECT code_fis FROM utente WHERE id=',string(adminsId(i)),';'));
                                %disp(adminCode)
                                admin = [admin string(adminCode{1,1})];
                                %disp(string(adminCode{1,1}));
                            end
                            centri.amministratori = admin.';
                            app.ListaCentri.Data = centri;
                            
                            %update select single on sceen
                            idCenters = centri(:,1);
                            nameCenters = centri(:,2);
                            app.CenterUserDropDown.Items = table2array(nameCenters);
                            app.CenterUserDropDown.ItemsData = table2array(idCenters);
                            app.CenterDropDown.Items = table2array(nameCenters);
                            app.CenterDropDown.ItemsData = table2array(idCenters);
                            app.SelectCenterAdminDropDown.Items = table2array(nameCenters);
                            app.SelectCenterAdminDropDown.ItemsData = table2array(idCenters);
                            app.SelectCenterDrop.ItemsData = table2array(idCenters);
                            app.SelectCenterDrop.Items = table2array(nameCenters);
                            
                            centroId = select(app.conn, strcat('SELECT id FROM centro WHERE nome="',nome,'" AND citta="',citta,'";'));
                            app.CenterDropDown.Value = centroId.id;
                        else
                            app.CentroSavingStatus.Color = 'r';
                            app.msg = m;
                        end
                    else
                        app.CentroSavingStatus.Color = 'r';
                        app.msg = m;
                    end
                    executionTime = toc;
                    update(app.conn, 'centro', {'evaluation_time' 'execution_time'}, table(evalTime, executionTime, 'VariableNames', {'evaluation_time' 'execution_time'}), strcat("WHERE nome = '",nome, "'"));
                end

                progbar.Value = .67;
                progbar.Message = app.msg;
                pause(.2)

                progbar.Value = 1;
                progbar.Message = 'Finishing';
                pause(.2)
            catch ME
                disp("NuovoCentroButtonPushedError")
                disp(ME.identifier)
                app.msg = ME.message;
                app.CentroSavingStatus.Color = 'r';
                close(progbar)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "NuovoCentroButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
%                 rethrow(ME)
            end
            app.CentroSaveUserMsg.Visible = 'on';
            app.CentroSavingStatus.Visible = 'on';
            app.CentroSaveUserMsg.Value = app.msg;
            close(progbar)
        end

        % Button pushed function: SalvaCentroButton
        function SalvaCentroButtonPushed(app, event)
            progbar =  uiprogressdlg(app.UIFigure,'Title','Save center edits',...
                'Message','Saving...','Cancelable','off');
            pause(.2)
            try
                check = true;
                app.CentroSavingStatus.Color = 'g';
                app.CentroSavingStatus.Visible = 'off';
                app.CentroSaveUserMsg.Value = '';
                app.CentroSaveUserMsg.Visible = "off";
                app.NomeCentroEditField.BackgroundColor = [1 1 1];
                app.IndirizzoEditField.BackgroundColor = [1 1 1];
                app.LocationCityEditField.BackgroundColor = [1 1 1];
                
                app.msg = "ERROR! insert a valid:";

                nome = string(app.NomeCentroEditField.Value);
                if isempty(nome) || strcmp(nome, "")
                    check = false;
                    app.CentroSavingStatus.Color = 'r';
                    app.NomeCentroEditField.BackgroundColor = [1 0 0];
                    app.msg = strcat(app.msg, ' Name');
                end
                indirizzo = string(app.IndirizzoEditField.Value);
%                 if isempty(indirizzo) || strcmp(indirizzo, "")
%                     check = false;
%                     app.CentroSavingStatus.Color = 'r';
%                     app.IndirizzoEditField.BackgroundColor = [1 0 0];
%                     app.msg = strcat(app.msg,' Address');
%                 end
                citta = string(app.LocationCityEditField.Value);
                if isempty(citta) || strcmp(citta, "")
                    check = false;
                    app.CentroSavingStatus.Color = 'r';
                    app.LocationCityEditField.BackgroundColor = [1 0 0];
                    app.msg = strcat(app.msg,' Location');
                end

                progbar.Value = .33; 
                progbar.Message = strcat("Saving center: ",nome);
                pause(0.2)

                if check
                    [m, r] = sqlprotection({nome, indirizzo, citta});
                    if r == true
                        m = strcat("Save error! Center: ",nome," already exist or Incorrect inputs");
                        app.msg = strcat("Saving ",nome, " complete!");
                        idAmministratore = app.CenteradministratorDropDown.Value;
                        n_medici = app.NumeroMediciEditField.Value;
                        n_pazienti = app.NumeroPazientiEditField.Value;
            
%                         check = true;
%                         values = [nome, indirizzo, citta];
%                         check = nullCheck(app, values, false);
        
                        if(check)
%                             s = app.CenterDropDown.Items(find(app.CenterDropDown.ItemsData == app.CenterDropDown.Value))
%                             s = strcat('SELECT id FROM centro WHERE nome = "',nome,'";');
%                             centroId = select(app.conn, s);
                            centroId = app.CenterDropDown.Value;
                            update_center = strcat('UPDATE centro SET nome="',nome, '", indirizzo="',indirizzo,'", citta="',citta,'", amministratore="',string(idAmministratore),'", numero_medici="',string(n_medici),'", numero_pazienti="',string(n_pazienti),'", date_edit="', datestr(now, 'yyyy-mm-dd HH:MM:SS'),'" WHERE id="',string(centroId),'";');
                            curs = exec(app.conn, update_center);app.conn.message
                            curs = fetch(curs);
            
                            %update table on sceen
                            centerTable = strcat('SELECT * FROM centro');
                            centri = select(app.conn, centerTable);
                            adminsId = centri.amministratore;
                            centri = removevars(centri, {'amministratore'});
                            admin = [];
                            for i = 1:size(adminsId)
                                %disp(string(adminsId(i)));
                                adminCode = select(app.conn, strcat('SELECT code_fis FROM utente WHERE id=',string(adminsId(i)),';'));
                                %disp(adminCode)
                                admin = [admin string(adminCode{1,1})];
                                %disp(string(adminCode{1,1}));
                            end
                            centri.amministratori = admin.';
                            app.ListaCentri.Data = centri;
            
                            %update select single on screen
                            idCenters = centri(:,1);
                            nameCenters = centri(:,2);
                            app.CenterUserDropDown.ItemsData = table2array(idCenters);
                            app.CenterUserDropDown.Items = table2array(nameCenters);
                            app.CenterDropDown.ItemsData = table2array(idCenters);
                            app.CenterDropDown.Items = table2array(nameCenters);
                            app.SelectCenterAdminDropDown.Items = table2array(nameCenters);
                            app.SelectCenterAdminDropDown.ItemsData = table2array(idCenters);
                            app.CenterDropDown.Value = centroId;
                            app.SelectCenterDrop.ItemsData = table2array(idCenters);
                            app.SelectCenterDrop.Items = table2array(nameCenters);
                        else 
                            ME = MException('MyComponent:noSuchVariable',"Save error! Center: ",nome," already exist or Incorrect inputs");
                            throw(ME)
                        end
                    else
                        app.CentroSavingStatus.Color = 'r';
                        app.msg = m;
                    end
                end
                %updating
                progbar.Value = .67;
                progbar.Message = app.msg;
                pause(.2)
                %updating
                progbar.Value = 1;
                progbar.Message = 'Finishing';
                pause(.2)
            catch ME
                disp("SalvaCentroButtonPushedError")
                disp(ME)
                app.msg = m;
                app.CentroSavingStatus.Color = 'r';
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "SalvaCentroButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
            end
            app.CentroSaveUserMsg.Visible = 'on';
            app.CentroSavingStatus.Visible = 'on';
            app.CentroSaveUserMsg.Value = app.msg;
            close(progbar)
        end

        % Button down function: InsertPatientTab
        function InsertPatientTabButtonDown(app, event)
%             progbar =  uiprogressdlg(app.UIFigure,'Title','Loading data',...
%                 'Indeterminate','on');
%             drawnow
            try
                app.HomePage.Visible = "on";
                app.HospitalizationPage.Visible = "off";
                app.AcquisitionsPage.Visible = "off";
                app.AnalysisPage.Visible = "off";
                startupHomePage(app);
                startupHospitalization(app);
                startupAcquisition(app);
                startupAnalysis(app);
            catch ME
                disp("InsertPatientTabButtonDownError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "InsertPatientTabButtonDown", 'VariableNames', ["identifier" "line" "function_name"]));
            end
%             close(progbar)
        end

        % Button pushed function: SalvaNuovoPazienteButton
        function SalvaNuovoPazienteButtonPushed(app, event)
            %ProgressBar init
            progbar =  uiprogressdlg(app.UIFigure,'Title','Save new patient',...
                'Message','Saving...','Cancelable','off');
            pause(.2)
            tic
            try 
                check = true;
                app.PersonalDataSavingStatus.Color = 'g';
                app.DataMessageText.Visible = 'off';
                app.NameEditField.BackgroundColor = [1 1 1];
                app.SurnameEditField.BackgroundColor = [1 1 1];
                app.BirthPlaceEditField.BackgroundColor = [1 1 1];
                app.BirthDateEditField.BackgroundColor = [1 1 1];
                app.AddressEditField.BackgroundColor = [1 1 1];
                app.SSNEditField.BackgroundColor = [1 1 1];
                outMsg = "ERROR! Insert: ";

                nome = app.NameEditField.Value;
                if isempty(nome) || strcmp(nome, "")
                    check = false;
                    app.PersonalDataSavingStatus.Color = 'r';
                    app.NameEditField.BackgroundColor = [1 0 0];
                    outMsg = strcat(outMsg, " Name");
                end
                cognome = app.SurnameEditField.Value;
                if isempty(cognome) || strcmp(nome, "")
                    check = false;
                    app.PersonalDataSavingStatus.Color = 'r';
                    app.SurnameEditField.BackgroundColor = [1 0 0];
                    outMsg = strcat(outMsg, " Surame");
                end
                luogoNascita = app.BirthPlaceEditField.Value;
                if luogoNascita == 1
                    check = false;
                    app.PersonalDataSavingStatus.Color = 'r';
                    app.BirthPlaceEditField.BackgroundColor = [1 0 0];
                    outMsg = strcat(outMsg, " Birth place");
                else
                    luogoNascita = app.nomiComuni{luogoNascita};
                end
                dataNascita = app.BirthDateEditField.Value;
                if isnat(dataNascita)
                    check = false;
                    app.PersonalDataSavingStatus.Color = 'r';
                    app.BirthDateEditField.BackgroundColor = [1 0 0];
                    outMsg = strcat(outMsg, ' Birthdate');
                end
                if app.FButton.Value == 1
                    sesso = 'F';
                else
                    sesso = 'M';
                end
                indirizzo = app.AddressEditField.Value;
%                 if isempty(indirizzo)
%                     check = false;
%                     app.PersonalDataSavingStatus.Color = 'r';
%                     app.AddressEditField.BackgroundColor = [1 0 0];
%                     msg = strcmp(msg, ' Address');
%                 end
                cf = app.SSNEditField.Value;
                if isempty(cf)
                    check = false;
                    app.PersonalDataSavingStatus.Color = 'r';
                    app.SSNEditField.BackgroundColor = [1 0 0];
                    outMsg = strcat(outMsg, ' SSN');
                else
                     ssnCheck = strcat('SELECT * FROM paziente p WHERE p.cf = ',string(cf));
                     if isempty(ssnCheck)
                        check = false;
                        app.PersonalDataSavingStatus.Color = 'r';
                        app.SSNEditField.BackgroundColor = [1 0 0];
                        outMsg = 'ERROR! patient already exists';
%                      else
%                         app.DataMessageText.Visible = 'off';
%                         app.PersonalDataSavingStatus.Color = 'g';
%                         app.SSNEditField.BackgroundColor = [1 1 1];
%                         msg = ' ';
                    end
                end
                progbar.Value = .33; 
                progbar.Message = strcat("Saving patient: ",cf);
                pause(0.2)
                if check
                    if app.livello == 1
                        center_id = app.SelectCenterAdminDropDown.Value;
                    else
                        center_id = app.idCentro;
                    end
                    [m, r] = sqlprotection({cf nome cognome indirizzo luogoNascita});
                    if r == true
                        %insert new patient
                        dateStr = ComputeData(dataNascita, 'en');
                        data = table(upper(string(cf)),string(nome),string(cognome),string(sesso),string(indirizzo),string(dateStr),string(luogoNascita),center_id,"000000000000",'VariableNames',{'cf','nome','cognome','sesso','indirizzo_residenza','data_nascita','luogo_nascita','id_centro','code'});
                        %disp(data)
                        sqlwrite(app.conn,'paziente',data);
                        loginEvalSql = strcat("SELECT EVENT_ID, TRUNCATE(TIMER_WAIT/1000000000000,6) as Duration ", ...
                            "FROM performance_schema.events_statements_history AS queryLog ", ...
                            "WHERE queryLog.CURRENT_SCHEMA LIKE 'cbra_new' AND queryLog.SQL_TEXT LIKE 'INSERT INTO paziente % VALUES %", upper(string(cf)), "%'");
                        loginEvaluation = select(app.conn, loginEvalSql);
                        evalTime = loginEvaluation.Duration(1);

                        app.EtaEditField_1.Value = calculateAge(app, datetime("now"),dataNascita);
                        savedPazienteSQL = strcat('SELECT * FROM paziente p WHERE p.cf ="', cf,'";');
                        paziente = select(app.conn, savedPazienteSQL);
                        app.selectedPaziente = paziente;
                        app.newPatientID = paziente;
                        nomeCentroSQL = strcat('SELECT nome FROM centro WHERE id = ', string(center_id));
                        nomeCentro = select(app.conn, nomeCentroSQL);
                        code = CalculeCode(nomeCentro.nome{1}, center_id, app.newPatientID.id);
                        codeTab = table(code, 'VariableNames', {'code'});
                        where = strcat('WHERE id =', string(app.selectedPaziente.id));
                        update(app.conn,'paziente',{'code'},codeTab,where);

                        centerTable = strcat(['SELECT c.id, c.nome, c.indirizzo, c.citta, c.amministratore, '...
                                                'COUNT(DISTINCT(u.id)) AS numero_medici, COUNT(DISTINCT(p.id)) AS numero_pazienti '...
                                                'FROM utente u, paziente p, centro c '...
                                                'WHERE u.id_centro = c.id AND u.livello = 3 AND p.id_centro = c.id '...
                                                'GROUP BY c.id']);
                        centri = select(app.conn, centerTable);
%                        medici = centri(:,6);
                        pazienti = centri(:,7);
                        for i = 1:size(centri.id)
                            whereClause=strcat("WHERE id =",string(centri.id(i)));
%                             disp(whereClause);
%                             disp(medici(i,:));
%                                 update(app.conn, 'centro', {'numero_medici'}, medici(i,:), whereClause);
                            update(app.conn, 'centro', {'numero_pazienti'}, pazienti(i,:), whereClause);
                        end

                        %update select single and table on page gestione
                            %fill pazienti table %update pazienti drop
                        pazientiDrop = strcat('');
                        switch app.livello
                            case 1
                                pazientiDrop = strcat('SELECT p.id, CONCAT(p.cognome, " ", p.nome) AS nome, p.cf FROM paziente p WHERE p.id_centro = ', string(app.SelectCenterAdminDropDown.Value),';');
                                pazientiTab = strcat('SELECT CONCAT(p.cognome, " ", p.nome) AS nome, p.cf, h.dataRicovero, h.oraRicovero, h.dataEvento, h.oraEvento, a.dataAcquisizione, a.oraAcquisizione FROM paziente p LEFT JOIN hospitalization h ON p.id = h.id_paziente LEFT JOIN acquisizione a ON h.id = a.id_hospedalization WHERE p.id_centro ="', string(app.idCentro),'";');
                                adminsId = centri.amministratore;
                                centri = removevars(centri, {'amministratore'});
                                admin = [];
                                for i = 1:size(adminsId)
                                    %disp(string(adminsId(i)));
                                    adminCode = select(app.conn, strcat('SELECT code_fis FROM utente WHERE id=',string(adminsId(i)),';'));
                                    %disp(adminCode)
                                    admin = [admin string(adminCode{1,1})];
                                    %disp(string(adminCode{1,1}));
                                end
                                centri.amministratori = admin.';
                                app.ListaCentri.Data = centri;
                            case 2
                            case 3
                                pazientiDrop = strcat('SELECT p.id, CONCAT(p.cognome, " ", p.nome) AS nome, p.cf FROM paziente p, utente m, medico_paziente mp WHERE mp.id_medico = m.id AND mp.id_paziente = p.id AND m.id = ', string(app.id),';');
                                pazientiTab = strcat('SELECT CONCAT(p.cognome, " ", p.nome) AS nome, p.cf, h.dataRicovero, h.oraRicovero, h.dataEvento, h.oraEvento, a.dataAcquisizione, a.oraAcquisizione FROM paziente p LEFT JOIN hospitalization h ON p.id = h.id_paziente LEFT JOIN acquisizione a ON h.id = a.id_hospedalization RIGHT JOIN medico_paziente mp ON p.id = mp.id_paziente WHERE mp.id_medico ="', string(app.id),'";');
                                medicoPaziente = table(app.id,app.newPatientID.id,'VariableNames',{'id_medico','id_paziente'});
                                sqlwrite(app.conn,'medico_paziente',medicoPaziente);
                        end
                        outMsg = 'SUCCESS: patient saved';
                        pazientiData = select(app.conn, pazientiDrop);
                        app.PazienteDropDown.ItemsData = table2array(pazientiData(:,1));
                        app.PazienteDropDown.Items = table2array(pazientiData(:,3));
                        rows = select(app.conn, pazientiTab);
                        app.PazientiList.Data = rows;
                        app.IndexHospitalizationPanel.Visible = 'on';
                        app.PazienteDropDown.Value = app.selectedPaziente.id;
                    else
                        app.PersonalDataSavingStatus.Color = 'r';
                        outMsg = m;
                    end

                    executionTime = toc;
                    update(app.conn, 'paziente', {'evaluation_time' 'execution_time'}, table(evalTime, executionTime, 'VariableNames', {'evaluation_time' 'execution_time'}), strcat("WHERE cf = '",upper(string(cf)),"'"));
                end
                %updating
                progbar.Value = .67;
                progbar.Message = outMsg;
                pause(.2)
                %updating
                progbar.Value = 1;
                progbar.Message = 'Finishing';
                pause(.2)
            catch ME
                disp("SalvaNuovoPazienteButtonPushedError")
                app.PersonalDataSavingStatus.Color = 'r';
                outMsg = strcat('ERROR! ',ME.message);
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "SalvaNuovoPazienteButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
%                 rethrow(ME)
            end
            app.DataMessageText.Value = outMsg;
            close(progbar)
            app.DataMessageText.Visible = 'on';
            app.PersonalDataSavingStatus.Visible = 'on';
        end

        % Button pushed function: CalculateFiscalCodeButton
        function CalculateFiscalCodeButtonPushed(app, event)
%             progbar =  uiprogressdlg(app.UIFigure,'Title','Calculating SSN',...
%                 'Indeterminate','on');
%             drawnow
            try
                check = true;
                app.PersonalDataSavingStatus.Color = 'g';
                app.PersonalDataSavingStatus.Visible = 'off';
                nome = app.NameEditField.Value;
                message = "ERROR! Insert: ";
                if isempty(nome)
                    check = false;
                    app.PersonalDataSavingStatus.Color = 'r';
                    app.NameEditField.BackgroundColor = [1 0 0];
                    app.DataMessageText.Value = strcat(app.DataMessageText.Value, " Name");
                else
                    app.DataMessageText.Visible = 'off';
                    app.PersonalDataSavingStatus.Color = 'g';
                    app.NameEditField.BackgroundColor = [1 1 1];
                    app.DataMessageText.Value = '1';
                end
%                 if(check)
                    cognome = app.SurnameEditField.Value;
                    if isempty(cognome)
                        check = false;
                        app.PersonalDataSavingStatus.Color = 'r';
                        app.SurnameEditField.BackgroundColor = [1 0 0];
                        app.DataMessageText.Value = strcat(app.DataMessageText.Value, " Surname");
                    else
                        app.DataMessageText.Visible = 'off';
                        app.PersonalDataSavingStatus.Color = 'g';
                        app.SurnameEditField.BackgroundColor = [1 1 1];
                        app.DataMessageText.Value = '2';
                    end
%                 end
%                 if(check)
                    luogoNascita = app.BirthPlaceEditField.Value;
                    disp(luogoNascita)
                    if luogoNascita == 1
                        check = false;
                        app.PersonalDataSavingStatus.Color = 'r';
                        app.BirthPlaceEditField.BackgroundColor = [1 0 0];
                        app.DataMessageText.Value = strcat(app.DataMessageText.Value, " Birth Place");
                    else
                        app.DataMessageText.Visible = 'off';
                        app.PersonalDataSavingStatus.Color = 'g';
                        app.BirthPlaceEditField.BackgroundColor = [1 1 1];
                        app.DataMessageText.Value = '3';
                    end
%                 end
%                 if(check)
                    dataNascita = app.BirthDateEditField.Value;
                    if isnat(dataNascita)
                        check = false;
                        app.PersonalDataSavingStatus.Color = 'r';
                        app.BirthDateEditField.BackgroundColor = [1 0 0];
                        app.DataMessageText.Value = strcat(app.DataMessageText.Value, " Birth Date");
                    else
                        app.DataMessageText.Visible = 'off';
                        app.PersonalDataSavingStatus.Color = 'g';
                        app.BirthDateEditField.BackgroundColor = [1 1 1];
                        app.DataMessageText.Value = '4';
                    end
%                 end
                if app.FButton.Value == 1
                    sesso = 'F';
                else
                    sesso = 'M';
                end
                if check
                    trad = datestr(dataNascita);                
                    data = datetime(trad, "InputFormat","dd-MMM-uuuu",'Locale','en_US');
                    d = num2str(day(data));
                    m = month(data);
                    y = num2str(year(data));
                    ssn = ComputeSSN(cognome, nome, d, m, y, sesso, luogoNascita);
                    app.SSNEditField.Value = ssn;
                    app.SSNEditField.BackgroundColor = [1 1 1];
                    app.PatientIDFCLabel_1.Visible = 'on';
                    app.SSNEditField.Visible = 'on';
                end
            catch ME
                disp("CalculateFiscalCodeButtonPushedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "CalculateFiscalCodeButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
%                 rethrow(ME)
            end
        end

        % Value changed function: SelectCenterDrop
        function SelectCenterDropValueChanged(app, event)
            progbar =  uiprogressdlg(app.UIFigure,'Title','Loading center data',...
                'Indeterminate','on');
            pause(.2)
            try
                app.idCentro = app.SelectCenterDrop.Value;
%                 app.nome_centro = 
                
                pazientiDrop = strcat('SELECT p.id, CONCAT(p.cognome, " ", p.nome) AS nome, p.cf FROM paziente p WHERE p.id_centro = ', string(app.idCentro),';');
                pazientiData = select(app.conn, pazientiDrop);
%                 assignin("base", "pazientiData", pazientiData)
                if ~isempty(pazientiData)
                    app.PazienteDropDown.ItemsData = table2array(pazientiData(:,1));
                    app.PazienteDropDown.Items = table2array(pazientiData(:,3));
                
                    pazientiTab = strcat('SELECT CONCAT(p.cognome, " ", p.nome) AS nome, p.cf, h.dataRicovero, h.oraRicovero, h.dataEvento, h.oraEvento, a.dataAcquisizione, a.oraAcquisizione FROM paziente p LEFT JOIN hospitalization h ON p.id = h.id_paziente LEFT JOIN acquisizione a ON h.id = a.id_hospedalization WHERE p.id_centro=', string(app.idCentro));
                    %disp(pazientiTab)
                    rows = select(app.conn, pazientiTab);
                    %disp(rows)
                    app.PazientiList.Data = rows;

                    elements = app.PazienteDropDown.ItemsData;
                    if ~isempty(elements)
                        app.PazienteDropDown.Value = elements(1);
                        pause(.2)
                        app.PazienteDropDownValueChanged;
                    end

                    app.PatientPersonalDataPanel_2.Visible = "on";
                    app.ControlPanel.Visible = "on";
                else
                    app.PazienteDropDown.ItemsData = NaN;
                    app.PazienteDropDown.Items = {' '};
                    app.PazientiList.Data = '';
                    app.PatientPersonalDataPanel_2.Visible = "off";
                    app.ControlPanel.Visible = "off";
                end
            catch ME
                disp("SelectCenterDropValueChangedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "SelectCenterDropValueChanged", 'VariableNames', ["identifier" "line" "function_name"]));
%                 rethrow(ME)
            end
            close(progbar)
        end

        % Button pushed function: SalvaPrimoRicoveroButton_1
        function SaveFirstHospitalizationButtonPushed(app, event)
            %ProgressBar init
            progbar =  uiprogressdlg(app.UIFigure,'Title','Save index hospitalization',...
                'Message','Saving...','Cancelable','off');
            pause(.2)
            tic
            try
                check = true;
                app.HospitalizationSavingStatus_1.Color = 'g';
                app.HospitalizationSavingStatus_1.Visible = 'off';
                app.HospitalizationText_1.Visible = 'off';
                app.DateandtimehospitalizationPanel_1.BackgroundColor = [1 1 1];
                app.DateandtimeAMIPanel_1.BackgroundColor = [1 1 1];

                newPatient = app.newPatientID.id;

                SaveHospMsg = "ERROR! insert:";

                dateHinput = app.HDateH_1.Value;
                if isnat(dateHinput)
                    check = false;
                    app.HospitalizationSavingStatus_1.Color = 'r';
                    app.DateandtimehospitalizationPanel_1.BackgroundColor = [1 0 0];
                    SaveHospMsg = strcat(SaveHospMsg,' Hospitalization date and time');
                else
                    dateH = ComputeData(dateHinput, 'en');
%                         app.HospitalizationText_1.Visible = 'off';
%                         app.HospitalizationSavingStatus_1.Color = 'g';
%                         app.DateandtimehospitalizationPanel_1.BackgroundColor = [1 1 1];
%                         SaveHospMsg = ' ';
                end
                hourHinput = app.HhourH_1.Value;
                minutesHinput = app.HminuteH_1.Value;
                secondsHinput = app.HsecondH_1.Value;
%                     if hourHinput == 0 || minutesHinput == 0 || secondsHinput == 0
%                         check = false;
%                         app.HospitalizationSavingStatus_1.Color = 'r';
%                         app.DateandtimehospitalizationPanel_1.BackgroundColor = [1 0 0];
%                         SaveHospMsg = 'Insert a AMI time or uncheck';
%                     else
                    timeH = ComputeOre(hourHinput, minutesHinput, secondsHinput);
%                         app.HospitalizationText_1.Visible = 'off';
%                         app.HospitalizationSavingStatus_1.Color = 'g';
%                         app.DateandtimehospitalizationPanel_1.BackgroundColor = [1 1 1];
%                         SaveHospMsg = ' ';
%                     end
                dateAMIinput = app.DateEvent_1.Value;
                if isnat(dateAMIinput)
                    check = false;
                    app.HospitalizationSavingStatus_1.Color = 'r';
                    app.DateandtimeAMIPanel_1.BackgroundColor = [1 0 0];
                    SaveHospMsg = strcat(SaveHospMsg,' AMI date and time');
                else
                    dateAMI = ComputeData(dateAMIinput, 'en');
%                         app.HospitalizationText_1.Visible = 'off';
%                         app.HospitalizationSavingStatus_1.Color = 'g';
%                         app.DateandtimeAMIPanel_1.BackgroundColor = [1 1 1];
%                         SaveHospMsg = ' ';
                end
                hourAMIinput = app.AMIhourEv_1.Value;
                minutesAMIinput = app.AMIminuteEv_1.Value;
                secondsAMIinput = app.AMIsecondEv_1.Value;
%                     if hourAMIinput == 0 || minutesAMIinput == 0 || secondsAMIinput == 0
%                         check = false;
%                         app.HospitalizationSavingStatus_1.Color = 'r';
%                         app.DateandtimeAMIPanel_1.BackgroundColor = [1 0 0];
%                         SaveHospMsg = 'Insert a AMI time or uncheck';
%                     else
                timeAMI = ComputeOre(hourAMIinput, minutesAMIinput, secondsAMIinput);
%                         app.HospitalizationText_1.Visible = 'off';
%                         app.HospitalizationSavingStatus_1.Color = 'g';
%                         app.DateandtimeAMIPanel_1.BackgroundColor = [1 1 1];
%                         SaveHospMsg = ' ';
%                     end

                dateTrombinput = app.DateTromb_1.Value;
                if ~isnat(dateTrombinput)
                    dateTromb = ComputeData(dateTrombinput, 'en');
%                         app.HospitalizationText_1.Visible = 'off';
%                         app.HospitalizationSavingStatus_1.Color = 'g';
%                         app.DateandtimeThrombolysisPanel_1.BackgroundColor = [1 1 1];
%                         SaveHospMsg = ' ';
                else
                    dateTromb = NaT;
                end
                hourTrombinput = app.TrombHour_1.Value;
                minutesTrombinput = app.Trombminute_1.Value;
                secondsTrombinput = app.Trombsecond_1.Value;
                timeTromb = ComputeOre(hourTrombinput, minutesTrombinput, secondsTrombinput);
%                     app.HospitalizationText_1.Visible = 'off';
%                     app.HospitalizationSavingStatus_1.Color = 'g';
%                     app.DateandtimeThrombolysisPanel_1.BackgroundColor = [1 1 1];
%                     SaveHospMsg = ' ';
                farmaco = app.DrugEditField_1.Value;
                
                dateRevinput = app.DateRev.Value;
                if ~isnat(dateRevinput)
                    dateRev = ComputeData(dateRevinput, 'en');
%                         app.HospitalizationText_1.Visible = 'off';
%                         app.HospitalizationSavingStatus_1.Color = 'g';
%                         app.DateandtimeRevascularizationPanel_1.BackgroundColor = [1 1 1];
%                         SaveHospMsg = ' ';
                else
                    dateRev = NaT;
                end
                hourRevinput = app.Revhour_1.Value;
                minutesRevinput = app.Revminute_1.Value;
                secondsRevinput = app.Revsecond_1.Value;
                timeRev = ComputeOre(hourRevinput, minutesRevinput, secondsRevinput);
%                     app.HospitalizationText_1.Visible = 'off';
%                     app.HospitalizationSavingStatus_1.Color = 'g';
%                     app.DateandtimeRevascularizationPanel_1.BackgroundColor = [1 1 1];
%                     SaveHospMsg = ' ';
                loco = app.LocoEditField_1.Value;

                if check
                    primoSocInput = app.stMedCoDate_1.Value;
                    if ~isnat(primoSocInput)
                        primoSoccorso = ComputeData(primoSocInput, 'en');
%                         primoSoccorso = strcat(primoSoccorso," 00:00:00");
                    else
                        primoSoccorso = NaT;
                    end
                    weight = app.WeightEditField_1.Value;
%                     if weight == 0
%                         weight = NaN;
%                     end
                    systolicBP = app.SystolicBPmmHgEditField_1.Value;
%                     if systolicBP == 0
%                         systolicBP = NaN;
%                     end
                    dyastolicBP = app.DyastolicBPmmHgEditField_1.Value;
%                     if dyastolicBP == 0
%                         dyastolicBP = NaN;
%                     end
                    hr = app.HRbpmEditField_1.Value;
%                     if hr == 0
%                         hr = NaN;
%                     end
                    timeToTreatm = app.TimetotreatmhoursEditField_1.Value;
%                     if tttinput == 0
%                         timeToTreatm = NaN;
%                     else
%                         timeToTreatm = tttinput;
%                     end
                    eta = app.EtaEditField_1.Value;
%                     if eta == 0 
%                         eta = NaN;
%                     end
                    diabete = app.DiabeteDropDown_1.Value;
                    hypertens = app.HypertensDropDown_1.Value;
                    angina = app.Anginaepisodesin24hEditField_1.Value;
                    killip = app.KillipDropDown_1.Value;
                    STEMI = app.STEMIDropDown_1.Value;
                    lbbb = app.LBBBDropDown_1.Value;
                    smoke = app.SmokeDropDown_1.Value;
                    obesity = app.ObesityDropDown_1.Value;
                    prevAMI = app.PrevCADDropDown_1.Value;
                    familiarity = app.FamiliarityDropDown_1.Value;
                    dyslipid = app.DyslipidemiaDropDown.Value;

                    hypercholesterolemia = app.HypercolDropDown_1.Value;
                    stenosis = app.StenosisEditField_1.Value;
                    asa_usage_7d = app.ASAusein7daysDropDown_1.Value;
                    st_deviation = app.EKGSTdevmmEditField_1.Value;
                    cardiac_marker = app.PositivecardiacmarkerDropDown_1.Value;

                    timi =  app.TimiEditField_1.Value;
                end
                %ProgressBar update
                progbar.Value = .33; 
                progbar.Message = "Saving hospitalizaion";
                pause(0.2)
                if check
                    [m, r] = sqlprotection({farmaco loco});
                    if r == true
                        data = table(string(dateH),string(timeH),string(dateAMI),string(timeAMI),newPatient,app.id,string(primoSoccorso),diabete,hypertens,angina,hr,killip,STEMI,lbbb,timeToTreatm,smoke, ...
                            familiarity,prevAMI,obesity,dyslipid,timi,"referto",systolicBP,dyastolicBP,weight,eta,string(dateTromb),string(timeTromb),string(farmaco),string(dateRev),string(timeRev),string(loco), ...
                            hypercholesterolemia, asa_usage_7d, st_deviation, cardiac_marker, stenosis, ...
                            'VariableNames',{'dataRicovero','oraRicovero','dataEvento','oraEvento','id_paziente','id_medico','dataPrimoSoccorso','diabete','ipertensione','angina','HR','killipClass','STEMI','LBBB','timeToTreatment','smoke', ...
                            'familiarity','prevAMI','obesity','dislip','timi','referto','systolicBP','dyastolicBP','weight','age', 'dataTrombolisi', 'oraTrombolisi', 'farmaco', 'dataRivascolarizzazione', 'oraRivascolarizzazione', 'loco', ...
                            'hypercholesterolemia', 'asa_usage_7d', 'st_deviation', 'cardiac_marker', 'stenosis'});
                        
                        sqlwrite(app.conn,'hospitalization',data);
                        loginEvalSql = strcat("SELECT EVENT_ID, TRUNCATE(TIMER_WAIT/1000000000000,6) as Duration ", ...
                            "FROM performance_schema.events_statements_history AS queryLog ", ...
                            "WHERE queryLog.CURRENT_SCHEMA LIKE 'cbra_new' AND queryLog.SQL_TEXT LIKE 'INSERT INTO hospitalization % VALUES %", string(newPatient), "%", string(app.id), "%'");
                        loginEvaluation = select(app.conn, loginEvalSql);
                        evalTime = loginEvaluation.Duration(1);

                        pause(.2)
                        app.PazienteDropDownValueChanged;
                        SaveHospMsg = 'Save complete';
                        app.HospitalizationSavingStatus_1.Color = 'g';
                    else
                        app.HospitalizationSavingStatus_1.Color = 'r';
                        SaveHospMsg = m;
                    end
                    executionTime = toc;
                    update(app.conn, 'hospitalization', {'evaluation_time' 'execution_time'}, table(evalTime, executionTime, 'VariableNames', {'evaluation_time' 'execution_time'}), strcat("WHERE id_medico = ",string(app.id)," ORDER BY id DESC LIMIT 1"));
                end
            %updating
            progbar.Value = .67;
            progbar.Message = SaveHospMsg;
            pause(.2)
            %updating
            progbar.Value = 1;
            progbar.Message = 'Finishing';
            pause(.2)
            catch ME
                disp("SaveFirstHospitalizationButtonPushedError")
                app.HospitalizationSavingStatus_1.Color = 'r';
                SaveHospMsg = strcat('ERROR: ',ME.identifier);
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "SaveFirstHospitalizationButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
%                 rethrow(ME)
            close(progbar)
            end
            app.HospitalizationText_1.Value = SaveHospMsg;
            app.HospitalizationText_1.Visible = 'on';
            app.HospitalizationSavingStatus_1.Visible = 'on';
            close(progbar)
        end

        % Button down function: ManagePatientsTab
        function ManagePatientsTabButtonDown(app, event)
%             progbar =  uiprogressdlg(app.UIFigure,'Title','Loading data',...
%                 'Indeterminate','on');
%             drawnow
            try
                startupInsert(app);
                switch app.livello
                    case 1
                        elements = app.SelectCenterDrop.ItemsData;
                        if ~isempty(elements)
                            app.SelectCenterDrop.Value = elements(1);
                            pause(.2)
                            app.SelectCenterDropValueChanged;
                        end
                    case 3
                        elements = app.PazienteDropDown.ItemsData;
                        if ~isempty(elements)
                            app.PazienteDropDown.Value = elements(1);
                            pause(.2)
                            app.PazienteDropDownValueChanged;
                        end
                end
            catch ME
                disp("ManagePatientsTabButtonDownError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "ManagePatientsTabButtonDown", 'VariableNames', ["identifier" "line" "function_name"]));
            end
%             close(progbar)
        end

        % Button pushed function: HomeButtonFAcq, HomeButtonFAn, 
        % ...and 1 other component
        function HomeButtonPushed(app, event)
%             progbar =  uiprogressdlg(app.UIFigure,'Title','Loading Home page data',...
%                 'Indeterminate','on');
%             drawnow
            try
                app.HomePage.Visible = 'on';
                app.HospitalizationPage.Visible = 'off';
                app.AcquisitionsPage.Visible = 'off';
                app.AnalysisPage.Visible = 'off';
            catch ME
                disp("HomeButtonPushedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "HomeButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
            end
%             close(progbar)
        end

        % Value changed function: PazienteDropDown
        function PazienteDropDownValueChanged(app, event)
            progbar =  uiprogressdlg(app.UIFigure,'Title','Loading patients data',...
                'Indeterminate','on');
            pause(.2)
            try
                idPaziente = app.PazienteDropDown.Value;
%                 startupHospitalization(app);
%                 startupAcquisition(app);
%                 startupAnalysis(app);
                getPaziente = strcat('SELECT * FROM paziente p WHERE p.id =',string(idPaziente),';');

                app.PatientProfileIndexEventButton.Enable = 'on';
                app.HospitalizationButtonFAcq.Enable = 'on';
                app.HospitalizationButtonFAn.Enable = 'on';

                app.LaboratoryDataButton.Enable = 'off';
                app.AcquisitionsButtonFH.Enable = 'off';
                app.AcquisitionsButtonFAn.Enable = 'off';

                app.CardiacmarkerstrendButton.Enable = 'off';
                app.AnalysisButtonFAcq.Enable = 'off';
                app.AnalysisButtonFH.Enable = 'off';

                app.selectedPaziente = select(app.conn, getPaziente);

                app.ControlPanel.Visible = 'on';
                app.NameEditField_2.Value = app.selectedPaziente.nome{1};
                app.SurnameEditField_2.Value = app.selectedPaziente.cognome{1};
                app.BirthPlaceEditField_2.Value = app.selectedPaziente.luogo_nascita{1};
                app.BirthDateEditField_2.Value = datetime(app.selectedPaziente.data_nascita{1});
                app.AddressEditField_2.Value = app.selectedPaziente.indirizzo_residenza{1};
                if strcmp(app.selectedPaziente.sesso{1}, 'M')
                    app.MButton_2.Value = 1;
                    app.FButton_2.Value = 0;
                else
                    app.MButton_2.Value = 0;
                    app.FButton_2.Value = 1;
                end
                app.SSNEditField_2.Value = app.selectedPaziente.cf{1};
                app.PatientPersonalDataPanel_2.Visible = "on";
                
                %populating hospedalizzation tab
                getHospedalizations = strcat('SELECT h.id, h.dataRicovero, h.oraRicovero, h.dataEvento, h.oraEvento FROM hospitalization h, paziente p WHERE h.id_paziente = p.id AND p.id = ', string(app.selectedPaziente.id), ';');
                rows = select(app.conn, getHospedalizations);
%                 app.HospedalizationsDropPA.ItemsData = NaN;
%                 app.HospedalizationsDropPA.Items = {'empty'};
                app.HospitalizationsDrop.ItemsData = NaN;
                app.HospitalizationsDrop.Items = {'empty'};
                app.HospitalizationsTable.Data = {};

                cla(app.AnalysisAx, "reset");
                cla(app.ax1, 'reset');
                cla(app.ax2, 'reset');
                app.PlotMedPanel.Title = "No acquisition inserted in this hospitalization.";
%                 app.PlotMedPanel.Visible = 'off';
                
                if ~isempty(rows)
                    app.HospitalizationsTable.Data = rows;
                    %populating hospitalizations drop
                    idsArray = rows.id;
                    dataRicoveros = rows.dataRicovero;
                    oraRicoveros = rows.oraRicovero;
                    itemsDataOut = [];
                    for i = 1:size(idsArray)
                        datacell = dataRicoveros(i);
                        oracell = oraRicoveros(i);
                        strData = datacell{1};
                        strOra = oracell{1};
                        item = {strcat(int2str(idsArray(i)),' | ',strData,' | ',strOra)};
                        itemsDataOut = [itemsDataOut, item];
                    end
%                     app.HospedalizationsDropPA.ItemsData = table2array(rows(:,1));
%                     app.HospedalizationsDropPA.Items = itemsDataOut;
                    app.HospitalizationsDrop.ItemsData = table2array(rows(:,1));
                    app.HospitalizationsDrop.Items = itemsDataOut;
                    app.LaboratoryDataButton.Enable = 'on';
                    app.AcquisitionsButtonFH.Enable = 'on';
                    app.AcquisitionsButtonFAn.Enable = 'on';
                    if height(rows) == 1
                        app.HospitalizationsDropValueChanged;
                    else
                        lastHosp = app.HospitalizationsDrop.ItemsData(length(app.HospitalizationsDrop.ItemsData));
                        app.HospitalizationsDrop.Value = lastHosp;
                        app.HospitalizationsDropValueChanged;
                    end
                else
                    app.LaboratoryDataButton.Enable = 'off';
                    app.AcquisitionsButtonFH.Enable = 'off';
                    app.AcquisitionsButtonFAn.Enable = 'off';
                end
            catch ME
                disp("PazienteDropDownValueChangedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "PazienteDropDownValueChanged", 'VariableNames', ["identifier" "line" "function_name"]));
%                 rethrow(ME)
            end
            close(progbar)
        end

        % Button pushed function: SalvaPazienteButton
        function SalvaPazienteButtonPushed(app, event)
            %ProgressBar init
            progbar =  uiprogressdlg(app.UIFigure,'Title','Save patient edits',...
                'Message','Saving...','Cancelable','off');
            pause(.2) 
            try 
                check = true;
                app.DataMessageText_2.Value = '';
                app.DataMessageText_2.Visible = 'off';
                app.PersonalDataSavingStatus_2.Color = 'g';
                app.PersonalDataSavingStatus_2.Visible = "off";
                app.NameEditField_2.BackgroundColor = [1 1 1];
                app.SurnameEditField_2.BackgroundColor = [1 1 1];
                app.BirthPlaceEditField_2.BackgroundColor = [1 1 1];
                app.BirthDateEditField_2.BackgroundColor = [1 1 1];
                app.AddressEditField_2.BackgroundColor = [1 1 1];
                app.DataMessageText_2.Value = 'ERROR! insert';

                nome = app.NameEditField_2.Value;
                if isempty(nome)
                    check = false;
                    app.PersonalDataSavingStatus_2.Color = 'r';
                    app.NameEditField_2.BackgroundColor = [1 0 0];
                    app.DataMessageText_2.Value = strcat(app.DataMessageText_2.Value, ' Name');
                end
                cognome = app.SurnameEditField_2.Value;
                if isempty(cognome)
                    check = false;
                    app.PersonalDataSavingStatus_2.Color = 'r';
                    app.SurnameEditField_2.BackgroundColor = [1 0 0];
                    app.DataMessageText_2.Value = strcat(app.DataMessageText_2.Value, ' Surname');
                end
                luogoNascita = app.BirthPlaceEditField_2.Value;
                if luogoNascita == 1
                    check = false;
                    app.PersonalDataSavingStatus_2.Color = 'r';
                    app.BirthPlaceEditField_2.BackgroundColor = [1 0 0];
                    app.DataMessageText_2.Value = strcat(app.DataMessageText_2.Value, ' Birth place');
                end
                dataNascita = app.BirthDateEditField_2.Value;
                if isnat(dataNascita)
                    check = false;
                    app.PersonalDataSavingStatus_2.Color = 'r';
                    app.BirthDateEditField_2.BackgroundColor = [1 0 0];
                    app.DataMessageText_2.Value = strcat(app.DataMessageText_2.Value, ' Birthdate');
                end
                if app.FButton_2.Value == 1
                    sesso = 'F';
                else
                    sesso = 'M';
                end
                indirizzo = app.AddressEditField_2.Value;
%                 if isempty(indirizzo)
%                     check = false;
%                     app.PersonalDataSavingStatus_2.Color = 'r';
%                     app.AddressEditField_2.BackgroundColor = [1 0 0];
%                     app.DataMessageText_2.Value = 'ERROR! insert a valid address';
%                 end
                cf = app.SSNEditField_2.Value;
                if isempty(cf)
                    check = false;
                    app.PersonalDataSavingStatus_2.Color = 'r';
                    app.SSNEditField_2.BackgroundColor = [1 0 0];
                    app.DataMessageText_2.Value = strcat(app.DataMessageText_2.Value, ' SSN');
                else
                     ssnCheck = strcat('SELECT * FROM paziente p WHERE p.cf = ',string(cf));
                     if isempty(ssnCheck)
                        check = false;
                        app.PersonalDataSavingStatus_2.Color = 'r';
                        app.DataMessageText_2.Value = 'ERROR! patient already exists';
                     end
                end
                progbar.Value = .33; 
                progbar.Message = strcat("Saving patient: ",cf);
                pause(0.2)
                if check
                    [m, r] = sqlprotection({cf nome cognome indirizzo luogoNascita});
                    if r == true
                        %insert new patient
                        dateStr = ComputeData(dataNascita, 'en');
                        data = table(upper(string(cf)),string(nome),string(cognome),string(sesso),string(indirizzo),string(dateStr),string(luogoNascita),app.idCentro,datestr(now, "yyyy-mm-dd HH:MM:SS"),'VariableNames',{'cf','nome','cognome','sesso','indirizzo_residenza','data_nascita','luogo_nascita','id_centro', 'date_edit'});
%                         disp(data)
                        where = strcat('WHERE id =', string(app.selectedPaziente.id));
                        update(app.conn,'paziente',{'cf','nome','cognome','sesso','indirizzo_residenza','data_nascita','luogo_nascita','id_centro'},data,where);
                        savedPazienteSQL = strcat('SELECT * FROM paziente p WHERE p.cf ="', cf,'";');
                        paziente = select(app.conn, savedPazienteSQL);
                        app.selectedPaziente = paziente;
    %                     app.newPatientID = paziente;
    %                     medicoPaziente = table(app.id,app.newPatientID.id,'VariableNames',{'id_medico','id_paziente'});
    %                     sqlwrite(app.conn,'medico_paziente',medicoPaziente);
    %                     app.DataMessageText_2.Value = 'SUCCESS: patient saved';
                        
                        centerTable = strcat(['SELECT c.id, c.nome, c.indirizzo, c.citta, c.amministratore, '...
                                                'COUNT(DISTINCT(u.id)) AS numero_medici, COUNT(DISTINCT(p.id)) AS numero_pazienti '...
                                                'FROM utente u, paziente p, centro c '...
                                                'WHERE u.id_centro = c.id AND u.livello = 3 AND p.id_centro = c.id '...
                                                'GROUP BY c.id']);
                        centri = select(app.conn, centerTable);
%                        medici = centri(:,6);
                        pazienti = centri(:,7);
                        for i = 1:size(centri.id)
                            whereClause=strcat("WHERE id =",string(centri.id(i)));
%                             disp(whereClause);
%                             disp(medici(i,:));
%                                 update(app.conn, 'centro', {'numero_medici'}, medici(i,:), whereClause);
                            update(app.conn, 'centro', {'numero_pazienti'}, pazienti(i,:), whereClause);
                        end

                        %update select single and table on page gestione
                            %fill pazienti table %update pazienti drop
                        pazientiDrop = strcat('');
                        switch app.livello
                            case 1
                                pazientiDrop = strcat('SELECT p.id, CONCAT(p.cognome, " ", p.nome) AS nome, p.cf FROM paziente p WHERE p.id_centro = ', string(app.idCentro),';');
                                pazientiTab = strcat('SELECT CONCAT(p.cognome, " ", p.nome) AS nome, p.cf, h.dataRicovero, h.oraRicovero, h.dataEvento, h.oraEvento, a.dataAcquisizione, a.oraAcquisizione FROM paziente p LEFT JOIN hospitalization h ON p.id = h.id_paziente LEFT JOIN acquisizione a ON h.id = a.id_hospedalization WHERE mp.id_medico ="', string(app.idCentro),'";');
                                adminsId = centri.amministratore;
                                centri = removevars(centri, {'amministratore'});
                                admin = [];
                                for i = 1:size(adminsId)
                                    %disp(string(adminsId(i)));
                                    adminCode = select(app.conn, strcat('SELECT code_fis FROM utente WHERE id=',string(adminsId(i)),';'));
                                    %disp(adminCode)
                                    admin = [admin string(adminCode{1,1})];
                                    %disp(string(adminCode{1,1}));
                                end
                                centri.amministratori = admin.';
                                app.ListaCentri.Data = centri;
                            case 2
                            case 3
                                pazientiDrop = strcat('SELECT p.id, CONCAT(p.cognome, " ", p.nome) AS nome, p.cf FROM paziente p, utente m, medico_paziente mp WHERE mp.id_medico = m.id AND mp.id_paziente = p.id AND m.id = ', string(app.id),';');
                                pazientiTab = strcat('SELECT CONCAT(p.cognome, " ", p.nome) AS nome, p.cf, h.dataRicovero, h.oraRicovero, h.dataEvento, h.oraEvento, a.dataAcquisizione, a.oraAcquisizione FROM paziente p LEFT JOIN hospitalization h ON p.id = h.id_paziente LEFT JOIN acquisizione a ON h.id = a.id_hospedalization RIGHT JOIN medico_paziente mp ON p.id = mp.id_paziente WHERE mp.id_medico ="', string(app.id),'";');
                        end
                        pazientiData = select(app.conn, pazientiDrop);
                        app.PazienteDropDown.ItemsData = table2array(pazientiData(:,1));
                        app.PazienteDropDown.Items = table2array(pazientiData(:,3));
                        rows = select(app.conn, pazientiTab);
                        app.PazientiList.Data = rows;
                        app.IndexHospitalizationPanel.Visible = 'on';
                        app.PazienteDropDown.Value = app.selectedPaziente.id;
                    else
                        app.PersonalDataSavingStatus_2.Color = 'r';
                        app.DataMessageText_2.Value = m;
                    end
                end
                %updating
                progbar.Value = .67;
                progbar.Message = app.DataMessageText_2.Value;
                pause(.2)
                %updating
                progbar.Value = 1;
                progbar.Message = 'Finishing';
                pause(.2)
             catch ME
                 disp(ME)
                 disp("SalvaPazienteButtonPushedError")
                 app.PersonalDataSavingStatus_2.Color = 'r';
                 app.DataMessageText_2.Value = strcat('ERROR: ',ME.identifier);
                
                 app.DataMessageText_2.Visible = 'on';
                 app.PersonalDataSavingStatus_2.Visible = 'on';
                 line = max([ME.stack(1:end).line]);
                 sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "SalvaPazienteButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
             end
            close(progbar)
            app.DataMessageText_2.Visible = 'on';
            app.PersonalDataSavingStatus_2.Visible = 'on';
        end

        % Button pushed function: HospitalizationButtonFAcq, 
        % ...and 2 other components
        function HospitalizationButtonPushed(app, event)
%             progbar =  uiprogressdlg(app.UIFigure,'Title','Loading Hospitalization page data',...
%                 'Indeterminate','on');
%             drawnow
            try
                app.HospitalizationPanel.Title = strcat('Hospitalization: ',app.selectedPaziente.cf{1},' - ',app.selectedPaziente.cognome{1});
                app.HomePage.Visible = 'off';
                app.HospitalizationPage.Visible = 'on';
                app.AcquisitionsPage.Visible = 'off';
%                 startupAcquisition(app);%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                app.AnalysisPage.Visible = 'off';
%                 startupAnalysis(app);%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%               close(progbar)
            catch ME
                disp("HospitalizationButtonPushedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "ModeDropDownValueChanged", 'VariableNames', ["identifier" "line" "function_name"]));
            end
        end

        % Value changed function: HospitalizationsDrop
        function HospitalizationsDropValueChanged(app, event)
            progbar =  uiprogressdlg(app.UIFigure,'Title','Loading clinicals data',...
                'Indeterminate','on');
            drawnow
            try
                startupHospitalization(app);
                startupAcquisition(app);
                startupAnalysis(app);

                app.PatientProfileIndexEventButton.Enable = 'on';
                app.HospitalizationButtonFAcq.Enable = 'on';
                app.HospitalizationButtonFAn.Enable = 'on';
                app.HospitalizationButtonFAn.Enable = "on";

                app.LaboratoryDataButton.Enable = 'on';
                app.AcquisitionsButtonFH.Enable = 'on';
                app.AcquisitionsButtonFAn.Enable = 'on';

                cla(app.AnalysisAx, "reset");

                value = app.HospitalizationsDrop.Value;
                hospitalizationSQL = strcat('select h.* from hospitalization h where id = ', string(value), ';');
                app.selectedHosp = select(app.conn, hospitalizationSQL);
                app.NameValueLabel.Text = app.selectedPaziente.nome{1};
                app.SurnameValueLabel.Text = app.selectedPaziente.cognome{1};
                app.SSNValueLabel.Text = app.selectedPaziente.cf{1};
                app.GenderValueLabel.Text = app.selectedPaziente.sesso{1};
                app.AddressValueLabel.Text = app.selectedPaziente.indirizzo_residenza{1};
                app.BirthdayValueLabel.Text = app.selectedPaziente.data_nascita{1};
                centerName = strcat('select nome from centro where id = ', string(app.selectedPaziente.id_centro),';');
                centerDataName = select(app.conn, centerName);
                app.CenterValueLabel.Text = centerDataName.nome{1};
                app.HDValueLabel.Text = strcat(app.selectedHosp.dataRicovero{1}, ' - ', app.selectedHosp.oraRicovero{1});
                app.EventDateValueLabel.Text = strcat(app.selectedHosp.dataEvento{1}, ' - ', app.selectedHosp.oraEvento{1});
                nameDrSQL = strcat('select CONCAT(cognome, " ", nome) as nome from utente where id = ',string(app.selectedHosp.id_medico));
                nameDr = select(app.conn, nameDrSQL);
                app.DoctorNameValueLabel.Text = nameDr.nome;
                acquisitionsSQL = strcat('select * from acquisizione where id_hospedalization = ', string(app.selectedHosp.id), ';');
                acquisitionsData = select(app.conn, acquisitionsSQL);
                
                app.AcquisitionsTab.Data = acquisitionsData;
                idsArray = acquisitionsData.id;
                dataAcquisizione = acquisitionsData.dataAcquisizione;
                oraAcquisizione = acquisitionsData.oraAcquisizione;
                itemsDataOut = [];
                for i = 1:size(idsArray)
                    datacell = dataAcquisizione(i);
                    oracell = oraAcquisizione(i);
                    strData = datacell{1};
                    strOra = oracell{1};
                    item = {strcat(int2str(idsArray(i)),' | ',strData,' | ',strOra)};
                    itemsDataOut = [itemsDataOut, item];
                end
                if(~isempty(idsArray))
                    app.AcquisitionslistDropDown.ItemsData = table2array(acquisitionsData(:,1));
                    app.AcquisitionslistDropDown.Items = itemsDataOut;
                    app.AcquisitionslistDropDown.Visible = "on";
                    app.AcquisitionslistDropDownLabel.Visible = "on";
                    app.MeasurementsDataPanel.Title = strcat('Measurements');
                else
                    app.AcquisitionslistDropDown.Visible = "off";
                    app.AcquisitionslistDropDownLabel.Visible = "off";
                    app.MeasurementsDataPanel.Title = strcat('Measurements - this hospitalization have no acquisition');
                end
                app.HDateH_2.Value = datetime(app.selectedHosp.dataRicovero);
                app.DateEvent_2.Value = datetime(app.selectedHosp.dataEvento);
                oraRicovero = split(app.selectedHosp.oraRicovero, ":");
                app.HhourH_2.Value = str2double(oraRicovero{1});
                app.HminuteH_2.Value = str2double(oraRicovero{2});
                app.HsecondH_2.Value = str2double(oraRicovero{3});
                if(~isempty(app.selectedHosp.oraEvento{1}))
                    oraEvento = split(app.selectedHosp.oraEvento, ":");
                    app.AMIhourEv_2.Value = str2double(oraEvento{1});
                    app.AMIminuteEv_2.Value = str2double(oraEvento{2});
                    app.AMIsecondEv_2.Value = str2double(oraEvento{3});
                end
                app.stMedCoDate_2.Value = datetime(app.selectedHosp.dataPrimoSoccorso);
                app.EtaEditField_2.Value = double(app.selectedHosp.age);
                app.WeightEditField_2.Value = double(app.selectedHosp.weight);
                app.SystolicBPmmHgEditField_2.Value = double(app.selectedHosp.systolicBP);
                app.DyastolicBPmmHgEditField_2.Value = double(app.selectedHosp.dyastolicBP);
                app.HRbpmEditField_2.Value = double(app.selectedHosp.HR);
                app.TimetotreatmhoursEditField_2.Value = double(app.selectedHosp.timeToTreatment);
                app.DiabeteDropDown_2.Value = app.selectedHosp.diabete;
                app.HypertensDropDown_2.Value = app.selectedHosp.ipertensione;
                app.Anginaepisodesin24hEditField_2.Value = double(app.selectedHosp.angina);
                app.KillipDropDown_2.Value = app.selectedHosp.killipClass;
                app.STEMIDropDown_2.Value = app.selectedHosp.STEMI;
                app.STEMIDropDown_2ValueChanged;
                app.LBBBDropDown_2.Value = app.selectedHosp.LBBB;
                app.SmokeDropDown_2.Value = app.selectedHosp.smoke;
                app.ObesityDropDown_2.Value = app.selectedHosp.obesity;
                app.PrevCADDropDown_2.Value = app.selectedHosp.prevAMI;
                app.FamiliarityDropDown_2.Value = app.selectedHosp.familiarity;
                app.DyslipidemiaDropDown_2.Value = app.selectedHosp.dislip;

                app.HypercolDropDown_2.Value = app.selectedHosp.hypercholesterolemia;
                app.StenosisEditField_2.Value = double(app.selectedHosp.stenosis);
                app.ASAusein7daysDropDown_2.Value = app.selectedHosp.asa_usage_7d;
                app.EKGSTdevmmEditField_2.Value = double(app.selectedHosp.st_deviation);
                app.PositivecardiacmarkerDropDown_2.Value = app.selectedHosp.cardiac_marker;

                app.TimiEditField_2.Value = app.selectedHosp.timi;
                
                if app.selectedHosp.dataTrombolisi ~= NaT
                    app.DateTromb_2.Value = datetime(app.selectedHosp.dataTrombolisi);
                    if ~isempty(app.selectedHosp.oraTrombolisi{1})
                        oraT = split(app.selectedHosp.oraTrombolisi, ":");
                        app.TrombHour_2.Value = str2double(oraT{1});
                        app.Trombminute_2.Value = str2double(oraT{2});
                        app.Trombsecond_2.Value = str2double(oraT{3});
                    else
                        app.TrombHour_2.Value = 0;
                        app.Trombminute_2.Value = 0;
                        app.Trombsecond_2.Value = 0;
                    end
                    app.DrugEditField_2.Value = app.selectedHosp.farmaco{1};
                end
                if app.selectedHosp.dataRivascolarizzazione ~= NaT
                    app.DateRev_2.Value = datetime(app.selectedHosp.dataRivascolarizzazione);
                    if ~isempty(app.selectedHosp.oraRivascolarizzazione{1})
                        oraR = split(app.selectedHosp.oraRivascolarizzazione, ":");
                        app.Revhour_2.Value = str2double(oraR{1});
                        app.Revminute_2.Value = str2double(oraR{2});
                        app.Revsecond_2.Value = str2double(oraR{3});
                    else
                        app.Revhour_2.Value = 0;
                        app.Revminute_2.Value = 0;
                        app.Revsecond_2.Value = 0;
                    end
                    app.LocoEditField_2.Value = app.selectedHosp.loco{1};
                end
                
                selectClause = strcat('select h.dataRicovero, h.oraRicovero, a.dataAcquisizione, a.oraAcquisizione, a.ctnt, a.ckmb, a.na, a.k, h.dataEvento, h.oraEvento ');
                fromClause = strcat(' from hospitalization h inner join acquisizione a on a.id_hospedalization = h.id inner join paziente p on h.id_paziente = p.id ');
                whereClause = strcat(' where p.id = ', string(app.selectedPaziente.id), ' and h.id = ', string(app.selectedHosp.id), ';');
                querySQL = strcat(selectClause, fromClause, whereClause);
    
                output = select(app.conn, querySQL);
    
                app.constant_vector = zeros(1,9);
                switch app.livello
                    case 1 %admin
                        app.ParametersSettingsPanel.Visible = 'on';
                        app.AdvancedSettings.Visible = 'on';
                        nAcquisitionSQL = strcat('select count(*) as n from acquisizione where id_hospedalization = ', string(app.selectedHosp.id));
                        nAcquisizioniTable = select(app.conn, nAcquisitionSQL);
                        app.nAcquisizioni = nAcquisizioniTable.n;
                        app.runValue = app.nAcquisizioni - 3;
                        app.aCheckBox.Value = 0;
                        app.bCheckBox.Value = 0;
                        app.TdCheckBox.Value = 0;
                        app.Cs0CheckBox.Value = 0;
                        app.Cc0CheckBox.Value = 0;
%                         disp(app.runValue)
                        if(app.nAcquisizioni >= 3 && app.nAcquisizioni < 5)
                            if(app.runValue < 0)
                                app.Acquisitionnumberinfo.Value = strcat('Insufficient number of acquisitions, select constant parameters. Acquisitions: ', string(app.nAcquisizioni));
                                app.Acquisitionnumberinfo.Visible = 'on';
                                app.RunButton.Enable = 'off';
                            else
                                app.Acquisitionnumberinfo.Value = '';
                                app.RunButton.Enable = 'on';
                            end
                        elseif app.nAcquisizioni >= 5
                            app.Acquisitionnumberinfo.Value = '';
                            app.RunButton.Enable = 'on';
                        else
                            app.Acquisitionnumberinfo.Value = strcat('Insufficient number of acquisitions. Insert more data to execute run.');
                            app.Acquisitionnumberinfo.Visible = 'on';
                            app.RunButton.Enable = 'off';
                        end
                    case 3 %clinic
                        app.ParametersSettingsPanel.Visible = 'off';
                        app.AdvancedSettings.Visible = 'off';
                        nAcquisitionSQL = strcat('select count(*) as n from acquisizione where id_hospedalization = ', string(app.selectedHosp.id));
                        nAcquisizioniTable = select(app.conn, nAcquisitionSQL);
                        app.nAcquisizioni = nAcquisizioniTable.n;
                        app.runValue = app.nAcquisizioni - 5;
                        if(app.nAcquisizioni >= 3 && app.nAcquisizioni < 5)
                            if(app.runValue < 0)
%                                 app.Acquisitionnumberinfo.Value = strcat('Insufficient number of acquisitions, select constant parameters. Acquisitions: :', string(app.nAcquisizioni));
%                                 app.Acquisitionnumberinfo.Visible = 'on';
                                app.RunMessage.Value = strcat('Insufficient number of acquisitions, select constant parameters. Acquisitions: :', string(app.nAcquisizioni));
                                app.RunMessage.Visible = "on";
                                app.RunButton.Enable = 'off';
                            else 
                                app.Acquisitionnumberinfo.Value = '';
                                app.RunButton.Enable = 'on';
                            end
                        elseif app.nAcquisizioni >= 5
                            app.Acquisitionnumberinfo.Value = '';
                            app.RunButton.Enable = 'on';
                        else
%                             app.Acquisitionnumberinfo.Value = strcat('Insufficient number of acquisitions. Insert more data to execute run.');
%                             app.Acquisitionnumberinfo.Visible = 'on';
                            app.RunMessage.Value = strcat('Insufficient number of acquisitions, select constant parameters. Acquisitions: :', string(app.nAcquisizioni));
                            app.RunMessage.Visible = "on";
                            app.RunButton.Enable = 'off';
                        end
                end
    
                dataAcq = output.dataAcquisizione;
                oraAcq = output.oraAcquisizione;
                dataEv = output.dataEvento;
                oraEv = output.oraEvento;
                app.ctnt = output.ctnt; %troponina
                app.ckmb = output.ckmb;
                app.sodio = output.na;
                app.potassio = output.k;
    
                app.TemAcq = tempi(dataAcq, oraAcq, dataEv, oraEv);
    
                app.PlotMedPanel.AutoResizeChildren = 'off';

%                 disp("hospitalization plotting...")

                analysisAx = app.AnalysisAx;
                if(app.nAcquisizioni > 0)
%                     app.PlotMedPanel.Visible = 'on';
                    x = app.TemAcq;
                    y1 = app.ctnt;
                    app.ax1 = subplot(2,1,1,'Parent',app.PlotMedPanel);
                    yyaxis(app.ax1, "left")
                    plot(app.ax1,x,y1,'LineStyle', 'none', 'Marker','o','MarkerSize',10)
                    ylabel(app.ax1,'cTnT [ng/ml]', 'Interpreter', 'latex', 'FontSize', 15);
                    y2 = app.ckmb;
                    yyaxis(app.ax1, "right")
                    plot(app.ax1,x,y2,'LineStyle', 'none','Marker','*','MarkerSize',10)
                    ylabel(app.ax1,'CK-MB [ng/ml]', 'Interpreter', 'latex', 'FontSize', 15);
                    title(app.ax1,'cTnT and CK-MB', 'Interpreter', 'latex', 'FontSize', 15);
                    legend(app.ax1,{'cTnT','CK-MB'}, 'Interpreter', 'latex', 'FontSize', 15);
                    set(app.ax1,'TickLabelInterpreter','latex', 'FontSize', 15);
                    grid(app.ax1, "on")
    
                    y3 = app.sodio;
                    app.ax2 = subplot(2,1,2,'Parent',app.PlotMedPanel);
                    yyaxis(app.ax2, "left")
                    plot(app.ax2,x,y3,'LineStyle', 'none', 'Marker','o','MarkerSize',10)
                    ylabel(app.ax2,'Na [ng/ml]', 'Interpreter', 'latex', 'FontSize', 15);
                    y4 = app.potassio;
                    yyaxis(app.ax2, "right")
                    plot(app.ax2,x,y4,'LineStyle', 'none','Marker','*','MarkerSize',10)
                    ylabel(app.ax2,'K [ng/ml]', 'Interpreter', 'latex', 'FontSize', 15);
                    title(app.ax2,'Na and Ks', 'Interpreter', 'latex', 'FontSize', 15);
                    legend(app.ax2,{'Na','K'}, 'Interpreter', 'latex', 'FontSize', 15);
                    set(app.ax2,'TickLabelInterpreter','latex', 'FontSize', 15);
                    xlabel(app.ax2,'Time [h]', 'Interpreter', 'latex', 'FontSize', 15);
                    grid(app.ax2, "on")

%                     disp("hospitalization plot ready")

    %%              Load data from saved analysis
                    resultAnalysisSQL = strcat("SELECT ar.* FROM analysis_result ar WHERE ar.id_hospedalization =  ", string(app.selectedHosp.id));
                    resultAnalysis = select(app.conn, resultAnalysisSQL);
    
                    if ~isempty(resultAnalysis)
                        app.SelectAnalysis.Items = {};
                        app.SelectAnalysis.ItemsData = {};
                        app.ExecutionNumber = 0;
                        app.ResultsPanel.Visible = 'off';
        
                        items = app.SelectAnalysis.Items;
                        itemsData = app.SelectAnalysis.ItemsData;
                        app.PredictedValuesPanel.Visible = 'off';

                        app.ExecutionNumber = height(resultAnalysis);
                        for i=1:app.ExecutionNumber
                            itemsData = [itemsData i];
                            dataAnalisiStr = ComputeData(datetime(resultAnalysis.dataAnalisi(i)), 'it');
                            oraAnalisiStr = resultAnalysis.oraAnalisi(i);
                            oraAnalisiStr = oraAnalisiStr{1}(1:5);
                            items = [items strcat("Saved execution ", dataAnalisiStr, " ", oraAnalisiStr)];
                            result = resultAnalysis(i,4:end-5);
                            result = result{1,:};
                            Execution = struct('id',resultAnalysis.id(i),'a',result(1),'b',result(2),'Td',result(5),'Cs0',result(6),'Cc0',result(7),'FPV',result(10),'FPT',result(11),'SPV',result(12),'SPT',result(13),'X',0,'T',0,'parametersRun',0);
                            if ~isempty(app.Executions)
                                app.Executions = [app.Executions Execution];
                            else
                                app.Executions = [Execution];
                            end
                        end
                        app.SelectAnalysis.Items = items;
                        app.SelectAnalysis.ItemsData = itemsData;
                        app.SelectAnalysis.Value = app.ExecutionNumber;
                        app.SelectAnalysisValueChanged;
                        app.ResultsPanel.Visible = 'on';
                        if app.livello == 1
                            app.PredictedValuesPanel.Visible = 'on';
                        else
                            app.PredictedValuesPanel.Visible = 'off';
                        end
                    else
                        plot(analysisAx, app.TemAcq, app.ctnt, '*','MarkerSize', 10, 'MarkerEdgeColor',[0.64,0.08,0.18])
                        xlabel(analysisAx,'Time [h]', 'Interpreter', 'latex', 'FontSize', 15)
                        ylabel(analysisAx,'cTnT [ng/ml]', 'Interpreter', 'latex', 'FontSize', 15)
                        legend(analysisAx,{'cTnT'}, 'Interpreter', 'latex', 'FontSize', 15)
                        title(analysisAx,'cTnT', 'Interpreter', 'latex', 'FontSize', 15)
                        set(analysisAx,'FontSize',15)
                        set(analysisAx,'TickLabelInterpreter','latex')
                        grid(analysisAx, 'on')
                    end
                    
                    app.PlotMedPanel.Title = "";

                    app.CardiacmarkerstrendButton.Enable = 'on';
                    app.AnalysisButtonFAcq.Enable = 'on';
                    app.AnalysisButtonFH.Enable = 'on';
                else
                    cla(app.ax1, 'reset');
                    cla(app.ax2, 'reset');
                    app.PlotMedPanel.Title = "No acquisition inserted in this hospitalization.";
                    app.CardiacmarkerstrendButton.Enable = 'off';
                    app.AnalysisButtonFAcq.Enable = 'off';
                    app.AnalysisButtonFH.Enable = 'off';
%                     disp("hospitalization page ready")
                end
            catch ME
                disp("HospitalizationsDropValueChangedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "HospitalizationsDropValueChanged", 'VariableNames', ["identifier" "line" "function_name"]));
%                 rethrow(ME)
            end
            close(progbar)
        end

        % Button pushed function: SaveNewHospitalization_2
        function SaveNewHospitalizationPushed(app, event)
            %ProgressBar init
            progbar =  uiprogressdlg(app.UIFigure,'Title','Save new hospitalization',...
                'Message','Saving...','Cancelable','off');
            pause(.2)
            tic
            try 
                check = true;
                app.HospitalizationSavingStatus_2.Color = 'g';
                app.HospitalizationSavingStatus_2.Visible ="off";
                app.HospitalizationText_2.Visible = 'off';
                app.DateandtimehospitalizationPanel_2.BackgroundColor = [1 1 1];
                app.DateandtimeAMIPanel_2.BackgroundColor = [1 1 1];

                SaveHospMsg = "ERROR! insert:";

                dateHinput = app.HDateH_2.Value;
                if isnat(dateHinput)
                    check = false;
                    app.HospitalizationSavingStatus_2.Color = 'r';
                    app.DateandtimehospitalizationPanel_2.BackgroundColor = [1 0 0];
                    SaveHospMsg = strcat(SaveHospMsg,' Hospitalization date and time');
                else
                    dateH = ComputeData(dateHinput, 'en');
                end
                hourHinput = app.HhourH_2.Value;
                minutesHinput = app.HminuteH_2.Value;
                secondsHinput = app.HsecondH_2.Value;
                timeH = ComputeOre(hourHinput, minutesHinput, secondsHinput);

                dateAMIinput = app.DateEvent_2.Value;
                if isnat(dateAMIinput)
                    check = false;
                    app.HospitalizationSavingStatus_2.Color = 'r';
                    app.DateandtimeAMIPanel_2.BackgroundColor = [1 0 0];
                    SaveHospMsg = strcat(SaveHospMsg,' AMI date and time');
                else
                    dateAMI = ComputeData(dateAMIinput, 'en');
                end
                hourAMIinput = app.AMIhourEv_2.Value;
                minutesAMIinput = app.AMIminuteEv_2.Value;
                secondsAMIinput = app.AMIsecondEv_2.Value;
                timeAMI = ComputeOre(hourAMIinput, minutesAMIinput, secondsAMIinput);

                dateTrombinput = app.DateTromb_2.Value;
                if ~isnat(dateTrombinput)
                    dateTromb = ComputeData(dateTrombinput, 'en');
                else
                    dateTromb = NaT;
                end
                hourTrombinput = app.TrombHour_2.Value;
                minutesTrombinput = app.Trombminute_2.Value;
                secondsTrombinput = app.Trombsecond_2.Value;
                timeTromb = ComputeOre(hourTrombinput, minutesTrombinput, secondsTrombinput);
                farmaco = app.DrugEditField_2.Value;
                
                dateRevinput = app.DateRev_2.Value;
                if ~isnat(dateRevinput)
                    dateRev = ComputeData(dateRevinput, 'en');
                else
                    dateRev = NaT;
                end
                hourRevinput = app.Revhour_2.Value;
                minutesRevinput = app.Revminute_2.Value;
                secondsRevinput = app.Revsecond_2.Value;
                timeRev = ComputeOre(hourRevinput, minutesRevinput, secondsRevinput);
                loco = app.LocoEditField_2.Value;

                if check
                    primoSocInput = app.stMedCoDate_2.Value;
                    if ~isnat(primoSocInput)
                        primoSoccorso = ComputeData(primoSocInput, 'en');
%                         primoSoccorso = strcat(primoSoccorso," 00:00:00");
                    else
                        primoSoccorso = NaT;
                    end
                    weight = app.WeightEditField_2.Value;
%                     if weight == 0
%                         weight = NaN;
%                     end
                    systolicBP = app.SystolicBPmmHgEditField_2.Value;
%                     if systolicBP == 0
%                         systolicBP = NaN;
%                     end
                    dyastolicBP = app.DyastolicBPmmHgEditField_2.Value;
%                     if dyastolicBP == 0
%                         dyastolicBP = NaN;
%                     end
                    hr = app.HRbpmEditField_2.Value;
%                     if hr == 0
%                         hr = NaN;
%                     end
                    tttinput = app.TimetotreatmhoursEditField_2.Value;
%                     if tttinput == 0
%                         timeToTreatm = NaN;
%                     else
%                         timeToTreatm = tttinput;
%                     end
                    timeToTreatm = app.TimetotreatmhoursEditField_2.Value;
                    eta = app.EtaEditField_2.Value;
%                     if eta == 0 
%                         eta = NaN;
%                     end
                    diabete = app.DiabeteDropDown_2.Value;
                    hypertens = app.HypertensDropDown_2.Value;
                    angina = app.Anginaepisodesin24hEditField_2.Value;
                    killip = app.KillipDropDown_2.Value;
                    STEMI = app.STEMIDropDown_2.Value;
                    lbbb = app.LBBBDropDown_2.Value;
                    smoke = app.SmokeDropDown_2.Value;
                    obesity = app.ObesityDropDown_2.Value;
                    prevAMI = app.PrevCADDropDown_2.Value;
                    familiarity = app.FamiliarityDropDown_2.Value;
                    dyslipid = app.DyslipidemiaDropDown_2.Value;

                    hypercholesterolemia = app.HypercolDropDown_2.Value;
                    stenosis = app.StenosisEditField_2.Value;
                    asa_usage_7d = app.ASAusein7daysDropDown_2.Value;
                    st_deviation = app.EKGSTdevmmEditField_2.Value;
                    cardiac_marker = app.PositivecardiacmarkerDropDown_2.Value;

                    timi = app.TimiEditField_2.Value;
                end
                %ProgressBar update
                progbar.Value = .33; 
                progbar.Message = "Saving hospitalization";
                pause(0.2)
                if check
                    [m, r] = sqlprotection({farmaco loco});
                    if r == true
                        data = table(string(dateH),string(timeH),string(dateAMI),string(timeAMI),app.selectedPaziente.id,app.id,string(primoSoccorso),diabete,hypertens,angina,hr,killip,STEMI,lbbb,timeToTreatm,smoke, ...
                            familiarity,prevAMI,obesity,dyslipid,timi,"referto",systolicBP,dyastolicBP,weight,eta,string(dateTromb),string(timeTromb),string(farmaco),string(dateRev),string(timeRev),string(loco), ...
                            hypercholesterolemia, asa_usage_7d, st_deviation, cardiac_marker, stenosis, ...
                            'VariableNames',{'dataRicovero','oraRicovero','dataEvento','oraEvento','id_paziente','id_medico','dataPrimoSoccorso','diabete','ipertensione','angina','HR','killipClass','STEMI','LBBB','timeToTreatment','smoke', ...
                            'familiarity','prevAMI','obesity','dislip','timi','referto','systolicBP','dyastolicBP','weight','age', 'dataTrombolisi', 'oraTrombolisi', 'farmaco', 'dataRivascolarizzazione', 'oraRivascolarizzazione', 'loco', ...
                            'hypercholesterolemia', 'asa_usage_7d', 'st_deviation', 'cardiac_marker', 'stenosis'});
                        sqlwrite(app.conn,'hospitalization',data);
                        loginEvalSql = strcat("SELECT EVENT_ID, TRUNCATE(TIMER_WAIT/1000000000000,6) as Duration ", ...
                            "FROM performance_schema.events_statements_history AS queryLog ", ...
                            "WHERE queryLog.CURRENT_SCHEMA LIKE 'cbra_new' AND queryLog.SQL_TEXT LIKE 'INSERT INTO hospitalization % VALUES %", string(app.selectedPaziente.id), "%", string(app.id), "%'");
                        loginEvaluation = select(app.conn, loginEvalSql);
                        evalTime = loginEvaluation.Duration(1);

                        pause(.2)
                        app.PazienteDropDownValueChanged;
                        SaveHospMsg = 'Save complete';
                        app.HospitalizationSavingStatus_2.Color = 'g';
                    else
                        app.HospitalizationSavingStatus_2.Color = 'r';
                        SaveHospMsg = m;
                    end
                    executionTime = toc;
                    update(app.conn, 'hospitalization', {'evaluation_time' 'execution_time'}, table(evalTime, executionTime, 'VariableNames', {'evaluation_time' 'execution_time'}), strcat("WHERE id_medico = ",string(app.id)," ORDER BY id DESC LIMIT 1"));
                end
            %updating
            progbar.Value = .67;
            progbar.Message = app.msg;
            pause(.2)
            %updating
            progbar.Value = 1;
            progbar.Message = 'Finishing';
            pause(.2)
            catch ME
                disp("SaveNewHospitalizationPushedError")
                app.HospitalizationSavingStatus_2.Color = 'r';
                SaveHospMsg = strcat('ERROR: ',ME.identifier);
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "SaveNewHospitalizationPushed", 'VariableNames', ["identifier" "line" "function_name"]));
%                 rethrow(ME)
            end
            app.HospitalizationText_2.Value = SaveHospMsg;
            app.HospitalizationText_2.Visible = 'on';
            app.HospitalizationSavingStatus_2.Visible = 'on';
            close(progbar)
        end

        % Button pushed function: SaveHospitalization_2
        function SaveHospitalizationButtonPushed(app, event)
            %ProgressBar init
            progbar =  uiprogressdlg(app.UIFigure,'Title','Save new user',...
                'Message','Saving...','Cancelable','off');
            pause(.2)
            try 
                check = true;
                app.HospitalizationSavingStatus_2.Color = 'g';
                app.HospitalizationSavingStatus_2.Visible ="off";
                app.HospitalizationText_2.Visible = 'off';
                app.DateandtimehospitalizationPanel_2.BackgroundColor = [1 1 1];
                app.DateandtimeAMIPanel_2.BackgroundColor = [1 1 1];
                SaveHospMsg = "ERROR! insert";

                dateHinput = app.HDateH_2.Value;
                if isnat(dateHinput)
                    check = false;
                    app.HospitalizationSavingStatus_2.Color = 'r';
                    app.DateandtimehospitalizationPanel_2.BackgroundColor = [1 0 0];
                    SaveHospMsg = strcat(SaveHospMsg,' Hospitalization date and time');
                else
                    dateH = ComputeData(dateHinput, 'en');
                end
                hourHinput = app.HhourH_2.Value;
                minutesHinput = app.HminuteH_2.Value;
                secondsHinput = app.HsecondH_2.Value;
                timeH = ComputeOre(hourHinput, minutesHinput, secondsHinput);
                dateAMIinput = app.DateEvent_2.Value;
                if isnat(dateAMIinput)
                    check = false;
                    app.HospitalizationSavingStatus_2.Color = 'r';
                    app.DateandtimeAMIPanel_2.BackgroundColor = [1 0 0];
                    SaveHospMsg = strcat(SaveHospMsg,' AMI date and time');
                else
                    dateAMI = ComputeData(dateAMIinput, 'en');
                end
                hourAMIinput = app.AMIhourEv_2.Value;
                minutesAMIinput = app.AMIminuteEv_2.Value;
                secondsAMIinput = app.AMIsecondEv_2.Value;
                timeAMI = ComputeOre(hourAMIinput, minutesAMIinput, secondsAMIinput);

                dateTrombinput = app.DateTromb_2.Value;
                if ~isnat(dateTrombinput)
                    dateTromb = ComputeData(dateTrombinput, 'en');
                else
                    dateTromb = NaT;
                end
                hourTrombinput = app.TrombHour_2.Value;
                minutesTrombinput = app.Trombminute_2.Value;
                secondsTrombinput = app.Trombsecond_2.Value;
                timeTromb = ComputeOre(hourTrombinput, minutesTrombinput, secondsTrombinput);
                farmaco = app.DrugEditField_2.Value;
                
                dateRevinput = app.DateRev_2.Value;
                if ~isnat(dateRevinput)
                    dateRev = ComputeData(dateRevinput, 'en');
                else
                    dateRev = NaT;
                end
                hourRevinput = app.Revhour_2.Value;
                minutesRevinput = app.Revminute_2.Value;
                secondsRevinput = app.Revsecond_2.Value;
                timeRev = ComputeOre(hourRevinput, minutesRevinput, secondsRevinput);
                loco = app.LocoEditField_2.Value;

                if check
                    primoSocInput = app.stMedCoDate_2.Value;
                    if ~isnat(primoSocInput)
                        primoSoccorso = ComputeData(primoSocInput, 'en');
%                         primoSoccorso = strcat(primoSoccorso," 00:00:00");
                    else
                        primoSoccorso = NaT;
                    end
                    weight = app.WeightEditField_2.Value;
%                     if weight == 0
%                         weight = NaN;
%                     end
                    systolicBP = app.SystolicBPmmHgEditField_2.Value;
%                     if systolicBP == 0
%                         systolicBP = NaN;
%                     end
                    dyastolicBP = app.DyastolicBPmmHgEditField_2.Value;
%                     if dyastolicBP == 0
%                         dyastolicBP = NaN;
%                     end
                    hr = app.HRbpmEditField_2.Value;
%                     if hr == 0
%                         hr = NaN;
%                     end
%                     tttinput = app.TimetotreatmhoursEditField_2.Value;
%                     timeToTreatm = NaN;
%                     if ~isempty(tttinput)
%                         timeToTreatm = tttinput;
%                     end
                    timeToTreatm = app.TimetotreatmhoursEditField_2.Value;
                    eta = app.EtaEditField_2.Value;
%                     if eta == 0 
%                         eta = NaN;
%                     end
                    diabete = app.DiabeteDropDown_2.Value;
                    hypertens = app.HypertensDropDown_2.Value;
                    angina = app.Anginaepisodesin24hEditField_2.Value;
                    killip = app.KillipDropDown_2.Value;
                    STEMI = app.STEMIDropDown_2.Value;
                    lbbb = app.LBBBDropDown_2.Value;
                    smoke = app.SmokeDropDown_2.Value;
                    obesity = app.ObesityDropDown_2.Value;
                    prevAMI = app.PrevCADDropDown_2.Value;
                    familiarity = app.FamiliarityDropDown_2.Value;
                    dyslipid = app.DyslipidemiaDropDown_2.Value;

                    hypercholesterolemia = app.HypercolDropDown_2.Value;
                    stenosis = app.StenosisEditField_2.Value;
                    asa_usage_7d = app.ASAusein7daysDropDown_2.Value;
                    st_deviation = app.EKGSTdevmmEditField_2.Value;
                    cardiac_marker = app.PositivecardiacmarkerDropDown_2.Value;

                    timi = app.TimiEditField_2.Value;
                end
                %ProgressBar update
                progbar.Value = .33; 
                progbar.Message = "Saving hospitalization edits";
                pause(0.2)
                if check
                    [m, r] = sqlprotection({farmaco loco});
                    if r == true
                        data = table(string(dateH),string(timeH),string(dateAMI),string(timeAMI), app.selectedPaziente.id, app.id,string(primoSoccorso),diabete,hypertens,angina,hr,killip,STEMI,lbbb,timeToTreatm,smoke, ...
                            familiarity,prevAMI,obesity,dyslipid,timi,"referto",systolicBP,dyastolicBP,weight,eta,string(dateTromb),string(timeTromb),string(farmaco),string(dateRev),string(timeRev),string(loco), ...
                            hypercholesterolemia, asa_usage_7d, st_deviation, cardiac_marker, stenosis, datestr(now, "yyyy-mm-dd HH:MM:SS"), ...
                            'VariableNames',{'dataRicovero','oraRicovero','dataEvento','oraEvento','id_paziente','id_medico','dataPrimoSoccorso','diabete','ipertensione','angina','HR','killipClass','STEMI','LBBB','timeToTreatment','smoke', ...
                            'familiarity','prevAMI','obesity','dislip','timi','referto','systolicBP','dyastolicBP','weight','age', 'dataTrombolisi', 'oraTrombolisi', 'farmaco', 'dataRivascolarizzazione', 'oraRivascolarizzazione', 'loco', ...
                            'hypercholesterolemia', 'asa_usage_7d', 'st_deviation', 'cardiac_marker', 'stenosis', 'data_edit'});
                        where = strcat('WHERE id = ', string(app.selectedHosp.id), ';');
                        update(app.conn,'hospitalization',{'dataRicovero','oraRicovero','dataEvento','oraEvento','id_paziente','id_medico','dataPrimoSoccorso','diabete','ipertensione','angina','HR','killipClass','STEMI','LBBB','timeToTreatment', ...
                            'smoke','familiarity','prevAMI','obesity','dislip','timi','referto','systolicBP','dyastolicBP','weight','age','dataTrombolisi', 'oraTrombolisi', 'farmaco', 'dataRivascolarizzazione', 'oraRivascolarizzazione', 'loco', ...
                            'hypercholesterolemia', 'asa_usage_7d', 'st_deviation', 'cardiac_marker', 'stenosis', 'data_edit'},data,where);
                        pause(.2)
                        app.PazienteDropDownValueChanged;
                        SaveHospMsg = 'Save complete';
                        app.HospitalizationSavingStatus_2.Color = 'g';
                    else
                        app.HospitalizationSavingStatus_2.Color = 'r';
                        SaveHospMsg = m;
                    end
                end
                %updating
                progbar.Value = .67;
                progbar.Message = SaveHospMsg;
                pause(.2)
                %updating
                progbar.Value = 1;
                progbar.Message = 'Finishing';
                pause(.2)
            catch ME
                disp("SaveHospitalizationButtonPushedError")
                app.HospitalizationSavingStatus_2.Color = 'r';
                SaveHospMsg = strcat('ERROR: ',ME.identifier);
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "SaveHospitalizationButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
%                 rethrow(ME)
            end
            app.HospitalizationText_2.Value = SaveHospMsg;
            app.HospitalizationText_2.Visible = 'on';
            app.HospitalizationSavingStatus_2.Visible = 'on';
            close(progbar)
        end

        % Button pushed function: AcquisitionsButtonFAn, 
        % ...and 2 other components
        function AcquisitionsButtonPushed(app, event)
            progbar =  uiprogressdlg(app.UIFigure,'Title','Loading Acquisition page data',...
                'Indeterminate','on');
            drawnow
            try    
                app.HospitalizationPanel.Title = strcat('Acquisition: ',app.selectedPaziente.cf{1},' - ',app.selectedPaziente.cognome{1});
                app.HomePage.Visible = 'off';
                app.HospitalizationPage.Visible = 'off';
%                 startupHospitalization(app);%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                app.AcquisitionsPage.Visible = 'on';
                app.AnalysisPage.Visible = 'off';
%                 startupAnalysis(app);%%%%%%%%%%%%%%%%%%%%%%%%%%%
                app.NameValueLabel.Text = app.selectedPaziente.nome{1};
                app.SurnameValueLabel.Text = app.selectedPaziente.cognome{1};
                app.SSNValueLabel.Text = app.selectedPaziente.cf{1};
                app.GenderValueLabel.Text = app.selectedPaziente.sesso{1};
                app.AddressValueLabel.Text = app.selectedPaziente.indirizzo_residenza{1};
                app.BirthdayValueLabel.Text = app.selectedPaziente.data_nascita{1};
                centerName = strcat('select nome from centro where id = ', string(app.selectedPaziente.id_centro),';');
                centerDataName = select(app.conn, centerName);
                app.CenterValueLabel.Text = centerDataName.nome{1};
            catch ME
                disp("AcquisitionsButtonPushedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "AcquisitionsButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
%                 rethrow(ME)
            end
            close(progbar)
        end

        % Value changed function: AcquisitionslistDropDown
        function AcquisitionslistDropDownValueChanged(app, event)
            progbar =  uiprogressdlg(app.UIFigure,'Title','Loading acquisitions data',...
                'Indeterminate','on');
            drawnow
            try
                idAcquisizione = app.AcquisitionslistDropDown.Value;
                acquisitionSQL = strcat('select id, dataAcquisizione, oraAcquisizione, ctnt, ctni, ckmb, na, k from acquisizione where id = ', string(idAcquisizione), ';');
                selectedAcquisition = select(app.conn, acquisitionSQL);
                app.cTnTEditField.Value = selectedAcquisition.ctnt;
                app.cTnIEditField.Value = selectedAcquisition.ctni;
                app.CKMBEditField.Value = selectedAcquisition.ckmb;
                app.NaEditField.Value = selectedAcquisition.na;
                app.KEditField.Value = selectedAcquisition.k;
                app.HDateA.Value = datetime(selectedAcquisition.dataAcquisizione);
                oraAcquisizione = split(selectedAcquisition.oraAcquisizione, ":");
                app.HhourA.Value = str2double(oraAcquisizione{1});
                app.HminuteA.Value = str2double(oraAcquisizione{2});
                app.HsecondA.Value = str2double(oraAcquisizione{3});
            catch ME
                disp("AcquisitionslistDropDownValueChangedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "AcquisitionslistDropDownValueChanged", 'VariableNames', ["identifier" "line" "function_name"]));
%                 rethrow(ME)
            end
            close(progbar)
        end

        % Button pushed function: SavenewAcqButton
        function SavenewAcqButtonPushed(app, event)
            %ProgressBar init
            progbar =  uiprogressdlg(app.UIFigure,'Title','Save new acquisition',...
                'Message','Saving...','Cancelable','off');
            pause(.2)
            tic
            try 
                check = true;
                saveAcqMsg = strcat('ERROR! Date and time are mandatory');
                app.MeasurementsSaveText.Visible = 'on';
                app.MeasurementsSaveText.Value = 'Saving...';
                app.MeasurementsSaveLamp.Visible = 'off';
                app.MeasurementsSaveLamp.Color = 'g';
                app.DateandTimeacquisitionPanel.BackgroundColor = [1 1 1];

                cTnT = app.cTnTEditField.Value;
                cTnI = app.cTnIEditField.Value;
                CKMB = app.CKMBEditField.Value;
                Na = app.NaEditField.Value;
                K = app.KEditField.Value;
                acquisitionDateInput = app.HDateA.Value;
                pause(.2)
                if isnat(acquisitionDateInput)
                    check = false;
                    app.MeasurementsSaveLamp.Color = 'r';
                    app.DateandTimeacquisitionPanel.BackgroundColor = [1 0 0];
                    app.MeasurementsSaveText.Value = 'ERROR! insert a valid date';
                else
                    acquisitionDateStr = ComputeData(acquisitionDateInput, 'en');
                    app.DateandTimeacquisitionPanel.BackgroundColor = [1 1 1];
                end
                h = app.HhourA.Value;
                m = app.HminuteA.Value;
                s = app.HsecondA.Value;
                acquisitionTimeStr = ComputeOre(h, m, s);
                %ProgressBar update
                progbar.Value = .33; 
                progbar.Message = "Saving acquisition";
                pause(0.2)
                if check
                    data = table(string(acquisitionDateStr), string(acquisitionTimeStr), cTnT, cTnI, CKMB, Na, K, app.selectedHosp.id ,'VariableNames', {'dataAcquisizione', 'oraAcquisizione', 'ctnt', 'ctni', 'ckmb', 'na', 'k', 'id_hospedalization'});
%                     disp(data);
                    
                    sqlwrite(app.conn,'acquisizione',data);
                    loginEvalSql = strcat("SELECT EVENT_ID, TRUNCATE(TIMER_WAIT/1000000000000,6) as Duration ", ...
                            "FROM performance_schema.events_statements_history AS queryLog ", ...
                            "WHERE queryLog.CURRENT_SCHEMA LIKE 'cbra_new' AND queryLog.SQL_TEXT LIKE 'INSERT INTO acquisizione % VALUES %", string(app.selectedHosp.id), "%'");
                    loginEvaluation = select(app.conn, loginEvalSql);
                    evalTime = loginEvaluation.Duration(1);
                    saveAcqMsg = 'Save success!';
                    app.MeasurementsSaveLamp.Color = 'g';
                    app.HospitalizationsDropValueChanged;
                end
                executionTime = toc;
                update(app.conn, 'acquisizione', {'evaluation_time' 'execution_time'}, table(evalTime, executionTime, 'VariableNames', {'evaluation_time' 'execution_time'}), strcat("WHERE id_hospedalization = ",string(app.selectedHosp.id)," ORDER BY id DESC LIMIT 1"));
                %updating
                progbar.Value = .67;
                progbar.Message = saveAcqMsg;
                pause(.2)
                %updating
                progbar.Value = 1;
                progbar.Message = 'Finishing';
                pause(.2)
            catch ME
                disp("SavenewAcqButtonPushedError")
                disp(ME)
                app.MeasurementsSaveLamp.Color = 'r';
                saveAcqMsg = strcat('ERROR: ',ME.identifier);
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "SavenewAcqButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
            end
            app.MeasurementsSaveText.Value = saveAcqMsg;
            app.MeasurementsSaveText.Visible = 'on';
            app.MeasurementsSaveLamp.Visible = 'on';
            close(progbar)
        end

        % Button pushed function: SaveeditsAcqButton
        function SaveeditsAcqButtonPushed(app, event)
            %ProgressBar init
            progbar =  uiprogressdlg(app.UIFigure,'Title','Save acquisition edits',...
                'Message','Saving...','Cancelable','off');
            pause(.2)
            try
                check = true;
                saveAcqMsg = strcat('ERROR! Date and time are mandatory');
                app.MeasurementsSaveLamp.Color = 'g';
                app.MeasurementsSaveText.Visible = 'on';
                app.MeasurementsSaveText.Value = 'Saving...';
                app.MeasurementsSaveLamp.Visible = 'off';
                acquisitionID = app.AcquisitionslistDropDown.Value;
                cTnT = app.cTnTEditField.Value;
                cTnI = app.cTnIEditField.Value;
                CKMB = app.CKMBEditField.Value;
                Na = app.NaEditField.Value;
                K = app.KEditField.Value;
                acquisitionDateInput = app.HDateA.Value;
                pause(.2)
                if isnat(acquisitionDateInput)
                    check = false;
                    app.MeasurementsSaveLamp.Color = 'r';
                    app.DateandTimeacquisitionPanel.BackgroundColor = [1 0 0];
                    app.MeasurementsSaveText.Value = 'ERROR! insert a valid date';
                else
                    acquisitionDateStr = ComputeData(acquisitionDateInput, 'en');
                    app.DateandTimeacquisitionPanel.BackgroundColor = [1 1 1];
                end
                h = app.HhourA.Value;
                m = app.HminuteA.Value;
                s = app.HsecondA.Value;
                acquisitionTimeStr = ComputeOre(h, m, s);
                progbar.Value = .33; 
                progbar.Message = "Saving acquisition edits";
                pause(0.2)
                if check
                    data = table(string(acquisitionDateStr), string(acquisitionTimeStr), cTnT, cTnI, CKMB, Na, K, datestr(now, "yyyy-mm-dd HH:MM:SS"), 'VariableNames', {'dataAcquisizione', 'oraAcquisizione', 'ctnt', 'ctni', 'ckmb', 'na', 'k'});
    %                 disp(data);
                    
                    where = strcat('WHERE id = ', string(acquisitionID), ';');
                    update(app.conn,'acquisizione',{'dataAcquisizione', 'oraAcquisizione', 'ctnt', 'ctni', 'ckmb', 'na', 'k', 'date_edit'},data,where);
                    saveAcqMsg = 'Save success!';
                    app.MeasurementsSaveLamp.Color = 'g';
                    app.HospitalizationsDropValueChanged;
                end
                %updating
                progbar.Value = .67;
                progbar.Message = saveAcqMsg;
                pause(.2)
                %updating
                progbar.Value = 1;
                progbar.Message = 'Finishing';
                pause(.2)
            catch ME
                disp("SaveeditsAcqButtonPushedError")
                disp(ME)
                app.MeasurementsSaveLamp.Color = 'r';
                saveAcqMsg = strcat('ERROR: ',ME.identifier);
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "SaveeditsAcqButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
            end
            app.MeasurementsSaveText.Value = saveAcqMsg;
            app.MeasurementsSaveText.Visible = 'on';
            app.MeasurementsSaveLamp.Visible = 'on';
            close(progbar)
        end

        % Button pushed function: AnalysisButtonFAcq, AnalysisButtonFH, 
        % ...and 1 other component
        function AnalysisButtonPushed(app, event)
%             progbar =  uiprogressdlg(app.UIFigure,'Title','Loading Analysis page data',...
%                 'Indeterminate','on');
%             drawnow
            try
                app.HospitalizationPanel.Title = strcat('Analysis: ',app.selectedPaziente.cf{1},' - ',app.selectedPaziente.cognome{1});
                app.HomePage.Visible = 'off';
                app.HospitalizationPage.Visible = 'off';
                app.AcquisitionsPage.Visible = 'off';
                app.AnalysisPage.Visible = 'on';
                app.NameValueLabel_2.Text = app.selectedPaziente.nome{1};
                app.SurnameValueLabel_2.Text = app.selectedPaziente.cognome{1};
                app.SSNValueLabel_2.Text = app.selectedPaziente.cf{1};
                app.GenderValueLabel_2.Text = app.selectedPaziente.sesso{1};
                app.AddressValueLabel_2.Text = app.selectedPaziente.indirizzo_residenza{1};
                app.BirthdayValueLabel_2.Text = app.selectedPaziente.data_nascita{1};
                centerName = strcat('select nome from centro where id = ', string(app.selectedPaziente.id_centro),';');
                centerDataName = select(app.conn, centerName);
                app.CenterValueLabel_2.Text = centerDataName.nome{1};
                app.HDValueLabel_2.Text = strcat(app.selectedHosp.dataRicovero{1}, ' - ', app.selectedHosp.oraRicovero{1});
                app.EventDateValueLabel_2.Text = strcat(app.selectedHosp.dataEvento{1}, ' - ', app.selectedHosp.oraEvento{1});
                nameDrSQL = strcat('select CONCAT(cognome, " ", nome) as nome from utente where id = ',string(app.selectedHosp.id_medico));
                nameDr = select(app.conn, nameDrSQL);
                app.DoctorNameValueLabel_2.Text = nameDr.nome;
%                 app.HospitalizationsDropValueChanged;
%                 if app.nAcquisizioni >= 5
%                     app.RunButton.Enable = "on";
%                 else
%                     app.RunButton.Enable = "off";
%                 end
            catch ME
                disp("AnalysisButtonPushedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "AnalysisButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
%                 rethrow(ME)
            end
%             close(progbar)
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.RunMessage.Visible = 'on';
            app.RunMessage.Value = 'Execution...';
             
            app.RunButton.Enable = 'off';
            app.AcquisitionsButtonFAn.Enable = 'off';
            app.HomeButtonFAn.Enable = 'off';
            app.HospitalizationButtonFAn.Enable = 'off';
%             app.StopButton.Enable = 'on';
            pause(.2)

            try    
                lowerBound = zeros(1, 9, 'double');
                lowerBound(1) = app.aLowerSpinner.Value; %a
                lowerBound(2) = app.bLowerSpinner.Value; %b
                lowerBound(5) = app.TdLowerSpinner.Value; %Td
                lowerBound(6) = app.Cs0LowerSpinner.Value; %Cs0
                lowerBound(7) = app.Cc0LowerSpinner.Value; %Cc0
                upperBound = zeros(1, 9, 'double');
                upperBound(1) = app.aUpperSpinner.Value;
                upperBound(2) = app.bUpperSpinner.Value;
                upperBound(5) = app.TdUpperSpinner.Value;
                upperBound(6) = app.Cs0UpperSpinner.Value;
                upperBound(7) = app.Cc0UpperSpinner.Value;
                parameters = zeros(1, 9, 'double');
                parameters(1) = app.aInitialEditField.Value;
                parameters(2) = app.bInitialEditField.Value;
                parameters(5) = app.TdInitialEditField.Value;
                parameters(6) = app.Cs0InitialEditField.Value;
                parameters(7) = app.Cc0InitialEditField.Value;
                globalAlgorithm = app.OptimizationAlgorithmDropDown.Value;
                localfunction = app.LocaloptimizationalgorithmDropDown.Value;
                npl = app.IterationsnumberSpinner.Value;
                
                data = app.ctnt;
                data = data';
                tempi = app.TemAcq;
                tempi = tempi';

%                 assignin("base", 'constant_vector', app.constant_vector)
%                 assignin("base", 'data', data)
%                 assignin("base", 'tempi', tempi)
%                 assignin("base", 'upperBound', upperBound)
%                 assignin("base", 'lowerBound', lowerBound)
%                 assignin("base", 'parameters', parameters)
%                 assignin("base", 'globalAlgorithm', globalAlgorithm)
%                 assignin("base", 'localfunction', localfunction)
%                 assignin("base", 'npl', npl)

                tic;
%                 delete(gcp('nocreate'));
%                 p = parpool('Processes',2);
%                 f = parfeval(p, @troponin_model, 3, data, tempi, @Obj, parameters, app.constant_vector, globalAlgorithm, localfunction, npl, lowerBound, upperBound);
%                 [T, X, params] = f.fetchOutputs;
                [T, X, params] = troponin_model(data, tempi, @Obj, parameters, app.constant_vector, globalAlgorithm, localfunction, npl, lowerBound, upperBound);
                timeVal = toc;
%                 assignin('base','T',T)
%                 assignin('base','X',X)
    
                params = 10.^(params);
%                 assignin("base", "params", params);
%                 disp(params)
                message = strcat({'Execution Time: '}, {num2str(timeVal/60)}, {' min.'});

                app.RunMessage.Value = message;
    
                [v_max, t_max] = min_max(X(:, 3), T);
                plotAnalysis(app, app.AnalysisAx, tempi, data, t_max, v_max, T, X(:,3))
                app.ResultsPanel.Visible = 'on';
                if app.livello == 1
                    app.PredictedValuesPanel.Visible = 'on';
                else
                    app.PredictedValuesPanel.Visible = 'off';
                end
                predictedOut = zeros(1,9); %1a 2b 5td 6cs 7cc
                index_constant_params = find(app.constant_vector ~=0); %y
                index_params = find(lowerBound ~=0); % 1 - 7 %x
                index_estimated = index_params(find(~ismember(index_params, index_constant_params)));
                predictedOut(index_constant_params) = app.constant_vector(index_constant_params);
                predictedOut(index_estimated) = params;
                app.ap_value.Text = string(predictedOut(1));
                app.bp_value.Text = string(predictedOut(2));
                app.Tdp_value.Text = string(predictedOut(5));
                app.Cs0p_value.Text = string(predictedOut(6));
                app.Cc0p_value.Text = string(predictedOut(7));
                app.FPV_value.Text = string(v_max(1));
                app.FPT_value.Text = string(t_max(1));
                app.SPV_value.Text = string(v_max(2));
                app.SPT_value.Text = string(t_max(2));
                app.Time_value.Text = strcat(string(timeVal/60), " min");
    
                parametersRun = struct('lower',lowerBound,'upper',upperBound,'initial',parameters,'global',globalAlgorithm,'local',localfunction,'iterations',npl,'fixed',app.constant_vector,'time',timeVal/60);
%                 disp(parametersRun)

%                 assignin("base", "parametersRun", parametersRun)
                items = app.SelectAnalysis.Items;
                itemsData = app.SelectAnalysis.ItemsData;

                app.ExecutionNumber = length(itemsData);
                
                if(isempty(items) && isempty(itemsData) || app.ExecutionNumber == 0)
                    itemsData = [itemsData 1];
                    items = [items "Execution 1"];
                    app.ExecutionNumber = 1;
%                     assignin("base", "X", X)
%                     assignin("base", "T", T)
                    Execution = struct('id',0,'a',predictedOut(1),'b',predictedOut(2),'Td',predictedOut(5),'Cs0',predictedOut(6),'Cc0',predictedOut(7),'FPV',v_max(1),'FPT',t_max(1),'SPV',v_max(2),'SPT',t_max(2), 'T', T, 'X', X, 'parametersRun', parametersRun);
                    if ~isempty(app.Executions)
                        app.Executions = [app.Executions Execution];
                    else
                        app.Executions = [Execution];
                    end
                else
                    app.ExecutionNumber = app.ExecutionNumber+1;
%                     itemsData(app.ExecutionNumber) = app.ExecutionNumber;
                    itemsData = [itemsData app.ExecutionNumber];
                    items = [items strcat("Execution ",string(app.ExecutionNumber))];
%                     assignin("base", "X", X)
%                     assignin("base", "T", T)
                    Execution = struct('id',0,'a',predictedOut(1),'b',predictedOut(2),'Td',predictedOut(5),'Cs0',predictedOut(6),'Cc0',predictedOut(7),'FPV',v_max(1),'FPT',t_max(1),'SPV',v_max(2),'SPT',t_max(2), 'T', T, 'X', X, 'parametersRun', parametersRun);
                    if ~isempty(app.Executions)
                        app.Executions = [app.Executions Execution];
                    else
                        app.Executions = [Execution];
                    end
                end
                app.SelectAnalysis.Items = items;
                app.SelectAnalysis.ItemsData = itemsData;
                app.SelectAnalysis.Value = app.ExecutionNumber;
                app.SelectAnalysisValueChanged;
            catch ME
                app.RunMessage.Value = "Execution error: retry with different settings.";
                disp("RunButtonPushedError")
                disp(ME)
%                 assignin("base", "error", ME)
                app.RunButton.Enable = 'on';
                app.AcquisitionsButtonFAn.Enable = 'on';
                app.HomeButtonFAn.Enable = 'on';
                app.HospitalizationButtonFAn.Enable = 'on';
                app.StopButton.Enable = 'off';
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "RunButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
%                 rethrow(ME)
            end
            app.RunButton.Enable = 'on';
            app.AcquisitionsButtonFAn.Enable = 'on';
            app.HomeButtonFAn.Enable = 'on';
            app.HospitalizationButtonFAn.Enable = 'on';
            app.StopButton.Enable = 'off';
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            disp('Stop button pressed')
            app.RunButton.Enable = 'on';
            app.AcquisitionsButtonFAn.Enable = 'on';
            app.HomeButtonFAn.Enable = 'on';
            app.HospitalizationButtonFAn.Enable = 'on';
%             quit force
        end

        % Button pushed function: RestoreButton
        function RestoreButtonPushed(app, event)
            try
                app.aLowerSpinner.Value = 0.001;
                app.aLowerSpinner.Limits = [0.001,4.5];
                app.bLowerSpinner.Value = 0.001;
                app.bLowerSpinner.Limits = [0.001,4.5];
                app.Cs0LowerSpinner.Value = 0.001;
                app.Cs0LowerSpinner.Limits = [0.001,180];
                app.Cc0LowerSpinner.Value = 0.1;
                app.Cc0LowerSpinner.Limits = [0.1,380];
                app.TdLowerSpinner.Value = 20;
                app.TdLowerSpinner.Limits = [20,280];
    
                app.aUpperSpinner.Value = 5;
                app.aUpperSpinner.Limits = [0.006,5];
                app.bUpperSpinner.Value = 5;
                app.bUpperSpinner.Limits = [0.006,5];
                app.Cs0UpperSpinner.Value = 200;
                app.Cs0UpperSpinner.Limits = [0.002,200];
                app.Cc0UpperSpinner.Value = 400;
                app.Cc0UpperSpinner.Limits = [0.199,400];
                app.TdUpperSpinner.Value = 300;
                app.TdUpperSpinner.Limits = [20.2,300];
    
                app.aInitialEditField.Value = 0.005;
                app.bInitialEditField.Value = 0.005;
                app.Cs0InitialEditField.Value = 0.1;
                app.Cc0InitialEditField.Value = 1;
                app.TdInitialEditField.Value = 30;
    
                app.IterationsnumberSpinner.Value = 25;
                app.OptimizationAlgorithmDropDown.Value = 'MultiStart';
                app.LocaloptimizationalgorithmDropDown.Value = 'fmincon';
    
                app.aEditField.Value = 0.005;
                app.bEditField.Value = 0.005;
                app.Cs0EditField.Value = 0.1;
                app.Cc0EditField.Value = 1;
                app.TdEditField.Value = 30;
            catch ME
                disp("RestoreButtonPushedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "RestoreButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
            end
        end

        % Value changing function: aLowerSpinner
        function aLowerSpinnerValueChanging(app, event)
            try
                current = app.aLowerSpinner.Value;
                newone = event.Value;
                diff_cn = (current - newone);
                if (current - newone) > 0
                    if (app.aUpperSpinner.Limits(1)-diff_cn) > 0
                        app.aUpperSpinner.Limits(1) = (app.aUpperSpinner.Limits(1)-diff_cn);
                    else
                        app.aUpperSpinner.Limits(1) = 0.006;
                    end
                else
                    app.aUpperSpinner.Limits(1) = (app.aUpperSpinner.Limits(1)+diff_cn);
                end
                app.aInitialEditField.Value = newone;
            catch ME
                disp("aLowerSpinnerValueChangingError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "aLowerSpinnerValueChanging", 'VariableNames', ["identifier" "line" "function_name"]));
            end
        end

        % Value changing function: bLowerSpinner
        function bLowerSpinnerValueChanging(app, event)
            try
                current = app.bLowerSpinner.Value;
                newone = event.Value;
                diff_cn = (current - newone);
                if (current - newone) > 0
                    if (app.bUpperSpinner.Limits(1)-diff_cn) > 0
                        app.bUpperSpinner.Limits(1) = (app.bUpperSpinner.Limits(1)-diff_cn);
                    else
                        app.bUpperSpinner.Limits(1) = 0.006;
                    end
                else
                    app.bUpperSpinner.Limits(1) = (app.bUpperSpinner.Limits(1)+diff_cn);
                end
                app.bInitialEditField.Value = newone;
            catch ME
                disp("bLowerSpinnerValueChangingError")
                diep(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "bLowerSpinnerValueChanging", 'VariableNames', ["identifier" "line" "function_name"]));
            end
        end

        % Value changing function: TdLowerSpinner
        function TdLowerSpinnerValueChanging(app, event)
            try
                current = app.TdLowerSpinner.Value;
                newone = event.Value;
                diff_cn = (current - newone);
                if (current - newone) > 0
                    if (app.TdUpperSpinner.Limits(1)-diff_cn) > 0
                        app.TdUpperSpinner.Limits(1) = (app.TdUpperSpinner.Limits(1)-diff_cn);
                    else
                        app.TdUpperSpinner.Limits(1) = 20.2;
                    end
                else
                    app.TdUpperSpinner.Limits(1) = (app.TdUpperSpinner.Limits(1)+diff_cn);
                end
                app.TdInitialEditField.Value = newone;
            catch ME
                disp("TdLowerSpinnerValueChangingError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "TdLowerSpinnerValueChanging", 'VariableNames', ["identifier" "line" "function_name"]));
            end
        end

        % Value changing function: Cs0LowerSpinner
        function Cs0LowerSpinnerValueChanging(app, event)
            try
                current = app.Cs0LowerSpinner.Value;
                newone = event.Value;
                diff_cn = (current - newone);
                if (current - newone) > 0
                    if (app.Cs0UpperSpinner.Limits(1)-diff_cn) > 0
                        app.Cs0UpperSpinner.Limits(1) = (app.Cs0UpperSpinner.Limits(1)-diff_cn);
                    else
                        app.Cs0UpperSpinner.Limits = 0.002;
                    end
                else
                    app.Cs0UpperSpinner.Limits = (app.Cs0UpperSpinner.Limits(1)+diff_cn);
                end
                app.Cs0InitialEditField.Value = newone;
            catch ME
                disp("Cs0LowerSpinnerValueChangingError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "Cs0LowerSpinnerValueChanging", 'VariableNames', ["identifier" "line" "function_name"]));
            end
        end

        % Value changing function: Cc0LowerSpinner
        function Cc0LowerSpinnerValueChanging(app, event)
            try
                current = app.Cc0LowerSpinner.Value;
                newone = event.Value;
                diff_cn = (current - newone);
                if (current - newone) > 0
                    if (app.Cc0UpperSpinner.Limits(1)-diff_cn) > 0
                        app.Cc0UpperSpinner.Limits(1) = (app.Cc0UpperSpinner.Limits(1)-diff_cn);
                    else
                        app.Cc0UpperSpinner.Limits(1) = 0.199;
                    end
                else
                    app.Cc0UpperSpinner.Limits(1) = (app.Cc0UpperSpinner.Limits(1)+diff_cn);
                end
                app.Cc0InitialEditField.Value = newone;
            catch ME
                disp("Cc0LowerSpinnerValueChangingError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "Cc0LowerSpinnerValueChanging", 'VariableNames', ["identifier" "line" "function_name"]));
            end
        end

        % Value changed function: SelectAnalysis
        function SelectAnalysisValueChanged(app, event)
            progbar =  uiprogressdlg(app.UIFigure,'Title','Loading selected analysis from database',...
                'Indeterminate','on');
            pause(.2)
            try
                value = app.SelectAnalysis.Value;
                Execution = app.Executions(value);
%                 if isa(app.Executions, 'struct')
%                     Execution = app.Executions;
%                 else
%                     Execution = app.Executions(1,value);
%                 end
%                 disp(Execution)

                lowerBound_r = zeros(1, 9, 'double');
                upperBound_r = zeros(1, 9, 'double');
                parameters_r = zeros(1, 9, 'double');
                constant_r = zeros(1, 9, 'double');

%%              Check if selected execution is saved by name
                execName = app.SelectAnalysis.Items{value};
                execName = split(execName);
                if length(execName) > 2
            
%%                  Get parameters data from selected execution
                    paramsLoadSQL = strcat("SELECT ap.* FROM analysis_params ap WHERE ap.id_result = ", string(Execution.id));
                    paramsLoad = select(app.conn, paramsLoadSQL);

                    app.aLowerSpinner.Value = paramsLoad.a_lb; %a
                    app.bLowerSpinner.Value = paramsLoad.b_lb; %b
                    app.TdLowerSpinner.Value = paramsLoad.td_lb; %Td
                    app.Cs0LowerSpinner.Value = paramsLoad.cs0_lb; %Cs0
                    app.Cc0LowerSpinner.Value = paramsLoad.cc0_lb; %Cc0
                
                    app.aUpperSpinner.Value = paramsLoad.a_ub;
                    app.bUpperSpinner.Value = paramsLoad.b_ub;
                    app.TdUpperSpinner.Value = paramsLoad.td_ub;
                    app.Cs0UpperSpinner.Value = paramsLoad.cs0_ub;
                    app.Cc0UpperSpinner.Value = paramsLoad.cc0_ub;
                
                    app.aInitialEditField.Value = paramsLoad.a_init;
                    app.bInitialEditField.Value = paramsLoad.b_init;
                    app.TdInitialEditField.Value = paramsLoad.td_init;
                    app.Cs0InitialEditField.Value = paramsLoad.cs0_init;
                    app.Cc0InitialEditField.Value = paramsLoad.cc0_init;

                    app.OptimizationAlgorithmDropDown.Value = paramsLoad.global_alg;
                    app.OptimizationAlgorithmDropDownValueChanged;
                    if ~isempty(paramsLoad.local_alg) && ~strcmp(paramsLoad.local_alg, "")
                        app.LocaloptimizationalgorithmDropDown.Value = paramsLoad.local_alg;
                    end
                    app.IterationsnumberSpinner.Value = double(paramsLoad.iterations);

                    if paramsLoad.a_fixed ~= 0
                        app.aCheckBox.Value = 1;
                        app.aCheckBoxValueChanged;
                        app.aEditField.Value = paramsLoad.a_fixed;
                    else
                        app.aCheckBox.Value = 0;
                        app.aCheckBoxValueChanged;
                    end
                    if paramsLoad.b_fixed ~= 0
                        app.bCheckBox.Value = 1;
                        app.bCheckBoxValueChanged;
                        app.bEditField.Value = paramsLoad.b_fixed;
                    else
                        app.bCheckBox.Value = 0;
                        app.bCheckBoxValueChanged;
                    end
                    if paramsLoad.td_fixed ~= 0
                        app.TdCheckBox.Value = 1;
                        app.TdCheckBoxValueChanged;
                        app.TdEditField.Value = paramsLoad.td_fixed;
                    else
                        app.TdCheckBox.Value = 0;
                        app.TdCheckBoxValueChanged;
                    end
                    if paramsLoad.cs0_fixed ~= 0
                        app.Cs0CheckBox.Value = 1;
                        app.Cs0CheckBoxValueChanged;
                        app.Cs0EditField.Value = paramsLoad.cs0_fixed;
                    else
                        app.Cs0CheckBox.Value = 0;
                        app.Cs0CheckBoxValueChanged;
                    end
                    if paramsLoad.cc0_fixed ~= 0
                        app.Cc0CheckBox.Value = 1;
                        app.Cc0CheckBoxValueChanged;
                        app.Cc0EditField.Value = paramsLoad.cc0_fixed;
                    else
                        app.Cc0CheckBox.Value = 0;
                        app.Cc0CheckBoxValueChanged;
                    end

%%                  Get curve data from selected execution

%                     curveLoadSQL = strcat("SELECT ac.* FROM analysis_curve ac WHERE ac.id_result = ", string(Execution.id));
%                     cruveLoad = select(app.conn, curveLoadSQL);
    
                    app.ap_value.Text = string(Execution.a); %1
                    app.bp_value.Text = string(Execution.b); %2
                    app.Tdp_value.Text = string(Execution.Td); %5
                    app.Cs0p_value.Text = string(Execution.Cs0); %6
                    app.Cc0p_value.Text = string(Execution.Cc0); %7
                    app.FPV_value.Text = string(Execution.FPV);
                    app.FPT_value.Text = string(Execution.FPT);
                    app.SPV_value.Text = string(Execution.SPV);
                    app.SPT_value.Text = string(Execution.SPT);
                    app.Time_value.Text = string(paramsLoad.time);
                
                    analysisAx = app.AnalysisAx;
                    cla(analysisAx);

                    tempo = app.TemAcq';
                    t_vec_stemi = linspace(0,tempo(end)+50,tempo(end)+51);

                    lowerBound_r(1) = paramsLoad.a_lb; %a %lower
                    lowerBound_r(2) = paramsLoad.b_lb; %b
                    lowerBound_r(5) = paramsLoad.td_lb; %Td
                    lowerBound_r(6) = paramsLoad.cs0_lb; %Cs0
                    lowerBound_r(7) = paramsLoad.cc0_lb; %Cc0
                
                    upperBound_r(1) = paramsLoad.a_ub; %upper
                    upperBound_r(2) = paramsLoad.b_ub;
                    upperBound_r(5) = paramsLoad.td_ub;
                    upperBound_r(6) = paramsLoad.cs0_ub;
                    upperBound_r(7) = paramsLoad.cc0_ub;
                
                    parameters_r(1) = paramsLoad.a_init; %initial
                    parameters_r(2) = paramsLoad.b_init;
                    parameters_r(5) = paramsLoad.td_init;
                    parameters_r(6) = paramsLoad.cs0_init;
                    parameters_r(7) = paramsLoad.cc0_init;

                    constant_r(1) = paramsLoad.a_fixed; %constant
                    constant_r(2) = paramsLoad.b_fixed;
                    constant_r(5) = paramsLoad.td_fixed;
                    constant_r(6) = paramsLoad.cs0_fixed;
                    constant_r(7) = paramsLoad.cc0_fixed;

                    index_params = [constant_r(1) constant_r(2) constant_r(5) constant_r(6) constant_r(7)];

                    params_r = [Execution.a Execution.b Execution.Td Execution.Cs0 Execution.Cc0];
                    params_r = params_r(index_params==0);
                    params_r = log10(params_r);
                    
                    [parameter_init, params_lb, params_ub, i] = initializationVector(parameters_r, constant_r, lowerBound_r, upperBound_r);

                    x0 = x0_found(params_r, constant_r, i);
                    [T_stemi, X_stemi] = ode23(@(t,x) odefun(t, x, params_r, constant_r), t_vec_stemi, x0);
    
                    plotAnalysis(app, analysisAx, app.TemAcq, app.ctnt, [Execution.FPT Execution.SPT], [Execution.FPV Execution.SPV], T_stemi, X_stemi(:,3))
                    app.SaveResultsButton.Enable = "off";

                else %% Not saved execution
                    app.aLowerSpinner.Value = Execution.parametersRun.lower(1); %a
                    app.bLowerSpinner.Value = Execution.parametersRun.lower(2); %b
                    app.TdLowerSpinner.Value = Execution.parametersRun.lower(5); %Td
                    app.Cs0LowerSpinner.Value = Execution.parametersRun.lower(6); %Cs0
                    app.Cc0LowerSpinner.Value = Execution.parametersRun.lower(7); %Cc0
                
                    app.aUpperSpinner.Value = Execution.parametersRun.upper(1);
                    app.bUpperSpinner.Value = Execution.parametersRun.upper(2);
                    app.TdUpperSpinner.Value = Execution.parametersRun.upper(5);
                    app.Cs0UpperSpinner.Value = Execution.parametersRun.upper(6);
                    app.Cc0UpperSpinner.Value = Execution.parametersRun.upper(7);
                
                    app.aInitialEditField.Value = Execution.parametersRun.initial(1);
                    app.bInitialEditField.Value = Execution.parametersRun.initial(2);
                    app.TdInitialEditField.Value = Execution.parametersRun.initial(5);
                    app.Cs0InitialEditField.Value = Execution.parametersRun.initial(6);
                    app.Cc0InitialEditField.Value = Execution.parametersRun.initial(7);

                    app.OptimizationAlgorithmDropDown.Value = Execution.parametersRun.global;
                    app.OptimizationAlgorithmDropDownValueChanged;
                    if ~isempty(Execution.parametersRun.local) && ~strcmp(Execution.parametersRun.local, "")
                        app.LocaloptimizationalgorithmDropDown.Value = Execution.parametersRun.local;
                    end
                    app.IterationsnumberSpinner.Value = double(Execution.parametersRun.iterations);

                    if Execution.parametersRun.fixed(1) ~= 0
                        app.aCheckBox.Value = 1;
                        app.aCheckBoxValueChanged;
                        app.aEditField.Value = Execution.parametersRun.fixed(1);
                    else
                        app.aCheckBox.Value = 0;
                        app.aCheckBoxValueChanged;
                    end
                    if Execution.parametersRun.fixed(2) ~= 0
                        app.bCheckBox.Value = 1;
                        app.bCheckBoxValueChanged;
                        app.bEditField.Value = Execution.parametersRun.fixed(2);
                    else
                        app.bCheckBox.Value = 0;
                        app.bCheckBoxValueChanged;
                    end
                    if Execution.parametersRun.fixed(5) ~= 0
                        app.TdCheckBox.Value = 1;
                        app.TdCheckBoxValueChanged;
                        app.TdEditField.Value = Execution.parametersRun.fixed(5);
                    else
                        app.TdCheckBox.Value = 0;
                        app.TdCheckBoxValueChanged;
                    end
                    if Execution.parametersRun.fixed(6) ~= 0
                        app.Cs0CheckBox.Value = 1;
                        app.Cs0CheckBoxValueChanged;
                        app.Cs0EditField.Value = Execution.parametersRun.fixed(6);
                    else
                        app.Cs0CheckBox.Value = 0;
                        app.Cs0CheckBoxValueChanged;
                    end
                    if Execution.parametersRun.fixed(7) ~= 0
                        app.Cc0CheckBox.Value = 1;
                        app.Cc0CheckBoxValueChanged;
                        app.Cc0EditField.Value = Execution.parametersRun.fixed(7);
                    else
                        app.Cc0CheckBox.Value = 0;
                        app.Cc0CheckBoxValueChanged;
                    end

                    app.ap_value.Text = string(Execution.a);
                    app.bp_value.Text = string(Execution.b);
                    app.Tdp_value.Text = string(Execution.Td);
                    app.Cs0p_value.Text = string(Execution.Cs0);
                    app.Cc0p_value.Text = string(Execution.Cc0);
                    app.FPV_value.Text = string(Execution.FPV);
                    app.FPT_value.Text = string(Execution.FPT);
                    app.SPV_value.Text = string(Execution.SPV);
                    app.SPT_value.Text = string(Execution.SPT);
                    app.Time_value.Text = string(Execution.parametersRun.time);

                    analysisAx = app.AnalysisAx;
                    cla(analysisAx);
    
                    plotAnalysis(app, analysisAx, app.TemAcq, app.ctnt, [Execution.FPT Execution.SPT], [Execution.FPV Execution.SPV], Execution.T, Execution.X(:,3))
                    app.SaveResultsButton.Enable = "on";
                end
%                 disp("analysis ready")
            catch ME
                disp("SelectAnalysisValueChangedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "SelectAnalysisValueChanged", 'VariableNames', ["identifier" "line" "function_name"]));
            end
            close(progbar)
        end

        % Value changed function: aCheckBox
        function aCheckBoxValueChanged(app, event)
            try
                a = app.aCheckBox.Value;
                if a == 1 
                    app.constant_vector(1,1) = app.aEditField.Value;
                else
                    app.constant_vector(1,1) = 0;
                end
%                 disp(app.constant_vector)
%                 disp(app.runValue)
                switch app.livello
                    case 1 %admin
                        if(app.nAcquisizioni >= 3 && app.nAcquisizioni < 5)
                            if a == 1 
                                app.runValue = app.runValue + 1;
                            else
                                app.runValue = app.runValue - 1;
                            end
                            if(app.runValue < 0)
                                app.Acquisitionnumberinfo.Value = strcat('Insufficient number of acquisitions, select constant parameters. Acquisitions :', string(app.nAcquisizioni));
                                app.Acquisitionnumberinfo.Visible = 'on';
                                app.RunButton.Enable = 'off';
                            else
                                app.Acquisitionnumberinfo.Value = '';
                                app.RunButton.Enable = 'on';
                            end
                        elseif app.nAcquisizioni >= 5
                            app.Acquisitionnumberinfo.Value = '';
                            app.RunButton.Enable = 'on';
                        else
                            app.Acquisitionnumberinfo.Value = strcat('Insufficient number of acquisitions. Insert more data to execute run.');
                            app.Acquisitionnumberinfo.Visible = 'on';
                            app.RunButton.Enable = 'off';
                        end
                    case 3 %clinic 
                        if(app.nAcquisizioni >= 3 && app.nAcquisizioni < 5)
                            if a == 1 
                                app.runValue = app.runValue + 1;
                            else
                                app.runValue = app.runValue - 1;
                            end
                            if(app.runValue < 0)
                                app.Acquisitionnumberinfo.Value = strcat('Insufficient number of acquisitions, select constant parameters. N:', string(app.nAcquisizioni));
                                app.Acquisitionnumberinfo.Visible = 'on';
                                app.RunButton.Enable = 'off';
                            else 
                                app.Acquisitionnumberinfo.Value = '';
                                app.RunButton.Enable = 'on';
                            end
                        elseif app.nAcquisizioni >= 5
                            app.Acquisitionnumberinfo.Value = '';
                            app.RunButton.Enable = 'on';
                        else
                            app.Acquisitionnumberinfo.Value = strcat('Insufficient number of acquisitions. Insert more data to execute run.');
                            app.Acquisitionnumberinfo.Visible = 'on';
                            app.RunButton.Enable = 'off';
                        end
                end
            catch ME
                disp("aCheckBoxValueChangedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "aCheckBoxValueChanged", 'VariableNames', ["identifier" "line" "function_name"]));
            end
        end

        % Value changed function: bCheckBox
        function bCheckBoxValueChanged(app, event)
            try
                b = app.bCheckBox.Value;
                if b == 1 
                    app.constant_vector(1,2) = app.bEditField.Value;
                else
                    app.constant_vector(1,2) = 0;
                end
%                 disp(app.constant_vector)
%                 disp(app.runValue)
    
                switch app.livello
                    case 1 %admin
                        if(app.nAcquisizioni >= 3 && app.nAcquisizioni < 5)
                            if b == 1 
                                app.runValue = app.runValue + 1;
                            else
                                app.runValue = app.runValue - 1;
                            end
                            if(app.runValue < 0)
                                app.Acquisitionnumberinfo.Value = strcat('Insufficient number of acquisitions, select constant parameters. Acquisitions :', string(app.nAcquisizioni));
                                app.Acquisitionnumberinfo.Visible = 'on';
                                app.RunButton.Enable = 'off';
                            else
                                app.Acquisitionnumberinfo.Value = '';
                                app.RunButton.Enable = 'on';
                            end
                        elseif app.nAcquisizioni >= 5
                            app.Acquisitionnumberinfo.Value = '';
                            app.RunButton.Enable = 'on';
                        else
                            app.Acquisitionnumberinfo.Value = strcat('Insufficient number of acquisitions. Insert more data to execute run.');
                            app.Acquisitionnumberinfo.Visible = 'on';
                            app.RunButton.Enable = 'off';
                        end
                    case 3 %clinic
                        if(app.nAcquisizioni >= 3 && app.nAcquisizioni < 5)
                            if b == 1 
                                app.runValue = app.runValue + 1;
                            else
                                app.runValue = app.runValue - 1;
                            end
                            if(app.runValue < 0)
                                app.Acquisitionnumberinfo.Value = strcat('Insufficient number of acquisitions, select constant parameters. N:', string(app.nAcquisizioni));
                                app.Acquisitionnumberinfo.Visible = 'on';
                                app.RunButton.Enable = 'off';
                            else 
                                app.Acquisitionnumberinfo.Value = '';
                                app.RunButton.Enable = 'on';
                            end
                        elseif app.nAcquisizioni >= 5
                            app.Acquisitionnumberinfo.Value = '';
                            app.RunButton.Enable = 'on';
                        else
                            app.Acquisitionnumberinfo.Value = strcat('Insufficient number of acquisitions. Insert more data to execute run.');
                            app.Acquisitionnumberinfo.Visible = 'on';
                            app.RunButton.Enable = 'off';
                        end
                end
            catch ME
                disp("bCheckBoxValueChangedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "bCheckBoxValueChanged", 'VariableNames', ["identifier" "line" "function_name"]));
            end
        end

        % Value changed function: TdCheckBox
        function TdCheckBoxValueChanged(app, event)
            try
                td = app.TdCheckBox.Value;
                if td == 1 
                    app.constant_vector(1,5) = app.TdEditField.Value;
                else
                    app.constant_vector(1,5) = 0;
                end
%                 disp(app.constant_vector)
%                 disp(app.runValue)
    
                switch app.livello
                    case 1 %admin
                        if(app.nAcquisizioni >= 3 && app.nAcquisizioni < 5)
                            if td == 1 
                                app.runValue = app.runValue + 1;
                            else
                                app.runValue = app.runValue - 1;
                            end
                            if(app.runValue < 0)
                                app.Acquisitionnumberinfo.Value = strcat('Insufficient number of acquisitions, select constant parameters. Acquisitions :', string(app.nAcquisizioni));
                                app.Acquisitionnumberinfo.Visible = 'on';
                                app.RunButton.Enable = 'off';
                            else
                                app.Acquisitionnumberinfo.Value = '';
                                app.RunButton.Enable = 'on';
                            end
                        elseif app.nAcquisizioni >= 5
                            app.Acquisitionnumberinfo.Value = '';
                            app.RunButton.Enable = 'on';
                        else
                            app.Acquisitionnumberinfo.Value = strcat('Insufficient number of acquisitions. Insert more data to execute run.');
                            app.Acquisitionnumberinfo.Visible = 'on';
                            app.RunButton.Enable = 'off';
                        end
                    case 3 %clinic 
                        if(app.nAcquisizioni >= 3 && app.nAcquisizioni < 5)
                            if(app.runValue < 0)
                                app.Acquisitionnumberinfo.Value = strcat('Insufficient number of acquisitions, select constant parameters. N:', string(app.nAcquisizioni));
                                app.Acquisitionnumberinfo.Visible = 'on';
                                app.RunButton.Enable = 'off';
                            else 
                                app.Acquisitionnumberinfo.Value = '';
                                app.RunButton.Enable = 'on';
                            end
                        elseif app.nAcquisizioni >= 5
                            app.Acquisitionnumberinfo.Value = '';
                            app.RunButton.Enable = 'on';
                        else
                            app.Acquisitionnumberinfo.Value = strcat('Insufficient number of acquisitions. Insert more data to execute run.');
                            app.Acquisitionnumberinfo.Visible = 'on';
                            app.RunButton.Enable = 'off';
                        end
                end
            catch ME
                disp("TdCheckBoxValueChangedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "TdCheckBoxValueChanged", 'VariableNames', ["identifier" "line" "function_name"]));
            end
        end

        % Value changed function: Cs0CheckBox
        function Cs0CheckBoxValueChanged(app, event)
            try
                cs0 = app.Cs0CheckBox.Value;
                if cs0 == 1 
                    app.constant_vector(1,6) = app.Cs0EditField.Value;
                else
                    app.constant_vector(1,6) = 0;
                end
%                 disp(app.constant_vector)
%                 disp(app.runValue)
    
                switch app.livello
                    case 1 %admin
                        if(app.nAcquisizioni >= 3 && app.nAcquisizioni < 5)
                            if cs0 == 1 
                                app.runValue = app.runValue + 1;
                            else
                                app.runValue = app.runValue - 1;
                            end
                            if(app.runValue < 0)
                                app.Acquisitionnumberinfo.Value = strcat('Insufficient number of acquisitions, select constant parameters. Acquisitions :', string(app.nAcquisizioni));
                                app.Acquisitionnumberinfo.Visible = 'on';
                                app.RunButton.Enable = 'off';
                            else
                                app.Acquisitionnumberinfo.Value = '';
                                app.RunButton.Enable = 'on';
                            end
                        elseif app.nAcquisizioni >= 5
                            app.Acquisitionnumberinfo.Value = '';
                            app.RunButton.Enable = 'on';
                        else
                            app.Acquisitionnumberinfo.Value = strcat('Insufficient number of acquisitions. Insert more data to execute run.');
                            app.Acquisitionnumberinfo.Visible = 'on';
                            app.RunButton.Enable = 'off';
                        end
                    case 3 %clinic 
                        if(app.nAcquisizioni >= 3 && app.nAcquisizioni < 5)
                            if cs0 == 1 
                                app.runValue = app.runValue + 1;
                            else
                                app.runValue = app.runValue - 1;
                            end
                            if(app.runValue < 0)
                                app.Acquisitionnumberinfo.Value = strcat('Insufficient number of acquisitions, select constant parameters. N:', string(app.nAcquisizioni));
                                app.Acquisitionnumberinfo.Visible = 'on';
                                app.RunButton.Enable = 'off';
                            else 
                                app.Acquisitionnumberinfo.Value = '';
                                app.RunButton.Enable = 'on';
                            end
                        elseif app.nAcquisizioni >= 5
                            app.Acquisitionnumberinfo.Value = '';
                            app.RunButton.Enable = 'on';
                        else
                            app.Acquisitionnumberinfo.Value = strcat('Insufficient number of acquisitions. Insert more data to execute run.');
                            app.Acquisitionnumberinfo.Visible = 'on';
                            app.RunButton.Enable = 'off';
                        end
                end
            catch ME
                disp("Cs0CheckBoxValueChangedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "Cs0CheckBoxValueChanged", 'VariableNames', ["identifier" "line" "function_name"]));
            end
        end

        % Value changed function: Cc0CheckBox
        function Cc0CheckBoxValueChanged(app, event)
            try
                cc0 = app.Cc0CheckBox.Value;
                if cc0 == 1 
                    app.constant_vector(1,7) = app.Cc0EditField.Value;
                else
                    app.constant_vector(1,7) = 0;
                end
%                 disp(app.constant_vector)
%                 disp(app.runValue)
    
                switch app.livello
                    case 1 %admin
                        if(app.nAcquisizioni >= 3 && app.nAcquisizioni < 5)
                            if cc0 == 1 
                                app.runValue = app.runValue + 1;
                            else
                                app.runValue = app.runValue - 1;
                            end
                            if(app.runValue < 0)
                                app.Acquisitionnumberinfo.Value = strcat('Insufficient number of acquisitions, select constant parameters. Acquisitions :', string(app.nAcquisizioni));
                                app.Acquisitionnumberinfo.Visible = 'on';
                                app.RunButton.Enable = 'off';
                            else
                                app.Acquisitionnumberinfo.Value = '';
                                app.RunButton.Enable = 'on';
                            end
                        elseif app.nAcquisizioni >= 5
                            app.Acquisitionnumberinfo.Value = '';
                            app.RunButton.Enable = 'on';
                        else
                            app.Acquisitionnumberinfo.Value = strcat('Insufficient number of acquisitions. Insert more data to execute run.');
                            app.Acquisitionnumberinfo.Visible = 'on';
                            app.RunButton.Enable = 'off';
                        end
                    case 3 %clinic 
                        if(app.nAcquisizioni >= 3 && app.nAcquisizioni < 5)
                            if cc0 == 1 
                                app.runValue = app.runValue + 1;
                            else
                                app.runValue = app.runValue - 1;
                            end
                            if(app.runValue < 0)
                                app.Acquisitionnumberinfo.Value = strcat('Insufficient number of acquisitions, select constant parameters. N:', string(app.nAcquisizioni));
                                app.Acquisitionnumberinfo.Visible = 'on';
                                app.RunButton.Enable = 'off';
                            else 
                                app.Acquisitionnumberinfo.Value = '';
                                app.RunButton.Enable = 'on';
                            end
                        elseif app.nAcquisizioni >= 5
                            app.Acquisitionnumberinfo.Value = '';
                            app.RunButton.Enable = 'on';
                        else
                            app.Acquisitionnumberinfo.Value = strcat('Insufficient number of acquisitions. Insert more data to execute run.');
                            app.Acquisitionnumberinfo.Visible = 'on';
                            app.RunButton.Enable = 'off';
                        end
                end
            catch ME
                disp("Cc0CheckBoxValueChangedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "Cc0CheckBoxValueChanged", 'VariableNames', ["identifier" "line" "function_name"]));
            end
        end

        % Button pushed function: CalculateTIMIButtonFH
        function CalculateTIMIButtonFHPushed(app, event)
            %CalculateTIMI index hospitalization STEMI/NSTEMI
            try
                app.HospitalizationSavingStatus_1.Color = 'g';
                app.HospitalizationSavingStatus_1.Visible = 'off';
                app.HospitalizationText_1.Value = '';
                app.HospitalizationText_1.Visible = 'off';

                app.EtaEditField_1.BackgroundColor = [1 1 1];
                app.WeightEditField_1.BackgroundColor = [1 1 1];
                app.SystolicBPmmHgEditField_1.BackgroundColor = [1 1 1];
                app.HRbpmEditField_1.BackgroundColor = [1 1 1];
                app.TimetotreatmhoursEditField_1.BackgroundColor = [1 1 1];
                app.DiabeteDropDown_1.BackgroundColor = [0.96,0.96,0.96];
                app.HypertensDropDown_1.BackgroundColor = [0.96,0.96,0.96];
                app.Anginaepisodesin24hEditField_1.BackgroundColor = [1 1 1];
                app.KillipDropDown_1.BackgroundColor = [0.96,0.96,0.96];
                app.STEMIDropDown_1.BackgroundColor = [0.96,0.96,0.96];
                app.LBBBDropDown_1.BackgroundColor = [0.96,0.96,0.96];

                app.PositivecardiacmarkerDropDown_1.BackgroundColor = [0.96,0.96,0.96];
                app.PrevCADDropDown_1.BackgroundColor = [0.96,0.96,0.96];
                app.SmokeDropDown_1.BackgroundColor = [0.96,0.96,0.96];
                app.HypercolDropDown_1.BackgroundColor = [0.96,0.96,0.96];
                app.FamiliarityDropDown_1.BackgroundColor = [0.96,0.96,0.96];
                app.ASAusein7daysDropDown_1.BackgroundColor = [0.96,0.96,0.96];
                app.EKGSTdevmmEditField_1.BackgroundColor = [1 1 1];
                app.StenosisEditField_1.BackgroundColor = [1 1 1];
                
                eta = app.EtaEditField_1.Value;
                diabete = app.DiabeteDropDown_1.Items(app.DiabeteDropDown_1.Value);
                angina = app.Anginaepisodesin24hEditField_1.Value;
                hyperTens = app.HypertensDropDown_1.Items(app.HypertensDropDown_1.Value);
                hr = app.HRbpmEditField_1.Value;
                systolicBP = app.SystolicBPmmHgEditField_1.Value;
                killip = app.KillipDropDown_1.Items(app.KillipDropDown_1.Value);
                STEMI = app.STEMIDropDown_1.Items(app.STEMIDropDown_1.Value);
                lbbb = app.LBBBDropDown_1.Items(app.LBBBDropDown_1.Value);
                weight = app.WeightEditField_1.Value;
                timetotreatm = app.TimetotreatmhoursEditField_1.Value;

                hypercholesterolemia = app.HypercolDropDown_1.Items(app.HypercolDropDown_1.Value);
                familiarity = app.FamiliarityDropDown_1.Items(app.FamiliarityDropDown_1.Value);
                smoke = app.SmokeDropDown_1.Items(app.SmokeDropDown_1.Value);
                stenosis = app.StenosisEditField_1.Value;
                asa_usage_7d = app.ASAusein7daysDropDown_1.Items(app.ASAusein7daysDropDown_1.Value);
                st_deviation = app.EKGSTdevmmEditField_1.Value;
                cardiac_marker = app.PositivecardiacmarkerDropDown_1.Items(app.PositivecardiacmarkerDropDown_1.Value);

                if strcmp(STEMI, "Yes")
                    components = [app.EtaEditField_1, app.DiabeteDropDown_1, app.Anginaepisodesin24hEditField_1, app.HypertensDropDown_1, app.HRbpmEditField_1, app.SystolicBPmmHgEditField_1, ...
                                app.KillipDropDown_1, app.STEMIDropDown_1, app.LBBBDropDown_1, app.WeightEditField_1, app.TimetotreatmhoursEditField_1];
                elseif strcmp(STEMI, "No")
                    components = [app.EtaEditField_1, app.HypertensDropDown_1, app.HypercolDropDown_1, app.DiabeteDropDown_1, app.FamiliarityDropDown_1, app.SmokeDropDown_1, app.Anginaepisodesin24hEditField_1, app.StenosisEditField_1, app.ASAusein7daysDropDown_1, ...
                        app.EKGSTdevmmEditField_1, app.PositivecardiacmarkerDropDown_1];
                end

                check = nullCheck(app,[eta, diabete, angina, hyperTens, hr, systolicBP, killip, STEMI, lbbb, weight, timetotreatm, hypercholesterolemia, familiarity, smoke, stenosis, asa_usage_7d, st_deviation, cardiac_marker], false);
                
                if check
                    [t, m, c, i_void] = calculateTIMI(app, eta, diabete, angina, hyperTens, hr, systolicBP, killip, STEMI, lbbb, weight, timetotreatm, hypercholesterolemia, familiarity, smoke, stenosis, asa_usage_7d, st_deviation, cardiac_marker);
                    for i = 1:length(i_void)
                        if i_void(i) > 0 && c == false
                            components(i_void(i)).BackgroundColor = "y";
                            app.HospitalizationSavingStatus_1.Color = 'y';
                        else
                            for j = 1:length(components)
                                if isa(class(components(j)), 'matlab.ui.control.DropDown')
                                    components(j).BackgroundColor = [0.96,0.96,0.96];
                                elseif isa(class(components(j)), 'matlab.ui.control.NumericEditField')
                                    components(j).BackgroundColor = [1 1 1];
                                end
                            end
                            
                        end
                    end
                    app.TimiEditField_1.Value = t;
                else
                    m = "Impossible to calculate, check null values";
                    app.HospitalizationSavingStatus_1.Color = 'y';
                end
                app.HospitalizationText_1.Value = m;
                app.HospitalizationText_1.Visible = 'on';
                app.HospitalizationSavingStatus_1.Visible = 'on';
%                 if c == false
%                     app.HospitalizationSavingStatus_1.Color = 'r';
%                 end
             catch ME
                 disp("CalculateTIMIButtonFHPushedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "CalculateTIMIButtonFHPushed", 'VariableNames', ["identifier" "line" "function_name"]));
%                 rethrow(ME)
            end
        end

        % Button pushed function: CalculateTIMIButton_2
        function CalculateTIMIButton_2Pushed(app, event)
            progbar =  uiprogressdlg(app.UIFigure,'Title','Calculating TIMI',...
                'Indeterminate','on');
            drawnow
            try
                app.HospitalizationSavingStatus_2.Color = 'g';
                app.HospitalizationSavingStatus_2.Visible = 'off';
                app.HospitalizationText_2.Value = '';
                app.HospitalizationText_2.Visible = 'off';

                app.EtaEditField_2.BackgroundColor = [1 1 1];
                app.WeightEditField_2.BackgroundColor = [1 1 1];
                app.SystolicBPmmHgEditField_2.BackgroundColor = [1 1 1];
                app.HRbpmEditField_2.BackgroundColor = [1 1 1];
                app.TimetotreatmhoursEditField_2.BackgroundColor = [1 1 1];
                app.DiabeteDropDown_2.BackgroundColor = [0.96,0.96,0.96];
                app.HypertensDropDown_2.BackgroundColor = [0.96,0.96,0.96];
                app.Anginaepisodesin24hEditField_2.BackgroundColor = [1 1 1];
                app.KillipDropDown_2.BackgroundColor = [0.96,0.96,0.96];
                app.STEMIDropDown_2.BackgroundColor = [0.96,0.96,0.96];
                app.LBBBDropDown_2.BackgroundColor = [0.96,0.96,0.96];

                app.PositivecardiacmarkerDropDown_2.BackgroundColor = [0.96,0.96,0.96];
                app.PrevCADDropDown_2.BackgroundColor = [0.96,0.96,0.96];
                app.SmokeDropDown_2.BackgroundColor = [0.96,0.96,0.96];
                app.HypercolDropDown_2.BackgroundColor = [0.96,0.96,0.96];
                app.FamiliarityDropDown_2.BackgroundColor = [0.96,0.96,0.96];
                app.ASAusein7daysDropDown_2.BackgroundColor = [0.96,0.96,0.96];
                app.EKGSTdevmmEditField_2.BackgroundColor = [1 1 1];
                app.StenosisEditField_2.BackgroundColor = [1 1 1];

                eta = app.EtaEditField_2.Value;
                diabete = app.DiabeteDropDown_2.Items(app.DiabeteDropDown_2.Value);
                angina = app.Anginaepisodesin24hEditField_2.Value;
                hyperTens = app.HypertensDropDown_2.Items(app.HypertensDropDown_2.Value);
                hr = app.HRbpmEditField_2.Value;
                systolicBP = app.SystolicBPmmHgEditField_2.Value;
                killip = app.KillipDropDown_2.Items(app.KillipDropDown_2.Value);
                STEMI = app.STEMIDropDown_2.Items(app.STEMIDropDown_2.Value);
                lbbb = app.LBBBDropDown_2.Items(app.LBBBDropDown_2.Value);
                weight = app.WeightEditField_2.Value;
                timetotreatm = app.TimetotreatmhoursEditField_2.Value;

                hypercholesterolemia = app.HypercolDropDown_2.Items(app.HypercolDropDown_2.Value);
                familiarity = app.FamiliarityDropDown_2.Items(app.FamiliarityDropDown_2.Value);
                smoke = app.SmokeDropDown_2.Items(app.SmokeDropDown_2.Value);
                stenosis = app.StenosisEditField_2.Value;
                asa_usage_7d = app.ASAusein7daysDropDown_2.Items(app.ASAusein7daysDropDown_2.Value);
                st_deviation = app.EKGSTdevmmEditField_2.Value;
                cardiac_marker = app.PositivecardiacmarkerDropDown_2.Items(app.PositivecardiacmarkerDropDown_2.Value);

                if strcmp(STEMI, "Yes")
                    components = [app.EtaEditField_2, app.DiabeteDropDown_2, app.Anginaepisodesin24hEditField_2, app.HypertensDropDown_2, app.HRbpmEditField_2, app.SystolicBPmmHgEditField_2, ...
                                app.KillipDropDown_2, app.STEMIDropDown_2, app.LBBBDropDown_2, app.WeightEditField_2, app.TimetotreatmhoursEditField_2];
                elseif strcmp(STEMI, "No")
                    components = [app.EtaEditField_2, app.HypertensDropDown_2, app.HypercolDropDown_2, app.DiabeteDropDown_2, app.FamiliarityDropDown_2, app.SmokeDropDown_2, app.Anginaepisodesin24hEditField_2, app.StenosisEditField_2, app.ASAusein7daysDropDown_2, ...
                        app.EKGSTdevmmEditField_2, app.PositivecardiacmarkerDropDown_2];
                end

                check = nullCheck(app,[eta, diabete, angina, hyperTens, hr, systolicBP, killip, STEMI, lbbb, weight, timetotreatm, hypercholesterolemia, familiarity, smoke, stenosis, asa_usage_7d, st_deviation, cardiac_marker], false);
                
                if check
                    [t, m, c, i_void] = calculateTIMI(app, eta, diabete, angina, hyperTens, hr, systolicBP, killip, STEMI, lbbb, weight, timetotreatm, hypercholesterolemia, familiarity, smoke, stenosis, asa_usage_7d, st_deviation, cardiac_marker);
                    for i = 1:length(i_void)
                        if i_void(i) > 0 && c == false
                            components(i_void(i)).BackgroundColor = "y";
                            app.HospitalizationSavingStatus_2.Color = 'y';
                        else
                            for j = 1:length(components)
                                if isa(class(components(j)), 'matlab.ui.control.DropDown')
                                    components(j).BackgroundColor = [0.96,0.96,0.96];
                                elseif isa(class(components(j)), 'matlab.ui.control.NumericEditField')
                                    components(j).BackgroundColor = [1 1 1];
                                end
                            end
                        end
                    end
                    app.TimiEditField_2.Value = t;
                else
                    m = "Impossible to calculate, check null value";
                    app.HospitalizationSavingStatus_2.Color = 'r';
                end
                app.HospitalizationSavingStatus_2.Visible = 'on';
                app.HospitalizationText_2.Value = m;
                app.HospitalizationText_2.Visible = 'on';
            catch ME
                disp("CalculateTIMIButton_2PushedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "CalculateTIMIButton_2Pushed", 'VariableNames', ["identifier" "line" "function_name"]));
%                 rethrow(ME)
            end
            close(progbar)
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            progbar =  uiprogressdlg(app.UIFigure,'Title','Saving results',...
                'Indeterminate','on');
            app.SavingResultsStatus.Visible = 'on';
            app.SavingResultsStatus.Color = 'y';
            pause(.2);
            tic
            try
%%              Saving analysis values                
                tablename = 'analysis_result';
                colnames = {'dataAnalisi', 'oraAnalisi', 'a', 'b', 'c', 'd', 'Td', 'Cs0', 'Cc0', 'Kc0', 't', 'FPV', 'FPT', 'SPV', 'SPT', 'id_hospedalization'};
                d = split(cellstr(datetime('now')));
%                 assignin("base", 'dateTime', d)
                dataanalisi = ComputeData(d{1}, 'en');
                oraanalisi = d{2};
                value = app.SelectAnalysis.Value;
                execution = app.Executions(value);
%                 assignin("base", 'execution', execution);
                data = table(string(dataanalisi), string(oraanalisi), execution.a, execution.b, 0, 0, execution.Td, execution.Cs0, execution.Cc0, 0, 0, execution.FPV, execution.FPT, execution.SPV, execution.SPT, app.selectedHosp.id, 'VariableNames', colnames);
%                 disp(data)
%                 assignin("base","table", data);
                sqlwrite(app.conn, tablename, data);
                loginEvalSql = strcat("SELECT EVENT_ID, TRUNCATE(TIMER_WAIT/1000000000000,6) as Duration ", ...
                    "FROM performance_schema.events_statements_history AS queryLog ", ...
                    "WHERE queryLog.CURRENT_SCHEMA LIKE 'cbra_new' AND queryLog.SQL_TEXT LIKE 'INSERT INTO analysis_result % VALUES %", string(app.selectedHosp.id), "%'");
                loginEvaluation = select(app.conn, loginEvalSql);
                evalTime = loginEvaluation.Duration(1);
                
                lastAnalysisSQL = strcat("SELECT ar.id FROM analysis_result ar WHERE ar.id_hospedalization = ", string(app.selectedHosp.id), " ORDER by id DESC LIMIT 1");
                lastAnalysis = select(app.conn, lastAnalysisSQL);
% %%              Saving curve
% %                 assignin("base", "X", execution.X)
% %                 x1 = num2str(execution.X(:,1)');
% %                 x2 = num2str(execution.X(:,2)');
% %                 x3 = num2str(execution.X(:,3)');
% %                 t = num2str(execution.T');
%                 x1 = execution.X(:,1);
%                 x2 = execution.X(:,2);
%                 x3 = execution.X(:,3);
%                 t = execution.T;
%                 curve = table(x1, x2, x3, t);
%                 curve.id_result(:) = lastAnalysis.id;
% %                 disp(curve)
%                 sqlwrite(app.conn, "analysis_curve", curve);

%%              Saving parameters 1-a 2-b 5-Td 6-Cs0 7-Cc0
%                 disp(app.parametersRun)
                a_lb = execution.parametersRun.lower(1);
                b_lb = execution.parametersRun.lower(2);
                td_lb = execution.parametersRun.lower(5);
                cs0_lb  = execution.parametersRun.lower(6);
                cc0_lb  = execution.parametersRun.lower(7);

                a_ub = execution.parametersRun.upper(1);
                b_ub = execution.parametersRun.upper(2);
                td_ub = execution.parametersRun.upper(5);
                cs0_ub = execution.parametersRun.upper(6);
                cc0_ub = execution.parametersRun.upper(7);

                a_init = execution.parametersRun.initial(1);
                b_init = execution.parametersRun.initial(2);
                td_init = execution.parametersRun.initial(5);
                cs0_init = execution.parametersRun.initial(6);
                cc0_init = execution.parametersRun.initial(7);

                iterations = execution.parametersRun.iterations;

%                 fixed_params = '';
%                 for i=1:length(app.parametersRun.fixed)
%                     if app.parametersRun.fixed(i) ~= 0
%                         fixed_params = strcat(fixed_params, "/", app.fixed_labels(i));
%                     end
%                 end
%                 disp(fixed_params)
%                 disp(class(fixed_params))

                a_fixed = execution.parametersRun.fixed(1);
                b_fixed = execution.parametersRun.fixed(2);
                td_fixed = execution.parametersRun.fixed(5);
                cs0_fixed = execution.parametersRun.fixed(6);
                cc0_fixed = execution.parametersRun.fixed(7);

                global_alg = string(execution.parametersRun.global);
                local_alg = string(execution.parametersRun.local);
                time = execution.parametersRun.time;

                paramTab = table(a_lb,b_lb,td_lb,cs0_lb,cc0_lb, ...
                    a_ub,b_ub,td_ub,cs0_ub,cc0_ub,a_init,b_init,td_init,cs0_init,cc0_init, ...
                    iterations,a_fixed,b_fixed,td_fixed,cs0_fixed,cc0_fixed,global_alg,local_alg,time);
                paramTab.id_result(:) = lastAnalysis.id;

%                 disp(paramTab)

                sqlwrite(app.conn, "analysis_params", paramTab);

                savedAnalysisSQL = strcat("SELECT ar.* FROM analysis_result ar WHERE ar.id =  ", string(lastAnalysis.id));
                savedAnalysis = select(app.conn, savedAnalysisSQL);
                result = savedAnalysis(1,4:end-5);
                result = result{1,:};
                app.Executions(value) = struct('id',savedAnalysis.id,'a',result(1),'b',result(2),'Td',result(5),'Cs0',result(6),'Cc0',result(7),'FPV',result(10),'FPT',result(11),'SPV',result(12),'SPT',result(13),'X',0,'T',0,'parametersRun',0);

                dataAnalisiStr = ComputeData(datetime(savedAnalysis.dataAnalisi(1)), 'it');
                oraAnalisiStr = savedAnalysis.oraAnalisi{1};
                oraAnalisiStr = oraAnalisiStr(1:5);
                app.SelectAnalysis.Items(value) = {char(strcat("Saved execution ", dataAnalisiStr, " ", oraAnalisiStr))};
%                 if length(app.Executions) == value
%                     app.HospitalizationsDropValueChanged;
%                 else
%                     exec_old = app.Executions(value+1, end);
%                     items_old = app.SelectAnalysis.Items(value+1, end);
%                     itemsData_old = app.SelectAnalysis.ItemsData(value+1, end);
%                     app.HospitalizationsDropValueChanged;
%                     app.SelectAnalysis.ItemsData = [app.SelectAnalysis.ItemsData itemsData_old];
%                     app.SelectAnalysis.Items = [app.SelectAnalysis.Items items_old];
%                     app.Executions = [app.Executions exec_old];
%                     app.ExecutionNumber = app.ExecutionNumber + length(itemsData_old);
%                     app.SelectAnalysis.Value = value;
%                 end
                app.SelectAnalysisValueChanged;
                executionTime = toc;
                update(app.conn, 'analysis_result', {'evaluation_time' 'execution_time'}, table(evalTime, executionTime, 'VariableNames', {'evaluation_time' 'execution_time'}), strcat("WHERE id_hospedalization = ",string(app.selectedHosp.id)," ORDER BY id DESC LIMIT 1"));
                close(progbar)
                app.SavingResultsStatus.Color = 'g';
                pause(.2)
            catch ME
                disp("SaveResultsButtonPushedError")
                app.SavingResultsStatus.Color = 'r';
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "SaveResultsButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
%                 rethrow(ME)
            end
            pause(.2)
            app.SavingResultsStatus.Visible = 'off';
        end

        % Button pushed function: AnnullaButton
        function AnnullaButtonPushed(app, event)
            %Cancel button manage users ADMIN
            try
                app.UtenteSavingStatus.Visible = 'off';
                app.UtenteSavingStatus.Color = 'g';
                app.UtenteSaveUserMsg.Value = '';
                app.UtenteSaveUserMsg.Visible = "off";
                app.NomeEditField.BackgroundColor = [1 1 1];
                app.CognomeEditField.BackgroundColor = [1 1 1];
                app.CodiceFiscaleEditField.BackgroundColor = [1 1 1];
                app.UsernameEditField.BackgroundColor = [1 1 1];
                app.PasswordEditField.BackgroundColor = [1 1 1];
                app.EmailEditField.BackgroundColor = [1 1 1];
    
                app.NomeEditField.Value = '';
                app.CognomeEditField.Value = '';
                app.UsernameEditField.Value = '';
                app.PasswordEditField.Value = '';
                app.EmailEditField.Value = '';
                app.CodiceFiscaleEditField.Value = '';
                elements = app.CenterUserDropDown.ItemsData;
                if ~isempty(elements)
                    app.CenterUserDropDown.Value = elements(1);
                end
                app.LivelloDropDown.Value = 1;
            catch ME
                disp("AnnullaButtonPushedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "AnnullaButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
            end
        end

        % Button pushed function: AnnullaCentroButton
        function AnnullaCentroButtonPushed(app, event)
            %Cancel button manage centers ADMIN
            try
                app.CentroSavingStatus.Visible = 'off';
                app.CentroSavingStatus.Color = 'g';
                app.CentroSaveUserMsg.Value = '';
                app.CentroSaveUserMsg.Visible = "off";
                app.NomeCentroEditField.BackgroundColor = [1 1 1];
                app.IndirizzoEditField.BackgroundColor = [1 1 1];
                app.LocationCityEditField.BackgroundColor = [1 1 1];
    
                app.NomeCentroEditField.Value = '';
                app.IndirizzoEditField.Value = '';
                app.LocationCityEditField.Value = '';
                elements = app.CenteradministratorDropDown.ItemsData;
                if ~isempty(elements)
                    app.CenteradministratorDropDown.Value = elements(1);
                end
                app.NumeroMediciEditField.Value = 0;
                app.NumeroPazientiEditField.Value = 0;
            catch ME
                disp("AnnullaCentroButtonPushedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "AnnullaCentroButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %Reset button insert patient CLINIC            
            try
                app.PersonalDataSavingStatus.Color = 'g';
                app.PersonalDataSavingStatus.Visible = 'off';
                app.DataMessageText.Visible = 'off';
                app.NameEditField.BackgroundColor = [1 1 1];
                app.SurnameEditField.BackgroundColor = [1 1 1];
                app.BirthPlaceEditField.BackgroundColor = [1 1 1];
                app.BirthDateEditField.BackgroundColor = [1 1 1];
                app.AddressEditField.BackgroundColor = [1 1 1];
                app.DataMessageText.Value = '';
    
                app.NameEditField.Value = '';
                app.SurnameEditField.Value = '';
                app.BirthPlaceEditField.Value = '';
                app.BirthDateEditField.Value = NaT;
                app.AddressEditField.Value = '';
                app.SSNEditField.Value = '';
                app.SSNEditField.Visible = 'off';
                app.PatientIDFCLabel_1.Visible = 'off';
            catch ME
                disp("ResetButtonPushedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "ResetButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
            end
        end

        % Button pushed function: ResetButtonFH_1
        function ResetButtonFH_1Pushed(app, event)
            %Reset button fisrt hospitalization CLINIC
            try
                app.HospitalizationSavingStatus_1.Color = 'g';
                app.HospitalizationSavingStatus_1.Visible = 'off';
                app.HospitalizationText_1.Value = "";
                app.HospitalizationText_1.Visible = 'off';
                app.DateandtimehospitalizationPanel_1.BackgroundColor = [1 1 1];
                app.DateandtimeAMIPanel_1.BackgroundColor = [1 1 1];
                app.EtaEditField_1.BackgroundColor = [1 1 1];
                app.WeightEditField_1.BackgroundColor = [1 1 1];
                app.SystolicBPmmHgEditField_1.BackgroundColor = [1 1 1];
                app.HRbpmEditField_1.BackgroundColor = [1 1 1];
                app.TimetotreatmhoursEditField_1.BackgroundColor = [1 1 1];
                app.DiabeteDropDown_1.BackgroundColor = [0.96,0.96,0.96];
                app.HypertensDropDown_1.BackgroundColor = [0.96,0.96,0.96];
                app.Anginaepisodesin24hEditField_1.BackgroundColor = [1 1 1];
                app.KillipDropDown_1.BackgroundColor = [0.96,0.96,0.96];
                app.STEMIDropDown_1.BackgroundColor = [0.96,0.96,0.96];
                app.LBBBDropDown_1.BackgroundColor = [0.96,0.96,0.96];
                app.SmokeDropDown_1.BackgroundColor = [0.96,0.96,0.96];
                app.FamiliarityDropDown_1.BackgroundColor = [0.96,0.96,0.96];
    
                app.StenosisEditField_1.BackgroundColor = [1 1 1];
                app.PositivecardiacmarkerDropDown_1.BackgroundColor = [0.96,0.96,0.96];
                app.HypercolDropDown_1.BackgroundColor = [0.96,0.96,0.96];
                app.EKGSTdevmmEditField_1.BackgroundColor = [1 1 1];
                
                app.HDateH_1.Value = NaT;
                app.HhourH_1.Value = 0;
                app.HminuteH_1.Value = 0;
                app.HsecondH_1.Value = 0;
                app.DateEvent_1.Value = NaT;
                app.AMIhourEv_1.Value = 0;
                app.AMIminuteEv_1.Value = 0;
                app.AMIsecondEv_1.Value = 0;
                app.DateTromb_1.Value = NaT;
                app.TrombHour_1.Value = 0;
                app.Trombminute_1.Value = 0;
                app.Trombsecond_1.Value = 0;
                app.DrugEditField_1.Value = '';
                app.DateRev.Value = NaT;
                app.Revhour_1.Value = 0;
                app.Revminute_1.Value = 0;
                app.Revsecond_1.Value = 0;
                app.LocoEditField_1.Value = '';
                app.stMedCoDate_1.Value = NaT;
    %             app.EtaEditField_1.Value = 0;
                app.WeightEditField_1.Value = 0;
                app.SystolicBPmmHgEditField_1.Value = 0;
                app.DyastolicBPmmHgEditField_1.Value = 0;
                app.HRbpmEditField_1.Value = 0;
                app.TimetotreatmhoursEditField_1.Value = 0;
                app.DiabeteDropDown_1.Value = 3;
                app.HypertensDropDown_1.Value = 3;
                app.Anginaepisodesin24hEditField_1.Value = 0;
                app.KillipDropDown_1.Value = 1;
                app.STEMIDropDown_1.Value = 3;
                app.STEMIDropDown_1ValueChanged;
                app.LBBBDropDown_1.Value = 3;
                app.SmokeDropDown_1.Value = 3;
                app.ObesityDropDown_1.Value = 3;
                app.PrevCADDropDown_1.Value = 3;
                app.FamiliarityDropDown_1.Value = 3;
                app.DyslipidemiaDropDown.Value = 3;
                app.TimiEditField_1.Value = 0;
    
                app.StenosisEditField_1.Value = 0;
                app.PositivecardiacmarkerDropDown_1.Value = 3;
                app.HypercolDropDown_1.Value = 3;
                app.EKGSTdevmmEditField_1.Value = 0;
            catch ME
                disp("ResetButtonFH_1PushedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "ResetButtonFH_1Pushed", 'VariableNames', ["identifier" "line" "function_name"]));
            end
        end

        % Button pushed function: CancelHosp_2
        function CancelHosp_2ButtonPushed(app, event)
            %Reset button manage hospitalizations CLINIC
            try
                app.HospitalizationSavingStatus_2.Color = 'g';
                app.HospitalizationSavingStatus_2.Visible ="off";
                app.HospitalizationText_2.Value = "";
                app.HospitalizationText_2.Visible = 'off';
                app.DateandtimehospitalizationPanel_2.BackgroundColor = [1 1 1];
                app.DateandtimeAMIPanel_2.BackgroundColor = [1 1 1];
    
                app.EtaEditField_2.BackgroundColor = [1 1 1];
                app.WeightEditField_2.BackgroundColor = [1 1 1];
                app.SystolicBPmmHgEditField_2.BackgroundColor = [1 1 1];
                app.HRbpmEditField_2.BackgroundColor = [1 1 1];
                app.TimetotreatmhoursEditField_2.BackgroundColor = [1 1 1];
                app.DiabeteDropDown_2.BackgroundColor = [0.96,0.96,0.96];
                app.HypertensDropDown_2.BackgroundColor = [0.96,0.96,0.96];
                app.Anginaepisodesin24hEditField_2.BackgroundColor = [1 1 1];
                app.KillipDropDown_2.BackgroundColor = [0.96,0.96,0.96];
                app.STEMIDropDown_2.BackgroundColor = [0.96,0.96,0.96];
                app.LBBBDropDown_2.BackgroundColor = [0.96,0.96,0.96];
    
                app.HDateH_2.Value = NaT;
                app.HhourH_2.Value = 0;
                app.HminuteH_2.Value = 0;
                app.HsecondH_2.Value = 0;
                app.DateEvent_2.Value = NaT;
                app.AMIhourEv_2.Value = 0;
                app.AMIhourEv_2.Value = 0;
                app.AMIsecondEv_2.Value = 0;
                app.DateTromb_2.Value = NaT;
                app.TrombHour_2.Value = 0;
                app.Trombminute_2.Value = 0;
                app.Trombsecond_2.Value = 0;
                app.DrugEditField_2.Value = '';
                app.DateRev_2.Value = NaT;
                app.Revhour_2.Value = 0;
                app.Revminute_2.Value = 0;
                app.Revsecond_2.Value = 0;
                app.LocoEditField_2.Value = '';
                app.stMedCoDate_2.Value = NaT;
                app.EtaEditField_2.Value = 0;
                app.WeightEditField_2.Value = 0;
                app.SystolicBPmmHgEditField_2.Value = 0;
                app.DyastolicBPmmHgEditField_2.Value = 0;
                app.HRbpmEditField_2.Value = 0;
                app.TimetotreatmhoursEditField_2.Value = 0;
                app.DiabeteDropDown_2.Value = 3;
                app.HypertensDropDown_2.Value = 3;
                app.Anginaepisodesin24hEditField_2.Value = 0;
                app.KillipDropDown_2.Value = 1;
                app.STEMIDropDown_2.Value = 3;
                app.STEMIDropDown_2ValueChanged;
                app.LBBBDropDown_2.Value = 3;
                app.SmokeDropDown_2.Value = 3;
                app.ObesityDropDown_2.Value = 3;
                app.PrevCADDropDown_2.Value = 3;
                app.FamiliarityDropDown_2.Value = 3;
                app.DyslipidemiaDropDown_2.Value = 3;
                app.TimiEditField_2.Value = 0;
             catch ME
                disp("CancelHosp_2ButtonPushedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "CancelHosp_2ButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            try
                app.MeasurementsSaveText.Visible = 'off';
                app.MeasurementsSaveText.Value = '';
                app.MeasurementsSaveLamp.Visible = 'off';
                app.MeasurementsSaveLamp.Color = 'g';
                app.DateandTimeacquisitionPanel.BackgroundColor = [1 1 1];

                app.cTnTEditField.Value = 0;
                app.cTnIEditField.Value = 0;
                app.CKMBEditField.Value = 0;
                app.NaEditField.Value = 0;
                app.KEditField.Value = 0;
                app.HDateA.Value = NaT;
                app.HhourA.Value = 0;
                app.HminuteA.Value = 0;
                app.HsecondA.Value = 0;
            catch ME
                disp("CancelButtonPushedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "CancelButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
%                 rethrow(ME)
            end
        end

        % Button pushed function: ResetButton_3
        function ResetButton_3Pushed(app, event)
            try
%               app.NameEditField_2.Value = '';
%               app.SurnameEditField_2.Value = '';
%               app.BirthPlaceEditField_2.Value = '';
%               app.BirthDateEditField_2.Value = NaT;
%               app.AddressEditField_2.Value = '';
%               app.MButton_2.Value = 1;
%               app.FButton_2.Value = 0;
%               app.SSNEditField_2.Value = '';

                app.NameEditField_2.Value = app.selectedPaziente.nome{1};
                app.SurnameEditField_2.Value = app.selectedPaziente.cognome{1};
                app.BirthPlaceEditField_2.Value = app.selectedPaziente.luogo_nascita{1};
                app.BirthDateEditField_2.Value = datetime(app.selectedPaziente.data_nascita{1});
                app.AddressEditField_2.Value = app.selectedPaziente.indirizzo_residenza{1};
                if strcmp(app.selectedPaziente.sesso{1}, 'M')
                    app.MButton_2.Value = 1;
                    app.FButton_2.Value = 0;
                else
                    app.MButton_2.Value = 0;
                    app.FButton_2.Value = 1;
                end
                app.SSNEditField_2.Value = app.selectedPaziente.cf{1};
    
                app.DataMessageText_2.Value = '';
                app.DataMessageText_2.Visible = 'off';
                app.PersonalDataSavingStatus_2.Color = 'g';
                app.PersonalDataSavingStatus_2.Visible = "off";
                app.NameEditField_2.BackgroundColor = [1 1 1];
                app.SurnameEditField_2.BackgroundColor = [1 1 1];
                app.BirthPlaceEditField_2.BackgroundColor = [1 1 1];
                app.BirthDateEditField_2.BackgroundColor = [1 1 1];
                app.AddressEditField_2.BackgroundColor = [1 1 1];
            catch ME
                disp("ResetButton_3PushedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "ResetButton_3Pushed", 'VariableNames', ["identifier" "line" "function_name"]));
%                 rethrow(ME)
            end
        end

        % Value changed function: MediciDropDown
        function MediciDropDownValueChanged(app, event)
            progbar =  uiprogressdlg(app.UIFigure,'Title','Loading clinicians data',...
                'Indeterminate','on');
            drawnow
            try
                value = app.MediciDropDown.Value;
                
                if value > 0
                    getMedicoSQL = strcat('SELECT * FROM utente u WHERE u.id = ', string(value));
                    selectedMedico = select(app.conn, getMedicoSQL);
                    app.NomeMedicoEditField.Value = selectedMedico.nome{1};
                    app.CognomeMedicoEditField.Value = selectedMedico.cognome{1};
                    app.CFMedicoEditField.Value = selectedMedico.code_fis{1};
                    app.UsernameMedicoEditField.Value = selectedMedico.username{1};
                    app.EmailMedicoEditField.Value = selectedMedico.email{1};
                end
            catch ME
                disp("MediciDropDownValueChangedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "MediciDropDownValueChanged", 'VariableNames', ["identifier" "line" "function_name"]));
%                 rethrow(ME)
            end
            close(progbar)
        end

        % Button pushed function: DeleteSelectedButton
        function DeleteSelectedButtonPushed(app, event)
            
        end

        % Button pushed function: NuovoMedicoButton
        function NuovoMedicoButtonPushed(app, event)
            app.MedicoSavingStatus.Visible = 'off';
            app.MedicoSaveUserMsg.Visible = 'off';
            app.MedicoSavingStatus.Color = 'g';
            app.MedicoSaveUserMsg.Value = strcat('Success: saved new clinic as:', " ", app.UsernameMedicoEditField.Value);
            
            %ProgressBar init
            progbar =  uiprogressdlg(app.UIFigure,'Title','Save new clinician',...
                'Message','Saving...','Cancelable','off');
            pause(.2)
            tic
            try
                check = true;
                app.MedicoSavingStatus.Visible = 'off';
                app.MedicoSavingStatus.Color = 'g';
                app.MedicoSaveUserMsg.Visible = "off";
                app.NomeMedicoEditField.BackgroundColor = [1 1 1];
                app.CognomeMedicoEditField.BackgroundColor = [1 1 1];
                app.CFMedicoEditField.BackgroundColor = [1 1 1];
                app.UsernameMedicoEditField.BackgroundColor = [1 1 1];
                app.PasswordMedicoEditField.BackgroundColor = [1 1 1];
                
                nome = string(app.NomeMedicoEditField.Value);
                if isempty(nome) || strcmp(nome, "")
                    check = false;
                    app.MedicoSavingStatus.Color = 'r';
                    app.NomeMedicoEditField.BackgroundColor = [1 0 0];
                    app.MedicoSaveUserMsg.Value = "ERROR! insert a valid name";
                end
                cognome = string(app.CognomeMedicoEditField.Value);
                if isempty(cognome) || strcmp(cognome, "")
                    check = false;
                    app.MedicoSavingStatus.Color = 'r';
                    app.CognomeMedicoEditField.BackgroundColor = [1 0 0];
                    app.MedicoSaveUserMsg.Value = "ERROR! insert a valid surname";
                end
                codeFis = upper(string(app.CFMedicoEditField.Value));
                if isempty(codeFis) || strcmp(codeFis, "")
                    check = false;
                    app.MedicoSavingStatus.Color = 'r';
                    app.CFMedicoEditField.BackgroundColor = [1 0 0];
                    app.MedicoSaveUserMsg.Value = "ERROR! insert a valid SSN";
                end
                username = string(app.UsernameMedicoEditField.Value);
                if isempty(username) || strcmp(username, "")
                    check = false;
                    app.MedicoSavingStatus.Color = 'r';
                    app.UsernameMedicoEditField.BackgroundColor = [1 0 0];
                    app.MedicoSaveUserMsg.Value = "ERROR! inserta  valid username";
                end
                password = string(app.PasswordMedicoEditField.Value);
                if isempty(password) || strcmp(password, "")
                    check = false;
                    app.MedicoSavingStatus.Color = 'r';
                    app.PasswordMedicoEditField.BackgroundColor = [1 0 0];
                    app.MedicoSaveUserMsg.Value = "ERROR! insert a valid password";
                end
                email = string(app.EmailMedicoEditField.Value);

                %ProgressBar update
                progbar.Value = .33; 
                progbar.Message = strcat("Saving clinician: ",codeFis);
                pause(0.2)

                if check
                    [m, r] = sqlprotection({nome cognome codeFis username password email});
                    if r == true
                        password = string(passEncrypt(password, 'SHA-256'));
                        data = table(nome,cognome,codeFis,username,password,email,app.idCentro,3,'VariableNames',{'nome','cognome','code_fis','username','password','email','id_centro','livello'});
                        sqlwrite(app.conn,'utente',data);
                        loginEvalSql = strcat("SELECT EVENT_ID, TRUNCATE(TIMER_WAIT/1000000000000,6) as Duration ", ...
                            "FROM performance_schema.events_statements_history AS queryLog ", ...
                            "WHERE queryLog.CURRENT_SCHEMA LIKE 'cbra_new' AND queryLog.SQL_TEXT LIKE 'INSERT INTO utente % VALUES %", username, "%'");
                        loginEvaluation = select(app.conn, loginEvalSql);
                        evalTime = loginEvaluation.Duration(1);

                        %update number of clinicians
                        clinicNumberSQL = strcat('SELECT numero_medici FROM centro WHERE id = ', string(app.idCentro));
                        clinicNumberData = select(app.conn, clinicNumberSQL);
                        class(clinicNumberData.numero_medici)
                        clinicNumberData = clinicNumberData.numero_medici + 1;
                        whereclause = strcat('WHERE id = ', string(app.idCentro));
                        update(app.conn, 'centro', {'numero_medici'}, {clinicNumberData}, whereclause);
        
                        %fill clinic drop down
                        clinisQuery = strcat('SELECT u.id, u.username, CONCAT(u.cognome, " ", u.nome) AS nome, u.code_fis FROM utente u WHERE u.id_centro = ', string(app.idCentro), ' AND u.livello = 3');
                        clinicsData = select(app.conn, clinisQuery);
                        app.MediciDropDown.Items = clinicsData.code_fis;
                        app.MediciDropDown.ItemsData = clinicsData.id;
                        %fill clinic table
                        rows = removevars(clinicsData, 'id');
                        app.ListaMediciCentro.Data = rows;
                        %fill center info
                        centerInfoQuery = strcat('SELECT c.id, CONCAT(c.nome, " ", c.indirizzo, " ", c.citta) AS nome, c.numero_medici, c.numero_pazienti FROM centro c WHERE c.id = ',string(app.idCentro));
                        centerInfoData = select(app.conn, centerInfoQuery);
                        app.CenterNameLabel.Text = strcat('Center:'," ",centerInfoData.nome(1));
                        app.NumeroMedici.Text = strcat('Clinicians: '," ",string(centerInfoData.numero_medici(1)));
                        app.NumeroPazienti.Text = strcat('Patients: '," ",string(centerInfoData.numero_pazienti(1)));
        
                        %select new clinic
                        lastClinicSQL = strcat('SELECT * FROM utente u WHERE u.username = "',username,'";');
                        disp(lastClinicSQL)
                        lastClinicData = select(app.conn, lastClinicSQL);
                        app.MediciDropDown.Value = lastClinicData.id;
                    else
                        app.MedicoSavingStatus.Color = 'r';
                        app.MedicoSaveUserMsg.Value = m;
                    end
                    executionTime = toc;
                    update(app.conn, 'utente', {'evaluation_time' 'execution_time'}, table(evalTime, executionTime, 'VariableNames', {'evaluation_time' 'execution_time'}), strcat("WHERE username = '",username,"'"));
                end
                %updating
                progbar.Value = .67;
                progbar.Message = app.MedicoSaveUserMsg.Value;
                pause(.2)
                %updating
                progbar.Value = 1;
                progbar.Message = 'Finishing';
                pause(.2)
            catch ME
                disp("NuovoMedicoButtonPushedError")
                disp(ME)
                app.MedicoSavingStatus.Color = 'r';
                app.MedicoSaveUserMsg.Value = strcat("ERROR! can't save:", " ", app.UsernameMedicoEditField.Value, " check if user is already saved or empty fields");
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "NuovoMedicoButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
            end
            app.MedicoSavingStatus.Visible = 'on';
            app.MedicoSaveUserMsg.Visible = 'on';
            close(progbar)
        end

        % Button pushed function: SalvaMedicoButton
        function SalvaMedicoButtonPushed(app, event)
            app.MedicoSavingStatus.Visible = 'off';
            app.MedicoSaveUserMsg.Visible = 'off';
            app.MedicoSavingStatus.Color = 'g';
            app.MedicoSaveUserMsg.Value = strcat('Success: saved new clinician as:', " ", app.UsernameMedicoEditField.Value);
            %ProgressBar init
            progbar =  uiprogressdlg(app.UIFigure,'Title','Save clinician edits',...
                'Message','Saving...','Cancelable','off');
            pause(.2)

            try
                idMedico = app.MediciDropDown.Value;

                check = true;
                app.MedicoSavingStatus.Visible = 'off';
                app.MedicoSavingStatus.Color = 'g';
                app.MedicoSaveUserMsg.Visible = "off";
                app.NomeMedicoEditField.BackgroundColor = [1 1 1];
                app.CognomeMedicoEditField.BackgroundColor = [1 1 1];
                app.CFMedicoEditField.BackgroundColor = [1 1 1];
                app.UsernameMedicoEditField.BackgroundColor = [1 1 1];
                app.PasswordMedicoEditField.BackgroundColor = [1 1 1];

                nome = string(app.NomeMedicoEditField.Value);
                if isempty(nome) || strcmp(nome, "")
                    check = false;
                    app.MedicoSavingStatus.Color = 'r';
                    app.NomeMedicoEditField.BackgroundColor = [1 0 0];
                    app.MedicoSaveUserMsg.Value = "ERROR! insert a valid name";
                end
                cognome = string(app.CognomeMedicoEditField.Value);
                if isempty(cognome) || strcmp(cognome, "")
                    check = false;
                    app.MedicoSavingStatus.Color = 'r';
                    app.CognomeMedicoEditField.BackgroundColor = [1 0 0];
                    app.MedicoSaveUserMsg.Value = "ERROR! insert a valid surname";
                end
                codeFis = upper(string(app.CFMedicoEditField.Value));
                if isempty(codeFis) || strcmp(codeFis, "")
                    check = false;
                    app.MedicoSavingStatus.Color = 'r';
                    app.CFMedicoEditField.BackgroundColor = [1 0 0];
                    app.MedicoSaveUserMsg.Value = "ERROR! insert a valid SSN";
                end
                username = string(app.UsernameMedicoEditField.Value);
                if isempty(username) || strcmp(username, "")
                    check = false;
                    app.MedicoSavingStatus.Color = 'r';
                    app.UsernameMedicoEditField.BackgroundColor = [1 0 0];
                    app.MedicoSaveUserMsg.Value = "ERROR! insert a valid username";
                end
                password = string(app.PasswordMedicoEditField.Value);
                email = string(app.EmailMedicoEditField.Value);

                %ProgressBar update
                progbar.Value = .33; 
                progbar.Message = strcat("Saving edits for clinician: ",codeFis);
                pause(0.2)

                if check
                    [m, r] = sqlprotection({nome cognome codeFis username password email});
                    if r == true
                        if isempty(convertStringToChar(password))
                            data = select(app.conn, strcat('SELECT u.password FROM utente u WHERE u.id = ', string(idMedico)));
                            password = data.password;
                        else
                            password = string(passEncrypt(password, 'SHA-256'));
                        end
                        data = table(nome,cognome,codeFis,username,password,email,app.idCentro,3,'VariableNames',{'nome','cognome','code_fis','username','password','email','id_centro','livello'});
                        whereclause = strcat('WHERE id = ', string(idMedico));
                        update(app.conn,'utente',{'nome','cognome','code_fis','username','password','email','id_centro','livello'},data, whereclause);

                        %fill clinic drop down
                        clinisQuery = strcat('SELECT u.id, u.username, CONCAT(u.cognome, " ", u.nome) AS nome, u.code_fis FROM utente u WHERE u.id_centro = ', string(app.idCentro), ' AND u.livello = 3');
                        clinicsData = select(app.conn, clinisQuery);
                        app.MediciDropDown.Items = clinicsData.code_fis;
                        app.MediciDropDown.ItemsData = clinicsData.id;
                        %fill clinic table
                        rows = removevars(clinicsData, 'id');
                        app.ListaMediciCentro.Data = rows;
                        %fill center info
                        centerInfoQuery = strcat('SELECT c.id, CONCAT(c.nome, " ", c.indirizzo, " ", c.citta) AS nome, c.numero_medici, c.numero_pazienti FROM centro c WHERE c.id = ',string(app.idCentro));
                        centerInfoData = select(app.conn, centerInfoQuery);
                        app.CenterNameLabel.Text = strcat('Center:'," ",centerInfoData.nome(1));
                        app.NumeroMedici.Text = strcat('Clinicians: '," ",string(centerInfoData.numero_medici(1)));
                        app.NumeroPazienti.Text = strcat('Patients: '," ",string(centerInfoData.numero_pazienti(1)));

                        %select new clinic
                        lastClinicSQL = strcat('SELECT * FROM utente u WHERE u.id = ',string(idMedico));
                        lastClinicData = select(app.conn, lastClinicSQL);
                        app.MediciDropDown.Value = lastClinicData.id;
                    else
                        app.MedicoSavingStatus.Color = 'r';
                        app.MedicoSaveUserMsg.Value = m;
                    end
                end
                %updating
                progbar.Value = .67;
                progbar.Message = app.MedicoSaveUserMsg.Value;
                pause(.2)
                %updating
                progbar.Value = 1;
                progbar.Message = 'Finishing';
                pause(.2)
            catch ME
                disp("SalvaMedicoButtonPushedError")
                disp(ME)
                app.MedicoSavingStatus.Color = 'r';
                app.MedicoSaveUserMsg.Value = strcat("ERROR! can't save:", " ", app.UsernameMedicoEditField.Value, " check if username is already used or empty fields");
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "SalvaMedicoButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
            end
            app.MedicoSavingStatus.Visible = 'on';
            app.MedicoSaveUserMsg.Visible = 'on';
            close(progbar)
        end

        % Button pushed function: AnnullaMedicoButton
        function AnnullaMedicoButtonPushed(app, event)
            try
                app.NomeMedicoEditField.Value = '';
                app.CognomeMedicoEditField.Value = '';
                app.CFMedicoEditField.Value = '';
                app.UsernameMedicoEditField.Value = '';
                app.PasswordMedicoEditField.Value = '';
                app.EmailMedicoEditField.Value = '';
    
                app.MedicoSavingStatus.Visible = 'off';
                app.MedicoSavingStatus.Color = 'g';
                app.MedicoSaveUserMsg.Visible = "off";
                app.NomeMedicoEditField.BackgroundColor = [1 1 1];
                app.CognomeMedicoEditField.BackgroundColor = [1 1 1];
                app.CFMedicoEditField.BackgroundColor = [1 1 1];
                app.UsernameMedicoEditField.BackgroundColor = [1 1 1];
                app.PasswordMedicoEditField.BackgroundColor = [1 1 1];
            catch ME
                disp("AnnullaMedicoButtonPushedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "AnnullaMedicoButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
            end
        end

        % Button pushed function: TemplateExcelButton
        function TemplateExcelButtonPushed(app, event)
            try
                app.ImportExportLamp.Visible = 'off';
                app.ImportExportText.Value = 'Exporting template...';
                app.ImportExportText.Visible = 'on';
    
                anonymized = app.AnonymizeDataSwitch.Value;
    
                if anonymized == 1
                    template = cell2table(cell(0,44),'VariableNames', {'Center', 'PatientCode', 'PatientRelationID', 'HospitalizationRelationID', 'HospitalizationDate', ...
                        'HospitalizationTime','AMIDate','AMITime','FisrtMedicalContactDate','Diabetes','Hypertension','AnginaEpisodesIn24h','HR_bpm_','KillipClass','STEMI','LBBB', ...
                        'TimeToTreatment_hours_','Smoke','Familiarity','PreviousCAD','Obesity','Dyslipidemia','SystolicBP','DyastolicBP','Weight','Age','ThrombolysisDate','ThromblysisTime', ...
                        'ThrombolysisDrug','RevascularizationDate','RevascularizationTime','RevascularizationLocation','Hypercholesterolemia','ASAUseInPast7d','EKGSTDeviation_mm_','PositiveCardiacMarker','StenosisPercentage___', ...
                        'AcquisitionsDate','AcquisitionTime','cTnT','cTnI','CKMB','Na','K'});
                else
                    template = cell2table(cell(0,49),'VariableNames', {'PatientID_CF_','Name','Surname','Gender','Birthdate','BirthPlace','Center','PatientCode','HospitalizationRelationID', 'HospitalizationDate', ...
                        'HospitalizationTime','AMIDate','AMITime','FisrtMedicalContactDate','Diabetes','Hypertension','AnginaEpisodesIn24h','HR_bpm_','KillipClass','STEMI','LBBB', ...
                        'TimeToTreatment_hours_','Smoke','Familiarity','PreviousCAD','Obesity','Dyslipidemia','SystolicBP','DyastolicBP','Weight','Age','ThrombolysisDate','ThromblysisTime', ...
                        'ThrombolysisDrug','RevascularizationDate','RevascularizationTime','RevascularizationLocation','Hypercholesterolemia','ASAUseInPast7d','EKGSTDeviation_mm_','PositiveCardiacMarker','StenosisPercentage___', ...
                        'AcquisitionsDate','AcquisitionTime','cTnT','cTnI','CKMB','Na','K'});
                end
                filter = {'*.xlsx'};
                [file, path] = uiputfile(filter);
                if path == 0
                    app.ImportExportText.Value = 'No file selected';
                    app.ImportExportLamp.Color = 'r';
                else
                    filename = strcat(path, file);
                    [filepath,name,ext] = fileparts(filename);
                    if strcmp(ext,".xlsx")
                        writetable(template,filename,'Sheet',1);
                    else
                        writetable(template,filename,'Delimiter',',','QuoteStrings',true);
                    end
                    app.ImportExportText.Value = strcat('Template saved in: ', ' ', filename);
                    app.ImportExportLamp.Color = 'g';
                end
            catch ME
                disp("TemplateExcelButtonPushedError")
                disp(ME);
                app.ImportExportText.Value = strcat('Error on export:', ' ', ME.message);
                app.ImportExportLamp.Color = 'r';
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "TemplateExcelButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
            end
            app.ImportExportLamp.Visible = 'on';
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            try
                progbar = uiprogressdlg(app.UIFigure,'Title','Import from file',...
                    'Message','Reading file...','Cancelable','off');
                pause(.2)
                app.ImportExportLamp.Visible = 'off';
                centerNameSQL = strcat('SELECT CONCAT(nome," ",citta) AS name FROM centro WHERE id = ', string(app.idCentro));
                centerName = select(app.conn, centerNameSQL);
                app.ImportExportText.Value = strcat('Importing data for center: ',centerName.name,", ","to user:", app.loggedUser.username);
                app.ImportExportText.Visible = 'on';
    %             pause(5)
                filter = {'*.xlsx';'*.csv'};
                [file, path] = uigetfile(filter);
                if path == 0
                    app.ImportExportText.Value = 'No file selected';
                    app.ImportExportLamp.Color = 'r';
                else
                    filepath = strcat(path, file);
%                     opts = detectImportOptions(filepath);
                    importData = readtable(filepath);
                    if isempty(importData)
                        error("Import:IncorrectFile", ...
                            "The selected file is not compatible: the inserted file is empty.")
                    end
                    rowToRem = [];
                    removedRowsMSG = "";
%%                  Check anonymized
                    if width(importData) == 49 %% File not anonymized
                        mandatoryColumns = [1 2 3 4 5 6 9 10 11 12 13 43 44 45 46 47 48 49];
                        for i=1:height(importData)
                            missingVal = ismissing(importData(i,:));
                            emptyIndex = find(missingVal==1);
                            sol = sum(ismember(emptyIndex, mandatoryColumns));
                            if sol >= 1
                                rowToRem = [rowToRem i];
                            end
                        end
                        importData(rowToRem, :) = [];
%                         disp("Removed rows: ")
%                         disp(rowToRem)
                        VariableNames = {'PatientID_CF_','Name','Surname','Gender','Birthdate','BirthPlace','Center','PatientCode','HospitalizationRelationID', 'HospitalizationDate', ...
                        'HospitalizationTime','AMIDate','AMITime','FisrtMedicalContactDate','Diabetes','Hypertension','AnginaEpisodesIn24h','HR_bpm_','KillipClass','STEMI','LBBB', ...
                        'TimeToTreatment_hours_','Smoke','Familiarity','PreviousCAD','Obesity','Dyslipidemia','SystolicBP','DyastolicBP','Weight','Age','ThrombolysisDate','ThromblysisTime', ...
                        'ThrombolysisDrug','RevascularizationDate','RevascularizationTime','RevascularizationLocation','Hypercholesterolemia','ASAUseInPast7d','EKGSTDeviation_mm_','PositiveCardiacMarker','StenosisPercentage___', ...
                        'AcquisitionsDate','AcquisitionTime','cTnT','cTnI','CKMB','Na','K'};
                        if(~isempty(rowToRem))
                            removedRowsMSG = strcat(compose("\n" + "Following rows can't be inserted because some mandatory fields is missing:"), ...
                                compose("\n"+strjoin(compose(" %d ", rowToRem + 1))), compose("\nMandatory fields:"), ...
                                compose("\n" + strjoin(compose(VariableNames(mandatoryColumns)))));
                            disp(removedRowsMSG)
                        end
%                         check = 1;
                        for i = 1:length(VariableNames)
                            if strcmp(VariableNames{i},importData.Properties.VariableNames{i}) == 0
%                                 check = 0;
                                error('Import:ColumnMatching', ...
                                    strcat('Column', " ", VariableNames{i}, ",", " ", "doesn't match in file"));
                            end
                        end
                        %% Check values
%                         if check
%%                          Entities Extraction
%                             patientTab = unique(importData(:,1:8), 'stable');
                            patientTab = unique_table(importData(:,1:8));
                            patientTab = renamevars(patientTab, patientTab.Properties.VariableNames, ["cf", "nome", "cognome", "sesso", "data_nascita", "luogo_nascita", "origin_center", "code"]);
%                             hospTab = unique([importData(:,"PatientID_CF_") importData(:,9:42)], 'stable');
                            hospTab = unique_table([importData(:,"PatientID_CF_") importData(:,9:42)]);
                            hospTab = renamevars(hospTab, hospTab.Properties.VariableNames, ["cf", "relation_id", "dataRicovero", "oraRicovero", "dataEvento", "oraEvento", "dataPrimoSoccorso", "diabete", ...
                                "ipertensione", "angina", "HR", "killipClass", "STEMI", "LBBB", "timeToTreatment", "smoke", "familiarity", "prevAMI", "obesity", "dislip", "systolicBP", "dyastolicBP", "weight", "age", ...
                                "dataTrombolisi", "oraTrombolisi", "farmaco", "dataRivascolarizzazione", "oraRivascolarizzazione", "loco", "hypercholesterolemia","asa_usage_7d","st_deviation","cardiac_marker","stenosis"]);
%                             acqTab = unique([importData(:,"HospitalizationRelationID") importData(:,43:end)], 'stable');
                            acqTab = unique_table([importData(:,"HospitalizationRelationID") importData(:,43:end)]);
                            acqTab = renamevars(acqTab, acqTab.Properties.VariableNames, ["id_hospedalization" "dataAcquisizione" "oraAcquisizione" "ctnt" "ctni" "ckmb" "na" "k"]);
                            if isempty(patientTab) || isempty(hospTab) || isempty(acqTab)
                                error("Import:IncorrectFile", ...
                                    "The selected file is not compatible: the inserted file have missing mandatory data.")
                            end
%%                          Insert Patients
                            patientTab.id_centro(:) = app.idCentro;
                            %%% Check PatientCode
                            lastID = select(app.conn, strcat('SELECT id FROM paziente ORDER By id DESC LIMIT 1'));
                            lastID = lastID.id;
%                             patientTab.id = cell(height(patientTab),1);
%                             nome_centro = select(app.conn, strcat('SELECT nome FROM centro WHERE id =', string(app.idCentro)));
                            deletion_cf = [];
                            toDelete = zeros(height(patientTab), 1);
                            for i = 1:height(patientTab)
                                calcule = 1;
                                if isempty(patientTab.code{i})
                                    lastID = lastID + 1;
                                    if isempty(patientTab.origin_center{i})
                                        id_centro = app.idCentro;
%                                         nome_centro = nome_centro.nome{1};
                                    else
                                        id_centro = select(app.conn, strcat('SELECT id FROM centro WHERE centro.nome = "', patientTab.origin_center{i}, '"'));
                                        if isempty(id_centro.id)
%                                             disp("Center not in DB")
%                                             toDelete(i) = 1;
%                                             deletion_cf = [deletion_cf; string(patientTab.cf{i})];
%                                             calcule = 0;
                                            id_centro = app.idCentro;
                                        end
                                    end
                                    if calcule
                                        patientTab.code{i} = char(CalculeCode(app.nome_centro, app.idCentro, lastID));
%                                         code = char(CalculeCode(app.nome_centro, id_centro, lastID));
%                                         patientTab.code(i) = {code};
                                    end
                                else
                                    if isempty(patientTab.origin_center{i})
                                        id_centro = uint32(str2double(patientTab.code{i}(4:6)));
                                        center_name = select(app.conn, strcat('SELECT nome FROM centro WHERE id =', string(id_centro)));
                                        if ~isempty(center_name)   
                                            patientTab.origin_center{i} = center_name.nome{1};
                                        end
                                    end
                                end
                            end
                            deletion_hosp_ids = [];
                            toDelete_hosp = [];
                            toDelete_acq = [];
                            for i = 1:length(deletion_cf)
                                toDelete_hosp = strcmp(hospTab.cf, deletion_cf(i));
                                deletion_hosp_ids = hospTab.relation_id(toDelete_hosp);
                                toDelete_acq = acqTab.id_hospedalization == deletion_hosp_ids;
                                hospTab(toDelete_hosp, :) = [];
                                acqTab(toDelete_acq, :) = [];
                            end
                            patientTab(logical(toDelete), :) = [];
%                             assignin("base", "patientTab_full", patientTab);
                            sqlwrite(app.conn, 'paziente', patientTab);
                            newPatientsCf = patientTab.cf;
                            cfs = strcat('"',strjoin(newPatientsCf', {'", "'}),'"');
                            newPatientsIdSQL = strcat('SELECT p.id AS id_paziente, p.cf, c.nome FROM paziente p, centro c WHERE p.id_centro = c.id AND p.cf IN (', cfs, ') AND p.id_centro = ', string(app.idCentro));
                            medPatTab = select(app.conn, newPatientsIdSQL);
                            if app.livello == 3
                                medPatTab.id_medico(:) = app.id;
                                sqlwrite(app.conn, 'medico_paziente', medPatTab);
                            end

    %%                      Insert Hospitalizations
                            result = [];
                            convertOra = char(hospTab.oraRicovero(:));
                            hospTab.oraRicovero = convertOra;
                            convertOra = char(hospTab.oraEvento(:));
                            hospTab.oraEvento = convertOra;
                            convertOra = char(hospTab.oraTrombolisi(:));
                            hospTab.oraTrombolisi = convertOra;
                            convertOra = char(hospTab.oraRivascolarizzazione(:));
                            hospTab.oraRivascolarizzazione = convertOra;
                            oldIDs = hospTab(:,"cf");
%                             oldIDs.id_centro = patientTab.origin_center;
                            newIDs = medPatTab.id_paziente;
                            result =[];
                            c = 1;
                            index = [c];
                            for i = 1:length(oldIDs.cf)-1
                                if strcmp(oldIDs.cf{i}, oldIDs.cf{i+1})
                                    index = [index c];
                                else
                                    c = c+1;
                                    index = [index c];
                                end
                            end
                            result = newIDs(index);

%                             for i = 1:height(hospTab)
% %                                 disp(hospTab.cf{i})
%                                 for j = 1:height(oldIDs)
% %                                     disp(oldIDs.cf{j})
%                                     if strcmp(hospTab.cf{i}, oldIDs.cf{j})
% %                                       disp('sostituisco')
%                                       result = [result; newIDs(j)];
% %                                       disp(result)
%                                     end
%                                 end
%                             end
                            hospTab = removevars(hospTab, 'cf');
                            hospTab.id_paziente = result;
                            hospTab.id_medico(:) = app.id;
                            hospTab = removevars(hospTab, 'relation_id');
                            sqlwrite(app.conn, 'hospitalization', hospTab);

%%                          Insert Acquisitions
                            convertOra = char(acqTab.oraAcquisizione);
                            acqTab.oraAcquisizione = convertOra;
                            oldIDs = acqTab.id_hospedalization;
                            newIDs = select(app.conn, strcat('SELECT id FROM hospitalization WHERE id_medico = ', string(app.id), ' ORDER BY id DESC LIMIT ', " ", string(height(hospTab))));
                            newIDs = newIDs{:,1};
                            newIDs = sort(newIDs);
                            result = [];
                            c = 1;
                            index = [c];
                            for i = 1:length(oldIDs)-1
                                if oldIDs(i) == oldIDs(i+1)
                                    index = [index c];
                                else
                                    c = c+1;
                                    index = [index c];
                                end
                            end
                            result = newIDs(index);
%                             for i = 1:height(acqTab)
%                                 for j = 1:length(oldIDs)
%                                     if acqTab.id_hospedalization(i) == oldIDs(j)
%                                         result = [result; newIDs(i)];
%                                     end
%                                 end
%                             end
                            acqTab.id_hospedalization = result;
                            sqlwrite(app.conn, 'acquisizione', acqTab);
%                         else
%                             %column doesn't match
%                                     %remove check and use error
%                             incorrectFileMsg = strcat('Column', " ", VariableNames{i}, ",", " ", "doesn't match in file");
%                             app.ImportExportText.Value = incorrectFileMsg;
%                             app.ImportExportLamp.Color = 'r';
%                         end
                    elseif width(importData) == 44 % Anonymized
                        mandatoryColumns = [3 4 5 6 7 8 38 39 40 41 42 43 44];
                        for i=1:height(importData)
                            missingVal = ismissing(importData(i,:));
                            emptyIndex = find(missingVal==1);
                            sol = sum(ismember(emptyIndex, mandatoryColumns));
                            if sol >= 1
                                rowToRem = [rowToRem i];
                            end
                        end
                        importData(rowToRem, :) = [];
                        VariableNames = {'Center', 'PatientCode', 'PatientRelationID', 'HospitalizationRelationID', 'HospitalizationDate', ...
                        'HospitalizationTime','AMIDate','AMITime','FisrtMedicalContactDate','Diabetes','Hypertension','AnginaEpisodesIn24h','HR_bpm_','KillipClass','STEMI','LBBB', ...
                        'TimeToTreatment_hours_','Smoke','Familiarity','PreviousCAD','Obesity','Dyslipidemia','SystolicBP','DyastolicBP','Weight','Age','ThrombolysisDate','ThromblysisTime', ...
                        'ThrombolysisDrug','RevascularizationDate','RevascularizationTime','RevascularizationLocation', 'Hypercholesterolemia','ASAUseInPast7d','EKGSTDeviation_mm_','PositiveCardiacMarker','StenosisPercentage___', ...
                        'AcquisitionsDate','AcquisitionTime','cTnT','cTnI','CKMB','Na','K'};
                        if(~isempty(rowToRem))
                            removedRowsMSG = strcat(compose("\n" + "Following rows can't be inserted because some mandatory fields is missing:"), ...
                                compose("\n"+strjoin(compose(" %d ", rowToRem + 1))), compose("\nMandatory fields:"), ...
                                compose("\n" + strjoin(compose(VariableNames(mandatoryColumns)))));
                            disp(removedRowsMSG)
                        end
                        check = 1;
                        for i = 1:length(VariableNames)
                            if strcmp(VariableNames{i},importData.Properties.VariableNames{i}) == 0
%                                 check = 0;
                                error('Import:ColumnMatching', ...
                                    strcat('Column', " ", VariableNames{i}, ",", " ", "doesn't match in file"));
                            end
                        end
%                         if check
%%                          Entities Extraction
%                             patientTab = sortrows(unique(importData(:,1:3)), 'PatientRelationID');
%                             patientTab = unique(importData(:,1:3), 'stable');
                            patientTab = unique_table(importData(:,1:3));
                            patientTab = renamevars(patientTab, patientTab.Properties.VariableNames, ["origin_center", "code", "id_paziente"]);
%                             hospTab = unique(importData(:,3:37), 'stable');
                            hospTab = unique_table(importData(:,3:37));
                            hospTab = renamevars(hospTab, hospTab.Properties.VariableNames, ["id_paziente", "relation_id", "dataRicovero", "oraRicovero", "dataEvento", "oraEvento", "dataPrimoSoccorso", "diabete", ...
                                "ipertensione", "angina", "HR", "killipClass", "STEMI", "LBBB", "timeToTreatment", "smoke", "familiarity", "prevAMI", "obesity", "dislip", "systolicBP", "dyastolicBP", "weight", "age", ...
                                "dataTrombolisi", "oraTrombolisi", "farmaco", "dataRivascolarizzazione", "oraRivascolarizzazione", "loco", "hypercholesterolemia","asa_usage_7d","st_deviation","cardiac_marker","stenosis"]);
%                             acqTab = unique([importData(:,"HospitalizationRelationID") importData(:,38:end)], 'stable');
                            acqTab = unique([importData(:,"HospitalizationRelationID") importData(:,38:end)]);
                            acqTab = renamevars(acqTab, acqTab.Properties.VariableNames, ["id_hospedalization" "dataAcquisizione" "oraAcquisizione" "ctnt" "ctni" "ckmb" "na" "k"]);

%%                          Insert Patients
                            lastID = select(app.conn, strcat('SELECT id FROM paziente ORDER By id DESC LIMIT 1'));
                            lastID = lastID.id;
                            ids = [];
                            cfs = {};
                            deletion_pat = [];
                            toDelete = zeros(height(patientTab), 1);
                            for i=1:height(patientTab)
                                lastID = lastID + 1;
                                ids = [ids; lastID];
                                cfs = [cfs; char(strcat(string(lastID), 'IMPORTED', datestr(now, 'yymmddFFF')))];
                                calcule = 1;
                                if isempty(patientTab.code{i})
                                    if isempty(patientTab.origin_center{i})
                                        id_centro = app.idCentro;
                                    else
                                        id_centro = select(app.conn, strcat('SELECT id FROM centro WHERE centro.nome = "', patientTab.origin_center{i}, '"'));
                                        if isempty(id_centro.id)
%                                             disp("Center not in DB")
%                                             toDelete(i) = 1;
%                                             deletion_pat = [deletion_pat; patientTab.id_paziente(i)];
%                                             calcule = 0;
                                            id_centro = app.idCentro;
                                        end
                                    end
                                    if calcule
                                        patientTab.code{i} = char(CalculeCode(app.nome_centro, app.idCentro, lastID));
                                    end
                                else
                                    if isempty(patientTab.origin_center{i})
                                        id_centro = uint32(str2double(patientTab.code{i}(4:6)));
                                        center_name = select(app.conn, strcat('SELECT nome FROM centro WHERE id =', string(id_centro)));
                                        if ~isempty(center_name)   
                                            patientTab.origin_center{i} = center_name.nome{1};
                                        end
                                    end
                                end
                            end
                            patientTab.id = ids;
                            patientTab.cf = cfs;
                            patientTab.nome(:) = {'IMPORTED'};
                            patientTab.cognome(:) = {'IMPORTED'};
                            patientTab.sesso(:) = {'M'};
%                             patientTab.indirizzo_residenza = zeros(height(patientTab), 1);
                            patientTab.data_nascita(:) = datetime('01-01-1850','InputFormat','dd-mm-uuuu');
                            patientTab.luogo_nascita(:) = {'Place'};
                            patientTab.id_centro(:) = app.idCentro;
                            deletion_hosp_ids = [];
                            toDelete_hosp = [];
                            toDelete_acq = [];
                            for i = 1:length(deletion_pat)
                                toDelete_hosp = hospTab.id_paziente == deletion_pat(i);
                                deletion_hosp_ids = hospTab.relation_id(toDelete_hosp);
                                toDelete_acq = acqTab.id_hospedalization == deletion_hosp_ids;
                                hospTab(toDelete_hosp, :) = [];
                                acqTab(toDelete_acq, :) = [];
                            end
                            patientTab(logical(toDelete), :) = [];
%                             assignin("base", "patientTab_anon", patientTab)
%                             patientTab = removevars(patientTab, "id");
                            patientTab = removevars(patientTab, "id_paziente");
                            sqlwrite(app.conn, 'paziente', patientTab);
                            newPatientsCf = patientTab.cf;
                            cfs = strcat('"',strjoin(newPatientsCf', {'", "'}),'"');
                            newPatientsIdSQL = strcat('SELECT p.id AS id_paziente, p.cf, c.nome FROM paziente p, centro c WHERE p.id_centro = c.id AND p.cf IN (', cfs, ') AND p.id_centro = ', string(app.idCentro));
                            medPatTab = select(app.conn, newPatientsIdSQL);
                            if app.livello == 3
                                medPatTab.id_medico(:) = app.id;
                                sqlwrite(app.conn, 'medico_paziente', medPatTab);
                            end
%%                          Insert Hospitalizations
                            result = [];
                            convertOra = char(hospTab.oraRicovero(:));
                            hospTab.oraRicovero = convertOra;
                            convertOra = char(hospTab.oraEvento(:));
                            hospTab.oraEvento = convertOra;
                            convertOra = char(hospTab.oraTrombolisi(:));
                            hospTab.oraTrombolisi = convertOra;
                            convertOra = char(hospTab.oraRivascolarizzazione(:));
                            hospTab.oraRivascolarizzazione = convertOra;
                            oldIDs = hospTab.id_paziente;
%                             oldIDs.id_centro = patientTab.origin_center;
                            newIDs = medPatTab.id_paziente;
                            result =[];
                            c = 1;
                            index = [c];
                            for i = 1:length(oldIDs)-1
                                if oldIDs(i) == oldIDs(i+1)
                                    index = [index c];
                                else
                                    c = c+1;
                                    index = [index c];
                                end
                            end
                            result = newIDs(index);
%                             for i = 1:height(hospTab)
%                                 for j = 1:height(oldIDs)
%                                     if hospTab.id_paziente(i) == oldIDs(j)
% %                                       disp('sostituisco')
%                                       result = [result; newIDs(i)];
% %                                       disp(result)
%                                     end
%                                 end
%                             end
%                             hospTab = removevars(hospTab, 'cf');
                            hospTab.id_paziente = result;
                            hospTab.id_medico(:) = app.id;
                            hospTab = removevars(hospTab, 'relation_id');
                            sqlwrite(app.conn, 'hospitalization', hospTab);

%%                          Insert Acquisitions
                            convertOra = char(acqTab.oraAcquisizione);
                            acqTab.oraAcquisizione = convertOra;
                            oldIDs = acqTab.id_hospedalization;
                            newIDs = select(app.conn, strcat('SELECT id FROM hospitalization WHERE id_medico = ', string(app.id), ' ORDER BY id DESC LIMIT ', " ", string(height(hospTab))));
                            newIDs = newIDs{:,1};
                            newIDs = sort(newIDs);
                            result = [];
                            c = 1;
                            index = [c];
                            for i = 1:length(oldIDs)-1
                                if oldIDs(i) == oldIDs(i+1)
                                    index = [index c];
                                else
                                    c = c+1;
                                    index = [index c];
                                end
                            end
                            result = newIDs(index);
%                             for i = 1:height(acqTab)
%                                 for j = 1:length(oldIDs)
%                                     if acqTab.id_hospedalization(i) == oldIDs(j)
%                                         result = [result; newIDs(j)];
%                                     end
%                                 end
%                             end
                            acqTab.id_hospedalization = result;
                            sqlwrite(app.conn, 'acquisizione', acqTab);
%                         else
%                             incorrectFileMsg = strcat('Column', " ", VariableNames{i}, ",", " ", "doesn't match in file");
%                             app.ImportExportText.Value = incorrectFileMsg;
%                             app.ImportExportLamp.Color = 'r';
%                         end
                    else
                        error("Import:IncorrectFile", ...
                            "The selected file is not compatible: wrong number of columns check template file.")
                    end
%%                  Reload page components
                    pazientiDrop = strcat('SELECT p.id, CONCAT(p.cognome, " ", p.nome) AS nome, p.cf FROM paziente p WHERE p.id_centro = ', string(app.idCentro),';');
                    pazientiData = select(app.conn, pazientiDrop);
                    if ~isempty(pazientiData)
                        app.PazienteDropDown.ItemsData = table2array(pazientiData(:,1));
                        app.PazienteDropDown.Items = table2array(pazientiData(:,3));
                    
                        pazientiTab = strcat(['SELECT CONCAT(p.cognome, " ", p.nome) AS nome, p.cf, h.dataRicovero, h.oraRicovero, h.dataEvento, h.oraEvento,' ...
                            'a.dataAcquisizione, a.oraAcquisizione FROM paziente p LEFT JOIN hospitalization h ON p.id = h.id_paziente LEFT JOIN acquisizione a ON h.id = a.id_hospedalization' ...
                            ' WHERE p.id_centro='], string(app.idCentro));
                        %disp(pazientiTab)
                        rows = select(app.conn, pazientiTab);
                        %disp(rows)
                        app.PazientiList.Data = rows;
                        pause(.2)
                        app.PazienteDropDownValueChanged;
    
                        app.PatientPersonalDataPanel_2.Visible = "on";
                        app.ControlPanel.Visible = "on";
                    else
                        app.PazienteDropDown.ItemsData = NaN;
                        app.PazienteDropDown.Items = {' '};
                        app.PazientiList.Data = '';
                        app.PatientPersonalDataPanel_2.Visible = "off";
                        app.ControlPanel.Visible = "off";
                    end
                    app.ImportExportText.Value = strcat('Import completed: added', " ", string(height(patientTab)), " ", ' patients,', " ", string(height(hospTab)), " ", 'hospitalizations and', " ", string(height(acqTab)), " ", 'acquisitions for center:'," ",centerName.name,", ","to user:", " ",app.loggedUser.username, removedRowsMSG);
                    app.ImportExportLamp.Color = 'g';
%                     else
%                         incorrectFileMsg = strcat('Column', " ", VariableNames{i}, ",", " ", "doesn't match in file");
%                         app.ImportExportText.Value = incorrectFileMsg;
%                         app.ImportExportLamp.Color = 'r';
%                     end
                end
            catch ME
                disp("ImportDataButtonPushedError");
                incorrectFileMsg = strcat('Error on import: check if the file is correctly compiled or if some patients already exist.');
%                 incorrectFileMsg = strcat('Error on import:', " ", ME.message);
                if strcmp(ME.identifier,"Import:IncorrectFile")
                    incorrectFileMsg = ME.message;
                elseif strcmp(ME.identifier,"Import:ColumnMatching")
                    incorrectFileMsg = strcat('Column', " ", VariableNames{i}, ",", " ", "doesn't match in file");
                else
                    line = max([ME.stack(1:end).line]);
                    sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "ImportDataButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
                end
                disp(incorrectFileMsg)
                app.ImportExportText.Value = incorrectFileMsg;
                app.ImportExportLamp.Color = 'r';
%                 rethrow(ME)
            end
            close(progbar)
            app.ImportExportLamp.Visible = 'on';
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            try
                app.ImportExportLamp.Visible = 'off';
                app.ImportExportText.Value = 'Exporting data...';
                app.ImportExportText.Visible = 'on';

                anonymized = app.AnonymizeDataSwitch.Value;

                if anonymized == 1
                    exportsql = strcat('SELECT c.nome AS `Center`, p.`code` AS `Patient code`, p.id AS `Patient Relation ID`, h.id AS `Hospitalization Relation ID`,', ...
                        ' h.dataRicovero AS `Hospitalization date`, h.oraRicovero AS `Hospitalization time`,', ...
                        ' h.dataEvento AS `AMI date`, h.oraRicovero AS `AMI time`, h.dataPrimoSoccorso AS `Fisrt medical contact date`,', ...
                        ' h.diabete AS `Diabetes`, h.ipertensione AS `Hypertension`, h.angina AS `Angina episodes in 24h`, h.HR AS `HR [bpm]`,', ...
                        ' h.killipClass AS `Killip Class`, h.STEMI AS `STEMI`, h.LBBB, h.timeToTreatment AS `Time to treatment [hours]`,', ...
                        ' h.smoke AS `Smoke`, h.familiarity AS `Familiarity`, h.prevAMI AS `Previous CAD`, h.obesity AS `Obesity`,', ...
                        ' h.dislip AS `Dyslipidemia`, h.systolicBP AS `Systolic BP`, h.dyastolicBP AS `Dyastolic BP`, h.weight AS `Weight`,', ...
                        ' h.age AS `Age`, h.dataTrombolisi AS `Thrombolysis date`, h.oraTrombolisi AS `Thromblysis time`, h.farmaco AS `Thrombolysis drug`,', ...
                        ' h.dataRivascolarizzazione AS `Revascularization date`, h.oraRivascolarizzazione AS `Revascularization time`,', ...
                        ' h.loco AS `Revascularization location`, h.hypercholesterolemia AS `Hypercholesterolemia`, h.asa_usage_7d AS `ASA use in past 7d`, h.st_deviation AS `EKG ST deviation [mm]`,', ...
                        ' h.cardiac_marker AS `Positive cardiac marker`, h.stenosis AS `Stenosis percentage [%]`,',...
                        ' a.dataAcquisizione AS `Acquisitions date`, a.oraAcquisizione AS `Acquisition time`,', ...
                        ' a.ctnt AS `cTnT`, a.ctni AS `cTnI`, a.ckmb AS `CKMB`, a.na AS `Na`, a.k AS `K`', ...
                        ' FROM paziente p, hospitalization h, acquisizione a, centro c', ...
                        ' WHERE p.id_centro =', string(app.idCentro), ' AND h.id_paziente = p.id AND a.id_hospedalization = h.id AND p.id_centro = c.id', ...
                        ' ORDER BY p.`code`, h.id');
                else
                    exportsql = strcat('SELECT p.cf AS `Patient ID (CF)`, p.nome AS `Name`, p.cognome AS `Surname`, p.sesso AS `Gender`, p.data_nascita AS `Birthdate`,', ...
                        ' p.luogo_nascita AS `Birth place`, c.nome AS `Center`, p.`code` AS `Patient code`, h.id AS `Hospitalization Relation ID`,', ...
                        ' h.dataRicovero AS `Hospitalization date`, h.oraRicovero AS `Hospitalization time`,', ...
                        ' h.dataEvento AS `AMI date`, h.oraRicovero AS `AMI time`, h.dataPrimoSoccorso AS `Fisrt medical contact date`,', ...
                        ' h.diabete AS `Diabetes`, h.ipertensione AS `Hypertension`, h.angina AS `Angina episodes in 24h`, h.HR AS `HR [bpm]`,', ...
                        ' h.killipClass AS `Killip Class`, h.STEMI AS `STEMI`, h.LBBB, h.timeToTreatment AS `Time to treatment [hours]`,', ...
                        ' h.smoke AS `Smoke`, h.familiarity AS `Familiarity`, h.prevAMI AS `Previous CAD`, h.obesity AS `Obesity`,', ...
                        ' h.dislip AS `Dyslipidemia`, h.systolicBP AS `Systolic BP`, h.dyastolicBP AS `Dyastolic BP`, h.weight AS `Weight`,', ...
                        ' h.age AS `Age`, h.dataTrombolisi AS `Thrombolysis date`, h.oraTrombolisi AS `Thromblysis time`, h.farmaco AS `Thrombolysis drug`,', ...
                        ' h.dataRivascolarizzazione AS `Revascularization date`, h.oraRivascolarizzazione AS `Revascularization time`,', ...
                        ' h.loco AS `Revascularization location`, h.hypercholesterolemia AS `Hypercholesterolemia`, h.asa_usage_7d AS `ASA use in past 7d`, h.st_deviation AS `EKG ST deviation [mm]`,', ...
                        ' h.cardiac_marker AS `Positive cardiac marker`, h.stenosis AS `Stenosis percentage [%]`,',...
                        ' a.dataAcquisizione AS `Acquisitions date`, a.oraAcquisizione AS `Acquisition time`,', ...
                        ' a.ctnt AS `cTnT`, a.ctni AS `cTnI`, a.ckmb AS `CKMB`, a.na AS `Na`, a.k AS `K`', ...
                        ' FROM paziente p, hospitalization h, acquisizione a, centro c', ...
                        ' WHERE p.id_centro =', string(app.idCentro), ' AND h.id_paziente = p.id AND a.id_hospedalization = h.id AND p.id_centro = c.id', ...
                        ' ORDER BY p.cf, h.id');
                end
% %                 switch app.livello 
% %                     case 1
%                         exportsql = strcat('SELECT p.id, p.id_centro, p.code, h.*, a.*', ...
%                             ' FROM paziente p, hospitalization h, acquisizione a ', ...
%                             ' WHERE p.id_centro = ', string(app.idCentro)', ...
%                             ' AND h.id_paziente = p.id ', ...
%                             ' AND a.id_hospedalization = h.id ', ... 
%                             ' ORDER BY p.id;');
% %                     case 3
% %                         exportsql = strcat('SELECT p.id, p.id_centro, p.code, h.*, a.* ',...
% %                             ' FROM medico_paziente mp, paziente p, hospitalization h, acquisizione a ',...
% %                             ' WHERE mp.id_medico = ', string(app.id), ...
% %                             ' AND p.id IN (SELECT mp.id_paziente ',...
% %                                 ' FROM medico_paziente mp ',...
% %                                 ' WHERE mp.id_medico = ', string(app.id),') ',...
% %                             ' AND h.id_paziente = p.id ', ...
% %                             ' AND a.id_hospedalization = h.id ', ...
% %                             ' ORDER BY p.id;');
%                 end
                dataExport = select(app.conn, exportsql);
%               assignin("base", "dataExport", dataExport)
                filter = {'*.xlsx';'*.csv'};
                [file, path] = uiputfile(filter);
                if path == 0
                    app.ImportExportText.Value = 'No file selected';
                    app.ImportExportLamp.Color = 'r';
                else
                    filename = strcat(path, file);
%                     [filepath,name,ext] = fileparts(filename);
                    writetable(dataExport,filename,'Sheet',1);
%                     app.ImportExportText.Value = strcat("Export saved in: ", filename);
                    app.ImportExportText.Value = strcat("Export created and ready");
                    app.ImportExportLamp.Color = 'g';
                end
            catch ME
                disp("ExportDataButtonPushedError")
                disp(ME);
                app.ImportExportText.Value = strcat("Error on export: ", ME.message);
                app.ImportExportLamp.Color = 'r';
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "ExportDataButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
            end
            app.ImportExportLamp.Visible = 'on';
        end

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
            try    
                YN = uiconfirm(app.UIFigure,'Do you want to exit from the app?', 'Logout');
                if strcmpi(YN,'OK')
                    progbar =  uiprogressdlg(app.UIFigure,'Title','Logout session',...
                        'Indeterminate','on');
                    pause(.2)
                    switch app.livello
                        case 1
                            startupAdmin(app);
                            app.HomePage.Visible = 'on';
                            app.HospitalizationPage.Visible = 'off';
                            app.AcquisitionsPage.Visible = 'off';
                            app.AnalysisPage.Visible = 'off';
                            startupInsert(app);
                            startupHomePage(app);
                            startupHospitalization(app);
                            startupAcquisition(app);
                            startupAnalysis(app);
                            app.ModeDropDown.Value = 1;
                            app.ModeLabel.Visible = 'off';
                            app.ModeDropDown.Visible = 'off';
                            app.SelectCenterDrop.Visible = 'off';
                            app.SelectCenterLabel.Visible = 'off';
                        case 2
                            startupCenter(app);
                        case 3
                            app.HomePage.Visible = 'on';
                            app.HospitalizationPage.Visible = 'off';
                            app.AcquisitionsPage.Visible = 'off';
                            app.AnalysisPage.Visible = 'off';
                            startupInsert(app);
                            startupHomePage(app);
                            startupHospitalization(app);
                            startupAcquisition(app);
                            startupAnalysis(app);
                    end
                    app.livello = 0;
                    app.msg = "Logout succesfull!";
                    app.TextArea.Value = app.msg;
                    if app.livello == 0
                        app.LoginView.Visible = 'on';
                        app.AdminView.Visible = 'off';
                        app.CenterView.Visible = 'off';
                        app.ClinicView.Visible = 'off';
                    end 
                    app.TextArea.Visible = 'on';
        
                    app.conn = {};
                    app.idCentro = 0;
                    app.id = 0;
                    app.loggedUser = {};
            
                    app.selectedPaziente = {};
                    app.selectedHosp = {};
                    app.newPatientID = {};
                    app.nAcquisizioni = 0;
                    app.constant_vector = zeros(1,9);
                    app.runValue = 0; % Description
                    app.TemAcq = 0; % Description
                    app.ctnt = {};
                    app.ckmb = {};
                    app.sodio = {};
                    app.potassio = {};
                    
                    app.Executions = [];
                    app.ExecutionNumber = 0;
                    close(app.conn);
                    pause(.2)
                    close(progbar)
                    app.RefreshConnPushed(app);
                end
            catch ME
                disp("LogoutButtonPushedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "LogoutButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
            end
        end

        % Button pushed function: ReloadPatientData
        function ReloadPatientDataPushed(app, event)
            progbar =  uiprogressdlg(app.UIFigure,'Title','Loading patient data',...
                'Indeterminate','on');
            drawnow
            try
                app.ImportExportText.Visible = 'on';
                app.ImportExportText.Value = 'Reloading...';
                pause(.2)
                switch app.livello
                    case 1
                        app.SelectCenterDropValueChanged
                    case 3
                        app.PazienteDropDownValueChanged
                end
                app.ImportExportText.Value = 'Done';
                pause(.2)
                app.ImportExportText.Visible = 'off';
                app.ImportExportLamp.Visible = 'off';
            catch ME
                disp("ReloadPatientDataPushedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "ReloadPatientDataPushed", 'VariableNames', ["identifier" "line" "function_name"]));
            end
            close(progbar)
        end

        % Button pushed function: ReloadHospData
        function ReloadHospDataButtonPushed(app, event)
            progbar =  uiprogressdlg(app.UIFigure,'Title','Loading hospitalization data',...
                'Indeterminate','on');
            drawnow
            try
                app.HospitalizationsDropValueChanged
            catch ME
                disp("ReloadHospDataButtonPushedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "ReloadHospDataButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
            end
            close(progbar)
        end

        % Button pushed function: ReloadAcqData
        function ReloadAcqDataButtonPushed(app, event)
            progbar =  uiprogressdlg(app.UIFigure,'Title','Loading acquisitions data',...
                'Indeterminate','on');
            drawnow
            try
                app.AcquisitionslistDropDownValueChanged
            catch ME
                disp("ReloadAcqDataButtonPushedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "ReloadAcqDataButtonPushedError", 'VariableNames', ["identifier" "line" "function_name"]));
            end
            close(progbar)
        end

        % Button pushed function: CalculateAgeButton_1
        function CalculateAgeButton_1Pushed(app, event)
            try
                birth = datetime(app.newPatientID.data_nascita);
                today = datetime("now");
                age = calculateAge(app, today, birth);
                app.EtaEditField_1.Value = age;
            catch ME
                disp("CalculateAgeButton_1PushedError")
                disp(ME)
                app.HospitalizationText_1.Value = ME.identifier;
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "CalculateAgeButton_1Pushed", 'VariableNames', ["identifier" "line" "function_name"]));
            end
        end

        % Button pushed function: CalculateAgeButton_2
        function CalculateAgeButton_2Pushed(app, event)
            try
                birth = datetime(app.selectedPaziente.data_nascita);
                today = datetime("now");
                age = calculateAge(app, today, birth);
                app.EtaEditField_2.Value = age;
            catch ME
                disp("CalculateAgeButton_2PushedError")
                disp(ME)
                app.HospitalizationText_2.Value = ME.identifier;
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "CalculateAgeButton_2Pushed", 'VariableNames', ["identifier" "line" "function_name"]));
            end
        end

        % Value changed function: STEMIDropDown_1
        function STEMIDropDown_1ValueChanged(app, event)
            try
                value = app.STEMIDropDown_1.Value;
                switch value
                    case 1
                        app.CalculateTIMIButtonFH.Enable = "on";
    %                     app.TimiEditField_1.Enable = "on";
                        app.StemiNstemiLabel_1.Text = "NSTEMI";
                    case 2
                        app.CalculateTIMIButtonFH.Enable = "on";
    %                     app.TimiEditField_1.Enable = "on";
                        app.StemiNstemiLabel_1.Text = "STEMI";
                    case 3
                        app.CalculateTIMIButtonFH.Enable = "off";
                        app.TimiEditField_1.Enable = "on";
                        app.StemiNstemiLabel_1.Text = 'Select "Yes" or "No" in STEMI selector';
                end
            catch ME
                disp("STEMIDropDown_1ValueChangedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "STEMIDropDown_1ValueChanged", 'VariableNames', ["identifier" "line" "function_name"]));
            end
        end

        % Value changed function: STEMIDropDown_2
        function STEMIDropDown_2ValueChanged(app, event)
            try    
                value = app.STEMIDropDown_2.Value;
                switch value
                    case 1
                        app.CalculateTIMIButton_2.Enable = "on";
    %                     app.TimiEditField_2.Enable = "on";
                        app.StemiNstemiLabel_2.Text = "NSTEMI";
                    case 2
                        app.CalculateTIMIButton_2.Enable = "on";
    %                     app.TimiEditField_2.Enable = "on";
                        app.StemiNstemiLabel_2.Text = "STEMI";
                    case 3
                        app.CalculateTIMIButton_2.Enable = "off";
                        app.TimiEditField_2.Enable = "on";
                        app.StemiNstemiLabel_2.Text = 'Select "Yes" or "No" in STEMI selector';
                end
            catch ME
                disp("STEMIDropDown_2ValueChangedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "STEMIDropDown_2ValueChanged", 'VariableNames', ["identifier" "line" "function_name"]));
            end
        end

        % Value changed function: OptimizationAlgorithmDropDown
        function OptimizationAlgorithmDropDownValueChanged(app, event)
            try
                value = app.OptimizationAlgorithmDropDown.Value;
                if strcmp(value,'MultiStart')
                    app.MultistarPanel.Visible = "on";
                else
                    app.MultistarPanel.Visible = "off";
                end
            catch ME
                disp("OptimizationAlgorithmDropDownValueChangedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "OptimizationAlgorithmDropDownValueChanged", 'VariableNames', ["identifier" "line" "function_name"]));
            end
        end

        % Button down function: UsagemonitorTab
        function UsagemonitorTabButtonDown(app, event)
            %Setup page
            try
                app.FromDatePicker.Value = datetime('now') - calmonths(1);
                app.ToDatePicker.Value = datetime('now');
                pause(.2)
                app.SearchUsageButtonPushed;
            catch ME
                disp("UsagemonitorTabButtonDownError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "UsagemonitorTabButtonDown", 'VariableNames', ["identifier" "line" "function_name"]));
            end
        end

        % Button pushed function: SearchUsageButton
        function SearchUsageButtonPushed(app, event)
            progbar =  uiprogressdlg(app.UIFigure,'Title','Loading reports',...
                'Indeterminate','on');
            try
                datefrom = string(app.FromDatePicker.Value, 'yyyy-MM-dd');
                dateto = string(app.ToDatePicker.Value, 'yyyy-MM-dd');

                %% Retrive errors
                strSQL = strcat("SELECT COUNT(id) AS 'ErrorNumber' FROM error_log WHERE date_time BETWEEN '", ...
                       datefrom, " 00:00:01' AND '", dateto, " 23:59:59' ORDER by date_time DESC LIMIT 10");
                data = select(app.conn, strSQL); 
                app.RegisterederrorsEditField.Value = double(data.ErrorNumber);
                
                %% UsageChart
                tableres = [];
                for i=1:length(app.entity)
                    strSQL = strcat("SELECT COUNT(id) AS '", app.entity(i).action, "' FROM ", app.entity(i).name, " WHERE date_insert BETWEEN '", ...
                       datefrom, " 00:00:01' AND '", dateto, " 23:59:59' OR date_edit BETWEEN '", datefrom, " 00:00:01' AND '", dateto, " 23:59:59'");
                    data = select(app.conn, strSQL);
                    tableres = [tableres data];
                end
                categ = categorical(tableres.Properties.VariableNames);
                histogram(app.UsageAxes, 'Categories', categ,'BinCounts',tableres{1,:})
                xlabel(app.UsageAxes, 'Pages', 'Interpreter', 'latex', 'FontSize', 15)
                ylabel(app.UsageAxes,'', 'Interpreter', 'latex', 'FontSize', 15)
                set(app.UsageAxes,'FontSize',15)
                set(app.UsageAxes,'TickLabelInterpreter','latex')
                grid(app.UsageAxes, 'on')
                hold(app.UsageAxes, "off")

                %% UsageTab - Last 10 errors
                strSQL = strcat("SELECT date_time, identifier, function_name, line FROM error_log WHERE date_time BETWEEN '", datefrom, " 00:00:01' AND '", dateto, " 23:59:59' LIMIT 10");
                app.ErrorTable.Data = select(app.conn, strSQL);
                %% LoginChart
                strSQL = strcat('SELECT * FROM tp_utente');
                access_levels = select(app.conn, strSQL);
                tableres = [];
                for i=1:height(access_levels)
                    strSQL = strcat("SELECT COUNT(id) AS '", access_levels.nome(i), "' FROM login WHERE access_level = ", string(access_levels.id(i)),  ...
                        " AND date_time BETWEEN '", datefrom, " 00:00:01' AND '", dateto, " 23:59:59'");
                    data = select(app.conn, strSQL);
                    tableres = [tableres data];
                end
                explode = [1 1 1];
                pie(app.LoginAxes, tableres{1,:}, explode)
                legend(app.LoginAxes, {'Admin','Center','Clinician'}, 'Location', 'southeast')
                set(app.UsageAxes,'FontSize',15)
                set(app.UsageAxes,'TickLabelInterpreter','latex')

                %% Login Tab
                strSQL = strcat("SELECT date_time, logged_user, access_level FROM login WHERE date_time BETWEEN '", datefrom, " 00:00:01' AND '", dateto, " 23:59:59' ORDER by date_time DESC LIMIT 10");
                app.LoginTable.Data = select(app.conn, strSQL); 
            catch ME
                disp("SearchUsageButtonPushedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "SearchUsageButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
            end
            close(progbar)
        end

        % Button pushed function: ExportUsageButton
        function ExportUsageButtonPushed(app, event)
            progbar =  uiprogressdlg(app.UIFigure,'Title','Exporting reports',...
                'Indeterminate','on');
            try
                datefrom = string(app.FromDatePicker.Value, 'yyyy-MM-dd');
                dateto = string(app.ToDatePicker.Value, 'yyyy-MM-dd');

                strSQL = strcat("SELECT date_time, logged_user, access_level FROM login WHERE date_time BETWEEN '", datefrom, " 00:00:01' AND '", dateto, " 23:59:59'");
                loginData = select(app.conn, strSQL);
                strSQL = strcat("SELECT date_time, identifier, line, function_name FROM error_log WHERE date_time BETWEEN '", datefrom, " 00:00:01' AND '", dateto, " 23:59:59'");
                errorData = select(app.conn, strSQL);
                strSQL = strcat("SELECT evaluation_time, execution_time FROM login WHERE date_time BETWEEN '", datefrom, " 00:00:01' AND '", dateto, " 23:59:59'");
                loginTimes = select(app.conn, strSQL);
                strSQL = strcat("SELECT evaluation_time, execution_time FROM utente WHERE date_insert BETWEEN '", datefrom, " 00:00:01' AND '", dateto, " 23:59:59'");
                utenteTimes = select(app.conn, strSQL);
                strSQL = strcat("SELECT evaluation_time, execution_time FROM centro WHERE date_insert BETWEEN '", datefrom, " 00:00:01' AND '", dateto, " 23:59:59'");
                centroTimes = select(app.conn, strSQL);
                strSQL = strcat("SELECT evaluation_time, execution_time FROM paziente WHERE date_insert BETWEEN '", datefrom, " 00:00:01' AND '", dateto, " 23:59:59'");
                pazienteTimes = select(app.conn, strSQL);
                strSQL = strcat("SELECT evaluation_time, execution_time FROM hospitalization WHERE date_insert BETWEEN '", datefrom, " 00:00:01' AND '", dateto, " 23:59:59'");
                hospitalizationTimes = select(app.conn, strSQL);
                strSQL = strcat("SELECT evaluation_time, execution_time FROM acquisizione WHERE date_insert BETWEEN '", datefrom, " 00:00:01' AND '", dateto, " 23:59:59'");
                acquisizioneTimes = select(app.conn, strSQL);
                strSQL = strcat("SELECT evaluation_time, execution_time FROM analysis_result WHERE date_insert BETWEEN '", datefrom, " 00:00:01' AND '", dateto, " 23:59:59'");
                analysisTimes = select(app.conn, strSQL);

                meanLogin = mean(loginTimes{:,:}); %1 evaluation, 2 execution
                resposneLogin = meanLogin(2) - meanLogin(1);
%                 disp(meanLogin)
%                 disp(resposneLogin)

                meanWriteTimesTab = [utenteTimes;centroTimes;pazienteTimes;hospitalizationTimes;acquisizioneTimes;analysisTimes];
                meanWrite = mean(meanWriteTimesTab{:,:});
                resposneWrite = meanWrite(2) - meanWrite(1);
%                 disp(meanWrite)
%                 disp(resposneWrite)

                filter = {'*.txt';};
                [file, path] = uiputfile(filter);
                if ~(path == 0)
                    filename = strcat(path, file);
%                     [filepath,name,ext] = fileparts(filename);
                    writelines(compose("REPORT: " + string(datetime("now"), 'dd/MM/yyyy hh:mm:ss') + " Logins by " + datefrom + " to " + dateto + "\n" ), filename);
                    % add intestation on file
                    writetable(loginData,filename,'Delimiter','|','WriteMode','Append','WriteVariableNames',true);
                    writelines(compose("\n" + "REPORT: " + string(datetime("now"), 'dd/MM/yyyy hh:mm:ss') + " Errors by " + datefrom + " to " + dateto + "\n"), filename, WriteMode="append");
                    % add empty row 
                    writetable(errorData,filename,'Delimiter','|','WriteMode','Append','WriteVariableNames',true);
                    writelines(compose("\n" + "- Login time execution mean: " + string(meanLogin(2)) + " seconds;"), filename, WriteMode="append");
                    writelines(compose("- Login time evaluation query mean: " + string(meanLogin(1)) + " seconds;"), filename, WriteMode="append");
                    writelines(compose("- Login time resposnse query mean: " + string(resposneLogin) + " seconds;"), filename, WriteMode="append");
                    writelines(compose("\n" + "- Write time execution mean: " + string(meanWrite(2)) + " seconds;"), filename, WriteMode="append");
                    writelines(compose("- Write time evaluation query mean: " + string(meanWrite(1)) + " seconds;"), filename, WriteMode="append");
                    writelines(compose("- Write time resposnse query mean: " + string(resposneWrite) + " seconds."), filename, WriteMode="append");
                    disp(strcat("Export saved in: ", filename));
                end
            catch ME
                disp("ExportUsageButtonPushedError")
                disp(ME)
                line = max([ME.stack(1:end).line]);
                sqlwrite(app.conn, "error_log", table(string(ME.identifier), line, "ExportUsageButtonPushed", 'VariableNames', ["identifier" "line" "function_name"]));
            end
            close(progbar)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CBRACardiacBiomarkersReleaseAnalyzerUMGLabel = uilabel(app.UIFigure);
            app.CBRACardiacBiomarkersReleaseAnalyzerUMGLabel.Text = 'CBRA - Cardiac Biomarkers Release Analyzer. UMG';
            app.LogoutButton = uibutton(app.UIFigure, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Text = 'Logout';
            app.ModeDropDown = uidropdown(app.UIFigure);
            app.ModeDropDown.Items = {'Admin', 'Clinician (advanced)'};
            app.ModeDropDown.ItemsData = [1 2];
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Visible = 'off';
            app.ModeDropDown.Value = 1;
            app.ModeLabel = uilabel(app.UIFigure);
            app.ModeLabel.Visible = 'off';
            app.ModeLabel.Text = 'Mode';
            app.ClinicView = uitabgroup(app.UIFigure);
            app.ClinicView.Visible = 'off';
            app.InsertPatientTab = uitab(app.ClinicView);
            app.InsertPatientTab.Title = 'Insert Patient';
            app.InsertPatientTab.ButtonDownFcn = createCallbackFcn(app, @InsertPatientTabButtonDown, true);
            app.InsertPatientPanel = uipanel(app.InsertPatientTab);
            app.PatientPersonalDataPanel = uipanel(app.InsertPatientPanel);
            app.PatientPersonalDataPanel.Title = 'Patient Personal Data';
            app.NameEditFieldLabel = uilabel(app.PatientPersonalDataPanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.PatientPersonalDataPanel, 'text');
            app.SurnameEditFieldLabel = uilabel(app.PatientPersonalDataPanel);
            app.SurnameEditFieldLabel.Text = 'Surname';
            app.SurnameEditField = uieditfield(app.PatientPersonalDataPanel, 'text');
            app.SurnameEditFieldLabel_2 = uilabel(app.PatientPersonalDataPanel);
            app.SurnameEditFieldLabel_2.Text = 'Birth Place';
            app.BirthPlaceEditField = uidropdown(app.PatientPersonalDataPanel);
            app.BirthPlaceEditField.Items = {};
            app.BirthPlaceEditField.Editable = 'on';
            app.BirthPlaceEditField.Value = {};
            app.BirthyDateLabel = uilabel(app.PatientPersonalDataPanel);
            app.BirthyDateLabel.Text = 'Birthy Date';
            app.BirthDateEditField = uidatepicker(app.PatientPersonalDataPanel);
            app.BirthDateEditField.Limits = [datetime([1850 1 1]) datetime([9999 12 31])];
            app.BirthDateEditField.DisplayFormat = 'dd/MMM/uuuu';
            app.BirthDateEditField.Placeholder = 'dd/MM/yyyy';
            app.BirthDateEditField.Value = datetime([1850 1 1]);
            app.AddressEditFieldLabel = uilabel(app.PatientPersonalDataPanel);
            app.AddressEditFieldLabel.Text = 'Address';
            app.AddressEditField = uieditfield(app.PatientPersonalDataPanel, 'text');
            app.GenderButtonGroup = uibuttongroup(app.PatientPersonalDataPanel);
            app.GenderButtonGroup.Title = 'Gender';
            app.MButton = uiradiobutton(app.GenderButtonGroup);
            app.MButton.Text = 'M';
            app.MButton.Value = true;
            app.FButton = uiradiobutton(app.GenderButtonGroup);
            app.FButton.Text = 'F';
            app.CalculateFiscalCodeButton = uibutton(app.PatientPersonalDataPanel, 'push');
            app.CalculateFiscalCodeButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateFiscalCodeButtonPushed, true);
            app.CalculateFiscalCodeButton.Text = 'Calculate Fiscal Code';
            app.PatientIDFCLabel_1 = uilabel(app.PatientPersonalDataPanel);
            app.PatientIDFCLabel_1.Text = 'Patient ID (F.C.)';
            app.SSNEditField = uieditfield(app.PatientPersonalDataPanel, 'text');
            app.SelectCenterAdminDropDownLabel = uilabel(app.PatientPersonalDataPanel);
            app.SelectCenterAdminDropDownLabel.Visible = 'off';
            app.SelectCenterAdminDropDownLabel.Text = 'Select Center (Admin)';
            app.SelectCenterAdminDropDown = uidropdown(app.PatientPersonalDataPanel);
            app.SelectCenterAdminDropDown.Items = {};
            app.SelectCenterAdminDropDown.Visible = 'off';
            app.SelectCenterAdminDropDown.Value = {};
            app.SalvaNuovoPazienteButton = uibutton(app.PatientPersonalDataPanel, 'push');
            app.SalvaNuovoPazienteButton.ButtonPushedFcn = createCallbackFcn(app, @SalvaNuovoPazienteButtonPushed, true);
            app.SalvaNuovoPazienteButton.Text = 'Save patient data';
            app.ResetButton = uibutton(app.PatientPersonalDataPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.DataMessageText = uitextarea(app.PatientPersonalDataPanel);
            app.DataMessageText.Editable = 'off';
            app.DataMessageText.Visible = 'off';
            app.PersonalDataSavingStatus = uilamp(app.PatientPersonalDataPanel);
            app.PersonalDataSavingStatus.Visible = 'off';
            app.IndexHospitalizationPanel = uipanel(app.InsertPatientPanel);
            app.IndexHospitalizationPanel.Title = 'Index Hospitalization';
            app.IndexHospitalizationPanel.Visible = 'off';
            app.DateandtimehospitalizationPanel_1 = uipanel(app.IndexHospitalizationPanel);
            app.DateandtimehospitalizationPanel_1.Title = 'Date and time hospitalization';
            app.DateDatePickerLabel = uilabel(app.DateandtimehospitalizationPanel_1);
            app.DateDatePickerLabel.Text = 'Date';
            app.HDateH_1 = uidatepicker(app.DateandtimehospitalizationPanel_1);
            app.HourSpinnerLabel = uilabel(app.DateandtimehospitalizationPanel_1);
            app.HourSpinnerLabel.Text = 'Hour';
            app.HhourH_1 = uispinner(app.DateandtimehospitalizationPanel_1);
            app.HhourH_1.Limits = [0 23];
            app.HminuteH_1 = uispinner(app.DateandtimehospitalizationPanel_1);
            app.HminuteH_1.Limits = [0 59];
            app.HsecondH_1 = uispinner(app.DateandtimehospitalizationPanel_1);
            app.HsecondH_1.Limits = [0 59];
            app.HhLabel_1 = uilabel(app.DateandtimehospitalizationPanel_1);
            app.HhLabel_1.Text = 'h';
            app.HmLabel_1 = uilabel(app.DateandtimehospitalizationPanel_1);
            app.HmLabel_1.Text = 'm';
            app.HsLabel_1 = uilabel(app.DateandtimehospitalizationPanel_1);
            app.HsLabel_1.Text = 's';
            app.DateandtimeAMIPanel_1 = uipanel(app.IndexHospitalizationPanel);
            app.DateandtimeAMIPanel_1.Title = 'Date and time AMI';
            app.DateLabel = uilabel(app.DateandtimeAMIPanel_1);
            app.DateLabel.Text = 'Date';
            app.DateEvent_1 = uidatepicker(app.DateandtimeAMIPanel_1);
            app.HourLabel_1 = uilabel(app.DateandtimeAMIPanel_1);
            app.HourLabel_1.Text = 'Hour';
            app.AMIhourEv_1 = uispinner(app.DateandtimeAMIPanel_1);
            app.AMIhourEv_1.Limits = [0 23];
            app.AMIminuteEv_1 = uispinner(app.DateandtimeAMIPanel_1);
            app.AMIminuteEv_1.Limits = [0 59];
            app.AMIsecondEv_1 = uispinner(app.DateandtimeAMIPanel_1);
            app.AMIsecondEv_1.Limits = [0 59];
            app.AMIhLabel_1 = uilabel(app.DateandtimeAMIPanel_1);
            app.AMIhLabel_1.Text = 'h';
            app.AMImLabel_1 = uilabel(app.DateandtimeAMIPanel_1);
            app.AMImLabel_1.Text = 'm';
            app.AMIsLabel_1 = uilabel(app.DateandtimeAMIPanel_1);
            app.AMIsLabel_1.Text = 's';
            app.DateandtimeThrombolysisPanel_1 = uipanel(app.IndexHospitalizationPanel);
            app.DateandtimeThrombolysisPanel_1.Title = 'Date and time Thrombolysis';
            app.DateLabel_2 = uilabel(app.DateandtimeThrombolysisPanel_1);
            app.DateLabel_2.Text = 'Date';
            app.DateTromb_1 = uidatepicker(app.DateandtimeThrombolysisPanel_1);
            app.TrombHourLabel_1 = uilabel(app.DateandtimeThrombolysisPanel_1);
            app.TrombHourLabel_1.Text = 'Hour';
            app.TrombHour_1 = uispinner(app.DateandtimeThrombolysisPanel_1);
            app.TrombHour_1.Limits = [0 23];
            app.Trombminute_1 = uispinner(app.DateandtimeThrombolysisPanel_1);
            app.Trombminute_1.Limits = [0 59];
            app.Trombsecond_1 = uispinner(app.DateandtimeThrombolysisPanel_1);
            app.Trombsecond_1.Limits = [0 59];
            app.TrombhLabel_1 = uilabel(app.DateandtimeThrombolysisPanel_1);
            app.TrombhLabel_1.Text = 'h';
            app.TrombmLabel_1 = uilabel(app.DateandtimeThrombolysisPanel_1);
            app.TrombmLabel_1.Text = 'm';
            app.TrombsLabel_1 = uilabel(app.DateandtimeThrombolysisPanel_1);
            app.TrombsLabel_1.Text = 's';
            app.DrugEditFieldLabel = uilabel(app.DateandtimeThrombolysisPanel_1);
            app.DrugEditFieldLabel.Text = 'Drug';
            app.DrugEditField_1 = uieditfield(app.DateandtimeThrombolysisPanel_1, 'text');
            app.DateandtimeRevascularizationPanel_1 = uipanel(app.IndexHospitalizationPanel);
            app.DateandtimeRevascularizationPanel_1.Title = 'Date and time Revascularization';
            app.DateLabel_3 = uilabel(app.DateandtimeRevascularizationPanel_1);
            app.DateLabel_3.Text = 'Date';
            app.DateRev = uidatepicker(app.DateandtimeRevascularizationPanel_1);
            app.RevHourLabel_1 = uilabel(app.DateandtimeRevascularizationPanel_1);
            app.RevHourLabel_1.Text = 'Hour';
            app.Revhour_1 = uispinner(app.DateandtimeRevascularizationPanel_1);
            app.Revhour_1.Limits = [0 23];
            app.Revminute_1 = uispinner(app.DateandtimeRevascularizationPanel_1);
            app.Revminute_1.Limits = [0 59];
            app.Revsecond_1 = uispinner(app.DateandtimeRevascularizationPanel_1);
            app.Revsecond_1.Limits = [0 59];
            app.RevhLabel_1 = uilabel(app.DateandtimeRevascularizationPanel_1);
            app.RevhLabel_1.Text = 'h';
            app.RevmLabel_1 = uilabel(app.DateandtimeRevascularizationPanel_1);
            app.RevmLabel_1.Text = 'm';
            app.RevsLabel_1 = uilabel(app.DateandtimeRevascularizationPanel_1);
            app.RevsLabel_1.Text = 's';
            app.LocoEditFieldLabel = uilabel(app.DateandtimeRevascularizationPanel_1);
            app.LocoEditFieldLabel.Text = 'Loco';
            app.LocoEditField_1 = uieditfield(app.DateandtimeRevascularizationPanel_1, 'text');
            app.ClinicalinformationPanel_1 = uipanel(app.IndexHospitalizationPanel);
            app.ClinicalinformationPanel_1.Title = 'Clinical information';
            app.CalculateAgeButton_1 = uibutton(app.ClinicalinformationPanel_1, 'push');
            app.CalculateAgeButton_1.ButtonPushedFcn = createCallbackFcn(app, @CalculateAgeButton_1Pushed, true);
            app.CalculateAgeButton_1.Icon = fullfile(pathToMLAPP, 'refresh.png');
            app.CalculateAgeButton_1.Text = '';
            app.STEMIDropDown_2Label = uilabel(app.ClinicalinformationPanel_1);
            app.STEMIDropDown_2Label.Text = 'STEMI';
            app.AgeLabel = uilabel(app.ClinicalinformationPanel_1);
            app.AgeLabel.Text = 'Age';
            app.WieightKgLabel = uilabel(app.ClinicalinformationPanel_1);
            app.WieightKgLabel.Text = 'Wieight [Kg]';
            app.SystolicBPLabel = uilabel(app.ClinicalinformationPanel_1);
            app.SystolicBPLabel.Text = 'Systolic BP [mmHg]';
            app.HRbpmEditFieldLabel = uilabel(app.ClinicalinformationPanel_1);
            app.HRbpmEditFieldLabel.Text = 'HR [bpm]';
            app.TimetotreatmhoursEditFieldLabel = uilabel(app.ClinicalinformationPanel_1);
            app.TimetotreatmhoursEditFieldLabel.Text = 'Time to treatm. [hours]';
            app.DyastolicBPmmHgEditFieldLabel = uilabel(app.ClinicalinformationPanel_1);
            app.DyastolicBPmmHgEditFieldLabel.Text = 'Dyastolic BP [mmHg]';
            app.DiabeteLabel = uilabel(app.ClinicalinformationPanel_1);
            app.DiabeteLabel.Text = 'Diabetes';
            app.SmokeDropDownLabel = uilabel(app.ClinicalinformationPanel_1);
            app.SmokeDropDownLabel.Text = 'Smoke';
            app.ObesityDropDownLabel = uilabel(app.ClinicalinformationPanel_1);
            app.ObesityDropDownLabel.Text = 'Obesity';
            app.KillipDropDownLabel = uilabel(app.ClinicalinformationPanel_1);
            app.KillipDropDownLabel.Text = 'Killip';
            app.LBBBDropDownLabel = uilabel(app.ClinicalinformationPanel_1);
            app.LBBBDropDownLabel.Text = 'LBBB';
            app.PrevAMILabel_2 = uilabel(app.ClinicalinformationPanel_1);
            app.PrevAMILabel_2.Text = 'Prev. CAD';
            app.FamiliarityDropDownLabel = uilabel(app.ClinicalinformationPanel_1);
            app.FamiliarityDropDownLabel.Text = 'Familiarity';
            app.DyslipidemiaDropDownLabel = uilabel(app.ClinicalinformationPanel_1);
            app.DyslipidemiaDropDownLabel.Text = 'Dyslipidemia';
            app.HypertensDropDownLabel = uilabel(app.ClinicalinformationPanel_1);
            app.HypertensDropDownLabel.Text = 'Hypertens.';
            app.HypercolLabel = uilabel(app.ClinicalinformationPanel_1);
            app.HypercolLabel.Text = 'Hypercol.';
            app.PositivecardiacmarkerDropDownLabel = uilabel(app.ClinicalinformationPanel_1);
            app.PositivecardiacmarkerDropDownLabel.Text = 'Positive cardiac marker';
            app.ASAusein7daysDropDownLabel = uilabel(app.ClinicalinformationPanel_1);
            app.ASAusein7daysDropDownLabel.Text = 'ASA use in 7 days';
            app.Anginaepisodesin24hEditFieldLabel = uilabel(app.ClinicalinformationPanel_1);
            app.Anginaepisodesin24hEditFieldLabel.Text = 'Angina episodes in 24h';
            app.StenosisEditFieldLabel = uilabel(app.ClinicalinformationPanel_1);
            app.StenosisEditFieldLabel.Text = 'Stenosis [%]';
            app.stMedCoDatePickerLabel = uilabel(app.ClinicalinformationPanel_1);
            app.stMedCoDatePickerLabel.Text = '1st Med. Co.';
            app.StemiNstemiLabel_1 = uilabel(app.ClinicalinformationPanel_1);
            app.StemiNstemiLabel_1.Text = 'Select "Yes" or "No" in STEMI selector';
            app.EKGSTdevmmEditField_3Label = uilabel(app.ClinicalinformationPanel_1);
            app.EKGSTdevmmEditField_3Label.Text = 'EKG ST dev. [mm]';
            app.EtaEditField_1 = uieditfield(app.ClinicalinformationPanel_1, 'numeric');
            app.WeightEditField_1 = uieditfield(app.ClinicalinformationPanel_1, 'numeric');
            app.STEMIDropDown_1 = uidropdown(app.ClinicalinformationPanel_1);
            app.STEMIDropDown_1.Items = {'No', 'Yes', 'Unknown'};
            app.STEMIDropDown_1.ItemsData = [1 2 3];
            app.STEMIDropDown_1.ValueChangedFcn = createCallbackFcn(app, @STEMIDropDown_1ValueChanged, true);
            app.STEMIDropDown_1.Value = 3;
            app.SystolicBPmmHgEditField_1 = uieditfield(app.ClinicalinformationPanel_1, 'numeric');
            app.DyastolicBPmmHgEditField_1 = uieditfield(app.ClinicalinformationPanel_1, 'numeric');
            app.HRbpmEditField_1 = uieditfield(app.ClinicalinformationPanel_1, 'numeric');
            app.TimetotreatmhoursEditField_1 = uieditfield(app.ClinicalinformationPanel_1, 'numeric');
            app.PositivecardiacmarkerDropDown_1 = uidropdown(app.ClinicalinformationPanel_1);
            app.PositivecardiacmarkerDropDown_1.Items = {'No', 'Yes', 'Unknown'};
            app.PositivecardiacmarkerDropDown_1.ItemsData = [1 2 3];
            app.PositivecardiacmarkerDropDown_1.Value = 1;
            app.stMedCoDate_1 = uidatepicker(app.ClinicalinformationPanel_1);
            app.DiabeteDropDown_1 = uidropdown(app.ClinicalinformationPanel_1);
            app.DiabeteDropDown_1.Items = {'No', 'Yes', 'Unknown'};
            app.DiabeteDropDown_1.ItemsData = [1 2 3];
            app.DiabeteDropDown_1.Value = 3;
            app.FamiliarityDropDown_1 = uidropdown(app.ClinicalinformationPanel_1);
            app.FamiliarityDropDown_1.Items = {'No', 'Yes', 'Unknown'};
            app.FamiliarityDropDown_1.ItemsData = [1 2 3];
            app.FamiliarityDropDown_1.Value = 3;
            app.ObesityDropDown_1 = uidropdown(app.ClinicalinformationPanel_1);
            app.ObesityDropDown_1.Items = {'No', 'Yes', 'Unknown'};
            app.ObesityDropDown_1.ItemsData = [1 2 3];
            app.ObesityDropDown_1.Value = 3;
            app.PrevCADDropDown_1 = uidropdown(app.ClinicalinformationPanel_1);
            app.PrevCADDropDown_1.Items = {'No', 'Yes', 'Unknown'};
            app.PrevCADDropDown_1.ItemsData = [1 2 3];
            app.PrevCADDropDown_1.Value = 3;
            app.SmokeDropDown_1 = uidropdown(app.ClinicalinformationPanel_1);
            app.SmokeDropDown_1.Items = {'No', 'Yes', 'Unknown'};
            app.SmokeDropDown_1.ItemsData = [1 2 3];
            app.SmokeDropDown_1.Value = 3;
            app.DyslipidemiaDropDown = uidropdown(app.ClinicalinformationPanel_1);
            app.DyslipidemiaDropDown.Items = {'No', 'Yes', 'Unknown'};
            app.DyslipidemiaDropDown.ItemsData = [1 2 3];
            app.DyslipidemiaDropDown.Value = 3;
            app.HypertensDropDown_1 = uidropdown(app.ClinicalinformationPanel_1);
            app.HypertensDropDown_1.Items = {'No', 'Yes', 'Unknown'};
            app.HypertensDropDown_1.ItemsData = [1 2 3];
            app.HypertensDropDown_1.Value = 3;
            app.KillipDropDown_1 = uidropdown(app.ClinicalinformationPanel_1);
            app.KillipDropDown_1.Items = {'I', 'II', 'III', 'IV'};
            app.KillipDropDown_1.ItemsData = [1 2 3 4];
            app.KillipDropDown_1.Value = 1;
            app.HypercolDropDown_1 = uidropdown(app.ClinicalinformationPanel_1);
            app.HypercolDropDown_1.Items = {'No', 'Yes', 'Unknown'};
            app.HypercolDropDown_1.ItemsData = [1 2 3];
            app.HypercolDropDown_1.Value = 1;
            app.LBBBDropDown_1 = uidropdown(app.ClinicalinformationPanel_1);
            app.LBBBDropDown_1.Items = {'No', 'Yes', 'Unknown'};
            app.LBBBDropDown_1.ItemsData = [1 2 3];
            app.LBBBDropDown_1.Value = 3;
            app.ASAusein7daysDropDown_1 = uidropdown(app.ClinicalinformationPanel_1);
            app.ASAusein7daysDropDown_1.Items = {'No', 'Yes', 'Unknown'};
            app.ASAusein7daysDropDown_1.ItemsData = [1 2 3];
            app.ASAusein7daysDropDown_1.Value = 3;
            app.EKGSTdevmmEditField_1 = uieditfield(app.ClinicalinformationPanel_1, 'numeric');
            app.Anginaepisodesin24hEditField_1 = uieditfield(app.ClinicalinformationPanel_1, 'numeric');
            app.StenosisEditField_1 = uieditfield(app.ClinicalinformationPanel_1, 'numeric');
            app.CalculateTIMIButtonFH = uibutton(app.ClinicalinformationPanel_1, 'push');
            app.CalculateTIMIButtonFH.ButtonPushedFcn = createCallbackFcn(app, @CalculateTIMIButtonFHPushed, true);
            app.CalculateTIMIButtonFH.Text = '  Calculate TIMI';
            app.TimiEditField_1 = uieditfield(app.ClinicalinformationPanel_1, 'numeric');
            app.SalvaPrimoRicoveroButton_1 = uibutton(app.IndexHospitalizationPanel, 'push');
            app.SalvaPrimoRicoveroButton_1.ButtonPushedFcn = createCallbackFcn(app, @SaveFirstHospitalizationButtonPushed, true);
            app.SalvaPrimoRicoveroButton_1.Text = 'Save hospitalization data';
            app.ResetButtonFH_1 = uibutton(app.IndexHospitalizationPanel, 'push');
            app.ResetButtonFH_1.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonFH_1Pushed, true);
            app.ResetButtonFH_1.Text = 'Reset';
            app.HospitalizationSavingStatus_1 = uilamp(app.IndexHospitalizationPanel);
            app.HospitalizationSavingStatus_1.Visible = 'off';
            app.HospitalizationText_1 = uitextarea(app.IndexHospitalizationPanel);
            app.HospitalizationText_1.Editable = 'off';
            app.HospitalizationText_1.Visible = 'off';
            app.ManagePatientsTab = uitab(app.ClinicView);
            app.ManagePatientsTab.Title = 'Manage Patients';
            app.ManagePatientsTab.ButtonDownFcn = createCallbackFcn(app, @ManagePatientsTabButtonDown, true);
            app.ManagePatientPanel = uipanel(app.ManagePatientsTab);
            app.AnalysisPage = uipanel(app.ManagePatientPanel);
            app.AnalysisPage.Title = 'Cardiac Marker Trend';
            app.AnalysisPage.Visible = 'off';
            app.AnalysisContainer = uipanel(app.AnalysisPage);
            app.HomeButtonFAn = uibutton(app.AnalysisContainer, 'push');
            app.HomeButtonFAn.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButtonFAn.Icon = 'homeIco.png';
            app.HomeButtonFAn.Text = 'Home';
            app.HospitalizationButtonFAn = uibutton(app.AnalysisContainer, 'push');
            app.HospitalizationButtonFAn.ButtonPushedFcn = createCallbackFcn(app, @HospitalizationButtonPushed, true);
            app.HospitalizationButtonFAn.Icon = 'hospIco.png';
            app.HospitalizationButtonFAn.Enable = 'off';
            app.HospitalizationButtonFAn.Text = 'Patient Profile (I.E.)';
            app.AcquisitionsButtonFAn = uibutton(app.AnalysisContainer, 'push');
            app.AcquisitionsButtonFAn.ButtonPushedFcn = createCallbackFcn(app, @AcquisitionsButtonPushed, true);
            app.AcquisitionsButtonFAn.Icon = 'measIco.png';
            app.AcquisitionsButtonFAn.Enable = 'off';
            app.AcquisitionsButtonFAn.Text = 'Lab. Data';
            app.MarkerLabel = uilabel(app.AnalysisContainer);
            app.MarkerLabel.Text = 'Marker';
            app.AnalysisTypeDropDown = uidropdown(app.AnalysisContainer);
            app.AnalysisTypeDropDown.Items = {'cTnT'};
            app.AnalysisTypeDropDown.Value = 'cTnT';
            app.ParametersSettingsPanel = uipanel(app.AnalysisContainer);
            app.ParametersSettingsPanel.Title = 'Parameters Settings';
            app.ParametersSettingsPanel.Visible = 'off';
            app.LowerBoundsLabel = uilabel(app.ParametersSettingsPanel);
            app.LowerBoundsLabel.Text = 'Lower Bounds';
            app.UpperBoundsLabel = uilabel(app.ParametersSettingsPanel);
            app.UpperBoundsLabel.Text = 'Upper Bounds';
            app.InitialLabel = uilabel(app.ParametersSettingsPanel);
            app.InitialLabel.Text = 'Initial';
            app.aSpinnerLabel = uilabel(app.ParametersSettingsPanel);
            app.aSpinnerLabel.Text = 'a';
            app.bSpinnerLabel = uilabel(app.ParametersSettingsPanel);
            app.bSpinnerLabel.Text = 'b';
            app.Cs0SpinnerLabel = uilabel(app.ParametersSettingsPanel);
            app.Cs0SpinnerLabel.Text = 'Cs0';
            app.Cc0SpinnerLabel = uilabel(app.ParametersSettingsPanel);
            app.Cc0SpinnerLabel.Text = 'Cc0';
            app.IterationsnumberSpinnerLabel = uilabel(app.ParametersSettingsPanel);
            app.IterationsnumberSpinnerLabel.Text = 'Iterations number';
            app.OptimizationAlgorithmLabel = uilabel(app.ParametersSettingsPanel);
            app.OptimizationAlgorithmLabel.Text = {'Optimization'; 'Algorithm'};
            app.TdSpinnerLabel = uilabel(app.ParametersSettingsPanel);
            app.TdSpinnerLabel.Text = 'Td';
            app.aLowerSpinner = uispinner(app.ParametersSettingsPanel);
            app.aLowerSpinner.Step = 0.044;
            app.aLowerSpinner.ValueChangingFcn = createCallbackFcn(app, @aLowerSpinnerValueChanging, true);
            app.aLowerSpinner.Limits = [0.001 4.5];
            app.aLowerSpinner.Value = 0.001;
            app.aUpperSpinner = uispinner(app.ParametersSettingsPanel);
            app.aUpperSpinner.Step = 0.05;
            app.aUpperSpinner.Limits = [0.006 5];
            app.aUpperSpinner.Value = 5;
            app.aInitialEditField = uieditfield(app.ParametersSettingsPanel, 'numeric');
            app.aInitialEditField.Editable = 'off';
            app.aInitialEditField.Value = 0.005;
            app.bLowerSpinner = uispinner(app.ParametersSettingsPanel);
            app.bLowerSpinner.Step = 0.044;
            app.bLowerSpinner.ValueChangingFcn = createCallbackFcn(app, @bLowerSpinnerValueChanging, true);
            app.bLowerSpinner.Limits = [0.001 4.5];
            app.bLowerSpinner.Value = 0.001;
            app.bUpperSpinner = uispinner(app.ParametersSettingsPanel);
            app.bUpperSpinner.Step = 0.005;
            app.bUpperSpinner.Limits = [0.006 5];
            app.bUpperSpinner.Value = 5;
            app.bInitialEditField = uieditfield(app.ParametersSettingsPanel, 'numeric');
            app.bInitialEditField.Editable = 'off';
            app.bInitialEditField.Value = 0.005;
            app.TdLowerSpinner = uispinner(app.ParametersSettingsPanel);
            app.TdLowerSpinner.Step = 2.6;
            app.TdLowerSpinner.ValueChangingFcn = createCallbackFcn(app, @TdLowerSpinnerValueChanging, true);
            app.TdLowerSpinner.Limits = [20 280];
            app.TdLowerSpinner.Value = 20;
            app.TdUpperSpinner = uispinner(app.ParametersSettingsPanel);
            app.TdUpperSpinner.Step = 0.2;
            app.TdUpperSpinner.Limits = [-Inf 300];
            app.TdUpperSpinner.Value = 300;
            app.TdInitialEditField = uieditfield(app.ParametersSettingsPanel, 'numeric');
            app.TdInitialEditField.Editable = 'off';
            app.TdInitialEditField.Value = 30;
            app.Cs0LowerSpinner = uispinner(app.ParametersSettingsPanel);
            app.Cs0LowerSpinner.ValueChangingFcn = createCallbackFcn(app, @Cs0LowerSpinnerValueChanging, true);
            app.Cs0LowerSpinner.Limits = [0.001 180];
            app.Cs0LowerSpinner.Value = 0.001;
            app.Cs0UpperSpinner = uispinner(app.ParametersSettingsPanel);
            app.Cs0UpperSpinner.Step = 0.2;
            app.Cs0UpperSpinner.Limits = [0.002 200];
            app.Cs0UpperSpinner.Value = 200;
            app.Cs0InitialEditField = uieditfield(app.ParametersSettingsPanel, 'numeric');
            app.Cs0InitialEditField.Editable = 'off';
            app.Cs0InitialEditField.Value = 0.1;
            app.Cc0LowerSpinner = uispinner(app.ParametersSettingsPanel);
            app.Cc0LowerSpinner.Step = 3.799;
            app.Cc0LowerSpinner.ValueChangingFcn = createCallbackFcn(app, @Cc0LowerSpinnerValueChanging, true);
            app.Cc0LowerSpinner.Limits = [0.1 380];
            app.Cc0LowerSpinner.Value = 0.1;
            app.Cc0UpperSpinner = uispinner(app.ParametersSettingsPanel);
            app.Cc0UpperSpinner.Step = 0.2;
            app.Cc0UpperSpinner.Limits = [-Inf 400];
            app.Cc0UpperSpinner.Value = 400;
            app.Cc0InitialEditField = uieditfield(app.ParametersSettingsPanel, 'numeric');
            app.Cc0InitialEditField.Editable = 'off';
            app.Cc0InitialEditField.Value = 1;
            app.IterationsnumberSpinner = uispinner(app.ParametersSettingsPanel);
            app.IterationsnumberSpinner.Value = 25;
            app.OptimizationAlgorithmDropDown = uidropdown(app.ParametersSettingsPanel);
            app.OptimizationAlgorithmDropDown.Items = {'MultiStart', 'Particle Swarm'};
            app.OptimizationAlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimizationAlgorithmDropDownValueChanged, true);
            app.OptimizationAlgorithmDropDown.Enable = 'off';
            app.OptimizationAlgorithmDropDown.Value = 'MultiStart';
            app.MultistarPanel = uipanel(app.ParametersSettingsPanel);
            app.MultistarPanel.Title = 'Multistar';
            app.LocaloptimizationalgorithmLabel = uilabel(app.MultistarPanel);
            app.LocaloptimizationalgorithmLabel.Text = 'Local optimization algorithm';
            app.LocaloptimizationalgorithmDropDown = uidropdown(app.MultistarPanel);
            app.LocaloptimizationalgorithmDropDown.Items = {'fmincon', 'lscqurvefit'};
            app.LocaloptimizationalgorithmDropDown.Enable = 'off';
            app.LocaloptimizationalgorithmDropDown.Value = 'fmincon';
            app.ResultsPanel = uipanel(app.AnalysisContainer);
            app.ResultsPanel.Title = 'Results';
            app.ResultsPanel.Visible = 'off';
            app.PredictedValuesPanel = uipanel(app.ResultsPanel);
            app.PredictedValuesPanel.Title = 'Predicted Values';
            app.PredictedValuesPanel.Visible = 'off';
            app.apLabel = uilabel(app.PredictedValuesPanel);
            app.apLabel.Text = 'a';
            app.ap_value = uilabel(app.PredictedValuesPanel);
            app.ap_value.Text = '';
            app.bpLabel = uilabel(app.PredictedValuesPanel);
            app.bpLabel.Text = 'b';
            app.bp_value = uilabel(app.PredictedValuesPanel);
            app.bp_value.Text = '';
            app.TdpLabel = uilabel(app.PredictedValuesPanel);
            app.TdpLabel.Text = 'Td';
            app.Tdp_value = uilabel(app.PredictedValuesPanel);
            app.Tdp_value.Text = '';
            app.Cs0pLabel = uilabel(app.PredictedValuesPanel);
            app.Cs0pLabel.Text = 'Cs0';
            app.Cs0p_value = uilabel(app.PredictedValuesPanel);
            app.Cs0p_value.Text = '';
            app.Cc0pLabel = uilabel(app.PredictedValuesPanel);
            app.Cc0pLabel.Text = 'Cc0';
            app.Cc0p_value = uilabel(app.PredictedValuesPanel);
            app.Cc0p_value.Text = '';
            app.TimeLabel = uilabel(app.PredictedValuesPanel);
            app.TimeLabel.Text = 'Time';
            app.Time_value = uilabel(app.PredictedValuesPanel);
            app.Time_value.Text = '';
            app.SelectAnalysis = uidropdown(app.ResultsPanel);
            app.SelectAnalysis.Items = {};
            app.SelectAnalysis.ValueChangedFcn = createCallbackFcn(app, @SelectAnalysisValueChanged, true);
            app.SelectAnalysis.Value = {};
            app.FittingResult = uipanel(app.ResultsPanel);
            app.FittingResult.Title = 'Fitting Results';
            app.FirstPeakValueLabel = uilabel(app.FittingResult);
            app.FirstPeakValueLabel.Text = 'First Peak Value:';
            app.FPV_value = uilabel(app.FittingResult);
            app.FPV_value.Text = '';
            app.SecondPeakValueLabel = uilabel(app.FittingResult);
            app.SecondPeakValueLabel.Text = 'Second Peak Value:';
            app.SPV_value = uilabel(app.FittingResult);
            app.SPV_value.Text = '';
            app.FirstPeakTimeLabel = uilabel(app.FittingResult);
            app.FirstPeakTimeLabel.Text = 'First Peak Time:';
            app.FPT_value = uilabel(app.FittingResult);
            app.FPT_value.Text = '';
            app.SecondPeakTimeLabel = uilabel(app.FittingResult);
            app.SecondPeakTimeLabel.Text = 'Second Peak Time:';
            app.SPT_value = uilabel(app.FittingResult);
            app.SPT_value.Text = '';
            app.SaveResultsButton = uibutton(app.ResultsPanel, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save results';
            app.SavingResultsStatus = uilamp(app.ResultsPanel);
            app.SavingResultsStatus.Visible = 'off';
            app.RunButton = uibutton(app.AnalysisContainer, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.RunMessage = uitextarea(app.AnalysisContainer);
            app.RunMessage.Editable = 'off';
            app.RunMessage.Visible = 'off';
            app.StopButton = uibutton(app.AnalysisContainer, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Visible = 'off';
            app.StopButton.Text = 'Stop';
            app.RestoreButton = uibutton(app.AnalysisContainer, 'push');
            app.RestoreButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreButtonPushed, true);
            app.RestoreButton.Visible = 'off';
            app.RestoreButton.Text = 'Restore';
            app.AdvancedPanel = uipanel(app.AnalysisContainer);
            app.AdvancedPanel.Title = 'Advanced';
            app.AdvancedPanel.Visible = 'off';
            app.aCheckBox = uicheckbox(app.AdvancedPanel);
            app.aCheckBox.ValueChangedFcn = createCallbackFcn(app, @aCheckBoxValueChanged, true);
            app.aCheckBox.Text = '';
            app.apLabel_2 = uilabel(app.AdvancedPanel);
            app.apLabel_2.Text = 'a';
            app.AdvancedSettings = uipanel(app.AdvancedPanel);
            app.aEditField = uieditfield(app.AdvancedSettings, 'numeric');
            app.aEditField.Value = 0.005;
            app.bEditField = uieditfield(app.AdvancedSettings, 'numeric');
            app.bEditField.Value = 0.005;
            app.TdEditField = uieditfield(app.AdvancedSettings, 'numeric');
            app.TdEditField.Value = 0.1;
            app.Cs0EditField = uieditfield(app.AdvancedSettings, 'numeric');
            app.Cs0EditField.Value = 1;
            app.Cc0EditField = uieditfield(app.AdvancedSettings, 'numeric');
            app.Cc0EditField.Value = 30;
            app.Acquisitionnumberinfo = uitextarea(app.AdvancedPanel);
            app.Acquisitionnumberinfo.Editable = 'off';
            app.bCheckBox = uicheckbox(app.AdvancedPanel);
            app.bCheckBox.ValueChangedFcn = createCallbackFcn(app, @bCheckBoxValueChanged, true);
            app.bCheckBox.Text = '';
            app.bpLabel_2 = uilabel(app.AdvancedPanel);
            app.bpLabel_2.Text = 'b';
            app.TdCheckBox = uicheckbox(app.AdvancedPanel);
            app.TdCheckBox.ValueChangedFcn = createCallbackFcn(app, @TdCheckBoxValueChanged, true);
            app.TdCheckBox.Text = '';
            app.TdpLabel_2 = uilabel(app.AdvancedPanel);
            app.TdpLabel_2.Text = 'Td';
            app.Cs0CheckBox = uicheckbox(app.AdvancedPanel);
            app.Cs0CheckBox.ValueChangedFcn = createCallbackFcn(app, @Cs0CheckBoxValueChanged, true);
            app.Cs0CheckBox.Text = '';
            app.Cs0pLabel_2 = uilabel(app.AdvancedPanel);
            app.Cs0pLabel_2.Text = 'Cs0';
            app.Cc0CheckBox = uicheckbox(app.AdvancedPanel);
            app.Cc0CheckBox.ValueChangedFcn = createCallbackFcn(app, @Cc0CheckBoxValueChanged, true);
            app.Cc0CheckBox.Text = '';
            app.Cc0pLabel_2 = uilabel(app.AdvancedPanel);
            app.Cc0pLabel_2.Text = 'Cc0';
            app.PatientDetailsPanel = uipanel(app.AnalysisContainer);
            app.PatientDetailsPanel.Title = 'Patient Details';
            app.NameLabel_2 = uilabel(app.PatientDetailsPanel);
            app.NameLabel_2.Text = 'Name';
            app.NameValueLabel_2 = uilabel(app.PatientDetailsPanel);
            app.NameValueLabel_2.Text = 'NameValue';
            app.CenterLabel_3 = uilabel(app.PatientDetailsPanel);
            app.CenterLabel_3.Text = 'Center';
            app.CenterValueLabel_2 = uilabel(app.PatientDetailsPanel);
            app.CenterValueLabel_2.Text = 'CenterValue';
            app.SurnameLabel_2 = uilabel(app.PatientDetailsPanel);
            app.SurnameLabel_2.Text = 'Surname';
            app.SurnameValueLabel_2 = uilabel(app.PatientDetailsPanel);
            app.SurnameValueLabel_2.Text = 'SurnameValue';
            app.BirthdayLabel_2 = uilabel(app.PatientDetailsPanel);
            app.BirthdayLabel_2.Text = 'Birthday';
            app.BirthdayValueLabel_2 = uilabel(app.PatientDetailsPanel);
            app.BirthdayValueLabel_2.Text = 'BirthdayLabel';
            app.PatientIDFCLabel_4 = uilabel(app.PatientDetailsPanel);
            app.PatientIDFCLabel_4.Text = 'Patient ID (F.C.)';
            app.SSNValueLabel_2 = uilabel(app.PatientDetailsPanel);
            app.SSNValueLabel_2.Text = 'SSNValue';
            app.HospitalizationdateLabel_2 = uilabel(app.PatientDetailsPanel);
            app.HospitalizationdateLabel_2.Text = 'Hospitalization date';
            app.HDValueLabel_2 = uilabel(app.PatientDetailsPanel);
            app.HDValueLabel_2.Text = 'HDValue';
            app.GenderLabel_2 = uilabel(app.PatientDetailsPanel);
            app.GenderLabel_2.Text = 'Gender';
            app.GenderValueLabel_2 = uilabel(app.PatientDetailsPanel);
            app.GenderValueLabel_2.Text = 'GenderValue';
            app.EventdateLabel_2 = uilabel(app.PatientDetailsPanel);
            app.EventdateLabel_2.Text = 'Event date';
            app.EventDateValueLabel_2 = uilabel(app.PatientDetailsPanel);
            app.EventDateValueLabel_2.Text = 'EventDateValue';
            app.AddressLabel_2 = uilabel(app.PatientDetailsPanel);
            app.AddressLabel_2.Text = 'Address';
            app.AddressValueLabel_2 = uilabel(app.PatientDetailsPanel);
            app.AddressValueLabel_2.Text = 'AddressValue';
            app.DoctorLabel_2 = uilabel(app.PatientDetailsPanel);
            app.DoctorLabel_2.Text = 'Doctor';
            app.DoctorNameValueLabel_2 = uilabel(app.PatientDetailsPanel);
            app.DoctorNameValueLabel_2.Text = 'DoctorNameValue';
            app.AnalysisPlotPanel = uipanel(app.AnalysisContainer);
            app.AnalysisAx = uiaxes(app.AnalysisPlotPanel);
            xlabel(app.AnalysisAx, 'X')
            ylabel(app.AnalysisAx, 'Y')
            zlabel(app.AnalysisAx, 'Z')
            app.AcquisitionsPage = uipanel(app.ManagePatientPanel);
            app.AcquisitionsPage.Title = 'Laboratory Data';
            app.AcquisitionsPage.Visible = 'off';
            app.HomeButtonFAcq = uibutton(app.AcquisitionsPage, 'push');
            app.HomeButtonFAcq.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButtonFAcq.Icon = 'homeIco.png';
            app.HomeButtonFAcq.Text = 'Home';
            app.HospitalizationButtonFAcq = uibutton(app.AcquisitionsPage, 'push');
            app.HospitalizationButtonFAcq.ButtonPushedFcn = createCallbackFcn(app, @HospitalizationButtonPushed, true);
            app.HospitalizationButtonFAcq.Icon = 'hospIco.png';
            app.HospitalizationButtonFAcq.Enable = 'off';
            app.HospitalizationButtonFAcq.Text = 'Patient Profile (I.E.)';
            app.AnalysisButtonFAcq = uibutton(app.AcquisitionsPage, 'push');
            app.AnalysisButtonFAcq.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.AnalysisButtonFAcq.Icon = 'trendIco.png';
            app.AnalysisButtonFAcq.Enable = 'off';
            app.AnalysisButtonFAcq.Text = 'C.M. Trend';
            app.MeasurementsDataPanel = uipanel(app.AcquisitionsPage);
            app.MeasurementsDataPanel.Title = 'Measurements Data';
            app.AcquisitionslistDropDownLabel = uilabel(app.MeasurementsDataPanel);
            app.AcquisitionslistDropDownLabel.Text = 'Acquisitions list';
            app.AcquisitionslistDropDown = uidropdown(app.MeasurementsDataPanel);
            app.AcquisitionslistDropDown.Items = {};
            app.AcquisitionslistDropDown.ValueChangedFcn = createCallbackFcn(app, @AcquisitionslistDropDownValueChanged, true);
            app.AcquisitionslistDropDown.Value = {};
            app.ReloadAcqData = uibutton(app.MeasurementsDataPanel, 'push');
            app.ReloadAcqData.ButtonPushedFcn = createCallbackFcn(app, @ReloadAcqDataButtonPushed, true);
            app.ReloadAcqData.Icon = 'refresh.png';
            app.ReloadAcqData.Text = '';
            app.cTnTEditFieldLabel = uilabel(app.MeasurementsDataPanel);
            app.cTnTEditFieldLabel.Text = 'cTnT';
            app.cTnTEditField = uieditfield(app.MeasurementsDataPanel, 'numeric');
            app.cTnIEditField = uieditfield(app.MeasurementsDataPanel, 'numeric');
            app.CKMBEditField = uieditfield(app.MeasurementsDataPanel, 'numeric');
            app.NaEditFieldLabel = uilabel(app.MeasurementsDataPanel);
            app.NaEditFieldLabel.Text = 'Na';
            app.NaEditField = uieditfield(app.MeasurementsDataPanel, 'numeric');
            app.cTnIEditFieldLabel = uilabel(app.MeasurementsDataPanel);
            app.cTnIEditFieldLabel.Text = 'cTnI';
            app.KEditFieldLabel = uilabel(app.MeasurementsDataPanel);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.MeasurementsDataPanel, 'numeric');
            app.DateandTimeacquisitionPanel = uipanel(app.MeasurementsDataPanel);
            app.DateandTimeacquisitionPanel.Title = 'Date and Time acquisition';
            app.DateDatePickerLabel_3 = uilabel(app.DateandTimeacquisitionPanel);
            app.DateDatePickerLabel_3.Text = 'Date';
            app.HDateA = uidatepicker(app.DateandTimeacquisitionPanel);
            app.HDateA.DisplayFormat = 'dd/MMM/uuuu';
            app.HourSpinnerLabel_3 = uilabel(app.DateandTimeacquisitionPanel);
            app.HourSpinnerLabel_3.Text = 'Hour';
            app.HhourA = uispinner(app.DateandTimeacquisitionPanel);
            app.HhourA.Limits = [0 23];
            app.HminuteA = uispinner(app.DateandTimeacquisitionPanel);
            app.HminuteA.Limits = [0 59];
            app.HsecondA = uispinner(app.DateandTimeacquisitionPanel);
            app.HsecondA.Limits = [0 59];
            app.HhLabelA = uilabel(app.DateandTimeacquisitionPanel);
            app.HhLabelA.Text = 'h';
            app.HmLabelA = uilabel(app.DateandTimeacquisitionPanel);
            app.HmLabelA.Text = 'm';
            app.HsLabelA = uilabel(app.DateandTimeacquisitionPanel);
            app.HsLabelA.Text = 's';
            app.CKMBEditFieldLabel = uilabel(app.MeasurementsDataPanel);
            app.CKMBEditFieldLabel.Text = 'CK-MB';
            app.SavenewAcqButton = uibutton(app.MeasurementsDataPanel, 'push');
            app.SavenewAcqButton.ButtonPushedFcn = createCallbackFcn(app, @SavenewAcqButtonPushed, true);
            app.SavenewAcqButton.Text = 'Save new';
            app.SaveeditsAcqButton = uibutton(app.MeasurementsDataPanel, 'push');
            app.SaveeditsAcqButton.ButtonPushedFcn = createCallbackFcn(app, @SaveeditsAcqButtonPushed, true);
            app.SaveeditsAcqButton.Text = 'Save edits';
            app.MeasurementsSaveText = uitextarea(app.MeasurementsDataPanel);
            app.MeasurementsSaveText.Visible = 'off';
            app.MeasurementsSaveLamp = uilamp(app.MeasurementsDataPanel);
            app.MeasurementsSaveLamp.Visible = 'off';
            app.CancelButton = uibutton(app.MeasurementsDataPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.AcquisitionsTab = uitable(app.MeasurementsDataPanel);
            app.AcquisitionsTab.ColumnName = {'ID'; 'Acquisition Data'; 'Aqcuisition Time'; 'cTnT'; 'cTnI'; 'CK-MB'; 'Na'; 'K'};
            app.AcquisitionsTab.RowName = {};
            app.PatientHospitalizationinfoPanel = uipanel(app.AcquisitionsPage);
            app.PatientHospitalizationinfoPanel.Title = 'Patient & Hospitalization info';
            app.NameLabel = uilabel(app.PatientHospitalizationinfoPanel);
            app.NameLabel.Text = 'Name';
            app.NameValueLabel = uilabel(app.PatientHospitalizationinfoPanel);
            app.NameValueLabel.Text = 'NameValue';
            app.SurnameLabel = uilabel(app.PatientHospitalizationinfoPanel);
            app.SurnameLabel.Text = 'Surname';
            app.SurnameValueLabel = uilabel(app.PatientHospitalizationinfoPanel);
            app.SurnameValueLabel.Text = 'SurnameValue';
            app.PatientIDFCLabel_3 = uilabel(app.PatientHospitalizationinfoPanel);
            app.PatientIDFCLabel_3.Text = 'Patient ID (F.C.)';
            app.SSNValueLabel = uilabel(app.PatientHospitalizationinfoPanel);
            app.SSNValueLabel.Text = 'SSNValue';
            app.GenderLabel = uilabel(app.PatientHospitalizationinfoPanel);
            app.GenderLabel.Text = 'Gender';
            app.GenderValueLabel = uilabel(app.PatientHospitalizationinfoPanel);
            app.GenderValueLabel.Text = 'GenderValue';
            app.AddressLabel = uilabel(app.PatientHospitalizationinfoPanel);
            app.AddressLabel.Text = 'Address';
            app.AddressValueLabel = uilabel(app.PatientHospitalizationinfoPanel);
            app.AddressValueLabel.Text = 'AddressValue';
            app.BirthdayLabel = uilabel(app.PatientHospitalizationinfoPanel);
            app.BirthdayLabel.Text = 'Birthday';
            app.BirthdayValueLabel = uilabel(app.PatientHospitalizationinfoPanel);
            app.BirthdayValueLabel.Text = 'BirthdayLabel';
            app.CenterLabel_2 = uilabel(app.PatientHospitalizationinfoPanel);
            app.CenterLabel_2.Text = 'Center';
            app.CenterValueLabel = uilabel(app.PatientHospitalizationinfoPanel);
            app.CenterValueLabel.Text = 'CenterValue';
            app.HospitalizationdateLabel = uilabel(app.PatientHospitalizationinfoPanel);
            app.HospitalizationdateLabel.Text = 'Hospitalization date';
            app.HDValueLabel = uilabel(app.PatientHospitalizationinfoPanel);
            app.HDValueLabel.Text = 'HDValue';
            app.EventdateLabel = uilabel(app.PatientHospitalizationinfoPanel);
            app.EventdateLabel.Text = 'Event date';
            app.EventDateValueLabel = uilabel(app.PatientHospitalizationinfoPanel);
            app.EventDateValueLabel.Text = 'EventDateValue';
            app.DoctorLabel = uilabel(app.PatientHospitalizationinfoPanel);
            app.DoctorLabel.Text = 'Doctor';
            app.DoctorNameValueLabel = uilabel(app.PatientHospitalizationinfoPanel);
            app.DoctorNameValueLabel.Text = 'DoctorNameValue';
            app.HospitalizationPage = uipanel(app.ManagePatientPanel);
            app.HospitalizationPage.Title = 'Patient Profile (Index Event)';
            app.HospitalizationPage.Visible = 'off';
            app.HospContainer = uipanel(app.HospitalizationPage);
            app.SelezionaricoveroLabel_2 = uilabel(app.HospContainer);
            app.SelezionaricoveroLabel_2.Text = 'Hospitalization';
            app.HospitalizationsDrop = uidropdown(app.HospContainer);
            app.HospitalizationsDrop.Items = {};
            app.HospitalizationsDrop.ValueChangedFcn = createCallbackFcn(app, @HospitalizationsDropValueChanged, true);
            app.HospitalizationsDrop.Value = {};
            app.ReloadHospData = uibutton(app.HospContainer, 'push');
            app.ReloadHospData.ButtonPushedFcn = createCallbackFcn(app, @ReloadHospDataButtonPushed, true);
            app.ReloadHospData.Icon = 'refresh.png';
            app.ReloadHospData.Text = '';
            app.HospitalizationPanel = uipanel(app.HospContainer);
            app.HospitalizationPanel.Title = 'Hospitalization';
            app.DateandtimehospitalizationPanel_2 = uipanel(app.HospitalizationPanel);
            app.DateandtimehospitalizationPanel_2.Title = 'Date and time hospitalization';
            app.DateDatePickerLabel_2 = uilabel(app.DateandtimehospitalizationPanel_2);
            app.DateDatePickerLabel_2.Text = 'Date';
            app.HDateH_2 = uidatepicker(app.DateandtimehospitalizationPanel_2);
            app.HourSpinnerLabel_2 = uilabel(app.DateandtimehospitalizationPanel_2);
            app.HourSpinnerLabel_2.Text = 'Hour';
            app.HhourH_2 = uispinner(app.DateandtimehospitalizationPanel_2);
            app.HhourH_2.Limits = [0 23];
            app.HminuteH_2 = uispinner(app.DateandtimehospitalizationPanel_2);
            app.HminuteH_2.Limits = [0 59];
            app.HsecondH_2 = uispinner(app.DateandtimehospitalizationPanel_2);
            app.HsecondH_2.Limits = [0 59];
            app.HhLabel_2 = uilabel(app.DateandtimehospitalizationPanel_2);
            app.HhLabel_2.Text = 'h';
            app.HmLabel_2 = uilabel(app.DateandtimehospitalizationPanel_2);
            app.HmLabel_2.Text = 'm';
            app.HsLabel_2 = uilabel(app.DateandtimehospitalizationPanel_2);
            app.HsLabel_2.Text = 's';
            app.DateandtimeAMIPanel_2 = uipanel(app.HospitalizationPanel);
            app.DateandtimeAMIPanel_2.Title = 'Date and time AMI';
            app.DateLabel_4 = uilabel(app.DateandtimeAMIPanel_2);
            app.DateLabel_4.Text = 'Date';
            app.DateEvent_2 = uidatepicker(app.DateandtimeAMIPanel_2);
            app.HourLabel_2 = uilabel(app.DateandtimeAMIPanel_2);
            app.HourLabel_2.Text = 'Hour';
            app.AMIhourEv_2 = uispinner(app.DateandtimeAMIPanel_2);
            app.AMIhourEv_2.Limits = [0 23];
            app.AMIminuteEv_2 = uispinner(app.DateandtimeAMIPanel_2);
            app.AMIminuteEv_2.Limits = [0 59];
            app.AMIsecondEv_2 = uispinner(app.DateandtimeAMIPanel_2);
            app.AMIsecondEv_2.Limits = [0 59];
            app.AMIhLabel_2 = uilabel(app.DateandtimeAMIPanel_2);
            app.AMIhLabel_2.Text = 'h';
            app.AMImLabel_2 = uilabel(app.DateandtimeAMIPanel_2);
            app.AMImLabel_2.Text = 'm';
            app.AMIsLabel_2 = uilabel(app.DateandtimeAMIPanel_2);
            app.AMIsLabel_2.Text = 's';
            app.DateandtimeThrombolysisPanel_2 = uipanel(app.HospitalizationPanel);
            app.DateandtimeThrombolysisPanel_2.Title = 'Date and time Thrombolysis';
            app.DateLabel_5 = uilabel(app.DateandtimeThrombolysisPanel_2);
            app.DateLabel_5.Text = 'Date';
            app.DateTromb_2 = uidatepicker(app.DateandtimeThrombolysisPanel_2);
            app.TrombHourLabel_2 = uilabel(app.DateandtimeThrombolysisPanel_2);
            app.TrombHourLabel_2.Text = 'Hour';
            app.TrombHour_2 = uispinner(app.DateandtimeThrombolysisPanel_2);
            app.TrombHour_2.Limits = [0 23];
            app.Trombminute_2 = uispinner(app.DateandtimeThrombolysisPanel_2);
            app.Trombminute_2.Limits = [0 59];
            app.Trombsecond_2 = uispinner(app.DateandtimeThrombolysisPanel_2);
            app.Trombsecond_2.Limits = [0 59];
            app.TrombhLabel_2 = uilabel(app.DateandtimeThrombolysisPanel_2);
            app.TrombhLabel_2.Text = 'h';
            app.TrombmLabel_2 = uilabel(app.DateandtimeThrombolysisPanel_2);
            app.TrombmLabel_2.Text = 'm';
            app.TrombsLabel_2 = uilabel(app.DateandtimeThrombolysisPanel_2);
            app.TrombsLabel_2.Text = 's';
            app.DrugEditField_2Label = uilabel(app.DateandtimeThrombolysisPanel_2);
            app.DrugEditField_2Label.Text = 'Drug';
            app.DrugEditField_2 = uieditfield(app.DateandtimeThrombolysisPanel_2, 'text');
            app.DateandtimeRevascularizationPanel_2 = uipanel(app.HospitalizationPanel);
            app.DateandtimeRevascularizationPanel_2.Title = 'Date and time Revascularization';
            app.DateLabel_6 = uilabel(app.DateandtimeRevascularizationPanel_2);
            app.DateLabel_6.Text = 'Date';
            app.DateRev_2 = uidatepicker(app.DateandtimeRevascularizationPanel_2);
            app.RevHourLabel_2 = uilabel(app.DateandtimeRevascularizationPanel_2);
            app.RevHourLabel_2.Text = 'Hour';
            app.Revhour_2 = uispinner(app.DateandtimeRevascularizationPanel_2);
            app.Revhour_2.Limits = [0 23];
            app.Revminute_2 = uispinner(app.DateandtimeRevascularizationPanel_2);
            app.Revminute_2.Limits = [0 59];
            app.Revsecond_2 = uispinner(app.DateandtimeRevascularizationPanel_2);
            app.Revsecond_2.Limits = [0 59];
            app.RevhLabel_2 = uilabel(app.DateandtimeRevascularizationPanel_2);
            app.RevhLabel_2.Text = 'h';
            app.RevmLabel_2 = uilabel(app.DateandtimeRevascularizationPanel_2);
            app.RevmLabel_2.Text = 'm';
            app.RevsLabel_2 = uilabel(app.DateandtimeRevascularizationPanel_2);
            app.RevsLabel_2.Text = 's';
            app.LocoEditField_2Label = uilabel(app.DateandtimeRevascularizationPanel_2);
            app.LocoEditField_2Label.Text = 'Loco';
            app.LocoEditField_2 = uieditfield(app.DateandtimeRevascularizationPanel_2, 'text');
            app.ClinicalinformationPanel_2 = uipanel(app.HospitalizationPanel);
            app.ClinicalinformationPanel_2.Title = 'Clinical information';
            app.EtEditField_3Label_2 = uilabel(app.ClinicalinformationPanel_2);
            app.EtEditField_3Label_2.Text = 'Age';
            app.WieightKgLabel_2 = uilabel(app.ClinicalinformationPanel_2);
            app.WieightKgLabel_2.Text = 'Wieight [Kg]';
            app.SystolicBPmmHgEditField_2Label = uilabel(app.ClinicalinformationPanel_2);
            app.SystolicBPmmHgEditField_2Label.Text = 'Systolic BP [mmHg]';
            app.DyastolicBPmmHgEditField_2Label = uilabel(app.ClinicalinformationPanel_2);
            app.DyastolicBPmmHgEditField_2Label.Text = 'Dyastolic BP [mmHg]';
            app.HRbpmEditField_2Label = uilabel(app.ClinicalinformationPanel_2);
            app.HRbpmEditField_2Label.Text = 'HR [bpm]';
            app.stMedCoDatePickerLabel_2 = uilabel(app.ClinicalinformationPanel_2);
            app.stMedCoDatePickerLabel_2.Text = '1st Med. Co.';
            app.TimetotreatmLabel = uilabel(app.ClinicalinformationPanel_2);
            app.TimetotreatmLabel.Text = 'Time to treatm. [hours]';
            app.STEMIDropDown_2Label_2 = uilabel(app.ClinicalinformationPanel_2);
            app.STEMIDropDown_2Label_2.Text = 'STEMI';
            app.Anginaepisodesin24hEditField_2Label = uilabel(app.ClinicalinformationPanel_2);
            app.Anginaepisodesin24hEditField_2Label.Text = 'Angina episodes in 24h';
            app.StenosisEditField_2Label = uilabel(app.ClinicalinformationPanel_2);
            app.StenosisEditField_2Label.Text = 'Stenosis [%]';
            app.HypertensDropDown_2Label = uilabel(app.ClinicalinformationPanel_2);
            app.HypertensDropDown_2Label.Text = 'Hypertens.';
            app.KillipDropDown_2Label = uilabel(app.ClinicalinformationPanel_2);
            app.KillipDropDown_2Label.Text = 'Killip';
            app.DiabetesDropDown_2Label = uilabel(app.ClinicalinformationPanel_2);
            app.DiabetesDropDown_2Label.Text = 'Diabetes';
            app.SmokeDropDown_2Label = uilabel(app.ClinicalinformationPanel_2);
            app.SmokeDropDown_2Label.Text = 'Smoke';
            app.ObesityDropDown_2Label = uilabel(app.ClinicalinformationPanel_2);
            app.ObesityDropDown_2Label.Text = 'Obesity';
            app.PrevAMILabel = uilabel(app.ClinicalinformationPanel_2);
            app.PrevAMILabel.Text = 'Prev. CAD';
            app.FamiliarityDropDown_2Label = uilabel(app.ClinicalinformationPanel_2);
            app.FamiliarityDropDown_2Label.Text = 'Familiarity';
            app.DyslipidemiaDropDown_2Label = uilabel(app.ClinicalinformationPanel_2);
            app.DyslipidemiaDropDown_2Label.Text = 'Dyslipidemia';
            app.LBBBDropDown_2Label = uilabel(app.ClinicalinformationPanel_2);
            app.LBBBDropDown_2Label.Text = 'LBBB';
            app.HypercolDropDown_2Label = uilabel(app.ClinicalinformationPanel_2);
            app.HypercolDropDown_2Label.Text = 'Hypercol.';
            app.ASAusein7daysDropDown_2Label = uilabel(app.ClinicalinformationPanel_2);
            app.ASAusein7daysDropDown_2Label.Text = 'ASA use in 7 days';
            app.PositivecardiacmarkerDropDown_2Label = uilabel(app.ClinicalinformationPanel_2);
            app.PositivecardiacmarkerDropDown_2Label.Text = 'Positive cardiac marker';
            app.StemiNstemiLabel_2 = uilabel(app.ClinicalinformationPanel_2);
            app.StemiNstemiLabel_2.Text = 'Select "Yes" or "No" in STEMI selector';
            app.EKGSTdevmmEditFieldLabel = uilabel(app.ClinicalinformationPanel_2);
            app.EKGSTdevmmEditFieldLabel.Text = 'EKG ST dev. [mm]';
            app.CalculateAgeButton_2 = uibutton(app.ClinicalinformationPanel_2, 'push');
            app.CalculateAgeButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateAgeButton_2Pushed, true);
            app.CalculateAgeButton_2.Icon = fullfile(pathToMLAPP, 'refresh.png');
            app.CalculateAgeButton_2.Text = '';
            app.EtaEditField_2 = uieditfield(app.ClinicalinformationPanel_2, 'numeric');
            app.EtaEditField_2.RoundFractionalValues = 'on';
            app.WeightEditField_2 = uieditfield(app.ClinicalinformationPanel_2, 'numeric');
            app.STEMIDropDown_2 = uidropdown(app.ClinicalinformationPanel_2);
            app.STEMIDropDown_2.Items = {'No', 'Yes', 'Unknown'};
            app.STEMIDropDown_2.ItemsData = [1 2 3];
            app.STEMIDropDown_2.ValueChangedFcn = createCallbackFcn(app, @STEMIDropDown_2ValueChanged, true);
            app.STEMIDropDown_2.Value = 3;
            app.SystolicBPmmHgEditField_2 = uieditfield(app.ClinicalinformationPanel_2, 'numeric');
            app.DyastolicBPmmHgEditField_2 = uieditfield(app.ClinicalinformationPanel_2, 'numeric');
            app.HRbpmEditField_2 = uieditfield(app.ClinicalinformationPanel_2, 'numeric');
            app.stMedCoDate_2 = uidatepicker(app.ClinicalinformationPanel_2);
            app.TimetotreatmhoursEditField_2 = uieditfield(app.ClinicalinformationPanel_2, 'numeric');
            app.Anginaepisodesin24hEditField_2 = uieditfield(app.ClinicalinformationPanel_2, 'numeric');
            app.StenosisEditField_2 = uieditfield(app.ClinicalinformationPanel_2, 'numeric');
            app.PositivecardiacmarkerDropDown_2 = uidropdown(app.ClinicalinformationPanel_2);
            app.PositivecardiacmarkerDropDown_2.Items = {'No', 'Yes', 'Unknown'};
            app.PositivecardiacmarkerDropDown_2.ItemsData = [1 2 3];
            app.PositivecardiacmarkerDropDown_2.Value = 1;
            app.DiabeteDropDown_2 = uidropdown(app.ClinicalinformationPanel_2);
            app.DiabeteDropDown_2.Items = {'No', 'Yes', 'Unknown'};
            app.DiabeteDropDown_2.ItemsData = [1 2 3];
            app.DiabeteDropDown_2.Value = 3;
            app.FamiliarityDropDown_2 = uidropdown(app.ClinicalinformationPanel_2);
            app.FamiliarityDropDown_2.Items = {'No', 'Yes', 'Unknown'};
            app.FamiliarityDropDown_2.ItemsData = [1 2 3];
            app.FamiliarityDropDown_2.Value = 3;
            app.ObesityDropDown_2 = uidropdown(app.ClinicalinformationPanel_2);
            app.ObesityDropDown_2.Items = {'No', 'Yes', 'Unknown'};
            app.ObesityDropDown_2.ItemsData = [1 2 3];
            app.ObesityDropDown_2.Value = 3;
            app.PrevCADDropDown_2 = uidropdown(app.ClinicalinformationPanel_2);
            app.PrevCADDropDown_2.Items = {'No', 'Yes', 'Unknown'};
            app.PrevCADDropDown_2.ItemsData = [1 2 3];
            app.PrevCADDropDown_2.Value = 3;
            app.SmokeDropDown_2 = uidropdown(app.ClinicalinformationPanel_2);
            app.SmokeDropDown_2.Items = {'No', 'Yes', 'Unknown'};
            app.SmokeDropDown_2.ItemsData = [1 2 3];
            app.SmokeDropDown_2.Value = 3;
            app.DyslipidemiaDropDown_2 = uidropdown(app.ClinicalinformationPanel_2);
            app.DyslipidemiaDropDown_2.Items = {'No', 'Yes', 'Unknown'};
            app.DyslipidemiaDropDown_2.ItemsData = [1 2 3];
            app.DyslipidemiaDropDown_2.Value = 3;
            app.HypertensDropDown_2 = uidropdown(app.ClinicalinformationPanel_2);
            app.HypertensDropDown_2.Items = {'No', 'Yes', 'Unknown'};
            app.HypertensDropDown_2.ItemsData = [1 2 3];
            app.HypertensDropDown_2.Value = 3;
            app.KillipDropDown_2 = uidropdown(app.ClinicalinformationPanel_2);
            app.KillipDropDown_2.Items = {'I', 'II', 'III', 'IV'};
            app.KillipDropDown_2.ItemsData = [1 2 3 4];
            app.KillipDropDown_2.Value = 1;
            app.HypercolDropDown_2 = uidropdown(app.ClinicalinformationPanel_2);
            app.HypercolDropDown_2.Items = {'No', 'Yes', 'Unknown'};
            app.HypercolDropDown_2.ItemsData = [1 2 3];
            app.HypercolDropDown_2.Value = 3;
            app.LBBBDropDown_2 = uidropdown(app.ClinicalinformationPanel_2);
            app.LBBBDropDown_2.Items = {'No', 'Yes', 'Unknown'};
            app.LBBBDropDown_2.ItemsData = [1 2 3];
            app.LBBBDropDown_2.Value = 3;
            app.ASAusein7daysDropDown_2 = uidropdown(app.ClinicalinformationPanel_2);
            app.ASAusein7daysDropDown_2.Items = {'No', 'Yes', 'Unknown'};
            app.ASAusein7daysDropDown_2.ItemsData = [1 2 3];
            app.ASAusein7daysDropDown_2.Value = 3;
            app.EKGSTdevmmEditField_2 = uieditfield(app.ClinicalinformationPanel_2, 'numeric');
            app.CalculateTIMIButton_2 = uibutton(app.ClinicalinformationPanel_2, 'push');
            app.CalculateTIMIButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateTIMIButton_2Pushed, true);
            app.CalculateTIMIButton_2.Text = '  Calculate TIMI';
            app.TimiEditField_2 = uieditfield(app.ClinicalinformationPanel_2, 'numeric');
            app.SaveNewHospitalization_2 = uibutton(app.HospitalizationPanel, 'push');
            app.SaveNewHospitalization_2.ButtonPushedFcn = createCallbackFcn(app, @SaveNewHospitalizationPushed, true);
            app.SaveNewHospitalization_2.Text = 'Save new hospitalization';
            app.SaveHospitalization_2 = uibutton(app.HospitalizationPanel, 'push');
            app.SaveHospitalization_2.ButtonPushedFcn = createCallbackFcn(app, @SaveHospitalizationButtonPushed, true);
            app.SaveHospitalization_2.Text = 'Save edits';
            app.CancelHosp_2 = uibutton(app.HospitalizationPanel, 'push');
            app.CancelHosp_2.ButtonPushedFcn = createCallbackFcn(app, @CancelHosp_2ButtonPushed, true);
            app.CancelHosp_2.Text = 'Cancel';
            app.HospitalizationText_2 = uitextarea(app.HospitalizationPanel);
            app.HospitalizationText_2.Editable = 'off';
            app.HospitalizationText_2.Visible = 'off';
            app.HospitalizationSavingStatus_2 = uilamp(app.HospitalizationPanel);
            app.HomeButtonFH = uibutton(app.HospContainer, 'push');
            app.HomeButtonFH.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButtonFH.Icon = 'homeIco.png';
            app.HomeButtonFH.Text = 'Home';
            app.AcquisitionsButtonFH = uibutton(app.HospContainer, 'push');
            app.AcquisitionsButtonFH.ButtonPushedFcn = createCallbackFcn(app, @AcquisitionsButtonPushed, true);
            app.AcquisitionsButtonFH.Icon = 'measIco.png';
            app.AcquisitionsButtonFH.Enable = 'off';
            app.AcquisitionsButtonFH.Text = 'Lab. Data';
            app.AnalysisButtonFH = uibutton(app.HospContainer, 'push');
            app.AnalysisButtonFH.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.AnalysisButtonFH.Icon = 'trendIco.png';
            app.AnalysisButtonFH.Enable = 'off';
            app.AnalysisButtonFH.Text = 'C. M Trend';
            app.PlotMedPanel = uipanel(app.HospContainer);
            app.PlotHosp = uiaxes(app.PlotMedPanel);
            xlabel(app.PlotHosp, 'Time [h]')
            ylabel(app.PlotHosp, 'Y')
            zlabel(app.PlotHosp, 'Z')
            app.HospitalizationsTable = uitable(app.HospContainer);
            app.HospitalizationsTable.ColumnName = {'ID'; 'Date Hospitalization'; 'Time Hospitalization'; 'Date AMI'; 'Time AMI'};
            app.HospitalizationsTable.RowName = {};
            app.HomePage = uipanel(app.ManagePatientPanel);
            app.HomePage.Title = 'Home';
            app.SelectCenterLabel = uilabel(app.HomePage);
            app.SelectCenterLabel.Visible = 'off';
            app.SelectCenterLabel.Text = 'Select center';
            app.SelectCenterDrop = uidropdown(app.HomePage);
            app.SelectCenterDrop.Items = {};
            app.SelectCenterDrop.Editable = 'on';
            app.SelectCenterDrop.ValueChangedFcn = createCallbackFcn(app, @SelectCenterDropValueChanged, true);
            app.SelectCenterDrop.Visible = 'off';
            app.SelectCenterDrop.Value = {};
            app.SelectpatientLabel = uilabel(app.HomePage);
            app.SelectpatientLabel.Text = 'Select patient';
            app.PazienteDropDown = uidropdown(app.HomePage);
            app.PazienteDropDown.Items = {};
            app.PazienteDropDown.Editable = 'on';
            app.PazienteDropDown.ValueChangedFcn = createCallbackFcn(app, @PazienteDropDownValueChanged, true);
            app.PazienteDropDown.Value = {};
            app.ReloadPatientData = uibutton(app.HomePage, 'push');
            app.ReloadPatientData.ButtonPushedFcn = createCallbackFcn(app, @ReloadPatientDataPushed, true);
            app.ReloadPatientData.Icon = 'refresh.png';
            app.ReloadPatientData.Text = '';
            app.ControlPanel = uipanel(app.HomePage);
            app.ControlPanel.Visible = 'off';
            app.PatientProfileIndexEventButton = uibutton(app.ControlPanel, 'push');
            app.PatientProfileIndexEventButton.ButtonPushedFcn = createCallbackFcn(app, @HospitalizationButtonPushed, true);
            app.PatientProfileIndexEventButton.Icon = 'hospIco.png';
            app.PatientProfileIndexEventButton.Enable = 'off';
            app.PatientProfileIndexEventButton.Text = 'Patient Profile (Index Event)';
            app.LaboratoryDataButton = uibutton(app.ControlPanel, 'push');
            app.LaboratoryDataButton.ButtonPushedFcn = createCallbackFcn(app, @AcquisitionsButtonPushed, true);
            app.LaboratoryDataButton.Icon = 'measIco.png';
            app.LaboratoryDataButton.Enable = 'off';
            app.LaboratoryDataButton.Text = 'Laboratory Data';
            app.CardiacmarkerstrendButton = uibutton(app.ControlPanel, 'push');
            app.CardiacmarkerstrendButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.CardiacmarkerstrendButton.Icon = 'trendIco.png';
            app.CardiacmarkerstrendButton.Enable = 'off';
            app.CardiacmarkerstrendButton.Text = 'Cardiac markers trend';
            app.ControlHintAnalysis = uilabel(app.ControlPanel);
            app.ControlHintAnalysis.Text = '**Select an hospitalization with at least 3 acquisitions to perform analysis**';
            app.PatientPersonalDataPanel_2 = uipanel(app.HomePage);
            app.PatientPersonalDataPanel_2.Title = 'Patient Personal Data';
            app.PatientPersonalDataPanel_2.Visible = 'off';
            app.NameEditField_2Label = uilabel(app.PatientPersonalDataPanel_2);
            app.NameEditField_2Label.Text = 'Name';
            app.NameEditField_2 = uieditfield(app.PatientPersonalDataPanel_2, 'text');
            app.GenderButtonGroup_2 = uibuttongroup(app.PatientPersonalDataPanel_2);
            app.GenderButtonGroup_2.Title = 'Gender';
            app.MButton_2 = uiradiobutton(app.GenderButtonGroup_2);
            app.MButton_2.Text = 'M';
            app.MButton_2.Value = true;
            app.FButton_2 = uiradiobutton(app.GenderButtonGroup_2);
            app.FButton_2.Text = 'F';
            app.SurnameEditField_2Label = uilabel(app.PatientPersonalDataPanel_2);
            app.SurnameEditField_2Label.Text = 'Surname';
            app.SurnameEditField_2 = uieditfield(app.PatientPersonalDataPanel_2, 'text');
            app.SurnameEditFieldLabel_3 = uilabel(app.PatientPersonalDataPanel_2);
            app.SurnameEditFieldLabel_3.Text = 'Birth Place';
            app.BirthPlaceEditField_2 = uidropdown(app.PatientPersonalDataPanel_2);
            app.BirthPlaceEditField_2.Items = {};
            app.BirthPlaceEditField_2.Editable = 'on';
            app.BirthPlaceEditField_2.Value = {};
            app.PatientIDFCLabel_2 = uilabel(app.PatientPersonalDataPanel_2);
            app.PatientIDFCLabel_2.Text = 'Patient ID (F.C.)';
            app.SSNEditField_2 = uieditfield(app.PatientPersonalDataPanel_2, 'text');
            app.BirthDateLabel_2 = uilabel(app.PatientPersonalDataPanel_2);
            app.BirthDateLabel_2.Text = 'Birth Date';
            app.BirthDateEditField_2 = uidatepicker(app.PatientPersonalDataPanel_2);
            app.BirthDateEditField_2.Limits = [datetime([1850 1 1]) datetime([9999 12 31])];
            app.BirthDateEditField_2.DisplayFormat = 'dd/MMM/uuuu';
            app.BirthDateEditField_2.Placeholder = 'dd/MM/yyyy';
            app.DataMessageText_2 = uitextarea(app.PatientPersonalDataPanel_2);
            app.DataMessageText_2.Editable = 'off';
            app.DataMessageText_2.Visible = 'off';
            app.PersonalDataSavingStatus_2 = uilamp(app.PatientPersonalDataPanel_2);
            app.PersonalDataSavingStatus_2.Visible = 'off';
            app.AddressEditField_2Label_2 = uilabel(app.PatientPersonalDataPanel_2);
            app.AddressEditField_2Label_2.Text = 'Address';
            app.AddressEditField_2 = uieditfield(app.PatientPersonalDataPanel_2, 'text');
            app.SalvaPazienteButton = uibutton(app.PatientPersonalDataPanel_2, 'push');
            app.SalvaPazienteButton.ButtonPushedFcn = createCallbackFcn(app, @SalvaPazienteButtonPushed, true);
            app.SalvaPazienteButton.Text = 'Save edit';
            app.ResetButton_3 = uibutton(app.PatientPersonalDataPanel_2, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_3Pushed, true);
            app.ResetButton_3.Text = 'Reset';
            app.ImportDataButton = uibutton(app.HomePage, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Icon = fullfile(pathToMLAPP, 'export_icon.png');
            app.ImportDataButton.Text = 'Import Data';
            app.ImportExportLamp = uilamp(app.HomePage);
            app.ImportExportLamp.Visible = 'off';
            app.ExportDataButton = uibutton(app.HomePage, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Icon = fullfile(pathToMLAPP, 'import_download.png');
            app.ExportDataButton.Text = 'Export Data';
            app.ImportExportText = uitextarea(app.HomePage);
            app.ImportExportText.Editable = 'off';
            app.ImportExportText.Visible = 'off';
            app.AnonymizeDataSwitch = uiswitch(app.HomePage, 'slider');
            app.AnonymizeDataSwitch.ItemsData = [0 1];
            app.AnonymizeDataSwitch.Value = 0;
            app.TemplateExcelButton = uibutton(app.HomePage, 'push');
            app.TemplateExcelButton.ButtonPushedFcn = createCallbackFcn(app, @TemplateExcelButtonPushed, true);
            app.TemplateExcelButton.Icon = fullfile(pathToMLAPP, 'import_download.png');
            app.TemplateExcelButton.Text = 'Template Excel';
            app.PazientiList = uitable(app.HomePage);

            app.PazientiList.ColumnName = {'Name'; 'SSN'; 'Hospitalization Date'; 'Hospitalization Time'; 'AMI Date'; 'AMI Time'; 'Acquisition Date'; 'Acquisition Time'};
            app.PazientiList.RowName = {};
            app.AnonymizeDataSwitchLabel = uilabel(app.HomePage);
            app.AnonymizeDataSwitchLabel.Text = 'Anonymize Data';
            app.CenterView = uitabgroup(app.UIFigure);
            app.CenterView.Visible = 'off';
            app.GestioneMediciTab = uitab(app.CenterView);
            app.GestioneMediciTab.Title = 'Manage Clinicians';
            app.UserLabel_2 = uilabel(app.GestioneMediciTab);
            app.UserLabel_2.Text = 'Clinician';
            app.MediciDropDown = uidropdown(app.GestioneMediciTab);
            app.MediciDropDown.Items = {};
            app.MediciDropDown.ItemsData = 1000;
            app.MediciDropDown.Editable = 'on';
            app.MediciDropDown.ValueChangedFcn = createCallbackFcn(app, @MediciDropDownValueChanged, true);
            app.MediciDropDown.Placeholder = 'Select user by SSN';
            app.MediciDropDown.Value = {};
            app.DeleteSelectedButton = uibutton(app.GestioneMediciTab, 'push');
            app.DeleteSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedButtonPushed, true);
            app.DeleteSelectedButton.Visible = 'off';
            app.DeleteSelectedButton.Text = 'Disable selected';
            app.NewClinic = uipanel(app.GestioneMediciTab);
            app.NewClinic.Title = 'New Clinician';
            app.NomeEditField_2Label_11 = uilabel(app.NewClinic);
            app.NomeEditField_2Label_11.Text = 'Name';
            app.NomeMedicoEditField = uieditfield(app.NewClinic, 'text');
            app.NomeEditField_2Label_10 = uilabel(app.NewClinic);
            app.NomeEditField_2Label_10.Text = 'Surname';
            app.CognomeMedicoEditField = uieditfield(app.NewClinic, 'text');
            app.NomeEditField_2Label_9 = uilabel(app.NewClinic);
            app.NomeEditField_2Label_9.Text = 'SSN';
            app.CFMedicoEditField = uieditfield(app.NewClinic, 'text');
            app.UsernameEditField_2Label_2 = uilabel(app.NewClinic);
            app.UsernameEditField_2Label_2.Text = 'Username';
            app.UsernameMedicoEditField = uieditfield(app.NewClinic, 'text');
            app.PasswordEditField_2Label = uilabel(app.NewClinic);
            app.PasswordEditField_2Label.Text = 'Password';
            app.PasswordMedicoEditField = uieditfield(app.NewClinic, 'text');
            app.EmailEditField_2Label = uilabel(app.NewClinic);
            app.EmailEditField_2Label.Text = 'E-mail';
            app.EmailMedicoEditField = uieditfield(app.NewClinic, 'text');
            app.MedicoSaveUserMsg = uitextarea(app.NewClinic);
            app.MedicoSaveUserMsg.Editable = 'off';
            app.MedicoSaveUserMsg.Visible = 'off';
            app.MedicoSavingStatus = uilamp(app.NewClinic);
            app.MedicoSavingStatus.Visible = 'off';
            app.NuovoMedicoButton = uibutton(app.NewClinic, 'push');
            app.NuovoMedicoButton.ButtonPushedFcn = createCallbackFcn(app, @NuovoMedicoButtonPushed, true);
            app.NuovoMedicoButton.Text = 'Save new';
            app.SalvaMedicoButton = uibutton(app.NewClinic, 'push');
            app.SalvaMedicoButton.ButtonPushedFcn = createCallbackFcn(app, @SalvaMedicoButtonPushed, true);
            app.SalvaMedicoButton.Text = 'Save edit';
            app.AnnullaMedicoButton = uibutton(app.NewClinic, 'push');
            app.AnnullaMedicoButton.ButtonPushedFcn = createCallbackFcn(app, @AnnullaMedicoButtonPushed, true);
            app.AnnullaMedicoButton.Text = 'Cancel';
            app.CenterinfoPanel = uipanel(app.GestioneMediciTab);
            app.CenterinfoPanel.Title = 'Center info';
            app.CenterNameLabel = uilabel(app.CenterinfoPanel);
            app.CenterNameLabel.Text = 'Center name';
            app.NumeroMedici = uilabel(app.CenterinfoPanel);
            app.NumeroMedici.Text = 'Clinicians';
            app.NumeroPazienti = uilabel(app.CenterinfoPanel);
            app.NumeroPazienti.Text = 'Patients';
            app.ListaMediciCentro = uitable(app.GestioneMediciTab);
            app.ListaMediciCentro.ColumnName = {'Username'; 'Name'; 'SSN'};
            app.ListaMediciCentro.RowName = {};
            app.AdminView = uitabgroup(app.UIFigure);
            app.AdminView.Visible = 'off';
            app.ManageusersTab = uitab(app.AdminView);
            app.ManageusersTab.Title = 'Manage users';
            app.ManageusersTab.ButtonDownFcn = createCallbackFcn(app, @ManageusersTabButtonDown, true);
            app.UserLabel = uilabel(app.ManageusersTab);
            app.UserLabel.Text = 'User';
            app.NomeUtenteDropDown = uidropdown(app.ManageusersTab);
            app.NomeUtenteDropDown.Items = {};
            app.NomeUtenteDropDown.Editable = 'on';
            app.NomeUtenteDropDown.ValueChangedFcn = createCallbackFcn(app, @NomeUtenteDropDownValueChanged, true);
            app.NomeUtenteDropDown.Placeholder = 'Select user by SSN';
            app.NomeUtenteDropDown.Value = {};
            app.DatiutentePanel = uipanel(app.ManageusersTab);
            app.DatiutentePanel.Title = 'User''s data';
            app.NomeEditField_2Label_8 = uilabel(app.DatiutentePanel);
            app.NomeEditField_2Label_8.Text = 'Name';
            app.NomeEditField = uieditfield(app.DatiutentePanel, 'text');
            app.NomeEditField_2Label_7 = uilabel(app.DatiutentePanel);
            app.NomeEditField_2Label_7.Text = 'Surname';
            app.CognomeEditField = uieditfield(app.DatiutentePanel, 'text');
            app.NomeEditField_2Label_3 = uilabel(app.DatiutentePanel);
            app.NomeEditField_2Label_3.Text = 'SSN';
            app.CodiceFiscaleEditField = uieditfield(app.DatiutentePanel, 'text');
            app.NomeEditField_2Label_6 = uilabel(app.DatiutentePanel);
            app.NomeEditField_2Label_6.Text = 'Username';
            app.UsernameEditField = uieditfield(app.DatiutentePanel, 'text');
            app.NomeEditField_2Label_5 = uilabel(app.DatiutentePanel);
            app.NomeEditField_2Label_5.Text = 'Password';
            app.PasswordEditField = uieditfield(app.DatiutentePanel, 'text');
            app.NomeEditField_2Label_4 = uilabel(app.DatiutentePanel);
            app.NomeEditField_2Label_4.Text = 'E-mail';
            app.EmailEditField = uieditfield(app.DatiutentePanel, 'text');
            app.CenterLabel = uilabel(app.DatiutentePanel);
            app.CenterLabel.Text = 'Center';
            app.CenterUserDropDown = uidropdown(app.DatiutentePanel);
            app.CenterUserDropDown.Items = {};
            app.CenterUserDropDown.Placeholder = 'Select a center';
            app.CenterUserDropDown.Value = {};
            app.SecuritylevelLabel = uilabel(app.DatiutentePanel);
            app.SecuritylevelLabel.Text = 'Security level';
            app.LivelloDropDown = uidropdown(app.DatiutentePanel);
            app.LivelloDropDown.Items = {};
            app.LivelloDropDown.Placeholder = 'Select access level';
            app.LivelloDropDown.Value = {};
            app.UtenteSaveUserMsg = uitextarea(app.DatiutentePanel);
            app.UtenteSaveUserMsg.Editable = 'off';
            app.UtenteSaveUserMsg.Visible = 'off';
            app.UtenteSavingStatus = uilamp(app.DatiutentePanel);
            app.UtenteSavingStatus.Visible = 'off';
            app.NuovoUtenteButton = uibutton(app.DatiutentePanel, 'push');
            app.NuovoUtenteButton.ButtonPushedFcn = createCallbackFcn(app, @NuovoUtenteButtonPushed, true);
            app.NuovoUtenteButton.Text = 'Save new';
            app.SalvaUtenteButton = uibutton(app.DatiutentePanel, 'push');
            app.SalvaUtenteButton.ButtonPushedFcn = createCallbackFcn(app, @SalvaUtenteButtonPushed, true);
            app.SalvaUtenteButton.Text = 'Save edit';
            app.AnnullaButton = uibutton(app.DatiutentePanel, 'push');
            app.AnnullaButton.ButtonPushedFcn = createCallbackFcn(app, @AnnullaButtonPushed, true);
            app.AnnullaButton.Text = 'Cancel';
            app.ListaUtenti = uitable(app.ManageusersTab);
            app.ListaUtenti.ColumnName = {'ID'; 'SSN'; 'Surname'; 'Name'; 'Username'; 'Center'};
            app.ListaUtenti.RowName = {};
            app.ListaUtenti.ColumnSortable = [true true true true true true];
            app.ListaUtenti.SelectionType = 'row';
            app.ManagecentersTab = uitab(app.AdminView);
            app.ManagecentersTab.Title = 'Manage centers';
            app.ManagecentersTab.ButtonDownFcn = createCallbackFcn(app, @ManagecentersTabButtonDown, true);
            app.CenterDropDownLabel = uilabel(app.ManagecentersTab);
            app.CenterDropDownLabel.Text = 'Center';
            app.CenterDropDown = uidropdown(app.ManagecentersTab);
            app.CenterDropDown.Items = {};
            app.CenterDropDown.Editable = 'on';
            app.CenterDropDown.ValueChangedFcn = createCallbackFcn(app, @CenterDropDownValueChanged, true);
            app.CenterDropDown.Placeholder = 'Select center by name';
            app.CenterDropDown.Value = {};
            app.CentersdataPanel = uipanel(app.ManagecentersTab);
            app.CentersdataPanel.Title = 'Center''s data';
            app.NomeEditField_2Label_2 = uilabel(app.CentersdataPanel);
            app.NomeEditField_2Label_2.Text = 'Name';
            app.NomeCentroEditField = uieditfield(app.CentersdataPanel, 'text');
            app.AddressEditField_2Label = uilabel(app.CentersdataPanel);
            app.AddressEditField_2Label.Text = 'Address';
            app.IndirizzoEditField = uieditfield(app.CentersdataPanel, 'text');
            app.LocationCityEditFieldLabel = uilabel(app.CentersdataPanel);
            app.LocationCityEditFieldLabel.Text = 'Location (City)';
            app.LocationCityEditField = uieditfield(app.CentersdataPanel, 'text');
            app.CenteradministratorDropDownLabel = uilabel(app.CentersdataPanel);
            app.CenteradministratorDropDownLabel.Text = 'Center administrator';
            app.CenteradministratorDropDown = uidropdown(app.CentersdataPanel);
            app.CenteradministratorDropDown.Items = {};
            app.CenteradministratorDropDown.Value = {};
            app.NumeromediciLabel = uilabel(app.CentersdataPanel);
            app.NumeromediciLabel.Text = 'Number of clinicians';
            app.NumeroMediciEditField = uieditfield(app.CentersdataPanel, 'numeric');
            app.NumeroMediciEditField.Editable = 'off';
            app.NumeromediciLabel_2 = uilabel(app.CentersdataPanel);
            app.NumeromediciLabel_2.Text = 'Number of patients';
            app.NumeroPazientiEditField = uieditfield(app.CentersdataPanel, 'numeric');
            app.NumeroPazientiEditField.Editable = 'off';
            app.CentroSaveUserMsg = uitextarea(app.CentersdataPanel);
            app.CentroSaveUserMsg.Editable = 'off';
            app.CentroSaveUserMsg.Visible = 'off';
            app.CentroSavingStatus = uilamp(app.CentersdataPanel);
            app.CentroSavingStatus.Visible = 'off';
            app.NuovoCentroButton = uibutton(app.CentersdataPanel, 'push');
            app.NuovoCentroButton.ButtonPushedFcn = createCallbackFcn(app, @NuovoCentroButtonPushed, true);
            app.NuovoCentroButton.Text = 'Save new';
            app.SalvaCentroButton = uibutton(app.CentersdataPanel, 'push');
            app.SalvaCentroButton.ButtonPushedFcn = createCallbackFcn(app, @SalvaCentroButtonPushed, true);
            app.SalvaCentroButton.Text = 'Save edits';
            app.AnnullaCentroButton = uibutton(app.CentersdataPanel, 'push');
            app.AnnullaCentroButton.ButtonPushedFcn = createCallbackFcn(app, @AnnullaCentroButtonPushed, true);
            app.AnnullaCentroButton.Text = 'Cancel';
            app.ListaCentri = uitable(app.ManagecentersTab);
            app.ListaCentri.ColumnName = {'ID'; 'Name'; 'Address'; 'Location'; '# Clinic'; '# Patients'; 'Administrator'};
            app.ListaCentri.RowName = {};
            app.ListaCentri.ColumnSortable = [true true true true false false false];
            app.UsagemonitorTab = uitab(app.AdminView);
            app.UsagemonitorTab.Title = 'Usage monitor';
            app.UsagemonitorTab.ButtonDownFcn = createCallbackFcn(app, @UsagemonitorTabButtonDown, true);
            app.UsageMonitorPanel = uipanel(app.UsagemonitorTab);
            app.FromDatePickerLabel = uilabel(app.UsageMonitorPanel);
            app.FromDatePickerLabel.Text = 'From';
            app.FromDatePicker = uidatepicker(app.UsageMonitorPanel);
            app.ToDatePickerLabel = uilabel(app.UsageMonitorPanel);
            app.ToDatePickerLabel.Text = 'To';
            app.ToDatePicker = uidatepicker(app.UsageMonitorPanel);
            app.LoginsdistributionPanel = uipanel(app.UsageMonitorPanel);
            app.LoginsdistributionPanel.Title = 'Logins distribution';
            app.LoginAxes = uiaxes(app.LoginsdistributionPanel);
            app.LoginTable = uitable(app.LoginsdistributionPanel);
            app.LoginTable.ColumnName = {'Datetime'; 'Username'; 'Access Level'};
            app.LoginTable.RowName = {};
            app.LoginTable.ColumnSortable = [true true true];
            app.LoginTable.SelectionType = 'row';
            app.LoginTable.Multiselect = 'off';
            app.ExportUsageButton = uibutton(app.UsageMonitorPanel, 'push');
            app.ExportUsageButton.ButtonPushedFcn = createCallbackFcn(app, @ExportUsageButtonPushed, true);
            app.ExportUsageButton.Text = 'Export';
            app.RegisterederrorsEditFieldLabel = uilabel(app.UsageMonitorPanel);
            app.RegisterederrorsEditFieldLabel.Text = 'Registered errors';
            app.RegisterederrorsEditField = uieditfield(app.UsageMonitorPanel, 'numeric');
            app.RegisterederrorsEditField.Editable = 'off';
            app.UsagedistributionPanel = uipanel(app.UsageMonitorPanel);
            app.UsagedistributionPanel.Title = 'Usage distribution';
            app.UsageAxes = uiaxes(app.UsagedistributionPanel);
            xlabel(app.UsageAxes, 'Pages')
            zlabel(app.UsageAxes, 'Z')
            app.ErrorTable = uitable(app.UsagedistributionPanel);
            app.ErrorTable.ColumnName = {'Datetime'; 'Identifier'; 'Function'; 'Line'};
            app.ErrorTable.RowName = {};
            app.ErrorTable.ColumnSortable = [true true true true];
            app.ErrorTable.SelectionType = 'row';
            app.SearchUsageButton = uibutton(app.UsageMonitorPanel, 'push');
            app.SearchUsageButton.ButtonPushedFcn = createCallbackFcn(app, @SearchUsageButtonPushed, true);
            app.SearchUsageButton.Text = 'Search';
            app.LoginView = uipanel(app.UIFigure);
            app.WELCOMEinCBRALabel = uilabel(app.LoginView);
            app.WELCOMEinCBRALabel.Text = 'WELCOME in CBRA';
            app.LoginForm = uipanel(app.LoginView);
            app.TextArea = uitextarea(app.LoginForm);
            app.TextArea.Editable = 'off';
            app.TextArea.Visible = 'off';
            app.UsernameEditField_2Label = uilabel(app.LoginForm);
            app.UsernameEditField_2Label.Text = 'Username';
            app.UsernameField = uieditfield(app.LoginForm, 'text');
            app.UsernameField.Placeholder = 'Insert username';
            app.PasswordHTMLLabel = uilabel(app.LoginForm);
            app.PasswordHTMLLabel.Text = {'Password'; ''};
            app.PasswordHTML = uihtml(app.LoginForm);
            app.PasswordHTML.HTMLSource = 'passwordEdit.html';
            app.PasswordHTML.DataChangedFcn = createCallbackFcn(app, @PasswordHTMLDataChanged, true);
            app.LoginButton = uibutton(app.LoginForm, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.RefreshConn = uibutton(app.LoginForm, 'push');
            app.RefreshConn.ButtonPushedFcn = createCallbackFcn(app, @RefreshConnPushed, true);
            app.RefreshConn.Icon = fullfile(pathToMLAPP, 'refresh.png');
            app.RefreshConn.Text = '';
            app.UMGLogo2 = uiimage(app.LoginView);
            app.UMGLogo2.ImageSource = 'UMG.jpg';
            app.UMGLogo1 = uiimage(app.LoginView);
            app.UMGLogo1.ImageSource = 'Logo_UMG_biomechatronics.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cbraAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end